classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        menu_Grid0                      matlab.ui.container.GridLayout
        menu_Grid1                      matlab.ui.container.GridLayout
        menu_Separator1_3               matlab.ui.control.Image
        menu_Separator1_2               matlab.ui.control.Image
        menu_Button1                    matlab.ui.control.StateButton
        menu_Button2                    matlab.ui.control.StateButton
        menu_Button3                    matlab.ui.control.StateButton
        menu_Button4                    matlab.ui.control.StateButton
        menu_Grid2                      matlab.ui.container.GridLayout
        menu_Button5                    matlab.ui.control.Button
        menu_Button6                    matlab.ui.control.Button
        menu_Button7                    matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        menu_Grid3                      matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_searchPath                 matlab.ui.control.Image
        Panel_3                         matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ToolBar_Grid                    matlab.ui.container.GridLayout
        ToolBar_Button1                 matlab.ui.control.Button
        ToolBar_Button2                 matlab.ui.control.Button
        ToolBar_Button3                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_Home                       matlab.ui.container.Tab
        Home_Grid0                      matlab.ui.container.GridLayout
        Home_mainTitle                  matlab.ui.control.Label
        Home_subTitle                   matlab.ui.control.Label
        Home_ProjectLogo                matlab.ui.control.Image
        Tab2_Connection                 matlab.ui.container.Tab
        Connection_Grid0                matlab.ui.container.GridLayout
        Connection_Label1               matlab.ui.control.Label
        Connection_Panel1               matlab.ui.container.Panel
        Connection_Grid1                matlab.ui.container.GridLayout
        DescrioopcionalLabel            matlab.ui.control.Label
        Connection_Description          matlab.ui.control.EditField
        Connection_Buffer               matlab.ui.control.NumericEditField
        TimeoutsegEditField_2Label      matlab.ui.control.Label
        Connection_Port                 matlab.ui.control.EditField
        Connection_Timeout              matlab.ui.control.NumericEditField
        PortaEditFieldLabel             matlab.ui.control.Label
        FamliaLabel                     matlab.ui.control.Label
        Connection_IP                   matlab.ui.control.EditField
        Connection_Type                 matlab.ui.control.DropDown
        Connection_Name                 matlab.ui.control.DropDown
        Connection_Family               matlab.ui.control.DropDown
        BufferentradaMBEditFieldLabel   matlab.ui.control.Label
        FabricanteemodeloDropDownLabel  matlab.ui.control.Label
        TipodeconexoDropDownLabel       matlab.ui.control.Label
        EndereoIPEditField_2Label       matlab.ui.control.Label
        Connection_Label2               matlab.ui.control.Label
        Connection_Panel2               matlab.ui.container.Panel
        Connection_Grid2                matlab.ui.container.GridLayout
        Connection_Grid3                matlab.ui.container.GridLayout
        Instr_Description               matlab.ui.control.Label
        Instr_PreampLabel               matlab.ui.control.Label
        Instr_freqRange                 matlab.ui.control.EditField
        FaixadefrequnciaLabel           matlab.ui.control.Label
        PontosporvarreduraLabel_2       matlab.ui.control.Label
        Instr_Points                    matlab.ui.control.TextArea
        Instr_Image                     matlab.ui.control.Image
        PotnciamximaLabel               matlab.ui.control.Label
        Instr_maxLevel                  matlab.ui.control.EditField
        Instr_Preamp                    matlab.ui.control.CheckBox
        Connection_Grid4                matlab.ui.container.GridLayout
        Instr_Detector                  matlab.ui.control.TextArea
        DetectorLabel                   matlab.ui.control.Label
        Instr_TraceMode                 matlab.ui.control.TextArea
        MododotraoLabel                 matlab.ui.control.Label
        Instr_Resolution                matlab.ui.control.TextArea
        ResoluoLabel                    matlab.ui.control.Label
        AtenuaoLabel_3                  matlab.ui.control.Label
        Instr_attFactor                 matlab.ui.control.TextArea
        Connection_addInstrument        matlab.ui.control.Image
        Connection_deleteInstrument     matlab.ui.control.Image
        Tree1                           matlab.ui.container.Tree
        Connection_EditButton           matlab.ui.control.StateButton
        Connection_ConnectButton        matlab.ui.control.Button
        Tab3_Task                       matlab.ui.container.Tab
        Task_Grid0                      matlab.ui.container.GridLayout
        Task_Grid6                      matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        Task_Grid7                      matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        StepWidthUnit_FSL               matlab.ui.control.Label
        PassodavarreduraLabel           matlab.ui.control.Label
        Task_StepWidth_FSL              matlab.ui.control.NumericEditField
        Task_DataPoints_EB500           matlab.ui.control.NumericEditField
        Task_StepWidth_EB500            matlab.ui.control.DropDown
        Task_Resolution_FSL             matlab.ui.control.DropDown
        ResoluoLabel_2                  matlab.ui.control.Label
        Task_attFactor                  matlab.ui.control.DropDown
        AtenuadorDropDownLabel          matlab.ui.control.Label
        Task_attMode                    matlab.ui.control.DropDown
        MododoatenuadorDropDownLabel    matlab.ui.control.Label
        PramplificadorDropDownLabel     matlab.ui.control.Label
        Task_Preamp                     matlab.ui.control.DropDown
        SeletividadeLabel               matlab.ui.control.Label
        Task_Selectivity                matlab.ui.control.DropDown
        Task_DataPoints_FSL             matlab.ui.control.Spinner
        PontosportraoLabel              matlab.ui.control.Label
        Task_RefreshButton              matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Task_BandUnit5                  matlab.ui.control.Label
        TempoderevisitaEditFieldLabel   matlab.ui.control.Label
        Task_RevisitTime                matlab.ui.control.NumericEditField
        DetectorDropDownLabel           matlab.ui.control.Label
        Task_Detector                   matlab.ui.control.DropDown
        kHzLabel_2                      matlab.ui.control.Label
        kHzLabel_3                      matlab.ui.control.Label
        ResoluoLabel_3                  matlab.ui.control.Label
        Task_Resolution                 matlab.ui.control.NumericEditField
        FatordeintegraoLabel            matlab.ui.control.Label
        Task_IntegrationFactor          matlab.ui.control.NumericEditField
        Task_LevelUnit                  matlab.ui.control.DropDown
        UnidadeDropDownLabel            matlab.ui.control.Label
        Task_TraceMode                  matlab.ui.control.DropDown
        MododotraoDropDownLabel         matlab.ui.control.Label
        Task_RFMode                     matlab.ui.control.DropDown
        MododeRFLabel                   matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        PassodavarreduraEditField_2Label  matlab.ui.control.Label
        Task_StepWidth                  matlab.ui.control.NumericEditField
        FrequnciafinalEditField_3Label  matlab.ui.control.Label
        Task_F1                         matlab.ui.control.NumericEditField
        FrequnciainicialEditField_2Label  matlab.ui.control.Label
        Task_F0                         matlab.ui.control.NumericEditField
        DescrioEditFieldLabel           matlab.ui.control.Label
        Task_Description                matlab.ui.control.EditField
        ThreadIDEditFieldLabel          matlab.ui.control.Label
        Task_ThreadID                   matlab.ui.control.NumericEditField
        Task_Label9_2                   matlab.ui.control.Label
        Task_Warning2                   matlab.ui.control.Image
        Task_editBand                   matlab.ui.control.StateButton
        Task_newBand                    matlab.ui.control.StateButton
        Task_executeTask                matlab.ui.control.Button
        Task_Label9                     matlab.ui.control.Label
        Task_Warning1                   matlab.ui.control.Image
        Task_addBand                    matlab.ui.control.Image
        Task_deleteBand                 matlab.ui.control.Image
        Tree3                           matlab.ui.container.Tree
        Task_Grid2                      matlab.ui.container.GridLayout
        Task_Panel1                     matlab.ui.container.Panel
        Task_Grid3                      matlab.ui.container.GridLayout
        Task_startTimeDate              matlab.ui.control.DatePicker
        IncioDatePickerLabel            matlab.ui.control.Label
        Task_startTimeHours             matlab.ui.control.Spinner
        Task_startTimeMinutes           matlab.ui.control.Spinner
        Task_Duration                   matlab.ui.control.NumericEditField
        Task_DurationUnit               matlab.ui.control.DropDown
        DuraoEditFieldLabel             matlab.ui.control.Label
        Task_startImediately            matlab.ui.control.CheckBox
        GridLayout10                    matlab.ui.container.GridLayout
        Task_deleteTask                 matlab.ui.control.Image
        Task_Label1                     matlab.ui.control.Label
        Tree2                           matlab.ui.container.Tree
        Task_Label6                     matlab.ui.control.Label
        Task_Name                       matlab.ui.control.EditField
        Task_BitsPerPoint               matlab.ui.control.DropDown
        Task_Label7                     matlab.ui.control.Label
        AntenaLabel                     matlab.ui.control.Label
        Task_Antenna                    matlab.ui.control.DropDown
        Task_Panel3                     matlab.ui.container.Panel
        Task_Grid5                      matlab.ui.container.GridLayout
        Task_AntennaHeight              matlab.ui.control.NumericEditField
        Task_AntennaUnit1               matlab.ui.control.Label
        Task_AntennaAzimuth             matlab.ui.control.NumericEditField
        Task_AntennaUnit2               matlab.ui.control.Label
        Task_AntennaElevation           matlab.ui.control.NumericEditField
        Task_AntennaUnit3               matlab.ui.control.Label
        ElevaoEditFieldLabel            matlab.ui.control.Label
        AzimuteEditFieldLabel           matlab.ui.control.Label
        AlturadeinstalaoEditFieldLabel  matlab.ui.control.Label
        Task_Instrument                 matlab.ui.control.DropDown
        Task_GPS                        matlab.ui.control.DropDown
        Task_Panel2                     matlab.ui.container.Panel
        Task_Grid4                      matlab.ui.container.GridLayout
        LatitudeEditFieldLabel          matlab.ui.control.Label
        Task_manualLatitude             matlab.ui.control.NumericEditField
        Task_BandUnit5_2                matlab.ui.control.Label
        Task_gpsRevisitTime             matlab.ui.control.NumericEditField
        TempoderevisitaLabel            matlab.ui.control.Label
        LongitudeEditFieldLabel         matlab.ui.control.Label
        Task_manualLongitude            matlab.ui.control.NumericEditField
        GPSLabel                        matlab.ui.control.Label
        AnalisadordeespectroreceptorLabel  matlab.ui.control.Label
        CodificaobitsporpontoDropDownLabel  matlab.ui.control.Label
        NomeEditFieldLabel              matlab.ui.control.Label
        Tab4_RealTime                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Connection_Grid1_2              matlab.ui.container.GridLayout
        udpLogger_TextInfo              matlab.ui.control.TextArea
        InformaestextuaisTextAreaLabel  matlab.ui.control.Label
        udpLogger_Panel3                matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        BlocosLabel                     matlab.ui.control.Label
        udpLogger_gpsCount              matlab.ui.control.NumericEditField
        udpLogger_gpsLongitude          matlab.ui.control.NumericEditField
        LongitudeLabel                  matlab.ui.control.Label
        LatitudeEditFieldLabel_2        matlab.ui.control.Label
        udpLogger_gpsLatitude           matlab.ui.control.NumericEditField
        udpLogger_Label4                matlab.ui.control.Label
        Button                          matlab.ui.control.StateButton
        Panel_6                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        udpLogger_Hostname              matlab.ui.control.EditField
        HostnameLabel                   matlab.ui.control.Label
        MethodLabel                     matlab.ui.control.Label
        udpLogger_Method                matlab.ui.control.EditField
        UnitInfoLabel                   matlab.ui.control.Label
        udpLogger_UnitInfo              matlab.ui.control.EditField
        udpLogger_Label4_2              matlab.ui.control.Label
        udpLogger_Panel1                matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        udpLogger_IP                    matlab.ui.control.EditField
        EndereoIPLabel                  matlab.ui.control.Label
        udpLogger_Unit2                 matlab.ui.control.Label
        udpLogger_Pause                 matlab.ui.control.Spinner
        PausaentrealeituradedatagramasLabel  matlab.ui.control.Label
        udpLogger_Unit1                 matlab.ui.control.Label
        udpLogger_Time                  matlab.ui.control.NumericEditField
        DuraodaconexoLabel              matlab.ui.control.Label
        udpLogger_Port                  matlab.ui.control.NumericEditField
        PortaLabel                      matlab.ui.control.Label
        udpLogger_Node                  matlab.ui.control.DropDown
        SensorLabel                     matlab.ui.control.Label
        udpLogger_Button                matlab.ui.control.StateButton
        GridLayout6_2                   matlab.ui.container.GridLayout
        udpLogger_CheckBox              matlab.ui.control.CheckBox
        Tree4                           matlab.ui.container.Tree
        udpLogger_Label3                matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        DatagramasdisponveisparaleituraEditFieldLabel  matlab.ui.control.Label
        udpLogger_Datagram2             matlab.ui.control.NumericEditField
        udpLogger_Datagram1             matlab.ui.control.NumericEditField
        DatagramaslidosLabel            matlab.ui.control.Label
        udpLogger_Button_2              matlab.ui.control.StateButton
        Panel_7                         matlab.ui.container.Panel
        GridLayout6_3                   matlab.ui.container.GridLayout
        udpLogger_DataPoints            matlab.ui.control.NumericEditField
        PontosportraoLabel_2            matlab.ui.control.Label
        UnidadeLabel                    matlab.ui.control.Label
        udpLogger_LevelUnit             matlab.ui.control.EditField
        OperaoLabel                     matlab.ui.control.Label
        udpLogger_Operation             matlab.ui.control.EditField
        kHzLabel_5                      matlab.ui.control.Label
        kHzLabel_4                      matlab.ui.control.Label
        ResoluoLabel_4                  matlab.ui.control.Label
        udpLogger_Resolution            matlab.ui.control.NumericEditField
        PassodavarreduraEditField_2Label_2  matlab.ui.control.Label
        udpLogger_StepWidth             matlab.ui.control.NumericEditField
        DescrioEditFieldLabel_2         matlab.ui.control.Label
        udpLogger_Description           matlab.ui.control.EditField
        udpLogger_Label3_2              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        AdicionarlistadegravaodoarquivoMenu  matlab.ui.container.Menu
        RetirardalistaMenu              matlab.ui.container.Menu
    end

    
    % Instrumentos usados nos testes:
    % - 'ROHDE&SCHWARZ,EB500,101.518/002,V04.90-4072.8710.00' 
    % - 'Rohde&Schwarz,FSL-6,100719/006,2.50'
    % - 'Anritsu,MS2720T/25/31/732,1912002,3.40'
        
    properties (Access = private)
        
        RootFolder = [];
        
        instrList
        taskList
        EB500_RBW
        
        bandList                    % udpLogger 
        selectBand                  % udpLogger (Fluxos Selecionados)
        recFlag = 0;
        
        instrFlag = 1;
        instrInfo
        
        Instrument
        udpPort
        gpsInstrument
        
        tmr      = [];
        timeInfo = [];
        specInfo = [];
        gpsInfo  = [];
        
        fig     = [];
        axes1   = [];
        
    end
    
    
    methods (Access = private)
        
        function Connection_infoPanel(app, instrumentName)
            
            % Refletir sobr o tipo de informação que constará aqui. Em
            % relação à RBW e StepWidth (no caso do EB500), por exemplo, 
            % indicar que somente foram implantados àqueles valores
            % comumente usados em monitorações do espectro.
            
            % Ajustar os campos e as informações a depender do equipamento.
            % O campo de atenuação, por exemplo, pode ser mais simples.
            % 0:5:30 dB (FSL) ou 0:1:40 dB (EB500).
            
            % Inserir na TABELA AUXILIAR que será lida no início da
            % aplicação. Comandos SCPI, possíveis valores (Detector,
            % TraceMode, RBW, Step), descrição, link pra imagem. Tudo!
            
            app.Connection_Label2.Visible = 1;
            app.Connection_Panel2.Visible = 1;
            
            switch instrumentName
                case 'CRFS RFeye Node 20-6'
                    app.Instr_Image.ImageSource = 'Instr_CRFS1.png';
                    app.Instr_Description.Text  = 'Adquiridas X unidades em 2012...';
                    
                    app.Instr_freqRange.Value   = '20 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 1;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'};
                    
                case 'Anritsu MS2720T'
                    app.Instr_Image.ImageSource = 'Instr_Anritsu1.png';
                    app.Instr_Description.Text  = 'Adquiridas X unidades em 2020...';
                    
                    app.Instr_freqRange.Value   = '10 MHz - 46 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'; 'RMS'; 'Positive'};

                case 'Keysight N9344C'
                    app.Instr_Image.ImageSource = 'Instr_KeySight1.png';
                    app.Instr_Description.Text  = ['Adquiridas 38 unidades entre 2006 e 2007, preenchendo a necessidade de um ' ...
                                                   'analisador mais portátil, monitorando até 6 GHz. Alta sensibilidade ' ...
                                                    '(em especial quando habilitado o pré-amplificador de 20dB), '    ...
                                                    'rapidez (até 2.5ms) e número de pontos por traço configurável, ' ...
                                                    'podendo coincidir com canalização da faixa monitorada.'];
                    
                    app.Instr_freqRange.Value   = '1 MHz - 20 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'; 'RMS'; 'Positive'};

                case 'Rohde & Schwarz EB500'
                    app.Instr_Image.ImageSource = 'Instr_R&S_EB500.png';
                    app.Instr_Description.Text  = 'Adquiridas X unidades em 2012...';
                    
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Points.Value      = '101 - 32001';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '10 dB'; '20 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'; '10 MHz'};
                    app.Instr_TraceMode.Value   = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
                    app.Instr_Detector.Value    = {'Fast'; 'RMS'; 'Positive'};
                    
                case 'Rohde & Schwarz FSL-6'
                    app.Instr_Image.ImageSource = 'Instr_R&S_FSL.png';
                    app.Instr_Description.Text  = ['Adquiridas 38 unidades entre 2006 e 2007, preenchendo a necessidade de um ' ...
                                                   'analisador mais portátil, monitorando até 6 GHz. Alta sensibilidade ' ...
                                                    '(em especial quando habilitado o pré-amplificador de 20dB), '    ...
                                                    'rapidez (até 2.5ms) e número de pontos por traço configurável, ' ...
                                                    'podendo coincidir com canalização da faixa monitorada.'];
                    
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Preamp.Value      = 1;
                    app.Instr_attFactor.Value   = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
                    app.Instr_TraceMode.Value   = {'Average'; 'Clear Write'; 'Max Hold'; 'Min Hold'};
                    app.Instr_Detector.Value    = {'Auto Peak'; 'Average'; 'Negative Peak'; 'Positive Peak'; 'Quasi Peak'; 'RMS'; 'Sample'};
                    
                case 'Rohde & Schwarz FSVR-20'
                    app.Instr_Image.ImageSource = 'Instr_R&S_FSVR.png';
                    app.Instr_Description.Text  = ['Adquiridas 13 unidades em 2012. ' ...
                                                   'Estes equipamentos anteciparam a evolução de novas tecnologias sem ' ...
                                                   'fio, como a digitalização do sinal de televisão e a ' ...
                                                   'adoção do padrão LTE por operadoras de SMP.'];
                    
                    app.Instr_freqRange.Value   = '10 MHz - 6 GHz';
                    app.Instr_maxLevel.Value    = '30 dBm';
                    app.Instr_Preamp.Value      = 0;
                    app.Instr_attFactor.Value   = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
                    app.Instr_Resolution.Value  = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
                    app.Instr_TraceMode.Value   = {'Average'; 'Clear Write'; 'Max Hold'; 'Min Hold'};
                    app.Instr_Detector.Value    = {'Auto Peak'; 'Average'; 'Negative Peak'; 'Positive Peak'; 'Quasi Peak'; 'RMS'; 'Sample'};
                    
                case {'Garmin GPSmap 62sc', 'Smartphone'}
                    app.Connection_Label2.Visible = 0;
                    app.Connection_Panel2.Visible = 0;
                    
            end
            
        end
        
        
        function Connection_viewSelectedNode(app)
            
            ind = app.Tree1.SelectedNodes.NodeData;
            
            app.Connection_Family.Value = app.instrList(ind).Family;
            Connection_FamilyValueChanged(app)
            
            app.Connection_Name.Value = app.instrList(ind).Name;
            Connection_NameValueChanged(app)
            
            app.Connection_Type.Value = app.instrList(ind).Type;
            
            if strcmp(app.instrList(ind).Type, 'Visa TCPIP')
                app.PortaEditFieldLabel.Enable = 0;
                app.Connection_Port.Enable     = 0;
            else
                app.PortaEditFieldLabel.Enable = 1;
                app.Connection_Port.Enable     = 1;
            end
                
            if strcmp(app.instrList(ind).Type, 'Serial')
                app.EndereoIPEditField_2Label.Enable = 0;
                app.Connection_IP.Enable             = 0;
            else
                app.EndereoIPEditField_2Label.Enable = 1;
                app.Connection_IP.Enable             = 1;
            end
            
            app.Connection_IP.Value          = app.instrList(ind).IP;
            app.Connection_Port.Value        = app.instrList(ind).Port;
            app.Connection_Timeout.Value     = app.instrList(ind).Timeout;
            app.Connection_Buffer.Value      = app.instrList(ind).inBuffer;
            app.Connection_Description.Value = app.instrList(ind).Description;
            
        end
        
        
        function Fcn_Tree1(app)
            
            delete(app.Tree1.Children);
            
            app.Connection_Family.Enable = 1;
            app.Tree1.Enable             = 1;
            
            app.Connection_deleteInstrument.Enable = 0;
            app.Connection_ConnectButton.Enable    = 0;
            set(app.Connection_EditButton, 'Enable', 0, 'Value', 0);
            
            value = length(app.instrList);
            if value
                for ii = 1:value
                    if contains(app.instrList(ii).Name, 'RFeye')
                        imageIcon = 'Instr_CRFS2.png';
                    elseif contains(app.instrList(ii).Name, {'FSL', 'FSVR', 'EB500'})
                        imageIcon = 'Instr_R&S.png';
                    elseif contains(app.instrList(ii).Name, {'N9344C'})
                        imageIcon = 'Instr_KeySight2.png';
                    elseif contains(app.instrList(ii).Name, 'MS2720T')
                        imageIcon = 'Instr_Anritsu2.png';
                    elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'})
                        imageIcon = 'ToolBar_Global.png';
                    end
                    
                    if ~isempty(app.instrList(ii).Description)
                        tempNode = uitreenode(app.Tree1, 'Text', sprintf('%d: %s, %s, "%s"', ii, app.instrList(ii).Name, app.instrList(ii).Type, app.instrList(ii).Description), 'NodeData', ii, 'Icon', imageIcon);
                    else
                        tempNode = uitreenode(app.Tree1, 'Text', sprintf('%d: %s, %s', ii, app.instrList(ii).Name, app.instrList(ii).Type), 'NodeData', ii, 'Icon', imageIcon);
                    end
                    
                    if ~isempty(app.instrList(ii).IP) & ~isempty(app.instrList(ii).Port)
                        uitreenode(tempNode, 'Text', sprintf('IP: %s, Porta: %s', app.instrList(ii).IP, app.instrList(ii).Port), 'NodeData', ii);
                    elseif ~isempty(app.instrList(ii).IP)
                        uitreenode(tempNode, 'Text', sprintf('IP: %s',    app.instrList(ii).IP),   'NodeData', ii);
                    elseif ~isempty(app.instrList(ii).Port)
                        uitreenode(tempNode, 'Text', sprintf('Porta: %s', app.instrList(ii).Port), 'NodeData', ii);
                    end
                                        
                    uitreenode(tempNode, 'Text', sprintf('Timeout : %.0f seg, Buffer entrada: %.0f MB', app.instrList(ii).Timeout, app.instrList(ii).inBuffer), 'NodeData', ii);
                    
                end
                app.Tree1.SelectedNodes = app.Tree1.Children(1);
                
                app.Connection_deleteInstrument.Enable = 1;
                app.Connection_ConnectButton.Enable    = 1;
                app.Connection_EditButton.Enable       = 1;
            end
            
        end
        
        
        function Fcn_Tree2(app)
            
            delete(app.Tree2.Children);
            
            value = length(app.taskList);
            if value
                for ii = 1:value
                    uitreenode(app.Tree2, 'Text', app.taskList{ii}.Name, 'NodeData', ii);
                end
                
                if value > 1; app.Task_deleteTask.Enable = 1;
                else;         app.Task_deleteTask.Enable = 0;
                end
                
                app.Tree2.SelectedNodes = app.Tree2.Children(1);
            end
            
        end
        
        
        function Fcn_Tree3(app, ind)
            
            delete(app.Tree3.Children);
            
            value = length(app.taskList{ind}.Band);            
            if value
                if value > 1; app.Task_deleteBand.Enable = 1;
                else;         app.Task_deleteBand.Enable = 0;
                end
                
                for ii = 1:value
                    str = sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                        app.taskList{ind}.Band(ii).ThreadID,      ...
                        app.taskList{ind}.Band(ii).F0 / 1e+6,     ...
                        app.taskList{ind}.Band(ii).F1 / 1e+6);
                    
                    uitreenode(app.Tree3, 'Text', str, 'NodeData', ii);
                end
                app.Tree3.SelectedNodes = app.Tree3.Children(1);
                
            end
            
        end
        
        
        function Fcn_Tree4(app)
            
            delete(app.Tree4.Children);
            
            if ~issorted([app.bandList.ThreadID])
                [~, ind]     = sort([app.bandList.ThreadID]);
                app.bandList = app.bandList(ind);
            end
            
            for ii = 1:length(app.bandList)
                str = sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                    app.bandList(ii).ThreadID,  ...
                    app.bandList(ii).F0 / 1e+6,     ...
                    app.bandList(ii).F1 / 1e+6);
                
                treeChild = uitreenode(app.Tree4, 'Text', str, 'NodeData', ii, 'ContextMenu', app.ContextMenu);
                if ~isempty(app.bandList(ii).REC)
                    treeChild.Icon = 'REC1.png';
                end
            end
            app.Tree4.SelectedNodes = app.Tree4.Children(1);
            Tree4SelectionChanged(app)
            
            if isempty([app.bandList.REC])
                app.udpLogger_Button_2.Enable = 0;
            else
                app.udpLogger_Button_2.Enable = 1;
            end
            
        end
        
        
        function Layout_ModeOperation(app)
            
            app.menu_Button7.Value = 0;
            set(app.menu_Grid2.Children, 'Enable', 0)

            % Home mode:
            if app.menu_Button1.Value
                ind = 1;
            
            % Connection mode:
            elseif app.menu_Button2.Value
                ind = 2;
                
                if isempty(app.Tree1.Children)
                    Fcn_Tree1(app)
                end
                Tree1SelectionChanged(app)
                
            % Task mode:
            elseif app.menu_Button3.Value
                ind = 3;
                set(app.menu_Grid2.Children, 'Enable', 1)
                
                Layout_TaskMode_Panel1(app)
                Layout_TaskMode_Panel2(app)
                
                if isempty(app.Tree2.Children)
                    Fcn_Tree2(app)
                end
                
                ind1 = app.Tree2.SelectedNodes.NodeData;
                Fcn_Tree3(app, ind1)
                
                ind2 = app.Tree3.SelectedNodes.NodeData;
                Fcn_TaskMode_Info(app, ind1, ind2)

                for ii = 1:length(app.taskList{ind1}.Band)
                    Fcn_TaskMode_Refresh1(app, ind1, ii)
                end
                
                Fcn_TaskMode_Instr(app, ind1, ind2)
            
            % Runing task mode:
            elseif app.menu_Button4.Value
                ind = 4;
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(ind);
            
        end
        
        
        function Layout_TaskMode_Panel1(app)
            
            str1 = {};
            str2 = {'0: Manual'};
            
            ind  = 1;
            for ii = 1:length(app.instrList)
                if contains(app.instrList(ii).Name, {'FSL', 'FSVR'})
                    str1 = [str1, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    
                elseif contains(app.instrList(ii).Name, {'EB500', 'MS2720T', 'N9344C'})
                    str1 = [str1, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    
                    if ii == 1
                        ind  = ii+1;
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    end
                    
                elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'})
                    str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                end
            end
            app.Task_Instrument.Items = {};
            app.Task_Instrument.Items = str1;
            
            app.Task_GPS.Items = str2;
            
            if ind == 1; app.Task_GPS.Value = app.Task_GPS.Items{end};
            else;        app.Task_GPS.Value = app.Task_GPS.Items{ind};
            end
            
            Task_GPSValueChanged(app)
            
        end
        
        
        function Layout_TaskMode_Panel2(app)
            
            if contains(app.Task_Instrument.Value, {'FSL', 'FSVR', 'MS2720T', 'N9344C'})
                app.GridLayout5.RowHeight = {15, 22, 0, 15, 22, 15, 22};
                
                app.SeletividadeLabel.Visible = 0;
                app.Task_Selectivity.Visible  = 0;
                
                app.PramplificadorDropDownLabel.Enable = 1;
                app.Task_Preamp.Enable                 = 1;
                
            elseif contains(app.Task_Instrument.Value, 'EB500')
                app.GridLayout5.RowHeight = {15, 0, 22, 15, 22, 15, 22};
                
                app.SeletividadeLabel.Visible = 1;
                app.Task_Selectivity.Visible  = 1;
                
                app.PramplificadorDropDownLabel.Enable = 0;
                set(app.Task_Preamp, 'Enable', 0, 'Value', 'Off')
            end
            
        end
        
        
        function Layout_EditTask(app)
            
            if app.Task_editBand.Value || app.Task_newBand.Value || app.menu_Button7.Value
                
                set(app.Task_Name,              'Editable', 1, 'BackgroundColor', [1,1,1]);
                set(app.Task_Description,       'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_F0,                'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_F1,                'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_StepWidth,         'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_Resolution,        'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_IntegrationFactor, 'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_RevisitTime,       'Editable', 1, 'BackgroundColor', [1,1,1])
                
                set(app.Task_RFMode,    'BackgroundColor', [1,1,1], 'Items', {'Alta sensibilidade', 'Normal', 'Baixa distorção'})
                set(app.Task_TraceMode, 'BackgroundColor', [1,1,1], 'Items', {'ClearWrite', 'Average', 'MaxHold', 'MinHold'})
                set(app.Task_Detector,  'BackgroundColor', [1,1,1], 'Items', {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'})
                set(app.Task_LevelUnit, 'BackgroundColor', [1,1,1], 'Items', {'dBm', 'dBμV'})
                
                app.Task_addBand.Enable    = 1;
                app.Task_deleteBand.Enable = 0;
                app.Tree2.Enable           = 0;
                app.Tree3.Enable           = 0;
                app.Task_Instrument.Enable = 0;
                
                set(app.GridLayout5.Children, 'Enable', 0)
                              
            else
                app.Task_ThreadID.Value = app.Tree3.SelectedNodes.NodeData;
                
                set(app.Task_Name,              'Editable', 0, 'BackgroundColor', [.94,.94,.94]);
                set(app.Task_Description,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_F0,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_F1,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_StepWidth,         'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_Resolution,        'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_IntegrationFactor, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_RevisitTime,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                
                set(app.Task_RFMode,    'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_RFMode.Value})
                set(app.Task_TraceMode, 'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_TraceMode.Value})
                set(app.Task_Detector,  'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_Detector.Value})
                set(app.Task_LevelUnit, 'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_LevelUnit.Value})
                
                app.Task_addBand.Enable    = 0;
                app.Tree2.Enable           = 1;
                app.Tree3.Enable           = 1;
                app.Task_Instrument.Enable = 1;
                
                set(app.GridLayout5.Children, 'Enable', 1)
                
                app.Task_StepWidth_FSL.Editable  = 1;
                if contains(app.Task_Instrument.Value, {'MS2720T', 'N9344C'})
                    app.Task_StepWidth_FSL.Editable = 0;
                    app.Task_DataPoints_FSL.Enable  = 0;
                    
                elseif contains(app.Task_Instrument.Value, 'EB500')
                    app.PramplificadorDropDownLabel.Enable = 0;
                    set(app.Task_Preamp, 'Enable', 0, 'Value', 'Off')
                end
                
            end
            
        end
        
        
        function Fcn_TaskMode_Info(app, ind1, ind2)
            
            % Left panel
            app.Task_Name.Value = app.taskList{ind1}.Name;
            
            % Right panel
            app.Task_ThreadID.Value    =  app.taskList{ind1}.Band(ind2).ThreadID;
            app.Task_Description.Value =  app.taskList{ind1}.Band(ind2).Description;
            app.Task_F0.Value          =  app.taskList{ind1}.Band(ind2).F0 / 1e+6;
            app.Task_F1.Value          =  app.taskList{ind1}.Band(ind2).F1 / 1e+6;
            app.Task_RFMode.Items      = {app.taskList{ind1}.Band(ind2).RFMode};
            app.Task_StepWidth.Value   =  app.taskList{ind1}.Band(ind2).StepWidth / 1e+3;
            app.Task_Resolution.Value  =  app.taskList{ind1}.Band(ind2).Resolution / 1e+3;
            app.Task_LevelUnit.Items   = {app.taskList{ind1}.Band(ind2).LevelUnit};
            app.Task_TraceMode.Items   = {app.taskList{ind1}.Band(ind2).TraceMode};
            app.Task_Detector.Items    = {app.taskList{ind1}.Band(ind2).Detector};
            app.Task_RevisitTime.Value =  app.taskList{ind1}.Band(ind2).RevisitTime;
            
            if app.taskList{ind1}.Band(ind2).IntegrationFactor
                app.FatordeintegraoLabel.Visible = 1;
                set(app.Task_IntegrationFactor, 'Visible', 1, 'Value', app.taskList{ind1}.Band(ind2).IntegrationFactor);
            else
                app.FatordeintegraoLabel.Visible = 0;
                set(app.Task_IntegrationFactor, 'Visible', 0)
            end
            
        end
        
        
        function Fcn_TaskMode_Instr(app, ind1, ind2)
            
            if ~isfield(app.taskList{ind1}.Band(ind2), 'Flag') || isempty(app.taskList{ind1}.Band(ind2).Flag)
                Fcn_TaskMode_Refresh1(app, ind1, ind2)
            elseif ~app.taskList{ind1}.Band(ind2).Flag
                app.Task_Warning2.Visible     = 0;
                app.Task_RefreshButton.Enable = 0;
            else
                app.Task_Warning2.Visible     = 1;
                app.Task_RefreshButton.Enable = 1;
            end
            
            if contains(app.Task_Instrument.Value, 'MS2720T')
                set(app.Task_StepWidth_FSL,  'Editable', 0, ...
                                             'Value', app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints_FSL, 'Enable', 0, ...
                                             'Value',  561)
                
            elseif contains(app.Task_Instrument.Value, 'N9344C')
                set(app.Task_StepWidth_FSL,  'Editable', 0, ...
                                             'Value', app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints_FSL, 'Enable', 0, ...
                                             'Value',  461)
                
            elseif contains(app.Task_Instrument.Value, {'FSL', 'FSVR'})
                set(app.Task_StepWidth_FSL,  'Editable', 1, ...
                                             'Value', app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints_FSL, 'Enable', 1, ...
                                             'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                             'Value',  app.taskList{ind1}.Band(ind2).instrDataPoints)
                
            elseif contains(app.Task_Instrument.Value, 'EB500')
                set(app.Task_StepWidth_EB500, 'Items', app.taskList{ind1}.Band(ind2).instrStepWidth_Items, ...
                                              'Value', app.taskList{ind1}.Band(ind2).instrStepWidth)
                app.Task_DataPoints_EB500.Value = app.taskList{ind1}.Band(ind2).instrDataPoints;
                app.Task_Selectivity.Value      = app.taskList{ind1}.Band(ind2).instrSelectivity;
                
            end
            set(app.Task_Resolution_FSL, 'Items', app.taskList{ind1}.Band(ind2).instrResolution_Items, ...
                                         'Value', app.taskList{ind1}.Band(ind2).instrResolution)
            
            app.Task_Preamp.Value  = app.taskList{ind1}.Band(ind2).instrPreamp;
            app.Task_attMode.Value = app.taskList{ind1}.Band(ind2).instrAttMode;
            
            set(app.Task_attFactor, 'Items', app.taskList{ind1}.Band(ind2).instrAttFactor_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrAttFactor)
            
            if strcmp(app.Task_attMode.Value, 'Automático')
                app.AtenuadorDropDownLabel.Visible = 0;
                app.Task_attFactor.Visible         = 0;
            else
                app.AtenuadorDropDownLabel.Visible = 1;
                app.Task_attFactor.Visible         = 1;
            end
            
        end
        
        
        function Fcn_TaskMode_Refresh1(app, ind1, ind2)
            
            instrSettings = Fcn_TaskMode_Refresh2(app, ind1, ind2);
            
            app.taskList{ind1}.Band(ind2).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{ind1}.Band(ind2).instrStepWidth         = instrSettings.StepWidth;
            
            app.taskList{ind1}.Band(ind2).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{ind1}.Band(ind2).instrDataPoints        = instrSettings.DataPoints;
            
            app.taskList{ind1}.Band(ind2).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{ind1}.Band(ind2).instrResolution        = instrSettings.Resolution;
            
            app.taskList{ind1}.Band(ind2).instrSelectivity       = instrSettings.Selectivity;
            
            app.taskList{ind1}.Band(ind2).instrLevelUnit         = instrSettings.LevelUnit;
            
            app.taskList{ind1}.Band(ind2).instrHighSensitivity   = instrSettings.HighSensitivity;
            app.taskList{ind1}.Band(ind2).instrPreamp            = instrSettings.Preamp;
            app.taskList{ind1}.Band(ind2).instrAttMode           = instrSettings.attMode;
            app.taskList{ind1}.Band(ind2).instrAttFactor_Items   = instrSettings.attFactor_Items;
            app.taskList{ind1}.Band(ind2).instrAttFactor         = instrSettings.attFactor;
            
            app.taskList{ind1}.Band(ind2).Flag = 0;
            app.Task_Warning2.Visible          = 0;
            app.Task_RefreshButton.Enable      = 0;
            
        end
        
        
        function instrSettings = Fcn_TaskMode_Refresh2(app, ind1, ind2) % Spectrum analyzers, receivers
            
            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'HighSensitivity',   [], 'Preamp',          [], ...
                                   'attMode',           [], 'attFactor_Items', [], ...
                                   'attFactor',         [], 'LevelUnit',       []);
            
            
            span = app.taskList{ind1}.Band(ind2).F1 - app.taskList{ind1}.Band(ind2).F0;
            
            
            if contains(app.Task_Instrument.Value, {'FSL', 'MS2720T', 'N9344C'})
                if contains(app.Task_Instrument.Value, 'FSL')
                    ind3 = find(strcmp(app.instrInfo.Instr, 'FSL'));
                     
                elseif contains(app.Task_Instrument.Value, 'MS2720T')
                    ind3 = find(strcmp(app.instrInfo.Instr, 'MS2720T'));
                    
                elseif contains(app.Task_Instrument.Value, 'N9344C')
                    ind3 = find(strcmp(app.instrInfo.Instr, 'N9344C'));
                end
                
                instrSettings.Resolution_Items  = strsplit(app.instrInfo.Resolution_Values{ind3}, ',');
                instrSettings.attFactor_Items   = strsplit(app.instrInfo.Attenuation_Values{ind3}, ',');
                
                rbwValues = [];
                for ii = 1:length(instrSettings.Resolution_Items)
                    rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                end
                
                instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{ind3};
                
                DataPoints = round(span/app.taskList{ind1}.Band(ind2).StepWidth + 1);
                if DataPoints < instrSettings.DataPoints_Limits(1)
                    instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                elseif DataPoints > instrSettings.DataPoints_Limits(2)
                    instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                else
                    instrSettings.DataPoints = DataPoints;
                end
                instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);
                
                rbwIndex  = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};
                
                instrSettings.HighSensitivity = 0;
                instrSettings.Preamp          = 'On';
                instrSettings.attMode         = 'Automático';
                instrSettings.attFactor       = instrSettings.attFactor_Items{1};
                
                switch app.taskList{ind1}.Band(ind2).RFMode
                    case 'Alta sensibilidade'
                        instrSettings.attMode         = 'Manual';
                        instrSettings.HighSensitivity = 1;
                    case 'Baixa distorção'
                        instrSettings.Preamp          = 'Off';
                end
                
                instrSettings.LevelUnit = app.taskList{ind1}.Band(ind2).LevelUnit;
                
                
            elseif contains(app.Task_Instrument.Value, 'EB500')
                ind3 = find(strcmp(app.instrInfo.Instr, 'EB500'));
                
                instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{ind3}, ',');
                instrSettings.Selectivity_Items = strsplit(app.instrInfo.Selectivity_Values{ind3}, ',');
                instrSettings.attFactor_Items   = strsplit(app.instrInfo.attenuation_Values{ind3}, ',');
                
                stepValues = [];
                for ii = 1:length(instrSettings.StepWidth_Items)
                    stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                end

                stepIndex  = find(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth) == min(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth)));
                instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};
                
                instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;
                
                rbwValues = [];
                rbwItems  = {};
                for ii = 1:3
                    rbwValues = [rbwValues, app.EB500_RBW{instrSettings.StepWidth, ii}];
                    rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                end
                instrSettings.Resolution_Items = rbwItems;
                
                rbwIndex = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                instrSettings.Selectivity = app.Task_Selectivity.Items{rbwIndex};
                
                instrSettings.Preamp    = 'Off';
                instrSettings.attMode   = 'Automático';
                instrSettings.attFactor = instrSettings.attFactor_Items{1};
                
                switch app.taskList{ind1}.Band(ind2).RFMode
                    case 'Alta sensibilidade'
                        instrSettings.attMode = 'Manual';
                end
                
                instrSettings.LevelUnit = 'dBμV';
            end
        
        end
        
        
        function Fcn_uiAlert(app, msg, title, type)
            
            uialert(app.MainApp, msg, title, 'Icon', type)
            
        end
        
        
        function Fcn_uiError(app, fileLog, ME)
            
            ind = str2double(extractBefore(app.Task_Instrument.Value, ':'));
            
            fprintf(fileLog, sprintf('%s,%s,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), replace(ME.identifier, '"', ''), replace(ME.message, '"', '')));
            fclose(fileLog);
            
            str = sprintf('%s\n\n%s', ME.identifier, ME.message);
            Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
            
        end
        
        
        function Task(app, Duration_sec)
            
            winProgress = uiprogressdlg(app.MainApp, 'Title', app.Task_Name.Value,                 ...
                                                     'Message', 'Iniciando...', 'Value', 0,        ...
                                                     'Cancelable', 'on', 'CancelText', 'Cancelar', ...
                                                     'Icon', 'info', 'Indeterminate', 'off');
            clear app.udpPort
            app.udpPort  = [];
            app.specInfo = [];
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            
            % BitsPerPoint
            if     strcmp(app.Task_BitsPerPoint.Value,  '8'); dataFormat = 'uint8';
            elseif strcmp(app.Task_BitsPerPoint.Value, '16'); dataFormat = 'int16';
            elseif strcmp(app.Task_BitsPerPoint.Value, '32'); dataFormat = 'single';
            end
            
            % Spectrum analyzer/receiver connection
            ind2 = str2double(extractBefore(app.Task_Instrument.Value, ':'));
            
            % LogFile
            baseName = ['appColeta_' datestr(now,'yymmdd_THHMMSS')];
            fileLog  = fopen(fullfile(app.menu_userPath.Value, [baseName '_LogFile.txt']), 'wt');
            fprintf(fileLog, sprintf('Time,Identifier,Message\n'));
            
            % Equipament
            try
                [app.Instrument, Node, app.udpPort] = Connection_Instrument(app.instrList(ind2).Type, app.instrList(ind2).IP, str2double(app.instrList(ind2).Port), app.instrList(ind2).Timeout, app.instrList(ind2).inBuffer, 'Task');
                fprintf(fileLog, sprintf('%s,Equipament,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), replace(Node, '"', '')));
            catch ME
                Fcn_uiError(app, fileLog, ME)
                return
            end
            winProgress.Message = sprintf('Conectado ao %s.', Node);
            
            % TimeIssue (mainRevisitTime and RevisitFactor)
            app.timeInfo = Fcn_timeIssue(app.Task_gpsRevisitTime.Value, app.taskList{ind1}, Duration_sec);
            fprintf(fileLog, sprintf('%s,TimeIssue,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ...
                sprintf('GlobalRevisitTime: %.3f sec, RevisitFactors: [%s]', app.timeInfo.RevisitTime, num2str(app.timeInfo.RevisitFactor(2:end)))));
            
            for ii = 1:length(app.taskList{ind1}.Band)
                if (app.Task_BitsPerPoint.Value/8 * app.taskList{ind1}.Band(ii).instrDataPoints * app.timeInfo.EstimatedSamples(ii+1)) > 1e+9
                    fprintf(fileLog, sprintf('%s,FileSize,"Maximum file size: 1e+9 bytes"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF')));
                    fclose(fileLog);
                    
                    str = sprintf(['Caso criado, o arquivo relacionado ao ThreadID %d extrapolaria o tamanho limite (1e+9 bytes). Favor ajustar ao ' ...
                                   'menos um dos seguintes parâmetros:\n• Número de bits por ponto;\n• Número de pontos por traço; ' ...
                                   'ou\n• Duração da monitoração.'], app.taskList{ind1}.Band(ii).ThreadID);
                    Fcn_uiAlert(app, str, app.taskList{ind1}.Name, 'error')
                    return
                end
            end
            
            % SCPI set
            ind3 = [];
            if     contains(Node, 'FSL');     ind3 = find(strcmp(app.instrInfo.Instr, 'FSL'));
            elseif contains(Node, 'FSVR');    ind3 = find(strcmp(app.instrInfo.Instr, 'FSVR'));
            elseif contains(Node, 'MS2720T'); ind3 = find(strcmp(app.instrInfo.Instr, 'MS2720T'));
            elseif contains(Node, 'N9344C');  ind3 = find(strcmp(app.instrInfo.Instr, 'N9344C'));
            elseif contains(Node, 'EB500');   ind3 = find(strcmp(app.instrInfo.Instr, 'EB500'));
            end
            
            if isempty(ind3)
                fprintf(fileLog, sprintf('%s,SCPI,"Not found"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF')));
                fclose(fileLog);

                str = sprintf('Não identificada na variável "instrInfo" os comandos de baixo nível (SCPI) relacionados ao %s.', Node);
                Fcn_uiAlert(app, str, app.taskList{ind1}.Name, 'error')
                return
            end
            
            % Spectrum analyzer/receiver MetaData
%             try
                app.specInfo = Fcn_MetaDataRead(app.Instrument, Node, app.instrInfo, ind3, app.taskList{ind1});
                fprintf(fileLog, sprintf('%s,MetaData,"Done"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF')));
%             catch ME
%                 Fcn_uiError(app, fileLog, ME)
%                 return
%             end
            
            % GPS Data
            try
                Task_gpsData(app, Node)
                if isempty(app.gpsInfo.TimeStamp)
                    app.gpsInfo.TimeStamp = datetime('now', 'Format', 'dd/MM/yyyy HH:mm:ss.SSS');
                end
                
                fprintf(fileLog, sprintf('%s,gpsInfo,"Type: %d, Status: %d, Latitude: .%6f, Longitude: %.6f"\n', ...
                    datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), app.gpsInfo.Type, app.gpsInfo.Status, app.gpsInfo.Latitude, app.gpsInfo.Longitude));
            catch ME
                Fcn_uiError(app, fileLog, ME)
                return
            end
            
            % appColeta training (EB500 only)
            if contains(Node, 'EB500')
                while true
                    try
                        Task_specData_Aux1(app, ind3, app.taskList{ind1})
                        break
                    catch ME
                        Fcn_uiError(app, fileLog, ME)
                        return
                    end
                end
            end
            
            % File               
            fileID = zeros(1, length(app.taskList{ind1}.Band));
            for ii = 1:length(app.taskList{ind1}.Band)
                fileName = fullfile(app.menu_userPath.Value, [baseName '_ThreadID' num2str(app.taskList{ind1}.Band(ii).ThreadID) '.bin']);
                fileID(ii) = fopen(fileName, 'w');
                
                fprintf(fileLog, sprintf('%s,FileCreated,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), replace(fileName, '\', '\\')));
                
                Write_formatHeader(app, fileID(ii), app.timeInfo.EstimatedSamples(ii+1))
                Write_metaHeader(app, fileID(ii), app.specInfo(ii,:))
                Write_gpsHeader(app, fileID(ii))
                [Offset1, Offset2] = Write_Body(app, fileID(ii),                     ...
                                                app.timeInfo.EstimatedSamples(ii+1), ...
                                                app.specInfo.DataPoints(ii),         ...
                                                app.taskList{ind1}.Name,             ...
                                                app.taskList{ind1}.Band(ii),         ...
                                                Node);
                
                fclose(fileID(ii));
                
                % Memory Map File
                app.specInfo.WritedSamples{ii} = memmapfile(fileName, 'Writable', true, 'Offset', 20, ...
                    'Format', {'uint16', 1, 'Value'}, 'Repeat', 1);
                
                app.specInfo.memmapData{ii}{1} = memmapfile(fileName, 'Writable', true, 'Offset', Offset1, ...
                    'Format', {'int8',   [1  6], 'localTimeStamp';    ...
                               'int16',  [1  1], 'localTimeStamp_ms'; ...
                               'int16',  [1  1], 'RefLevel';          ...
                               'uint8',  [1  1], 'attFactor';         ...
                               'uint8',  [1  1], 'gpsStatus';         ...
                               'single', [1  1], 'Latitude';          ...
                               'single', [1  1], 'Longitude'},        ...
                    'Repeat', app.timeInfo.EstimatedSamples(ii+1));
                
                app.specInfo.memmapData{ii}{2} = memmapfile(fileName, 'Writable', true, 'Offset', Offset2,              ...
                    'Format', {dataFormat, [app.specInfo.DataPoints(ii) app.timeInfo.EstimatedSamples(ii+1)], 'Array'}, ...
                    'Repeat', 1);
            end
            
            Task_specData(app, ind3, Node, app.taskList{ind1}, fileLog, winProgress)
            
            fclose(fileLog);
            fclose(app.Instrument);
            if ~isempty(app.gpsInstrument)
                fclose(app.gpsInstrument);
            end
            
%             stop(app.tmr)
            delete(timerfindall)
            
            Fcn_uiAlert(app, 'Tarefa finalizada!', app.Task_Name.Value, 'success')
            
        end
        
        
        function Task_gpsData(app, Node)
            
            lastwarn('')
            
            try
                instrIndex = str2double(extractBefore(app.Task_Instrument.Value, ':'));
                gpsIndex   = str2double(extractBefore(app.Task_GPS.Value, ':'));
                
                if gpsIndex == 0
                    app.gpsInfo.Status    = -1;
                    app.gpsInfo.Latitude  = app.Task_manualLatitude.Value;
                    app.gpsInfo.Longitude = app.Task_manualLongitude.Value;
                    app.gpsInfo.Type      =  0;
                    
                elseif gpsIndex == instrIndex
                    app.gpsInfo = Fcn_gpsDecode(app.Instrument, Node, 'Full');
                    app.gpsInfo.Type = 1;
                    
                else
                    [app.gpsInstrument, app.gpsInfo] = Connection_gps(app.instrList(gpsIndex).Type, app.instrList(gpsIndex).IP, app.instrList(gpsIndex).Port, app.instrList(gpsIndex).Timeout, app.instrList(gpsIndex).inBuffer, 'Full');
                    app.gpsInfo.Type = 2;
                end
                
            catch ME
                errorStruct.message    = ME.message;
                errorStruct.identifier = ME.identifier;
                
                error(errorStruct)
            end
            
        end
        
        
        function Task_specData(app, ind, Node, Task, fileLog, winProgress)
            
            EstimatedSamples = max(app.timeInfo.EstimatedSamples);
            
%             if ~app.Task_startImediately.Value
%                 pause(seconds(app.Task_startTimeDate.Value + hours(app.Task_startTimeHours.Value) + ...
%                       minutes(app.Task_startTimeMinutes.Value) - datetime('now')));
%             end
            
            fprintf(fileLog, sprintf('%s,WritedSamples,"%d of %d"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), 0, EstimatedSamples));
            
            for ii = 0:EstimatedSamples-1
                if winProgress.CancelRequested
                    fprintf(fileLog, sprintf('%s,WritedSamples,"%d of %d"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ii, EstimatedSamples));
                    fprintf(fileLog, sprintf('%s,CancelRequested,"User operation"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF')));
                    return
                end
                
                gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                
                tic;
                for jj = 0:length(Task.Band)
                    if mod(ii, app.timeInfo.RevisitFactor(jj+1))
                        continue
                    end
                    
                    if jj == 0
                        try
                            if app.gpsInfo.Type == 1
                                auxGPS = Fcn_gpsDecode(app.Instrument, Node, 'Essential');
                                if ~isempty(auxGPS.Status)
                                    gpsData = auxGPS;
                                end
                                
                            elseif app.gpsInfo.Type == 2
                                gpsData = Fcn_gpsReader(app.gpsInstrument, 1, 'Essential'); % Resposta entre 1 e 15 ms (usando o Garmin GPSmap 62sc).
                            end
                                
                        catch ME
                            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                            fprintf(fileLog, sprintf('%s,gpsInfo,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), replace(ME.message, '"', '')));
                        end
                        
                    else
                        while true
                            try
                                if length(Task.Band) > 1
                                    fprintf(app.Instrument, app.specInfo.scpiGeneral{jj});
                                    
                                    if ~app.specInfo.AttenuationMode(jj)
                                        fprintf(app.Instrument, sprintf('%s %d', app.instrInfo.scpiAttenuation{ind}, app.specInfo.AttenuationValue(jj)));
                                    end
                                    
                                    if contains(Node, {'FSL', 'FSVR', 'MS2720T', 'N9344C'})
                                        if ~isempty(app.instrInfo.scpiDataPoints{ind})
                                            fprintf(app.Instrument, sprintf(app.instrInfo.scpiDataPoints{ind}, app.specInfo.DataPoints(jj)));
                                        end
                                    end
                                    
                                end
                                    
                                if contains(Node, {'FSL', 'FSVR', 'MS2720T', 'N9344C'})
                                    flushinput(app.Instrument)
                                    
                                    fprintf(app.Instrument, app.instrInfo.scpiTraceData{ind});
                                    specData = binblockread(app.Instrument, 'float');
                                    
                                    if numel(specData) ~= app.specInfo.DataPoints(jj)
                                        continue
                                    end
                                    
                                elseif contains(Node, 'EB500')
                                    pause(.01)
                                    flush(app.udpPort)
                                    
                                    specData = Task_specData_Aux2(app, jj);
                                end
                                attFactor = str2double(deblank(query(app.Instrument, sprintf('%s?', app.instrInfo.scpiAttenuation{ind}))));
                                Offset  = max(specData);
                                
                                Write_specData(app, jj, Offset, attFactor, gpsData, specData)
                                break
                                
                            catch ME
                                fprintf(fileLog, sprintf('%s,Equipament,"%s"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), replace(ME.message, '"', '')));
                                
                                while true
                                    delete(app.Instrument)
                                    
                                    clear app.udpPort
                                    app.udpPort  = [];
                                    
                                    pause(1)
                                    instrIndex = str2double(extractBefore(app.Task_Instrument.Value, ':'));
                                    
                                    try
                                        if winProgress.CancelRequested
                                            fprintf(fileLog, sprintf('%s,WritedSamples,"%d of %d"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ii, EstimatedSamples));
                                            fprintf(fileLog, sprintf('%s,CancelRequested,"User operation"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF')));
                                            return
                                        end
                                        
                                        [app.Instrument, Node, app.udpPort] = Connection_Instrument(app.instrList(instrIndex).Type, app.instrList(instrIndex).IP, str2double(app.instrList(instrIndex).Port), app.instrList(instrIndex).Timeout, app.instrList(instrIndex).inBuffer, 'Task');
                                        break
                                    catch
                                    end
                                end
                            end
                        end
                    end
                end
                set(winProgress, 'Message', sprintf('Em andamento a leitura de dados de espectro.\n%d de %d', ii, EstimatedSamples), 'Value', ii/EstimatedSamples)

                pause(app.timeInfo.RevisitTime - toc)
            end
            fprintf(fileLog, sprintf('%s,WritedSamples,"%d of %d"\n', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ii+1, EstimatedSamples));
            
        end
        
        
        function Task_specData_Aux1(app, ind, Task)
        
            lastwarn('')
            
            for ii = 1:numel(Task.Band)
                fprintf(app.Instrument, sprintf(':%s %s;:%s %s;:%s %.0f;:%s %.0f;:%s %.0f;:%s %s;:%s %d',    ...
                                        app.instrInfo.TraceMode{ind},   app.specInfo.strTraceMode{ii}, ...
                                        app.instrInfo.Detector{ind},    app.specInfo.strDetector{ii},  ...
                                        app.instrInfo.FreqStart{ind},   app.specInfo.F0(ii),           ...
                                        app.instrInfo.FreqStop{ind},    app.specInfo.F1(ii),           ...
                                        app.instrInfo.StepWidth{ind},   app.specInfo.StepWidth(ii),    ...
                                        app.instrInfo.Selectivity{ind}, app.specInfo.Selectivity{ii},  ...
                                        app.instrInfo.att_auto{ind},    app.specInfo.attMode(ii)));
                
                if ~app.specInfo.attMode(ii)
                    fprintf(app.Instrument, sprintf(':%s %d', app.instrInfo.attenuation{ind}, app.specInfo.attFactor(ii)));
                end
                
                pause(.5)
                flush(app.udpPort)
                pause(3)
                
                while app.udpPort.NumDatagramsAvailable < 100
                    pause(1)
                end
                specDatagrama = read(app.udpPort, app.udpPort.NumDatagramsAvailable);
                
                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    errorStruct.message    = msg;
                    errorStruct.identifier = warnID;
                    
                    error(errorStruct)
                end
                
                auxIndex1  = 0;
                Terminator = [];
                for jj = 1:length(specDatagrama)
                    specDatagrama(jj).Data = uint8(specDatagrama(jj).Data);
                    
                    MagicNumber = typecast(specDatagrama(jj).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(jj).Data(16:-1:13), 'uint32');
                    
                    F0_low    = double(typecast(specDatagrama(jj).Data(32:-1:29), 'uint32'));
                    F0_high   = double(typecast(specDatagrama(jj).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth = typecast(specDatagrama(jj).Data(40:-1:37), 'uint32');
                    
                    F0 = F0_low + F0_high;
                            
                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(jj).Data)) || (app.specInfo.F0(ii) ~= F0) || (app.specInfo.StepWidth(ii) ~= StepWidth)
                        continue
                    end
                    
                    auxIndex1                = auxIndex1+1;
                    Terminator(auxIndex1, 1) = typecast(specDatagrama(auxIndex1).Data(end:-1:end-1), 'uint16');
                end
                auxIndex2 = find(Terminator(:,1) == 2000);
                
                app.specInfo.DatagramasPerTrace(ii) = round(length(specDatagrama)./length(auxIndex2));
            end
        
        end
        
        
        function specData = Task_specData_Aux2(app, ind)
            
            lastwarn('')
            specData = zeros(app.specInfo.DataPoints(ind), 1);
            N = 0;
            
            while true
                NumberOfDatagrams = (2+N) .* app.specInfo.DatagramasPerTrace(ind) - 1;
                specDatagrama     = read(app.udpPort, NumberOfDatagrams);
                
                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    errorStruct.message    = msg;
                    errorStruct.identifier = warnID;
                    
                    error(errorStruct)
                end
                
                udpFlag         = 0;
                Points          = 0;
                DatagramaNumber = 0;
                
                for ii = 1:length(specDatagrama)
                    specDatagrama(ii).Data = uint8(specDatagrama(ii).Data);
                    
                    SequenceNumber_low  = double(typecast(specDatagrama(ii).Data(10:-1: 9), 'uint16'));
                    SequenceNumber_high = double(typecast(specDatagrama(ii).Data(12:-1:11), 'uint16')).*2^16;
                    SequenceNumber      = SequenceNumber_low + SequenceNumber_high;
                    
                    if udpFlag & (SequenceNumber ~= DatagramaNumber+1)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;
                        
                        if ii ~= length(specDatagrama)
                            continue
                        else
                            N = N+1;
                            break                        
                        end
                    end
        
                    MagicNumber = typecast(specDatagrama(ii).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(ii).Data(16:-1:13), 'uint32');
                    F0_low      = double(typecast(specDatagrama(ii).Data(32:-1:29), 'uint32'));
                    F0_high     = double(typecast(specDatagrama(ii).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth   = typecast(specDatagrama(ii).Data(40:-1:37), 'uint32');
                    
                    F0 = F0_low + F0_high;
                    
                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(ii).Data)) || (app.specInfo.F0(ind) ~= F0) || (app.specInfo.StepWidth(ind) ~= StepWidth)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;
                        
                        continue
                    end
                    Terminator = typecast(specDatagrama(ii).Data(end:-1:end-1), 'uint16');
                    
                    auxIndex1 = Points + 1;
                    Points    = Points + typecast(specDatagrama(ii).Data(22:-1:21), 'uint16');                
                    auxIndex2 = Points;
                    
                    if Terminator == 2000
                        if Points == app.specInfo.DataPoints(ind)+1
                            specData(auxIndex1:auxIndex2-1) = (double(flip(typecast(specDatagrama(ii).Data((end-2):-1:81),'int16')))./10)';
                            
                            N       = 0;
                            udpFlag = 2;
                            break
                            
                        else
                            udpFlag = 0;
                        end
                        Points = 0;
                        
                    else
                        udpFlag         = 1;
                        DatagramaNumber = SequenceNumber;
                        specData(auxIndex1:auxIndex2) = (double(flip(typecast(specDatagrama(ii).Data(end:-1:81),'int16')))./10)';
                    end
                end
                
                if udpFlag == 2
                    break
                end
            end
            
        end
        
        
        function Write_specData(app, ind1, RefLevel, attFactor, gpsData, specData)
            
            TimeStamp = datetime('now');
            ind2      = app.specInfo.WritedSamples{ind1}.Data.Value + 1;
            
            app.specInfo.WritedSamples{ind1}.Data.Value = ind2;
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp  = int8([year(TimeStamp)-2000, month(TimeStamp), day(TimeStamp), ...
                                                                             hour(TimeStamp), minute(TimeStamp), fix(second(TimeStamp))]);
            
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp_ms = int16((second(TimeStamp) - fix(second(TimeStamp))).*1000);
            
            app.specInfo.memmapData{ind1}{1}.Data(ind2).RefLevel  = int16(RefLevel);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).attFactor = uint8(attFactor);
            
            if isempty(gpsData.Status)
                gpsData.Status    = 0;
                gpsData.Latitude  = -1;
                gpsData.Longitude = -1;
            end
            app.specInfo.memmapData{ind1}{1}.Data(ind2).gpsStatus = uint8(gpsData.Status);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Latitude  = single(gpsData.Latitude);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Longitude = single(gpsData.Longitude);
            
            % Codificação
            if strcmp(app.Task_BitsPerPoint.Value, '8')
                value = -2*RefLevel+255;
                app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = uint8(2.*specData + value); % 0.5 dB
                
            elseif strcmp(app.Task_BitsPerPoint.Value, '16')
                app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = int16(100.*specData);       % 0.01 dB
                
            elseif strcmp(app.Task_BitsPerPoint.Value, '32')
                app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = single(specData);           % 0.0000000001 dB
            end
            
        end
        
        
% %     Task Data
        function Write_formatHeader(app, fileID, EstimatedSamples)
            
            fwrite(fileID, 'RFlookBin v.1/1', 'char*1');
            fwrite(fileID, str2double(app.Task_BitsPerPoint.Value));        % 8 | 16 | 32 bits per point
            fwrite(fileID, EstimatedSamples, 'uint32');                     % EstimatedSamples
            fwrite(fileID, 0,                'uint32');                     % WritedSamples
            
        end
        
        
% %     Spectrum analyzer (or receiver) MetaData
        function Write_metaHeader(app, fileID, MetaData)
            
            fwrite(fileID, MetaData.FreqStart, 'single');
            fwrite(fileID, MetaData.FreqStop, 'single');
            fwrite(fileID, MetaData.Resolution, 'single');
            fwrite(fileID, MetaData.DataPoints, 'uint16');                  % Sweep Points (0-65535 pontos)
            fwrite(fileID, MetaData.Trace_ID, 'int8');
            fwrite(fileID, MetaData.Detector_ID, 'int8');
            fwrite(fileID, MetaData.LevelUnit_ID, 'int8');                    % LevelUnit (1: dBm)
            fwrite(fileID, MetaData.Preamp, 'int8');
            fwrite(fileID, MetaData.AttenuationMode, 'int8');
            
            if MetaData.AttenuationMode; attFactor = -1;
            else;                        attFactor = MetaData.AttenuationValue;
            end
            fwrite(fileID, attFactor, 'int8');
            
            fwrite(fileID, MetaData.SampleTimeValue, 'single');
            fwrite(fileID, zeros(1, 2));                                    % Alignment
            
        end
        
        
% %     GPS Data
        function Write_gpsHeader(app, fileID)
            
            fwrite(fileID, app.gpsInfo.Type);
            
            if app.gpsInfo.Type
                fwrite(fileID, app.gpsInfo.Status, 'int8');
                
                if ~isempty(app.gpsInfo.Latitude) & ~isempty(app.gpsInfo.Longitude)
                    fwrite(fileID, app.gpsInfo.Latitude,  'single');
                    fwrite(fileID, app.gpsInfo.Longitude, 'single');
                else
                    fwrite(fileID, -1, 'single');
                    fwrite(fileID, -1, 'single');
                end
                
                if app.gpsInfo.Status
                    fwrite(fileID, year(app.gpsInfo.TimeStamp)-2000, 'int8');
                    fwrite(fileID, month(app.gpsInfo.TimeStamp),     'int8');
                    fwrite(fileID, day(app.gpsInfo.TimeStamp),       'int8');
                
                    fwrite(fileID, hour(app.gpsInfo.TimeStamp),      'int8');
                    fwrite(fileID, minute(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, second(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, (second(app.gpsInfo.TimeStamp) - ...
                          fix(second(app.gpsInfo.TimeStamp))).*1000, 'int16');
                    
                else
                    fwrite(fileID, -ones(6, 1), 'int8');
                    fwrite(fileID, -1,          'int16');
                end
                
            else
                fwrite(fileID, -1,          'int8');
                fwrite(fileID, app.gpsInfo.Latitude,  'single');
                fwrite(fileID, app.gpsInfo.Longitude, 'single');
                fwrite(fileID, -ones(6, 1), 'int8');
                fwrite(fileID, -1,          'int16');
            end
            
        end
        
        
% %     Body Block
        function [Offset1, Offset2] = Write_Body(app, fileID, EstimatedSamples, DataPoints, TaskName, Task, Node)
            
            Offset1 = ftell(fileID) + 12;
            Offset2 = Offset1 + 20*EstimatedSamples;
            Offset3 = Offset2 + (str2double(app.Task_BitsPerPoint.Value)/8)*DataPoints.*EstimatedSamples;
            
            metaStr = sprintf('{TaskName: "%s"; ThreadID: "%d"; Description: "%s"; Node: "%s"; Antenna: "%s"; AntennaHeight: "%d m"; RevisitTime: "%d seg"}', ...
                               TaskName, Task.ThreadID, Task.Description, Node, app.Task_Antenna.Value, app.Task_AntennaHeight.Value, Task.RevisitTime);
            
            fwrite(fileID, Offset1, 'uint32');
            fwrite(fileID, Offset2, 'uint32');
            fwrite(fileID, Offset3, 'uint32');
    
            fwrite(fileID, zeros(1, (20 + (str2double(app.Task_BitsPerPoint.Value)/8)*DataPoints).*EstimatedSamples, 'uint8'));
            fwrite(fileID, metaStr, 'char*1');
            
        end
        
        
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        % % % % Funcionalidade 3: Leitura de fluxo UDP gerado pelo Logger.  % % % %
        % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
        function udpLogger(app)
            
            fileID   = [];
            
            UDP_IP   = app.udpLogger_IP.Value;  % IP
            UDP_PORT = app.udpLogger_Port.Value; % Port
            UDP_TIME = app.udpLogger_Time.Value; % in seconds
            
            % Conexão
            OPEN_CONNECTION = ['open ' num2str(UDP_TIME)];
            
            clear SOCKET
            SOCKET = udpport('Datagram', 'IPV4', 'LocalPort', UDP_PORT, "ByteOrder", "big-endian");
            pause(0.1)
            
            write(SOCKET, OPEN_CONNECTION, 'uint8', UDP_IP, UDP_PORT);
            pause(1)

            tic
            t1 = toc;
            while app.udpLogger_Button.Value
                app.udpLogger_Datagram2.Value = SOCKET.NumDatagramsAvailable;
                
                if t1 > UDP_TIME
                    if ~app.udpLogger_Datagram1.Value
                        msg = ['Não foi recebido sequer um datagrama do sensor. Favor verificar as suas configurações, em ' ...
                               'especial o "Endereço IP" e a "Porta", atentando para o fato de que a porta padrão do RFeye é 8910'];
                        Fcn_uiAlert(app, msg, app.udpLogger_Node.Value, 'warning')
                        
                        app.udpLogger_Button.Value = 0;
                        udpLogger_ButtonValueChanged(app)
                        
                        break
                    end
                    
                    tic
                    write(SOCKET, OPEN_CONNECTION, 'uint8', UDP_IP, UDP_PORT);
                    pause(1)
                
                elseif SOCKET.NumDatagramsAvailable
                    SpecInfo = read(SOCKET, 1, 'uint8');
                    app.udpLogger_Datagram1.Value = app.udpLogger_Datagram1.Value + 1;
                    
                    for ii = 1:length(SpecInfo)
                        if length(SpecInfo(ii).Data) < 96
                            continue
                        end
                        
                        MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                        ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                        DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                        if ~ismember(DataType, [40, 42, 67]) 
                            if isempty(app.udpLogger_TextInfo.Value{1}); app.udpLogger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', sprintf('Não implementado leitor do DataType %d. Favor entrar em contato com o servidor Eric Magalhães Delgado (GR08).', DataType));
                            else;                                        app.udpLogger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', sprintf('Não implementado leitor do DataType %d. Favor entrar em contato com o servidor Eric Magalhães Delgado (GR08).', DataType));
                            end
                        end
                        
                        EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                        if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                            switch DataType
                                case 67; udpLogger_Read_DataType67(app, SpecInfo(ii).Data, ThreadID)
                                case 40; udpLogger_Read_DataType40(app, SpecInfo(ii).Data)
                                case 42; udpLogger_Read_DataType42(app, SpecInfo(ii).Data)
                            end
                            
                            if app.recFlag
                                if isempty(fileID)
                                    fileID = udpLogger_Write_FileHeader(app, UDP_IP, UDP_PORT);
                                end
                                
                                if ismember(ThreadID, [app.bandList.REC])
                                    fwrite(fileID, uint8((SpecInfo(ii).Data(9:end))));
                                end
                            else
                                if ~isempty(fileID)
                                    fclose(fileID);
                                    fileID = [];
                                end
                            end
                        end
                        pause(app.udpLogger_Pause.Value)
                    end
                    
                end 
                t1 = toc;
            end
            
            if ~isempty(fileID)
                fclose(fileID);
            end
            
        end
        
        
        function udpLogger_Read_DataType40(app, Datagram)
            
            Status = Datagram(41);
            if Status
                Date_Day     = Datagram(21);
                Date_Month   = Datagram(22);
                Date_Year    = Datagram(23) + 2000;
                
                Time_Hours   = Datagram(25);
                Time_Minutes = Datagram(26);
                Time_Seconds = Datagram(27);
                
                TimeStamp    = datestr(datetime([Date_Year, Date_Month, Date_Day, Time_Hours, Time_Minutes, Time_Seconds]), ...
                                       'dd/mm/yyyy HH:MM:ss');
                
                Latitude  = double(typecast(uint8(Datagram(45:48)), 'int32')) .* 1e+6;
                Longitude = double(typecast(uint8(Datagram(49:52)), 'int32')) .* 1e+6;
                
                if app.udpLogger_gpsCount.Value == 0
                    app.udpLogger_gpsLatitude.Value  = Latitude;
                    app.udpLogger_gpsLongitude.Value = Longitude;
                else
                    app.udpLogger_gpsLatitude.Value  = (app.udpLogger_gpsCount.Value * app.udpLogger_gpsLatitude.Value + Latitude) ./ (app.udpLogger_gpsCount.Value + 1);
                end
                app.udpLogger_gpsCount.Value = app.udpLogger_gpsCount.Value + 1;
                
                set(app.GridLayout11.Children, 'Enable', 1)
                
            else
                Latitude  = -1;
                Longitude = -1;
            end
            
            if isempty(app.udpLogger_TextInfo.Value{1}); app.udpLogger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, 'GPS', sprintf('Status: %d, Latitude: %.6f, Longitude: %.6f', Status, Latitude, Longitude));
            else;                                        app.udpLogger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, 'GPS', sprintf('Status: %d, Latitude: %.6f, Longitude: %.6f', Status, Latitude, Longitude));
            end
            
        end


        function udpLogger_Read_DataType42(app, Datagram)
            
            Date_Day     = Datagram(21);
            Date_Month   = Datagram(22);
            Date_Year    = Datagram(23) + 2000;
            
            Time_Hours   = Datagram(25);
            Time_Minutes = Datagram(26);
            Time_Seconds = Datagram(27);
            
            TimeStamp    = datestr(datetime([Date_Year, Date_Month, Date_Day, Time_Hours, Time_Minutes, Time_Seconds]), ...
                                   'dd/mm/yyyy HH:MM:ss');
            
            Identifier   = deblank(char(Datagram(41:72)));
            
            FreeTextLength = double(typecast(uint8(Datagram(73:76)), 'uint32'));
            if FreeTextLength
                FreeText = deblank(char(Datagram(77:76+FreeTextLength)));
            end
            
            if isempty(app.udpLogger_TextInfo.Value{1}); app.udpLogger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
            else;                                        app.udpLogger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
            end
            
            if contains(FreeText, 'hostname')
                str = strsplit(FreeText(2:end-1), ',');
                
                app.udpLogger_Hostname.Value = char(extractBetween(extractAfter(str{1}, '"hostname":'), '"', '"'));
                app.udpLogger_UnitInfo.Value = char(extractBetween(extractAfter(str{2}, '"unitInfo":'), '"', '"'));
                app.udpLogger_Method.Value = char(extractBetween(extractAfter(str{3}, '"method":'),   '"', '"'));
                                
                set(app.GridLayout12.Children, 'Enable', 1)
                
                if isempty(app.axes1.Title.String)
                    title(app.axes1, app.udpLogger_Hostname.Value, sprintf('%s, %s', app.udpLogger_UnitInfo.Value, app.udpLogger_Method.Value))
                end
                
            elseif contains(FreeText, 'gps')
                str = strsplit(FreeText(2:end-1), ',');
                
                gpsStatus = str2double(extractAfter(str{1}, '"gpsStatus":'));
                if gpsStatus
                    gpsLatitude  = str2double(extractAfter(str{2}, '"gpsLatitude":'))  ./ 1e+6;
                    gpsLongitude = str2double(extractAfter(str{3}, '"gpsLongitude":')) ./ 1e+6;
                    
                    if app.udpLogger_gpsCount.Value == 0
                        app.udpLogger_gpsLatitude.Value  = gpsLatitude;
                        app.udpLogger_gpsLongitude.Value = gpsLongitude;
                        
                        set(app.GridLayout11.Children, 'Enable', 1)
                        
                    else
                        app.udpLogger_gpsLatitude.Value  = (app.udpLogger_gpsCount.Value * app.udpLogger_gpsLatitude.Value  + gpsLatitude)  ./ (app.udpLogger_gpsCount.Value + 1);
                        app.udpLogger_gpsLongitude.Value = (app.udpLogger_gpsCount.Value * app.udpLogger_gpsLongitude.Value + gpsLongitude) ./ (app.udpLogger_gpsCount.Value + 1);
                    end
                    app.udpLogger_gpsCount.Value = app.udpLogger_gpsCount.Value + 1;
                end
            end
            
        end


        function udpLogger_Read_DataType67(app, Datagram, ThreadID)
            
            Bin.ThreadID = ThreadID;
            
            DescriptionLength = double(typecast(uint8(Datagram(41:44)), 'uint32'));
            Bin.Description   = deblank(char(Datagram(45:45+DescriptionLength-1)));
                        
            IntegerPart = double(typecast(uint8(Datagram(45+DescriptionLength:46+DescriptionLength)), 'uint16'));
            DecimalPart = double(typecast(uint8(Datagram(47+DescriptionLength:50+DescriptionLength)), 'int32'));
            Bin.F0      = (IntegerPart + DecimalPart./1e+9) .* 1e+6;
            
            IntegerPart = double(typecast(uint8(Datagram(51+DescriptionLength:52+DescriptionLength)), 'uint16'));
            DecimalPart = double(typecast(uint8(Datagram(53+DescriptionLength:56+DescriptionLength)), 'int32'));
            Bin.F1      = (IntegerPart + DecimalPart./1e+9) .* 1e+6;
            
            Bin.Resolution = double(typecast(uint8(Datagram(57+DescriptionLength:60+DescriptionLength)), 'uint32'));
            
            switch Datagram(74+DescriptionLength)
                case 0; Bin.Operation = 'Single Measurement'; 
                case 1; Bin.Operation = 'Mean'; 
                case 2; Bin.Operation = 'Peak'; 
                case 3; Bin.Operation = 'Minimum';
            end
            
            switch Datagram(75+DescriptionLength)
                case 0; Bin.LevelUnit = 'dBm';
                case 1; Bin.LevelUnit = 'dBµV/m';
            end
            
            OFFSET = double(typecast(uint8(Datagram(76+DescriptionLength)), 'int8'));
            NTUN   = typecast(uint8(Datagram(80+DescriptionLength:81+DescriptionLength)), 'uint16');
            NAGC   = typecast(uint8(Datagram(82+DescriptionLength:83+DescriptionLength)), 'uint16');
            
            Bin.DataPoints = double(typecast(uint8(Datagram(85+DescriptionLength:88+DescriptionLength)), 'uint32'));
            Bin.StepWidth  = (Bin.F1-Bin.F0)/(Bin.DataPoints-1);
            Bin.REC        = [];
            
            Array          = Datagram(89+DescriptionLength+4*NTUN+NAGC:89+DescriptionLength+4*NTUN+NAGC+Bin.DataPoints-1)./2 + OFFSET - 127.5;
            
            if isempty(app.bandList)
                app.bandList  = Bin;
                Fcn_Tree4(app)
            else
                ind = find([app.bandList.ThreadID] == Bin.ThreadID);
                if isempty(ind)
                    app.bandList(end+1) = Bin;                    
                    Fcn_Tree4(app)
                else
                    Flag = 0;
                    for ii = 1:length(ind)
                        if isequal(rmfield(app.bandList(ind(ii)), 'REC'), rmfield(Bin, 'REC'))
                            Flag = 1;
                            break
                        end
                    end
                    
                    if ~Flag
                        app.bandList(end+1) = Bin;
                        Fcn_Tree4(app)
                    end
                end
            end
            
            if app.udpLogger_CheckBox.Value
                if ~(app.bandList(app.Tree4.SelectedNodes.NodeData).ThreadID == Bin.ThreadID)
                    return
                end
            end
            
            if strcmp(Bin.LevelUnit, 'dBm')
                downYLim = -120;
                upYLim   = -20;
            else
                downYLim = 0;
                upYLim   = 100;
            end            
            
            set(app.axes1, 'XLim', [Bin.F0, Bin.F1], 'YLim', [downYLim, upYLim])
            xlabel(app.axes1, 'Frequência (Hz)')
            ylabel(app.axes1, sprintf('Nível (%s)', Bin.LevelUnit))
            
            app.axes1.XTick = linspace(Bin.F0, Bin.F1, 5);

            if ~isempty(app.axes1.Children)
                set(app.axes1.Children(1), 'XData', linspace(Bin.F0, Bin.F1, Bin.DataPoints), 'YData', Array)
                drawnow
            else
                plot(app.axes1, linspace(Bin.F0, Bin.F1, Bin.DataPoints), Array)
                drawnow nocallbacks
            end
            
        end
        
        

        function fileID = udpLogger_Write_FileHeader(app, UDP_IP, UDP_PORT)
            
            % Leitura de informações e escrita em arquivo.
            filename = ['appColeta_udpLogger_' datestr(now,'yymmdd_THHMMSS') '.bin'];
            fileID = fopen(fullfile(app.menu_userPath.Value, filename), 'w+');
            
            % File Format Header
            fwrite(fileID, 23, 'int32');
            fwrite(fileID, ['CRFS DATA FILE V023' zeros(1, 13)], 'char*1');
            
            % Unit Information
            if isempty(app.udpLogger_Hostname.Value)
                Node     = app.udpLogger_Node.Value;
                unitInfo = 'Stationary';
                method   = sprintf('udpLogger_IP%s_Port%d', UDP_IP, UDP_PORT);
            else
                Node     = app.udpLogger_Hostname.Value;
                unitInfo = app.udpLogger_UnitInfo.Value;
                method   = sprintf('%s (%s:%d)', app.udpLogger_Method.Value, UDP_IP, UDP_PORT);
            end
            
            udpLogger_Write_DataType21(app, fileID, Node, unitInfo, method)
            
        end


        function udpLogger_Write_BlockHeader(app, fileID, ThreadID, BytesBlock, DataType)
            
            fwrite(fileID, [ThreadID, BytesBlock], 'uint32');
            fwrite(fileID, DataType, 'int32');
            
        end     
        
        
        function udpLogger_Write_DataType21(app, fileID, Node, unitInfo, method)
        
            Node = [Node zeros(1, 16-length(Node))];
            
            if mod(length(unitInfo), 4)
                unitInfo = [unitInfo zeros(1, 4-mod(length(unitInfo), 4))];
            end
            unitInfoLength = length(unitInfo);
            
            if mod(length(method), 4)
                method = [method zeros(1, 4-mod(length(method), 4))];
            end    
            methodLength = length(method);
            
            FileNumber = 0;
            BytesBlock = 28 + unitInfoLength + methodLength;
            
            udpLogger_Write_BlockHeader(app, fileID, 0, BytesBlock, 21);
            
            fwrite(fileID, Node,           'char*1');
            fwrite(fileID, unitInfoLength, 'uint32');
            fwrite(fileID, unitInfo,       'char*1');
            fwrite(fileID, methodLength,   'uint32');
            fwrite(fileID, method,         'char*1');
            fwrite(fileID, FileNumber,     'uint32');
            
            udpLogger_Write_BlockTrailer(app, fileID, BytesBlock)
            
        end
        
        
        function udpLogger_Write_BlockTrailer(app, fileID, BytesBlock)
            
            fseek(fileID, -(12+BytesBlock), 'cof');
            CheckSumAdd = fread(fileID, (12+BytesBlock), 'uint8');
            CheckSumAdd = uint32(sum(CheckSumAdd));
            fseek(fileID, 0, 'cof');
            
            fwrite(fileID, CheckSumAdd, 'uint32');
            fwrite(fileID, 'UUUU', 'char*1');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            proj = matlab.project.rootProject;
            if isempty(proj)
                auxStr = 'O appColeta somente poderá ser executado se o projeto "appColeta.prj" estiver aberto.';
                uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error', 'CloseFcn', @(~,~)MainAppCloseRequest(app));
                
            else
                app.RootFolder          = char(proj.RootFolder);
                app.menu_userPath.Items = {[app.RootFolder '\Temp']};
                
                % Instrument list, tasks list and EB500 map (StepWidth Vs.
                % Selectivity Vs. Resolution)
                [app.instrList, app.taskList, app.EB500_RBW, app.instrInfo] = Fcn_startup('Full');
%                 app.instrInfo = readtable('instrInfo.xlsx');
                
                % Warnings
                app.Task_Warning1.Visible = 0;
                app.Task_Warning2.Visible = 0;
                
                % SelectedTab (Home)
                app.TabGroup.SelectedTab   = app.TabGroup.Children(1);
                
                % Observation time (Start)
                startTime = datetime('now', 'Format', 'dd/MM/yyyy HH:mm:ss') + minutes(10);
                app.Task_startTimeDate.Value    = startTime;
                app.Task_startTimeHours.Value   = hour(startTime);
                app.Task_startTimeMinutes.Value = minute(startTime);
                
                %% Posição da janela principal do app.
                mainMonitor = get(0, 'MonitorPositions');
                mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
                
                if height(mainMonitor) > 1; mainMonitor(2:end,:) = [];
                end
                
                if mainMonitor(3) >= 1016; x0 = 1016;
                else;                      x0 = MainMonitor(3);
                end
                
                if mainMonitor(4) > 768;   y0 = (mainMonitor(4) - 726);
                else;                      y0 = 42;
                end
                app.MainApp.Position = [1, y0, x0, 696];
                
                
                % Criação da figura e dos eixos.
                % Inicialmente apaga toda e qualquer figura que porventura
                % esteja aberta no Matlab.
                close(findobj('Type', 'Figure', '-not', 'Name', 'appColeta'))
                
                if mainMonitor(3) > x0 + 852; x1 = x0+6;
                else;                         x1 = (mainMonitor(3)-852);                
                end
                
                app.fig   = figure('Name', 'appColeta: udpLogger', 'NumberTitle', 'off',   ...
                                   'Position', [x1, app.MainApp.Position(2), 852, 622],    ...
                                   'MenuBar', 'figure', 'ToolBar', 'none', 'Visible', 'off', ...
                                   'Color', [0.94,0.94,0.94],                              ...
                                   'CloseRequestFcn', "set(gcf, 'Visible', 'off')");
    
                app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                          'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                          'GridAlpha'     , .25, 'GridColor'     , [0.94,0.94,0.94], ...
                                          'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                          'Unit', 'normalized', 'InnerPosition', [.08 .1 .85 .82],   ...
                                          'TitleHorizontalAlignment', 'left', 'Toolbar', []);
                
                set(app.axes1.Title,    'FontSize', 10, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.Subtitle, 'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                app.axes1.XAxis.Exponent = 6;
                hold(app.axes1, 'on')
                disableDefaultInteractivity(app.axes1)
                
            end
            
        end

        % Close request function: MainApp
        function MainAppCloseRequest(app, event)
            
            Instruments = app.instrList;
            Tasks       = app.taskList;
            EB500       = app.EB500_RBW;
            
            for ii = 1:length(app.taskList)
                if isfield(Tasks{ii}.Band, 'Flag')
                    Tasks{ii}.Band = rmfield(Tasks{ii}.Band, {'instrStepWidth_Items', 'instrStepWidth',          ...
                                                              'instrDataPoints_Limits', 'instrDataPoints',       ...
                                                              'instrResolution_Items', 'instrResolution',        ...
                                                              'instrSelectivity', 'instrPreamp', 'instrAttMode', ...
                                                              'instrAttFactor_Items', 'instrAttFactor', 'Flag'});
                end
            end

            filename = fullfile([app.RootFolder '\DataBase'], 'StartUpData.mat');
            save(filename, 'Instruments', 'Tasks', 'EB500', '-mat', '-v7.3')
            
            delete(app.fig)
            delete(app)
            
        end

        % Value changed function: menu_Button1
        function menu_Button1ValueChanged(app, event)
            
            if app.menu_Button1.Value
                app.menu_Button2.Value = 0;
                app.menu_Button3.Value = 0;
                app.menu_Button4.Value = 0;
            else
                app.menu_Button1.Value = 1;
            end
            Layout_ModeOperation(app)
            
        end

        % Value changed function: menu_Button2
        function menu_Button2ValueChanged(app, event)
            
            if app.menu_Button2.Value
                app.menu_Button1.Value = 0;
                app.menu_Button3.Value = 0;
                app.menu_Button4.Value = 0;
            else
                app.menu_Button2.Value = 1;
            end
            Layout_ModeOperation(app)
            
        end

        % Value changed function: menu_Button3
        function menu_Button3ValueChanged(app, event)
            
            if app.menu_Button3.Value
                if isempty(app.instrList) || isempty(find(contains([app.instrList.Family], 'Analisador de espectro / receptor'), 1))
                    app.menu_Button3.Value = 0;
                    str = 'É necessário incluir ao menos um instrumento da família "Analisador de espectro / receptor".';
                    Fcn_uiAlert(app, str, 'Alarme!', 'warning')
                    
                    return
                else
                    app.menu_Button1.Value = 0;
                    app.menu_Button2.Value = 0;
                    app.menu_Button4.Value = 0;
                end
            else
                app.menu_Button3.Value = 1;
            end
            Layout_ModeOperation(app)
            
        end

        % Value changed function: menu_Button4
        function menu_Button4ValueChanged(app, event)
            
            if app.menu_Button4.Value
                app.menu_Button1.Value = 0;
                app.menu_Button2.Value = 0;
                app.menu_Button3.Value = 0;
            else
                app.menu_Button4.Value = 1;
            end
            Layout_ModeOperation(app)
            
        end

        % Button pushed function: menu_Button5
        function menu_Button5Pushed(app, event)
            
            Folder = uigetdir(app.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de tarefas');
            
            if Folder
                Tasks = app.taskList;
                
                for ii = 1:length(app.taskList)
                    if isfield(Tasks{ii}.Band, 'Flag')
                        Tasks{ii}.Band = rmfield(Tasks{ii}.Band, {'instrStepWidth_Items', 'instrStepWidth',          ...
                                                                  'instrDataPoints_Limits', 'instrDataPoints',       ...
                                                                  'instrResolution_Items', 'instrResolution',        ...
                                                                  'instrSelectivity', 'instrPreamp', 'instrAttMode', ...
                                                                  'instrAttFactor_Items', 'instrAttFactor', 'Flag'});
                    end
                end
    
                filename = fullfile(Folder, ['Script_' datestr(now,'yymmdd_THHMMSS') '.mat']);
                save(filename, 'Tasks', '-mat', '-v7.3')
            end
            
        end

        % Button pushed function: menu_Button6
        function menu_Button6Pushed(app, event)
            
            [File, Path] = uigetfile({'Script_*.mat', 'appColeta (*.mat)'}, ...
                                      'Selecione um arquivo', 'MultiSelect', 'off');
            
            if File
                load(fullfile(Path, File), "Tasks")
                
                app.taskList = Tasks;
                
                delete(app.Tree2.Children)
                Layout_ModeOperation(app)
            end
            
        end

        % Value changed function: menu_Button7
        function menu_Button7ValueChanged(app, event)
            
            if app.menu_Button7.Value
                Layout_EditTask(app)
                
                app.Task_Name.Value            = 'Nova tarefa';
                app.Task_ThreadID.Value        = 1;
                app.Task_Description.Value     = 'Faixa 1 de 1';
                app.Task_F0.Value              = 88;
                app.Task_F1.Value              = 108;
                app.Task_RFMode.Value          = 'Normal';
                app.Task_StepWidth.Value       = 25;
                app.Task_Resolution.Value      = 100;
                app.Task_LevelUnit.Value       = 'dBm';
                app.Task_TraceMode.Value       = 'ClearWrite';
                app.Task_Detector.Value        = 'Average/RMS';
                app.Task_THR.Value             = 0;
                app.Task_RevisitTime.Value     = 10;
                app.Task_IntegrationFactor.Value = 0;
                
                delete(app.Tree3.Children)
                
            else
                menu_Button3ValueChanged(app)
                Layout_EditTask(app)
            end
            
        end

        % Image clicked function: menu_searchPath
        function menu_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            
            if newUserPath
                ind = find(strcmp(app.menu_userPath.Items, newUserPath));
                
                if isempty(ind)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(ind);
                end
            end
            
        end

        % Button pushed function: ToolBar_Button1
        function ToolBar_Button1Pushed(app, event)
            
            global instrInfo1
            global instrList1
            global taskList1
            global specInfo1
            
            instrInfo1 = app.instrInfo;
            instrList1 = app.instrList;
            taskList1  = app.taskList;
            specInfo1  = app.specInfo;
            
            msg = sprintf(['As principais variáveis do appColeta - "instrInfo1", "instrList1", "taskList1" e "specInfo1" - foram ' ...
                           'definidas como globais, sendo possível a sua importação na área de trabalho do Matlab por meio do '    ...
                           'comando:\n\n• global <NomeVariável>']);
            
            Fcn_uiAlert(app, msg, 'Variáveis globais', 'info')
            
        end

        % Button pushed function: ToolBar_Button2
        function ToolBar_Button2Pushed(app, event)
            
            msg = sprintf(['appColeta v.0.5\n2021\n\nAplicação de coleta de dados gerados em monitorações '          ...
                           'desenvolvida no âmbito dos projetos 3A e RFLOOK - Processos nºs 53554.000939/2020-26 e ' ...
                           '53500.063493/2020-76.']);
            
            Fcn_uiAlert(app, msg, 'Informações', 'info')
            
        end

        % Button pushed function: ToolBar_Button3
        function ToolBar_Button3Pushed(app, event)
            
            msg = sprintf(['appColeta v.0.5\n2021\n\nPendente elaboração documental (manuais do usuário e do desenvolvedor), '   ...
                           'de vídeos tutoriais e de treinamentos.\n\nReportes de bugs devem ser direcionados ao servidor Eric ' ...
                           'Magalhães Delgado (GR08).']);
            
            Fcn_uiAlert(app, msg, 'Ajuda', 'info')
            
        end

        % Selection changed function: Tree1
        function Tree1SelectionChanged(app, event)
            
            app.Connection_deleteInstrument.Enable = 1;
            
            if length(app.Tree1.SelectedNodes) == 1
                app.Connection_EditButton.Enable    = 1;
                app.Connection_ConnectButton.Enable = 1;
                
                Connection_viewSelectedNode(app)
            else
                app.Connection_EditButton.Enable    = 0;
                app.Connection_ConnectButton.Enable = 0;
            end
            
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            
            Fcn_Tree3(app, ind1)
            Tree3SelectionChanged(app)
            
        end

        % Selection changed function: Tree3
        function Tree3SelectionChanged(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            Fcn_TaskMode_Info(app, ind1, ind2)
            Fcn_TaskMode_Instr(app, ind1, ind2)
            
        end

        % Selection changed function: Tree4
        function Tree4SelectionChanged(app, event)
            
            set(app.GridLayout6_3.Children, 'Enable', 1)
            
            ind = app.Tree4.SelectedNodes.NodeData;
            app.udpLogger_Description.Value = app.bandList(ind).Description;
            app.udpLogger_DataPoints.Value   = app.bandList(ind).DataPoints;
            app.udpLogger_StepWidth.Value   = app.bandList(ind).StepWidth ./ 1e+3;
            app.udpLogger_Resolution.Value  = app.bandList(ind).Resolution ./ 1e+3;
            app.udpLogger_LevelUnit.Value = app.bandList(ind).LevelUnit;
            app.udpLogger_Operation.Value = app.bandList(ind).Operation;
            
        end

        % Value changed function: Connection_Family
        function Connection_FamilyValueChanged(app, event)
            
            switch app.Connection_Family.Value
                case 'Analisador de espectro / receptor'
                    app.Connection_Name.Items = {'Anritsu MS2720T',       ...
                                                 'Keysight N9344C',       ...
                                                 'Rohde & Schwarz EB500', ...
                                                 'Rohde & Schwarz FSL-6', ...
                                                 'Rohde & Schwarz FSVR-20'};
                    app.Connection_Type.Items = {'TCPIP Socket', 'Visa TCPIP'};
                    
                case 'GPS'
                    app.Connection_Name.Items = {'Garmin GPSmap 62sc', 'Smartphone'};
                    app.Connection_Type.Items = {'Serial', 'TCPIP Socket'};
            end
            Connection_NameValueChanged(app)
            
        end

        % Value changed function: Connection_Name
        function Connection_NameValueChanged(app, event)
            
            % Tipos de comunicação implantadas para cada um dos
            % instrumentos. 
            
            % Pendente ajuste que possibilite selecionar, por
            % exemplo, entre os fluxos massivos disponíveis no EB500 (TCP
            % ou UDP). Pendente incluir outros instrumentos.
            
            switch app.Connection_Name.Value
                case 'Anritsu MS2720T'
                    set(app.Connection_Type, 'Items', {'TCPIP Socket', 'Visa TCPIP'}, 'Value', 'TCPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '9001';
                    
                case {'Keysight N9344C', 'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR'}
                    set(app.Connection_Type, 'Items', {'TCPIP Socket', 'Visa TCPIP'}, 'Value', 'TCPIP Socket');
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '5025';
                    
                case 'Rohde & Schwarz EB500'
                    set(app.Connection_Type, 'Items', {'TCPIP Socket'});
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '5555';
                    
                case 'Garmin GPSmap 62sc'
                    set(app.Connection_Type, 'Items', {'Serial', 'TCPIP Socket'}, 'Value', 'Serial');
                    
                    app.Connection_IP.Enable  = 0;
                    app.Connection_Port.Value = 'COM1';
                    
                case 'Smartphone'
                    set(app.Connection_Type, 'Items', {'TCPIP Socket'});
                    
                    app.Connection_IP.Enable  = 1;
                    app.Connection_Port.Value = '1100';
                    
            end
            Connection_infoPanel(app, app.Connection_Name.Value)
            
        end

        % Value changed function: Connection_Type
        function Connection_TypeValueChanged(app, event)
            
            app.EndereoIPEditField_2Label.Enable = 1;
            app.Connection_IP.Enable             = 1;
            
            app.PortaEditFieldLabel.Enable = 1;
            app.Connection_Port.Enable     = 1;
            
            switch app.Connection_Type.Value
                case 'TCPIP Socket'
                    switch app.Connection_Name.Value
                        case 'Rohde & Schwarz EB500'
                            app.Connection_Port.Value = '5555';
                        case {'Garmin GPSmap 62sc', 'Smartphone'}
                            app.Connection_Port.Value = '1100';
                        otherwise
                            app.Connection_Port.Value = '5025';
                    end
                    
                case 'Visa TCPIP'
                    app.PortaEditFieldLabel.Enable = 0;
                    set(app.Connection_Port, 'Enable', 0, 'Value', '');
                    
                case 'Serial'
                    app.EndereoIPEditField_2Label.Enable = 0;
                    set(app.Connection_IP,   'Enable', 0, 'Value', '');
                    
                    set(app.Connection_Port, 'Enable', 1, 'Value', 'COM1');
                    
            end
            
        end

        % Image clicked function: Connection_addInstrument
        function Connection_addInstrumentClicked(app, event)
            
            if app.Connection_EditButton.Value
                ind = app.Tree1.SelectedNodes.NodeData;
            else
                ind = length(app.instrList)+1;
            end
            
            app.instrList(ind).Family      = app.Connection_Family.Value;
            app.instrList(ind).Name        = app.Connection_Name.Value;
            app.instrList(ind).Type        = app.Connection_Type.Value;
            app.instrList(ind).IP          = app.Connection_IP.Value;
            app.instrList(ind).Port        = app.Connection_Port.Value;
            app.instrList(ind).Timeout     = app.Connection_Timeout.Value;
            app.instrList(ind).inBuffer    = app.Connection_Buffer.Value;
            app.instrList(ind).Description = app.Connection_Description.Value;
            
            if ~isempty(app.Tree1.Children)
                app.Tree1.SelectedNodes = app.Tree1.Children(1);
                Connection_viewSelectedNode(app)
            end
            
            Fcn_Tree1(app)
            
        end

        % Image clicked function: Connection_deleteInstrument
        function Connection_deleteInstrumentClicked(app, event)
            
            ind = [];
            for ii = 1:length(app.Tree1.SelectedNodes)
                ind = [ind, app.Tree1.SelectedNodes(ii).NodeData];
            end
            
            app.instrList(ind) = [];
            Fcn_Tree1(app)
            
        end

        % Value changed function: Connection_EditButton
        function Connection_EditButtonPushed(app, event)
            
            if app.Connection_EditButton.Value
                app.Tree1.Enable             = 0;
                app.Connection_Family.Enable = 0;
                
                ind = app.Tree1.SelectedNodes.NodeData;
                
                app.Connection_Name.Items = {app.instrList(ind).Name};
                Connection_NameValueChanged(app)
                
                app.Connection_Type.Value = app.instrList(ind).Type;
                
                if ~isempty(app.instrList(ind).IP)
                    app.Connection_IP.Value = app.instrList(ind).IP;
                end
                
                app.Connection_Port.Value        = app.instrList(ind).Port;
                app.Connection_Timeout.Value     = app.instrList(ind).Timeout;
                app.Connection_Buffer.Value      = app.instrList(ind).inBuffer;
                app.Connection_Description.Value = app.instrList(ind).Description;
                
            else
                app.Tree1.Enable             = 1;
                app.Connection_Family.Enable = 1;
                
                Connection_viewSelectedNode(app)
            end
            
        end

        % Button pushed function: Connection_ConnectButton
        function Connection_ConnectButtonPushed(app, event)
            
            uiprogressdlg(app.MainApp, "Indeterminate", "on");
            
            ind = app.Tree1.SelectedNodes.NodeData;
            if strcmp(app.instrList(ind).Family, 'Analisador de espectro / receptor')
                try
                    [~, Node] = Connection_Instrument(app.instrList(ind).Type, app.instrList(ind).IP, str2double(app.instrList(ind).Port), app.instrList(ind).Timeout, app.instrList(ind).inBuffer);
                catch ME
                    str = sprintf('Conectividade instrumento\n\n• %s\n• %s', ME.identifier, ME.message);
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                    return
                end
                
                if ~isempty(Node)
                    Fcn_uiAlert(app, Node, app.instrList(ind).Name, 'success')
                else
                    str = 'Apesar de criado objeto, não foi recebida resposta ao comando "*IDN?".';
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                end
                
                
            elseif strcmp(app.instrList(ind).Family, 'GPS')
                try
                    [~, gps] = Connection_gps(app.instrList(ind).Type, app.instrList(ind).IP, app.instrList(ind).Port, app.instrList(ind).Timeout, app.instrList(ind).inBuffer, 'Full');
                catch ME
                    str = sprintf('Conectividade GPS\n\n• %s\n• %s', ME.identifier, ME.message);
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                    return
                end
                
                if ~isempty(gps)
                    str = sprintf('Status: %.0f\nLatitude: %.6f\nLongitude: %.6f\nHorário: %s (UTC)', ...
                                  gps.Status, gps.Latitude, gps.Longitude, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'success')
                else
                    str = 'Apesar de criado objeto, não foi recebida informação NMEA/RMC.';
                    Fcn_uiAlert(app, str, app.instrList(ind).Name, 'error')
                end
            end
            
        end

        % Image clicked function: Task_deleteTask
        function Task_deleteTaskImageClicked(app, event)
            
            ind = app.Tree2.SelectedNodes.NodeData;
            app.taskList(ind) = [];
            
            Fcn_Tree2(app)
            Tree2SelectionChanged(app)
            
        end

        % Image clicked function: Task_deleteBand
        function Task_deleteBandImageClicked(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2) = [];
            
            value = length(app.taskList{ind1}.Band);
            for ii = 1:value
                app.taskList{ind1}.Band(ii).ThreadID = ii;
            end
            
            Fcn_Tree3(app, ind1)
            Tree3SelectionChanged(app)
            
            app.Task_addBand.Enable    = 0;
            if value > 1; app.Task_deleteBand.Enable = 1;
            end
            
        end

        % Value changed function: Task_newBand
        function Task_newBandButtonPushed(app, event)
            
            ind = app.Tree2.SelectedNodes.NodeData;
            if app.Task_newBand.Value
                app.Task_editBand.Value = 0;
                
                value = length(app.taskList{ind}.Band);
                app.Task_ThreadID.Value = value+1;
            end
            Layout_EditTask(app)
            
        end

        % Value changed function: Task_editBand
        function Task_editBandValueChanged(app, event)
            
            if app.Task_editBand.Value
                app.Task_newBand.Value  = 0;
                app.Task_ThreadID.Value = app.Tree3.SelectedNodes.NodeData;
            end
            Layout_EditTask(app)
            
        end

        % Image clicked function: Task_addBand
        function Task_addBandImageClicked(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            if app.Task_editBand.Value
                ind2 = app.Tree3.SelectedNodes.NodeData;
            
            elseif app.Task_newBand.Value
                ind2 = length(app.taskList{ind1}.Band) + 1; 
                
            elseif app.menu_Button7.Value
                ind1 = length(app.Tree2.Children)+1;
                ind2 = 1;
            end
            
            app.taskList{ind1}.Name = app.Task_Name.Value;
            
            app.taskList{ind1}.Band(ind2).ThreadID    = app.Task_ThreadID.Value;
            app.taskList{ind1}.Band(ind2).Description = app.Task_Description.Value;
            
            app.taskList{ind1}.Band(ind2).F0         = app.Task_F0.Value * 1e+6;
            app.taskList{ind1}.Band(ind2).F1         = app.Task_F1.Value * 1e+6;
            app.taskList{ind1}.Band(ind2).StepWidth  = app.Task_StepWidth.Value * 1e+3;
            app.taskList{ind1}.Band(ind2).Resolution = app.Task_Resolution.Value * 1e+3;
            
            app.taskList{ind1}.Band(ind2).RFMode     = app.Task_RFMode.Value;
            app.taskList{ind1}.Band(ind2).TraceMode  = app.Task_TraceMode.Value;
            app.taskList{ind1}.Band(ind2).Detector   = app.Task_Detector.Value;
            app.taskList{ind1}.Band(ind2).LevelUnit  = app.Task_LevelUnit.Value;
            
            app.taskList{ind1}.Band(ind2).RevisitTime       = app.Task_RevisitTime.Value;
            app.taskList{ind1}.Band(ind2).IntegrationFactor = app.Task_IntegrationFactor.Value;
            
            Fcn_TaskMode_Refresh1(app, ind1, ind2)
            
            Fcn_Tree2(app)
            app.Tree2.SelectedNodes = app.Tree2.Children(ind1);
            
            Fcn_Tree3(app, ind1)
            Tree3SelectionChanged(app)
            
            app.Task_addBand.Enable    = 0;
            app.Tree3.Enable           = 1;
            app.Task_Instrument.Enable = 1;
            
            set(app.GridLayout5.Children, 'Enable', 1)
            
            app.Task_editBand.Value = 0;
            app.Task_newBand.Value  = 0;
            app.menu_Button7.Value  = 0;
            Layout_EditTask(app)
            
        end

        % Button pushed function: Task_RefreshButton
        function Task_RefreshButtonPushed(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).Flag = [];            
            Fcn_TaskMode_Instr(app, ind1, ind2)
            
        end

        % Value changed function: Task_Instrument
        function Task_InstrumentValueChanged(app, event)
            
            value = length(app.instrList);
            
            str2 = {'0: Manual'};
            ind  = 1;
            
            if contains(app.Task_Instrument.Value, {'EB500', 'MS2720T', 'N9344C'})
                for ii = 1:value
                    auxStr = sprintf('%d: %s', ii, app.instrList(ii).Name);
                    
                    if strcmp(auxStr, app.Task_Instrument.Value)
                        ind  = ii;
                        str2 = [str2, auxStr];
                        
                    elseif contains(app.instrList(ii).Name, {'GPS', 'Smartphone'})
                        str2 = [str2, auxStr];
                    end
                end
                ind = find(strcmp(str2, app.Task_Instrument.Value));
                
            else
                for ii = 1:value
                    if contains(app.instrList(ii).Name, {'GPS', 'Smartphone'})
                        str2 = [str2, sprintf('%d: %s', ii, app.instrList(ii).Name)];
                    end
                end
                
            end
            app.Task_GPS.Items = str2;
            
            if ind == 1; app.Task_GPS.Value = app.Task_GPS.Items{end};
            else;        app.Task_GPS.Value = app.Task_GPS.Items{ind};
            end
            
            Task_GPSValueChanged(app)
            
            Layout_TaskMode_Panel2(app)

            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
                        
            for ii = 1:length(app.taskList{ind1}.Band)
                Fcn_TaskMode_Refresh1(app, ind1, ii)
            end
            
            Fcn_TaskMode_Instr(app, ind1, ind2)
            
        end

        % Value changed function: Task_GPS
        function Task_GPSValueChanged(app, event)
            
            if strcmp(app.Task_GPS.Value, '0: Manual')
                app.LatitudeEditFieldLabel.Enable  = 1;
                app.LongitudeEditFieldLabel.Enable = 1;
                
                set(app.Task_manualLatitude,  'Enable', 1, 'Value', -1)
                set(app.Task_manualLongitude, 'Enable', 1, 'Value', -1)
            else
                app.LatitudeEditFieldLabel.Enable  = 0;
                app.LongitudeEditFieldLabel.Enable = 0;
                
                set(app.Task_manualLatitude,  'Enable', 0, 'Value', -1)
                set(app.Task_manualLongitude, 'Enable', 0, 'Value', -1)
            end
            
        end

        % Value changed function: Task_startImediately
        function Task_startImediatelyValueChanged(app, event)
            
            if app.Task_startImediately.Value
                app.Task_startTimeDate.Enable    = 0;
                app.Task_startTimeHours.Enable   = 0;
                app.Task_startTimeMinutes.Enable = 0;
            else
                app.Task_startTimeDate.Enable    = 1;
                app.Task_startTimeHours.Enable   = 1;
                app.Task_startTimeMinutes.Enable = 1;
            end
            
        end

        % Value changed function: Task_F0, Task_F1
        function Task_SpanValueChanged(app, event)
            
            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            
            if span <= 0
                app.Task_Warning1.Visible = 1;
                app.Task_StepWidth.Enable = 0;
                app.Task_addBand.Enable   = 0;

            else
                app.Task_Warning1.Visible = 0;
                set(app.Task_StepWidth, 'Enable', 1, 'Value', (span/(app.Task_DataPoints_FSL.Value-1)) / 1000)
                app.Task_addBand.Enable   = 1;
                
            end
            
        end

        % Value changed function: Task_StepWidth_FSL
        function Task_StepWidthValueChanged(app, event)
            
            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            
            DataPoints = round(span/(1000*app.Task_StepWidth_FSL.Value) + 1);
            if DataPoints < app.Task_DataPoints_FSL.Limits(1)
                app.Task_DataPoints_FSL.Value = app.Task_DataPoints_FSL.Limits(1);
            elseif DataPoints > app.Task_DataPoints_FSL.Limits(2)
                app.Task_DataPoints_FSL.Value = fix(app.Task_DataPoints_FSL.Limits(2));
            else
                app.Task_DataPoints_FSL.Value = DataPoints;
            end
            
            app.Task_StepWidth_FSL.Value = (span/(app.Task_DataPoints_FSL.Value-1)) / 1000;
            
            % Registra edição de ao menos um dos valores que se aproximariam o máximo 
            % possível dos parâmetros da tarefa.
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrStepWidth = 1000*app.Task_StepWidth_FSL.Value;
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_DataPoints_FSL
        function Task_DataPoints_FSLValueChanged(app, event)
            
            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            app.Task_StepWidth_FSL.Value = (span/(app.Task_DataPoints_FSL.Value-1)) / 1000;
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrDataPoints = app.Task_DataPoints_FSL.Value;
            app.taskList{ind1}.Band(ind2).instrStepWidth  = app.Task_StepWidth_FSL.Value*1000;
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_attMode
        function Task_attModeValueChanged(app, event)
            
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrAttMode = app.Task_attMode.Value;
            
            % Visibilidade do valor do atenuador.
            if strcmp(app.Task_attMode.Value, 'Automático')
                app.AtenuadorDropDownLabel.Visible = 0;
                app.Task_attFactor.Visible         = 0;
            else
                app.AtenuadorDropDownLabel.Visible = 1;
                app.Task_attFactor.Visible         = 1;
            end
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Button pushed function: Task_executeTask
        function Task_executeTaskButtonPushed(app, event)
            
            switch app.Task_DurationUnit.Value
                case 'seg';   Duration = duration(0, 0, app.Task_Duration.Value);
                case 'min';   Duration = duration(0, app.Task_Duration.Value, 0);
                case 'horas'; Duration = duration(app.Task_Duration.Value, 0, 0);
            end
            
            if app.Task_startImediately.Value
                waitTime = 0;
                
            else
                t0 = datetime('now', 'Format', 'dd/MM/yyyy HH:mm:ss');
                t1 = app.Task_startTimeDate.Value + hours(app.Task_startTimeHours.Value) + minutes(app.Task_startTimeMinutes.Value);
                t2 = t1 + Duration;
                
                if t0 < t1;     waitTime = seconds(t1-t0);
                elseif t0 < t2; waitTime = 0;
                end
            end
            
            Task(app, seconds(Duration))
            
            %             app.tmr = timer('ExecutionMode', 'singleShot', ...
            %                             'BusyMode',      'queue',      ...
            %                             'StartDelay',    waitTime-10,  ...
            %                             'TimerFcn',      @(~,~)Task(app, Duration));
            %
            %             start(app.tmr)
            
        end

        % Value changed function: Task_StepWidth_EB500
        function Task_StepWidth_EB500ValueChanged(app, event)

            app.Task_Warning2.Visible = 1;
            
            span      = (app.Task_F1.Value-app.Task_F0.Value)*1e+6;
            stepValue = extractBefore(app.Task_StepWidth_EB500.Value, ' kHz');
            
            % DataPoints
            app.Task_DataPoints_EB500.Value = span/(1000*str2double(stepValue)) + 1;
            
            % Resolution, Selectivity
            rbwValues = [];
            rbwItems  = {};
            for ii = 1:3
                rbwValues = [rbwValues, app.EB500_RBW{app.Task_StepWidth_EB500.Value,ii}];
                rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
            end
            app.Task_Resolution_FSL.Items = rbwItems;
            
            rbwIndex = find(abs(rbwValues - app.Task_Resolution.Value*1000) == min(abs(rbwValues - app.Task_Resolution.Value*1000)));
            app.Task_Resolution_FSL.Value = app.Task_Resolution_FSL.Items{rbwIndex};
            app.Task_Selectivity.Value    = app.Task_Selectivity.Items{rbwIndex};
            
            % Registra edição de ao menos um dos valores que se aproximariam o máximo 
            % possível dos parâmetros da tarefa.
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrStepWidth   = app.Task_StepWidth_EB500.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints  = app.Task_DataPoints_EB500.Value;
            app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;
            
            app.taskList{ind1}.Band(ind2).instrResolution_Items = app.Task_Resolution_FSL.Items;
            app.taskList{ind1}.Band(ind2).instrResolution       = app.Task_Resolution_FSL.Value;
                        
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_Selectivity
        function Task_SelectivityValueChanged(app, event)
            
            app.Task_Warning2.Visible    = 1;
            app.Task_Resolution_FSL.Value = sprintf('%.3f kHz', app.EB500_RBW{app.Task_StepWidth_EB500.Value, app.Task_Selectivity.Value}/1000);
            
            % Registra edição dos valores que se aproximariam o máximo 
            % possível dos parâmetros da tarefa.
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;
            app.taskList{ind1}.Band(ind2).instrResolution  = app.Task_Resolution_FSL.Value;

            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_Resolution_FSL
        function Task_Resolution_FSLValueChanged(app, event)

            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrResolution  = app.Task_Resolution_FSL.Value;
            
            if contains(app.Task_Instrument.Value, 'EB500')
                if app.Task_Selectivity.Visible
                    ind3 = find(strcmp(app.Task_Resolution_FSL.Items, app.Task_Resolution_FSL.Value));
                    app.Task_Selectivity.Value = app.Task_Selectivity.Items{ind3};
                end
                app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;
            end
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_Preamp
        function Task_PreampValueChanged(app, event)

            app.Task_Warning2.Visible = 1;
            
            % Registra edição de ao menos um dos valores que se aproximariam o máximo 
            % possível dos parâmetros da tarefa.
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrPreamp = app.Task_Preamp.Value;
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: Task_attFactor
        function Task_attFactorValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo 
            % possível dos parâmetros da tarefa.
            ind1 = app.Tree2.SelectedNodes.NodeData;
            ind2 = app.Tree3.SelectedNodes.NodeData;
            
            app.taskList{ind1}.Band(ind2).instrAttFactor = app.Task_attFactor.Value;
            
            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;
            
        end

        % Value changed function: udpLogger_Button
        function udpLogger_ButtonValueChanged(app, event)
            
            app.recFlag = 0;
            set(app.udpLogger_Button_2, 'Text', 'Iniciar gravação', 'Icon', 'rEC3.png')
            set(app.udpLogger_Button_2, 'Enable', 0, 'Value', 0)
            
            if app.udpLogger_Button.Value
                app.menu_Button1.Enable = 0;
                app.menu_Button2.Enable = 0;
                app.menu_Button3.Enable = 0;
                app.menu_Button4.Enable = 0;
                
                app.Button.Enable = 0;
                
                app.udpLogger_Node.Enable = 0;
                
                app.udpLogger_Button.Text = 'Parar leitura';
                app.udpLogger_Button.Icon = 'Media_Pause.png';
                
                set(app.GridLayout8.Children, 'Enable', 0)
                app.PausaentrealeituradedatagramasLabel.Enable = 1;
                app.udpLogger_Pause.Enable = 1;
                app.udpLogger_Unit2.Enable = 1;
                
                app.GridLayout6_2.Visible = 1;
                set(app.GridLayout6_3.Children, 'Enable', 0)
                
                app.bandList = [];
                delete(app.Tree4.Children)
                
                % GPS Panel
                set(app.GridLayout11.Children, 'Enable', 0)
                set(app.udpLogger_gpsCount,    'Enable', 1, 'Value', 0)
                app.BlocosLabel.Enable           = 1;
                app.udpLogger_gpsLatitude.Value  = -1;
                app.udpLogger_gpsLongitude.Value = -1;
                
                % Datagrama Panel
                app.udpLogger_Datagram2.Value = 0;
                app.udpLogger_Datagram1.Value      = 0;
                app.udpLogger_TextInfo.Value  = '';
                
                set(app.GridLayout12.Children, 'Enable', 0)
                app.udpLogger_Hostname.Value = '';
                app.udpLogger_UnitInfo.Value = '';
                app.udpLogger_Method.Value = '';
                
                title(app.axes1, '', '')
                                
                app.fig.Visible = 1;
                cla(app.axes1)
                
                udpLogger(app)
                
            else
                app.menu_Button1.Enable = 1;
                app.menu_Button2.Enable = 1;
                app.menu_Button3.Enable = 1;
                app.menu_Button4.Enable = 1;
                
                app.Button.Enable = 1;
                
                app.udpLogger_Node.Enable = 1;
                
                app.udpLogger_Button.Text = 'Iniciar leitura';
                app.udpLogger_Button.Icon = 'Media_PlayWhite.png';
                
                set(app.GridLayout8.Children, 'Enable', 1)
                
            end
            
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            
            if app.Button.Value
                set(app.udpLogger_IP,  'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.udpLogger_Port, 'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.udpLogger_Time, 'Editable', 1, 'BackgroundColor', [1,1,1])
            else
                set(app.udpLogger_IP,  'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.udpLogger_Port, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.udpLogger_Time, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
            end
            
        end

        % Value changed function: udpLogger_IP, udpLogger_Port, 
        % udpLogger_Time
        function udpLogger_IP1ValueChanged(app, event)
            
            if length(strfind(app.udpLogger_IP.Value, '.')) ~= 3
                return
            else
                app.Button.Value = 0;
                ButtonValueChanged(app)
            end
            
        end

        % Value changed function: udpLogger_Node
        function udpLogger_NodeValueChanged(app, event)
            
            switch app.udpLogger_Node.Value
                case 'RFeye002092'; app.udpLogger_IP.Value = '189.89.182.38';  % Salvador (Paripe)
                case 'RFeye002186'; app.udpLogger_IP.Value = '189.16.189.66';  % Curitiba
                case 'RFeye002263'; app.udpLogger_IP.Value = '200.250.1.162';  % Londrina
                case 'RFeye002292'; app.udpLogger_IP.Value = '187.44.203.199'; % Salvador (Vila Privilege)
            end
            
        end

        % Menu selected function: 
        % AdicionarlistadegravaodoarquivoMenu
        function AdicionarlistadegravaodoarquivoMenuSelected(app, event)
            
            if isempty(app.bandList(app.Tree4.SelectedNodes.NodeData).REC)
                app.bandList(app.Tree4.SelectedNodes.NodeData).REC = app.bandList(app.Tree4.SelectedNodes.NodeData).ThreadID;
                Fcn_Tree4(app)
            end
            
        end

        % Menu selected function: RetirardalistaMenu
        function RetirardalistaMenuSelected(app, event)
            
            if ~isempty(app.bandList(app.Tree4.SelectedNodes.NodeData).REC)
                app.bandList(app.Tree4.SelectedNodes.NodeData).REC = [];
                Fcn_Tree4(app)
            end
            
        end

        % Value changed function: udpLogger_Button_2
        function udpLogger_Button_2ValueChanged(app, event)
            
            if app.udpLogger_Button_2.Value
                app.recFlag = 1;
                set(app.udpLogger_Button_2, 'Text', 'Parar gravação', 'Icon', 'Media_PlayWhite2.png')
            else
                app.recFlag = 0;
                set(app.udpLogger_Button_2, 'Text', 'Iniciar gravação', 'Icon', 'rEC3.png')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appColeta';
            app.MainApp.Icon = 'Bluemine.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Home_Grid0 = uigridlayout(app.Tab1_Home);
            app.Home_ProjectLogo = uiimage(app.Home_Grid0);
            app.Home_ProjectLogo.ImageSource = 'RFLook3A.png';
            app.Home_subTitle = uilabel(app.Home_Grid0);
            app.Home_subTitle.Text = 'v.0.5 (Homologação)';
            app.Home_mainTitle = uilabel(app.Home_Grid0);
            app.Home_mainTitle.Text = 'appColeta';
            app.Tab2_Connection = uitab(app.TabGroup);
            app.Connection_Grid0 = uigridlayout(app.Tab2_Connection);
            app.Connection_ConnectButton = uibutton(app.Connection_Grid0, 'push');
            app.Connection_ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Connection_ConnectButtonPushed, true);
            app.Connection_ConnectButton.Icon = 'ConnectWhite.png';
            app.Connection_ConnectButton.Enable = 'off';
            app.Connection_ConnectButton.Text = 'Conectividade';
            app.Connection_EditButton = uibutton(app.Connection_Grid0, 'state');
            app.Connection_EditButton.ValueChangedFcn = createCallbackFcn(app, @Connection_EditButtonPushed, true);
            app.Connection_EditButton.Enable = 'off';
            app.Connection_EditButton.Icon = 'Edit2.png';
            app.Connection_EditButton.Text = '';
            app.Tree1 = uitree(app.Connection_Grid0);
            app.Tree1.Multiselect = 'on';
            app.Tree1.SelectionChangedFcn = createCallbackFcn(app, @Tree1SelectionChanged, true);
            app.Connection_deleteInstrument = uiimage(app.Connection_Grid0);
            app.Connection_deleteInstrument.ImageClickedFcn = createCallbackFcn(app, @Connection_deleteInstrumentClicked, true);
            app.Connection_deleteInstrument.Enable = 'off';
            app.Connection_deleteInstrument.ImageSource = 'Check2.png';
            app.Connection_addInstrument = uiimage(app.Connection_Grid0);
            app.Connection_addInstrument.ImageClickedFcn = createCallbackFcn(app, @Connection_addInstrumentClicked, true);
            app.Connection_addInstrument.ImageSource = 'Check1.png';
            app.Connection_Panel2 = uipanel(app.Connection_Grid0);
            app.Connection_Grid2 = uigridlayout(app.Connection_Panel2);
            app.Connection_Grid4 = uigridlayout(app.Connection_Grid2);
            app.Instr_attFactor = uitextarea(app.Connection_Grid4);
            app.Instr_attFactor.Editable = 'off';
            app.Instr_attFactor.Value = {'0 dB'; '5 dB'; '10 dB'; '15 dB'; '20 dB'; '25 dB'; '30 dB'};
            app.AtenuaoLabel_3 = uilabel(app.Connection_Grid4);
            app.AtenuaoLabel_3.Text = 'Atenuação:';
            app.ResoluoLabel = uilabel(app.Connection_Grid4);
            app.ResoluoLabel.Text = 'Resolução:';
            app.Instr_Resolution = uitextarea(app.Connection_Grid4);
            app.Instr_Resolution.Editable = 'off';
            app.Instr_Resolution.Value = {'1 kHz'; '3 kHz'; '10 kHz'; '30 kHz'; '100 kHz'; '200 kHz'; '300 kHz'; '1 MHz'};
            app.MododotraoLabel = uilabel(app.Connection_Grid4);
            app.MododotraoLabel.Text = 'Modo do traço:';
            app.Instr_TraceMode = uitextarea(app.Connection_Grid4);
            app.Instr_TraceMode.Editable = 'off';
            app.Instr_TraceMode.Value = {'Clear Write'; 'Max Hold'; 'Min Hold'; 'Average'};
            app.DetectorLabel = uilabel(app.Connection_Grid4);
            app.DetectorLabel.Text = 'Detector:';
            app.Instr_Detector = uitextarea(app.Connection_Grid4);
            app.Instr_Detector.Editable = 'off';
            app.Instr_Detector.Value = {'Auto Peak'; 'Positive Peak'; 'Negative Peak'; 'Sample'; 'RMS'; 'Average'; 'Quasi Peak'};
            app.Connection_Grid3 = uigridlayout(app.Connection_Grid2);
            app.Instr_Preamp = uicheckbox(app.Connection_Grid3);
            app.Instr_Preamp.Enable = 'off';
            app.Instr_Preamp.Text = '';
            app.Instr_Preamp.Value = true;
            app.Instr_maxLevel = uieditfield(app.Connection_Grid3, 'text');
            app.Instr_maxLevel.Editable = 'off';
            app.Instr_maxLevel.Value = '30 dBm';
            app.PotnciamximaLabel = uilabel(app.Connection_Grid3);
            app.PotnciamximaLabel.Text = 'Potência máxima:';
            app.Instr_Image = uiimage(app.Connection_Grid3);
            app.Instr_Image.ImageSource = 'Instr_R&S_FSL.png';
            app.Instr_Points = uitextarea(app.Connection_Grid3);
            app.Instr_Points.Editable = 'off';
            app.Instr_Points.Value = {'101 - 32001'};
            app.PontosporvarreduraLabel_2 = uilabel(app.Connection_Grid3);
            app.PontosporvarreduraLabel_2.Text = 'Pontos por varredura:';
            app.FaixadefrequnciaLabel = uilabel(app.Connection_Grid3);
            app.FaixadefrequnciaLabel.Text = 'Faixa de frequência:';
            app.Instr_freqRange = uieditfield(app.Connection_Grid3, 'text');
            app.Instr_freqRange.Editable = 'off';
            app.Instr_freqRange.Value = '9 kHz - 6 GHz';
            app.Instr_PreampLabel = uilabel(app.Connection_Grid3);
            app.Instr_PreampLabel.Text = 'Pré-amplificador';
            app.Instr_Description = uilabel(app.Connection_Grid3);
            app.Instr_Description.Text = 'Adquirido entre 2005 e 2006. Alta sensibilidade (em especial quando habilitado o pré-amplificador de 20dB), rapidez (até 2.5ms) e número de pontos por traço configurável, podendo coincidir com canalização da faixa monitorada.';
            app.Connection_Label2 = uilabel(app.Connection_Grid0);
            app.Connection_Label2.Text = 'Principais características';
            app.Connection_Panel1 = uipanel(app.Connection_Grid0);
            app.Connection_Grid1 = uigridlayout(app.Connection_Panel1);
            app.EndereoIPEditField_2Label = uilabel(app.Connection_Grid1);
            app.EndereoIPEditField_2Label.Text = 'Endereço IP:';
            app.TipodeconexoDropDownLabel = uilabel(app.Connection_Grid1);
            app.TipodeconexoDropDownLabel.Text = 'Tipo de conexão:';
            app.FabricanteemodeloDropDownLabel = uilabel(app.Connection_Grid1);
            app.FabricanteemodeloDropDownLabel.Text = 'Fabricante e modelo';
            app.BufferentradaMBEditFieldLabel = uilabel(app.Connection_Grid1);
            app.BufferentradaMBEditFieldLabel.Text = 'Buffer entrada (MB):';
            app.Connection_Family = uidropdown(app.Connection_Grid1);
            app.Connection_Family.Items = {'Analisador de espectro / receptor', 'GPS'};
            app.Connection_Family.ValueChangedFcn = createCallbackFcn(app, @Connection_FamilyValueChanged, true);
            app.Connection_Family.Value = 'Analisador de espectro / receptor';
            app.Connection_Name = uidropdown(app.Connection_Grid1);
            app.Connection_Name.Items = {'Anritsu MS2720T', 'Rohde & Schwarz EB500', 'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR-20'};
            app.Connection_Name.ValueChangedFcn = createCallbackFcn(app, @Connection_NameValueChanged, true);
            app.Connection_Name.Value = 'Anritsu MS2720T';
            app.Connection_Type = uidropdown(app.Connection_Grid1);
            app.Connection_Type.Items = {'TCPIP Socket', 'Visa TCPIP'};
            app.Connection_Type.ValueChangedFcn = createCallbackFcn(app, @Connection_TypeValueChanged, true);
            app.Connection_Type.Value = 'TCPIP Socket';
            app.Connection_IP = uieditfield(app.Connection_Grid1, 'text');
            app.Connection_IP.Value = '10.71.0.2';
            app.FamliaLabel = uilabel(app.Connection_Grid1);
            app.FamliaLabel.Text = 'Família:';
            app.PortaEditFieldLabel = uilabel(app.Connection_Grid1);
            app.PortaEditFieldLabel.Text = 'Porta:';
            app.Connection_Timeout = uieditfield(app.Connection_Grid1, 'numeric');
            app.Connection_Timeout.Limits = [1 Inf];
            app.Connection_Timeout.Value = 1;
            app.Connection_Port = uieditfield(app.Connection_Grid1, 'text');
            app.Connection_Port.Value = '5025';
            app.TimeoutsegEditField_2Label = uilabel(app.Connection_Grid1);
            app.TimeoutsegEditField_2Label.Text = 'Timeout (seg):';
            app.Connection_Buffer = uieditfield(app.Connection_Grid1, 'numeric');
            app.Connection_Buffer.Limits = [1 Inf];
            app.Connection_Buffer.Value = 2;
            app.Connection_Description = uieditfield(app.Connection_Grid1, 'text');
            app.DescrioopcionalLabel = uilabel(app.Connection_Grid1);
            app.DescrioopcionalLabel.Text = 'Descrição (opcional):';
            app.Connection_Label1 = uilabel(app.Connection_Grid0);
            app.Connection_Label1.Text = 'Instrumento';
            app.Tab3_Task = uitab(app.TabGroup);
            app.Task_Grid0 = uigridlayout(app.Tab3_Task);
            app.Task_Grid2 = uigridlayout(app.Task_Grid0);
            app.NomeEditFieldLabel = uilabel(app.Task_Grid2);
            app.NomeEditFieldLabel.Text = 'Nome:';
            app.CodificaobitsporpontoDropDownLabel = uilabel(app.Task_Grid2);
            app.CodificaobitsporpontoDropDownLabel.Text = 'Codificação (bits por ponto):';
            app.AnalisadordeespectroreceptorLabel = uilabel(app.Task_Grid2);
            app.AnalisadordeespectroreceptorLabel.Text = 'Analisador de espectro / receptor';
            app.GPSLabel = uilabel(app.Task_Grid2);
            app.GPSLabel.Text = 'GPS';
            app.Task_Panel2 = uipanel(app.Task_Grid2);
            app.Task_Grid4 = uigridlayout(app.Task_Panel2);
            app.Task_manualLongitude = uieditfield(app.Task_Grid4, 'numeric');
            app.Task_manualLongitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLongitude.Enable = 'off';
            app.Task_manualLongitude.Value = -1;
            app.LongitudeEditFieldLabel = uilabel(app.Task_Grid4);
            app.LongitudeEditFieldLabel.Enable = 'off';
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.TempoderevisitaLabel = uilabel(app.Task_Grid4);
            app.TempoderevisitaLabel.Text = 'Tempo de revisita:';
            app.Task_gpsRevisitTime = uieditfield(app.Task_Grid4, 'numeric');
            app.Task_gpsRevisitTime.Limits = [0 Inf];
            app.Task_gpsRevisitTime.ValueDisplayFormat = '%.3f';
            app.Task_gpsRevisitTime.Value = 10;
            app.Task_BandUnit5_2 = uilabel(app.Task_Grid4);
            app.Task_BandUnit5_2.Text = 'seg';
            app.Task_manualLatitude = uieditfield(app.Task_Grid4, 'numeric');
            app.Task_manualLatitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLatitude.Enable = 'off';
            app.Task_manualLatitude.Value = -1;
            app.LatitudeEditFieldLabel = uilabel(app.Task_Grid4);
            app.LatitudeEditFieldLabel.Enable = 'off';
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Task_GPS = uidropdown(app.Task_Grid2);
            app.Task_GPS.Items = {'Embarcado no instrumento', 'Garmin GPSmap 62s', 'Smartphone', 'Manual'};
            app.Task_GPS.ValueChangedFcn = createCallbackFcn(app, @Task_GPSValueChanged, true);
            app.Task_GPS.Value = 'Garmin GPSmap 62s';
            app.Task_Instrument = uidropdown(app.Task_Grid2);
            app.Task_Instrument.Items = {'CRFS RFeye 20-6', 'Rohde & Schwarz FSL-6', 'Rohde & Schwarz FSVR-20'};
            app.Task_Instrument.ValueChangedFcn = createCallbackFcn(app, @Task_InstrumentValueChanged, true);
            app.Task_Instrument.Value = 'CRFS RFeye 20-6';
            app.Task_Panel3 = uipanel(app.Task_Grid2);
            app.Task_Grid5 = uigridlayout(app.Task_Panel3);
            app.AlturadeinstalaoEditFieldLabel = uilabel(app.Task_Grid5);
            app.AlturadeinstalaoEditFieldLabel.Text = 'Altura de instalação:';
            app.AzimuteEditFieldLabel = uilabel(app.Task_Grid5);
            app.AzimuteEditFieldLabel.Enable = 'off';
            app.AzimuteEditFieldLabel.Text = 'Azimute:';
            app.ElevaoEditFieldLabel = uilabel(app.Task_Grid5);
            app.ElevaoEditFieldLabel.Enable = 'off';
            app.ElevaoEditFieldLabel.Text = 'Elevação:';
            app.Task_AntennaUnit3 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit3.Enable = 'off';
            app.Task_AntennaUnit3.Text = 'graus';
            app.Task_AntennaElevation = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaElevation.Limits = [0 90];
            app.Task_AntennaElevation.RoundFractionalValues = 'on';
            app.Task_AntennaElevation.ValueDisplayFormat = '%.0f';
            app.Task_AntennaElevation.Enable = 'off';
            app.Task_AntennaUnit2 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit2.Enable = 'off';
            app.Task_AntennaUnit2.Text = 'graus';
            app.Task_AntennaAzimuth = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaAzimuth.Limits = [0 359];
            app.Task_AntennaAzimuth.RoundFractionalValues = 'on';
            app.Task_AntennaAzimuth.ValueDisplayFormat = '%.0f';
            app.Task_AntennaAzimuth.Enable = 'off';
            app.Task_AntennaUnit1 = uilabel(app.Task_Grid5);
            app.Task_AntennaUnit1.Text = 'metros';
            app.Task_AntennaHeight = uieditfield(app.Task_Grid5, 'numeric');
            app.Task_AntennaHeight.Limits = [0 127];
            app.Task_AntennaHeight.RoundFractionalValues = 'on';
            app.Task_AntennaHeight.ValueDisplayFormat = '%.0f';
            app.Task_AntennaHeight.Value = 2;
            app.Task_Antenna = uidropdown(app.Task_Grid2);
            app.Task_Antenna.Items = {'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107', 'Rohde & Schwarz ADD207', 'Telescopica', 'Outra'};
            app.Task_Antenna.Value = 'CRFS Low Band (10 MHz - 1.2 GHz)';
            app.AntenaLabel = uilabel(app.Task_Grid2);
            app.AntenaLabel.Text = 'Antena';
            app.Task_Label7 = uilabel(app.Task_Grid2);
            app.Task_Label7.Text = 'Período de observação';
            app.Task_BitsPerPoint = uidropdown(app.Task_Grid2);
            app.Task_BitsPerPoint.Items = {'8', '16', '32'};
            app.Task_BitsPerPoint.Value = '8';
            app.Task_Name = uieditfield(app.Task_Grid2, 'text');
            app.Task_Name.Editable = 'off';
            app.Task_Name.Value = 'FM e TV_UHF';
            app.Task_Label6 = uilabel(app.Task_Grid2);
            app.Task_Label6.Text = 'Aspectos gerais';
            app.Tree2 = uitree(app.Task_Grid2);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.Task_Label1 = uilabel(app.Task_Grid2);
            app.Task_Label1.Text = 'Lista de tarefas';
            app.GridLayout10 = uigridlayout(app.Task_Grid2);
            app.Task_deleteTask = uiimage(app.GridLayout10);
            app.Task_deleteTask.ImageClickedFcn = createCallbackFcn(app, @Task_deleteTaskImageClicked, true);
            app.Task_deleteTask.ImageSource = 'Check2.png';
            app.Task_Panel1 = uipanel(app.Task_Grid2);
            app.Task_Grid3 = uigridlayout(app.Task_Panel1);
            app.Task_startImediately = uicheckbox(app.Task_Grid3);
            app.Task_startImediately.ValueChangedFcn = createCallbackFcn(app, @Task_startImediatelyValueChanged, true);
            app.Task_startImediately.Enable = 'off';
            app.Task_startImediately.Text = 'Imediato';
            app.Task_startImediately.Value = true;
            app.DuraoEditFieldLabel = uilabel(app.Task_Grid3);
            app.DuraoEditFieldLabel.Text = 'Duração:';
            app.Task_DurationUnit = uidropdown(app.Task_Grid3);
            app.Task_DurationUnit.Items = {'seg', 'min', 'horas'};
            app.Task_DurationUnit.Value = 'min';
            app.Task_Duration = uieditfield(app.Task_Grid3, 'numeric');
            app.Task_Duration.Limits = [0 Inf];
            app.Task_Duration.Value = 10;
            app.Task_startTimeMinutes = uispinner(app.Task_Grid3);
            app.Task_startTimeMinutes.Step = 10;
            app.Task_startTimeMinutes.Limits = [0 59];
            app.Task_startTimeMinutes.RoundFractionalValues = 'on';
            app.Task_startTimeMinutes.ValueDisplayFormat = '%.0f';
            app.Task_startTimeMinutes.Enable = 'off';
            app.Task_startTimeHours = uispinner(app.Task_Grid3);
            app.Task_startTimeHours.Limits = [0 23];
            app.Task_startTimeHours.RoundFractionalValues = 'on';
            app.Task_startTimeHours.ValueDisplayFormat = '%.0f';
            app.Task_startTimeHours.Enable = 'off';
            app.IncioDatePickerLabel = uilabel(app.Task_Grid3);
            app.IncioDatePickerLabel.Text = 'Início:';
            app.Task_startTimeDate = uidatepicker(app.Task_Grid3);
            app.Task_startTimeDate.Enable = 'off';
            app.Task_Grid6 = uigridlayout(app.Task_Grid0);
            app.Tree3 = uitree(app.Task_Grid6);
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @Tree3SelectionChanged, true);
            app.Task_deleteBand = uiimage(app.Task_Grid6);
            app.Task_deleteBand.ImageClickedFcn = createCallbackFcn(app, @Task_deleteBandImageClicked, true);
            app.Task_deleteBand.Enable = 'off';
            app.Task_deleteBand.ImageSource = 'Check2.png';
            app.Task_addBand = uiimage(app.Task_Grid6);
            app.Task_addBand.ImageClickedFcn = createCallbackFcn(app, @Task_addBandImageClicked, true);
            app.Task_addBand.Enable = 'off';
            app.Task_addBand.ImageSource = 'Check1.png';
            app.Task_Warning1 = uiimage(app.Task_Grid6);
            app.Task_Warning1.Enable = 'off';
            app.Task_Warning1.ImageSource = 'Alarm1.png';
            app.Task_Label9 = uilabel(app.Task_Grid6);
            app.Task_Label9.Text = 'Faixas de frequência a monitorar';
            app.Task_executeTask = uibutton(app.Task_Grid6, 'push');
            app.Task_executeTask.ButtonPushedFcn = createCallbackFcn(app, @Task_executeTaskButtonPushed, true);
            app.Task_executeTask.Icon = 'Media_PlayWhite.png';
            app.Task_executeTask.Text = 'Executar tarefa';
            app.Task_newBand = uibutton(app.Task_Grid6, 'state');
            app.Task_newBand.ValueChangedFcn = createCallbackFcn(app, @Task_newBandButtonPushed, true);
            app.Task_newBand.Icon = 'Edit2.png';
            app.Task_newBand.Text = '';
            app.Task_editBand = uibutton(app.Task_Grid6, 'state');
            app.Task_editBand.ValueChangedFcn = createCallbackFcn(app, @Task_editBandValueChanged, true);
            app.Task_editBand.Icon = 'Edit1.png';
            app.Task_editBand.Text = '';
            app.Task_Warning2 = uiimage(app.Task_Grid6);
            app.Task_Warning2.Enable = 'off';
            app.Task_Warning2.ImageSource = 'Alarm2.png';
            app.Task_Label9_2 = uilabel(app.Task_Grid6);
            app.Task_Label9_2.Text = 'Parâmetros relacionados ao instrumento selecionado';
            app.Panel_5 = uipanel(app.Task_Grid6);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Task_ThreadID = uieditfield(app.GridLayout6, 'numeric');
            app.Task_ThreadID.Limits = [1 255];
            app.Task_ThreadID.RoundFractionalValues = 'on';
            app.Task_ThreadID.ValueDisplayFormat = '%.0f';
            app.Task_ThreadID.Editable = 'off';
            app.Task_ThreadID.Value = 1;
            app.ThreadIDEditFieldLabel = uilabel(app.GridLayout6);
            app.ThreadIDEditFieldLabel.Text = 'ThreadID';
            app.Task_Description = uieditfield(app.GridLayout6, 'text');
            app.Task_Description.Editable = 'off';
            app.Task_Description.Value = 'PMEC 2021 (108-137 MHz)';
            app.DescrioEditFieldLabel = uilabel(app.GridLayout6);
            app.DescrioEditFieldLabel.Text = 'Descrição:';
            app.Task_F0 = uieditfield(app.GridLayout6, 'numeric');
            app.Task_F0.ValueDisplayFormat = '%.6f';
            app.Task_F0.ValueChangedFcn = createCallbackFcn(app, @Task_SpanValueChanged, true);
            app.Task_F0.Editable = 'off';
            app.Task_F0.Value = 108;
            app.FrequnciainicialEditField_2Label = uilabel(app.GridLayout6);
            app.FrequnciainicialEditField_2Label.Text = 'Frequência inicial:';
            app.Task_F1 = uieditfield(app.GridLayout6, 'numeric');
            app.Task_F1.ValueDisplayFormat = '%.6f';
            app.Task_F1.ValueChangedFcn = createCallbackFcn(app, @Task_SpanValueChanged, true);
            app.Task_F1.Editable = 'off';
            app.Task_F1.Value = 108;
            app.FrequnciafinalEditField_3Label = uilabel(app.GridLayout6);
            app.FrequnciafinalEditField_3Label.Text = 'Frequência final:';
            app.Task_StepWidth = uieditfield(app.GridLayout6, 'numeric');
            app.Task_StepWidth.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth.Editable = 'off';
            app.PassodavarreduraEditField_2Label = uilabel(app.GridLayout6);
            app.PassodavarreduraEditField_2Label.Text = 'Passo da varredura:';
            app.MHzLabel = uilabel(app.GridLayout6);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.GridLayout6);
            app.MHzLabel_2.Text = 'MHz';
            app.MododeRFLabel = uilabel(app.GridLayout6);
            app.MododeRFLabel.Text = 'Modo de RF:';
            app.Task_RFMode = uidropdown(app.GridLayout6);
            app.Task_RFMode.Items = {'Alta sensibilidade', 'Normal', 'Baixa distorção'};
            app.Task_RFMode.Value = 'Alta sensibilidade';
            app.MododotraoDropDownLabel = uilabel(app.GridLayout6);
            app.MododotraoDropDownLabel.Text = 'Modo do traço:';
            app.Task_TraceMode = uidropdown(app.GridLayout6);
            app.Task_TraceMode.Items = {'Average'};
            app.Task_TraceMode.Value = 'Average';
            app.UnidadeDropDownLabel = uilabel(app.GridLayout6);
            app.UnidadeDropDownLabel.Text = 'Unidade:';
            app.Task_LevelUnit = uidropdown(app.GridLayout6);
            app.Task_LevelUnit.Items = {'dBm', 'dBuV', 'dBuV/m'};
            app.Task_LevelUnit.Value = 'dBm';
            app.Task_IntegrationFactor = uieditfield(app.GridLayout6, 'numeric');
            app.Task_IntegrationFactor.Limits = [1 Inf];
            app.Task_IntegrationFactor.RoundFractionalValues = 'on';
            app.Task_IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.Task_IntegrationFactor.Editable = 'off';
            app.Task_IntegrationFactor.Value = 1;
            app.FatordeintegraoLabel = uilabel(app.GridLayout6);
            app.FatordeintegraoLabel.Text = 'Fator de integração:';
            app.Task_Resolution = uieditfield(app.GridLayout6, 'numeric');
            app.Task_Resolution.ValueDisplayFormat = '%.3f';
            app.Task_Resolution.Editable = 'off';
            app.ResoluoLabel_3 = uilabel(app.GridLayout6);
            app.ResoluoLabel_3.Text = 'Resolução:';
            app.kHzLabel_3 = uilabel(app.GridLayout6);
            app.kHzLabel_3.Text = 'kHz';
            app.kHzLabel_2 = uilabel(app.GridLayout6);
            app.kHzLabel_2.Text = 'kHz';
            app.Task_Detector = uidropdown(app.GridLayout6);
            app.Task_Detector.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.Task_Detector.Value = 'Negative Peak';
            app.DetectorDropDownLabel = uilabel(app.GridLayout6);
            app.DetectorDropDownLabel.Text = 'Detector:';
            app.Task_RevisitTime = uieditfield(app.GridLayout6, 'numeric');
            app.Task_RevisitTime.Limits = [0 Inf];
            app.Task_RevisitTime.ValueDisplayFormat = '%.3f';
            app.Task_RevisitTime.Editable = 'off';
            app.TempoderevisitaEditFieldLabel = uilabel(app.GridLayout6);
            app.TempoderevisitaEditFieldLabel.Text = 'Tempo de revisita:';
            app.Task_BandUnit5 = uilabel(app.GridLayout6);
            app.Task_BandUnit5.Text = 'seg';
            app.Task_RefreshButton = uibutton(app.Task_Grid6, 'push');
            app.Task_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Task_RefreshButtonPushed, true);
            app.Task_RefreshButton.Icon = 'Refresh.jpg';
            app.Task_RefreshButton.Text = '';
            app.Panel_4 = uipanel(app.Task_Grid6);
            app.Task_Grid7 = uigridlayout(app.Panel_4);
            app.GridLayout5 = uigridlayout(app.Task_Grid7);
            app.PontosportraoLabel = uilabel(app.GridLayout5);
            app.PontosportraoLabel.Text = 'Pontos por traço:';
            app.Task_DataPoints_FSL = uispinner(app.GridLayout5);
            app.Task_DataPoints_FSL.Step = 100;
            app.Task_DataPoints_FSL.Limits = [101 32001];
            app.Task_DataPoints_FSL.RoundFractionalValues = 'on';
            app.Task_DataPoints_FSL.ValueDisplayFormat = '%.0f';
            app.Task_DataPoints_FSL.ValueChangedFcn = createCallbackFcn(app, @Task_DataPoints_FSLValueChanged, true);
            app.Task_DataPoints_FSL.Value = 101;
            app.Task_Selectivity = uidropdown(app.GridLayout5);
            app.Task_Selectivity.Items = {'Normal', 'Narrow', 'Sharp'};
            app.Task_Selectivity.ValueChangedFcn = createCallbackFcn(app, @Task_SelectivityValueChanged, true);
            app.Task_Selectivity.Value = 'Normal';
            app.SeletividadeLabel = uilabel(app.GridLayout5);
            app.SeletividadeLabel.Text = 'Seletividade:';
            app.Task_Preamp = uidropdown(app.GridLayout5);
            app.Task_Preamp.Items = {'On', 'Off'};
            app.Task_Preamp.ValueChangedFcn = createCallbackFcn(app, @Task_PreampValueChanged, true);
            app.Task_Preamp.Value = 'Off';
            app.PramplificadorDropDownLabel = uilabel(app.GridLayout5);
            app.PramplificadorDropDownLabel.Text = 'Pré-amplificador:';
            app.MododoatenuadorDropDownLabel = uilabel(app.GridLayout5);
            app.MododoatenuadorDropDownLabel.Text = 'Modo do atenuador:';
            app.Task_attMode = uidropdown(app.GridLayout5);
            app.Task_attMode.Items = {'Manual', 'Automático'};
            app.Task_attMode.ValueChangedFcn = createCallbackFcn(app, @Task_attModeValueChanged, true);
            app.Task_attMode.Value = 'Automático';
            app.AtenuadorDropDownLabel = uilabel(app.GridLayout5);
            app.AtenuadorDropDownLabel.Text = 'Atenuador:';
            app.Task_attFactor = uidropdown(app.GridLayout5);
            app.Task_attFactor.Items = {'0 dB', '5 dB', '10 dB', '15 dB', '20 dB', '25 dB', '30 dB'};
            app.Task_attFactor.ValueChangedFcn = createCallbackFcn(app, @Task_attFactorValueChanged, true);
            app.Task_attFactor.Value = '0 dB';
            app.ResoluoLabel_2 = uilabel(app.GridLayout5);
            app.ResoluoLabel_2.Text = 'Resolução:';
            app.Task_Resolution_FSL = uidropdown(app.GridLayout5);
            app.Task_Resolution_FSL.Items = {'10 kHz', '30 kHz', '100 kHz', '300 kHz', '1000 kHz'};
            app.Task_Resolution_FSL.ValueChangedFcn = createCallbackFcn(app, @Task_Resolution_FSLValueChanged, true);
            app.Task_Resolution_FSL.Value = '10 kHz';
            app.Task_StepWidth_EB500 = uidropdown(app.GridLayout5);
            app.Task_StepWidth_EB500.Items = {'10 kHz', '30 kHz', '100 kHz', '300 kHz', '1000 kHz'};
            app.Task_StepWidth_EB500.ValueChangedFcn = createCallbackFcn(app, @Task_StepWidth_EB500ValueChanged, true);
            app.Task_StepWidth_EB500.Value = '10 kHz';
            app.Task_DataPoints_EB500 = uieditfield(app.GridLayout5, 'numeric');
            app.Task_DataPoints_EB500.ValueDisplayFormat = '%.0f';
            app.Task_DataPoints_EB500.Editable = 'off';
            app.Task_StepWidth_FSL = uieditfield(app.GridLayout5, 'numeric');
            app.Task_StepWidth_FSL.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth_FSL.ValueChangedFcn = createCallbackFcn(app, @Task_StepWidthValueChanged, true);
            app.PassodavarreduraLabel = uilabel(app.GridLayout5);
            app.PassodavarreduraLabel.Text = 'Passo da varredura:';
            app.StepWidthUnit_FSL = uilabel(app.GridLayout5);
            app.StepWidthUnit_FSL.Text = 'kHz';
            app.Tab4_RealTime = uitab(app.TabGroup);
            app.GridLayout7 = uigridlayout(app.Tab4_RealTime);
            app.GridLayout6_2 = uigridlayout(app.GridLayout7);
            app.udpLogger_Label3_2 = uilabel(app.GridLayout6_2);
            app.udpLogger_Label3_2.Text = 'Informações complementares relacionadas ao fluxo selecionado';
            app.Panel_7 = uipanel(app.GridLayout6_2);
            app.GridLayout6_3 = uigridlayout(app.Panel_7);
            app.udpLogger_Description = uieditfield(app.GridLayout6_3, 'text');
            app.udpLogger_Description.Editable = 'off';
            app.DescrioEditFieldLabel_2 = uilabel(app.GridLayout6_3);
            app.DescrioEditFieldLabel_2.Text = 'Descrição:';
            app.udpLogger_StepWidth = uieditfield(app.GridLayout6_3, 'numeric');
            app.udpLogger_StepWidth.ValueDisplayFormat = '%.3f';
            app.udpLogger_StepWidth.Editable = 'off';
            app.udpLogger_StepWidth.Value = 12000;
            app.PassodavarreduraEditField_2Label_2 = uilabel(app.GridLayout6_3);
            app.PassodavarreduraEditField_2Label_2.Text = 'Passo da varredura:';
            app.udpLogger_Resolution = uieditfield(app.GridLayout6_3, 'numeric');
            app.udpLogger_Resolution.ValueDisplayFormat = '%.3f';
            app.udpLogger_Resolution.Editable = 'off';
            app.udpLogger_Resolution.Value = 30;
            app.ResoluoLabel_4 = uilabel(app.GridLayout6_3);
            app.ResoluoLabel_4.Text = 'Resolução:';
            app.kHzLabel_4 = uilabel(app.GridLayout6_3);
            app.kHzLabel_4.Text = 'kHz';
            app.kHzLabel_5 = uilabel(app.GridLayout6_3);
            app.kHzLabel_5.Text = 'kHz';
            app.udpLogger_Operation = uieditfield(app.GridLayout6_3, 'text');
            app.udpLogger_Operation.Editable = 'off';
            app.OperaoLabel = uilabel(app.GridLayout6_3);
            app.OperaoLabel.Text = 'Operação:';
            app.udpLogger_LevelUnit = uieditfield(app.GridLayout6_3, 'text');
            app.udpLogger_LevelUnit.Editable = 'off';
            app.UnidadeLabel = uilabel(app.GridLayout6_3);
            app.UnidadeLabel.Text = 'Unidade:';
            app.PontosportraoLabel_2 = uilabel(app.GridLayout6_3);
            app.PontosportraoLabel_2.Text = 'Pontos por traço:';
            app.udpLogger_DataPoints = uieditfield(app.GridLayout6_3, 'numeric');
            app.udpLogger_DataPoints.Limits = [1 Inf];
            app.udpLogger_DataPoints.ValueDisplayFormat = '%.0f';
            app.udpLogger_DataPoints.Editable = 'off';
            app.udpLogger_DataPoints.Value = 501;
            app.udpLogger_Button_2 = uibutton(app.GridLayout6_2, 'state');
            app.udpLogger_Button_2.ValueChangedFcn = createCallbackFcn(app, @udpLogger_Button_2ValueChanged, true);
            app.udpLogger_Button_2.Enable = 'off';
            app.udpLogger_Button_2.Icon = 'REC2.png';
            app.udpLogger_Button_2.Text = 'Iniciar gravação';
            app.GridLayout13 = uigridlayout(app.GridLayout6_2);
            app.DatagramaslidosLabel = uilabel(app.GridLayout13);
            app.DatagramaslidosLabel.Text = {'Datagramas'; 'lidos:'};
            app.udpLogger_Datagram1 = uieditfield(app.GridLayout13, 'numeric');
            app.udpLogger_Datagram1.Limits = [0 Inf];
            app.udpLogger_Datagram1.ValueDisplayFormat = '%.0f';
            app.udpLogger_Datagram1.Editable = 'off';
            app.udpLogger_Datagram2 = uieditfield(app.GridLayout13, 'numeric');
            app.udpLogger_Datagram2.Limits = [0 Inf];
            app.udpLogger_Datagram2.ValueDisplayFormat = '%.0f';
            app.udpLogger_Datagram2.Editable = 'off';
            app.DatagramasdisponveisparaleituraEditFieldLabel = uilabel(app.GridLayout13);
            app.DatagramasdisponveisparaleituraEditFieldLabel.Text = {'Datagramas'; 'disponíveis para leitura:'};
            app.udpLogger_Label3 = uilabel(app.GridLayout6_2);
            app.udpLogger_Label3.Text = 'Fluxos de dados';
            app.Tree4 = uitree(app.GridLayout6_2);
            app.Tree4.SelectionChangedFcn = createCallbackFcn(app, @Tree4SelectionChanged, true);
            app.udpLogger_CheckBox = uicheckbox(app.GridLayout6_2);
            app.udpLogger_CheckBox.Text = 'Visualizar o plot apenas do(s) fluxo(s) selecionado(s).';
            app.Connection_Grid1_2 = uigridlayout(app.GridLayout7);
            app.udpLogger_Button = uibutton(app.Connection_Grid1_2, 'state');
            app.udpLogger_Button.ValueChangedFcn = createCallbackFcn(app, @udpLogger_ButtonValueChanged, true);
            app.udpLogger_Button.Icon = 'Media_PlayWhite.png';
            app.udpLogger_Button.Text = 'Iniciar leitura';
            app.SensorLabel = uilabel(app.Connection_Grid1_2);
            app.SensorLabel.Text = 'Sensor';
            app.udpLogger_Node = uidropdown(app.Connection_Grid1_2);
            app.udpLogger_Node.Items = {'RFeye002092', 'RFeye002186', 'RFeye002263', 'RFeye002292', 'Outro'};
            app.udpLogger_Node.ValueChangedFcn = createCallbackFcn(app, @udpLogger_NodeValueChanged, true);
            app.udpLogger_Node.Value = 'RFeye002292';
            app.udpLogger_Panel1 = uipanel(app.Connection_Grid1_2);
            app.GridLayout8 = uigridlayout(app.udpLogger_Panel1);
            app.PortaLabel = uilabel(app.GridLayout8);
            app.PortaLabel.Text = 'Porta:';
            app.udpLogger_Port = uieditfield(app.GridLayout8, 'numeric');
            app.udpLogger_Port.Limits = [0 65536];
            app.udpLogger_Port.RoundFractionalValues = 'on';
            app.udpLogger_Port.ValueDisplayFormat = '%.0f';
            app.udpLogger_Port.ValueChangedFcn = createCallbackFcn(app, @udpLogger_IP1ValueChanged, true);
            app.udpLogger_Port.Editable = 'off';
            app.udpLogger_Port.Value = 7891;
            app.DuraodaconexoLabel = uilabel(app.GridLayout8);
            app.DuraodaconexoLabel.Text = 'Duração da conexão:';
            app.udpLogger_Time = uieditfield(app.GridLayout8, 'numeric');
            app.udpLogger_Time.Limits = [10 Inf];
            app.udpLogger_Time.RoundFractionalValues = 'on';
            app.udpLogger_Time.ValueDisplayFormat = '%.0f';
            app.udpLogger_Time.ValueChangedFcn = createCallbackFcn(app, @udpLogger_IP1ValueChanged, true);
            app.udpLogger_Time.Editable = 'off';
            app.udpLogger_Time.Value = 10;
            app.udpLogger_Unit1 = uilabel(app.GridLayout8);
            app.udpLogger_Unit1.Text = 'seg';
            app.PausaentrealeituradedatagramasLabel = uilabel(app.GridLayout8);
            app.PausaentrealeituradedatagramasLabel.Text = 'Pausa entre a leitura de datagramas:';
            app.udpLogger_Pause = uispinner(app.GridLayout8);
            app.udpLogger_Pause.Step = 0.05;
            app.udpLogger_Pause.Limits = [0 10];
            app.udpLogger_Pause.ValueDisplayFormat = '%.3f';
            app.udpLogger_Unit2 = uilabel(app.GridLayout8);
            app.udpLogger_Unit2.Text = 'seg';
            app.EndereoIPLabel = uilabel(app.GridLayout8);
            app.EndereoIPLabel.Text = 'Endereço IP:';
            app.udpLogger_IP = uieditfield(app.GridLayout8, 'text');
            app.udpLogger_IP.ValueChangedFcn = createCallbackFcn(app, @udpLogger_IP1ValueChanged, true);
            app.udpLogger_IP.Editable = 'off';
            app.udpLogger_IP.Value = '187.44.203.199';
            app.udpLogger_Label4_2 = uilabel(app.Connection_Grid1_2);
            app.udpLogger_Label4_2.Text = 'Informações gerais';
            app.Panel_6 = uipanel(app.Connection_Grid1_2);
            app.GridLayout12 = uigridlayout(app.Panel_6);
            app.udpLogger_UnitInfo = uieditfield(app.GridLayout12, 'text');
            app.udpLogger_UnitInfo.Editable = 'off';
            app.udpLogger_UnitInfo.Enable = 'off';
            app.UnitInfoLabel = uilabel(app.GridLayout12);
            app.UnitInfoLabel.Enable = 'off';
            app.UnitInfoLabel.Text = 'UnitInfo:';
            app.udpLogger_Method = uieditfield(app.GridLayout12, 'text');
            app.udpLogger_Method.Editable = 'off';
            app.udpLogger_Method.Enable = 'off';
            app.MethodLabel = uilabel(app.GridLayout12);
            app.MethodLabel.Enable = 'off';
            app.MethodLabel.Text = 'Method:';
            app.HostnameLabel = uilabel(app.GridLayout12);
            app.HostnameLabel.Enable = 'off';
            app.HostnameLabel.Text = 'Hostname:';
            app.udpLogger_Hostname = uieditfield(app.GridLayout12, 'text');
            app.udpLogger_Hostname.Editable = 'off';
            app.udpLogger_Hostname.Enable = 'off';
            app.Button = uibutton(app.Connection_Grid1_2, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'Edit2.png';
            app.Button.Text = '';
            app.udpLogger_Label4 = uilabel(app.Connection_Grid1_2);
            app.udpLogger_Label4.Text = 'GPS';
            app.udpLogger_Panel3 = uipanel(app.Connection_Grid1_2);
            app.GridLayout11 = uigridlayout(app.udpLogger_Panel3);
            app.udpLogger_gpsLatitude = uieditfield(app.GridLayout11, 'numeric');
            app.udpLogger_gpsLatitude.ValueDisplayFormat = '%.6f';
            app.udpLogger_gpsLatitude.Editable = 'off';
            app.udpLogger_gpsLatitude.Enable = 'off';
            app.udpLogger_gpsLatitude.Value = -1;
            app.LatitudeEditFieldLabel_2 = uilabel(app.GridLayout11);
            app.LatitudeEditFieldLabel_2.Enable = 'off';
            app.LatitudeEditFieldLabel_2.Text = 'Latitude:';
            app.LongitudeLabel = uilabel(app.GridLayout11);
            app.LongitudeLabel.Enable = 'off';
            app.LongitudeLabel.Text = 'Longitude:';
            app.udpLogger_gpsLongitude = uieditfield(app.GridLayout11, 'numeric');
            app.udpLogger_gpsLongitude.ValueDisplayFormat = '%.6f';
            app.udpLogger_gpsLongitude.Editable = 'off';
            app.udpLogger_gpsLongitude.Enable = 'off';
            app.udpLogger_gpsLongitude.Value = -1;
            app.udpLogger_gpsCount = uieditfield(app.GridLayout11, 'numeric');
            app.udpLogger_gpsCount.Limits = [0 Inf];
            app.udpLogger_gpsCount.ValueDisplayFormat = '%.0f';
            app.udpLogger_gpsCount.Editable = 'off';
            app.BlocosLabel = uilabel(app.GridLayout11);
            app.BlocosLabel.Text = 'Blocos:';
            app.InformaestextuaisTextAreaLabel = uilabel(app.Connection_Grid1_2);
            app.InformaestextuaisTextAreaLabel.Text = 'Informações textuais';
            app.udpLogger_TextInfo = uitextarea(app.Connection_Grid1_2);
            app.udpLogger_TextInfo.Editable = 'off';
            app.Panel_3 = uipanel(app.Grid1);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.ToolBar_Grid = uigridlayout(app.GridLayout4);
            app.ToolBar_Button3 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button3.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button3Pushed, true);
            app.ToolBar_Button3.Icon = 'Toolbar_Help.png';
            app.ToolBar_Button3.Text = '';
            app.ToolBar_Button2 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button2.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button2Pushed, true);
            app.ToolBar_Button2.Icon = 'Toolbar_Info.png';
            app.ToolBar_Button2.Text = '';
            app.ToolBar_Button1 = uibutton(app.ToolBar_Grid, 'push');
            app.ToolBar_Button1.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button1Pushed, true);
            app.ToolBar_Button1.Icon = 'Toolbar_Global.png';
            app.ToolBar_Button1.Text = '';
            app.menu_Panel = uipanel(app.Grid1);
            app.GridLayout3 = uigridlayout(app.menu_Panel);
            app.menu_Grid0 = uigridlayout(app.GridLayout3);
            app.menu_Grid3 = uigridlayout(app.menu_Grid0);
            app.menu_searchPath = uiimage(app.menu_Grid3);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @menu_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = 'Zoom.png';
            app.menu_userPath = uidropdown(app.menu_Grid3);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.Value = 'Path';
            app.menu_Grid1 = uigridlayout(app.menu_Grid0);
            app.menu_Separator1 = uiimage(app.menu_Grid1);
            app.menu_Separator1.ImageSource = 'Menu_Line.png';
            app.menu_Grid2 = uigridlayout(app.menu_Grid1);
            app.menu_Button7 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button7.ValueChangedFcn = createCallbackFcn(app, @menu_Button7ValueChanged, true);
            app.menu_Button7.Enable = 'off';
            app.menu_Button7.Icon = 'Menu_NewTask.png';
            app.menu_Button7.Text = '';
            app.menu_Button6 = uibutton(app.menu_Grid2, 'push');
            app.menu_Button6.ButtonPushedFcn = createCallbackFcn(app, @menu_Button6Pushed, true);
            app.menu_Button6.Icon = 'Menu_OpenTask.png';
            app.menu_Button6.Enable = 'off';
            app.menu_Button6.Text = '';
            app.menu_Button5 = uibutton(app.menu_Grid2, 'push');
            app.menu_Button5.ButtonPushedFcn = createCallbackFcn(app, @menu_Button5Pushed, true);
            app.menu_Button5.Icon = 'Menu_SaveTask.png';
            app.menu_Button5.Enable = 'off';
            app.menu_Button5.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @menu_Button4ValueChanged, true);
            app.menu_Button4.Tooltip = {'RFeye'};
            app.menu_Button4.Icon = 'Instr_CRFS2.png';
            app.menu_Button4.Text = '';
            app.menu_Button3 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @menu_Button3ValueChanged, true);
            app.menu_Button3.Tooltip = {'Tarefas'};
            app.menu_Button3.Icon = 'Menu_Task.png';
            app.menu_Button3.Text = '';
            app.menu_Button2 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @menu_Button2ValueChanged, true);
            app.menu_Button2.Tooltip = {'Conectividade'};
            app.menu_Button2.Icon = 'Menu_Connect.png';
            app.menu_Button2.Text = '';
            app.menu_Button1 = uibutton(app.menu_Grid1, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @menu_Button1ValueChanged, true);
            app.menu_Button1.Icon = 'Menu_Home.png';
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1_2 = uiimage(app.menu_Grid1);
            app.menu_Separator1_2.ImageSource = 'Menu_Line.png';
            app.menu_Separator1_3 = uiimage(app.menu_Grid1);
            app.menu_Separator1_3.ImageSource = 'Menu_Line.png';
            app.ContextMenu = uicontextmenu(app.MainApp);
            app.AdicionarlistadegravaodoarquivoMenu = uimenu(app.ContextMenu);
            app.AdicionarlistadegravaodoarquivoMenu.MenuSelectedFcn = createCallbackFcn(app, @AdicionarlistadegravaodoarquivoMenuSelected, true);
            app.AdicionarlistadegravaodoarquivoMenu.Text = 'Adicionar à lista de gravação do arquivo.';
            app.RetirardalistaMenu = uimenu(app.ContextMenu);
            app.RetirardalistaMenu.MenuSelectedFcn = createCallbackFcn(app, @RetirardalistaMenuSelected, true);
            app.RetirardalistaMenu.Text = 'Retirar da lista.';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end