classdef appColeta_winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Grid1                   matlab.ui.container.GridLayout
        Button                  matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        Grid2                   matlab.ui.container.GridLayout
        Password                matlab.ui.control.HTML
        Login                   matlab.ui.control.EditField
        Port                    matlab.ui.control.NumericEditField
        PortaEditField_2Label   matlab.ui.control.Label
        IP                      matlab.ui.control.EditField
        IPEditFieldLabel        matlab.ui.control.Label
        UsurioEditFieldLabel    matlab.ui.control.Label
        Protocol                matlab.ui.control.DropDown
        ProtocoloDropDownLabel  matlab.ui.control.Label
        PasswordLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, NodeIP, NodePass)
            
            app.CallingApp = mainapp;
            app.Password.HTMLSource = 'Misc_PasswordBox1.html';
            
            x0 = fix(Position(1)+Position(3)/2-app.UIFigure.Position(3)/2);
            x1 = fix(Position(2)+Position(4)/2-app.UIFigure.Position(4)/2);
            app.UIFigure.Position(1:2) = [x0, x1];

            app.IP.Value      = NodeIP;
            app.Password.Data = NodePass;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if ~isempty(app.Login.Value) && ~isempty(app.Password.Data)
                if ~isempty(app.CallingApp.ftpObj)
                    close(app.CallingApp.ftpObj)
                    app.CallingApp.ftpObj = [];
                    
                    drawnow nocallbacks
                end

                try
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando se conectar ao sensor...');

                    host = sprintf('%s:%.0f', app.IP.Value,app.Port.Value);
                    switch app.Protocol.Value
                        case 'File Transfer Protocol (FTP)'
                            app.CallingApp.ftpObj =  ftp(host, app.Login.Value, app.Password.Data);
                            app.CallingApp.udpLogger_ftpInfo('sucess', sprintf('%s.%s', 'ftp', host))

                        case 'Secure File Transfer Protocol (SFTP)'
                            app.CallingApp.ftpObj = sftp(host, app.Login.Value, 'Password', app.Password.Data);
                            app.CallingApp.udpLogger_ftpInfo('sucess', sprintf('%s.%s', 'sftp', host))
                    end
                                        
                    delete(app)
                catch ME
                    app.CallingApp.udpLogger_ftpInfo('error', '')
                    uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
                end
                    
            else
                uialert(app.UIFigure, 'Favor inserir as credenciais.', 'appColeta', 'Icon', 'error')
            end
            
        end

        % Value changed function: Protocol
        function ProtocolValueChanged(app, event)

            switch app.Protocol.Value
                case 'File Transfer Protocol (FTP)'
                    app.Port.Value = 21;

                case 'Secure File Transfer Protocol (SFTP)'
                    app.Port.Value = 2828;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.PasswordLabel = uilabel(app.Grid2);
            app.PasswordLabel.Text = 'Senha:';
            app.ProtocoloDropDownLabel = uilabel(app.Grid2);
            app.ProtocoloDropDownLabel.Text = 'Protocolo:';
            app.Protocol = uidropdown(app.Grid2);
            app.Protocol.Items = {'File Transfer Protocol (FTP)', 'Secure File Transfer Protocol (SFTP)'};
            app.Protocol.ValueChangedFcn = createCallbackFcn(app, @ProtocolValueChanged, true);
            app.Protocol.Value = 'File Transfer Protocol (FTP)';
            app.UsurioEditFieldLabel = uilabel(app.Grid2);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.IPEditFieldLabel = uilabel(app.Grid2);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IP = uieditfield(app.Grid2, 'text');
            app.IP.Editable = 'off';
            app.PortaEditField_2Label = uilabel(app.Grid2);
            app.PortaEditField_2Label.Text = 'Porta:';
            app.Port = uieditfield(app.Grid2, 'numeric');
            app.Port.Limits = [0 65535];
            app.Port.RoundFractionalValues = 'on';
            app.Port.ValueDisplayFormat = '%.0f';
            app.Port.Value = 21;
            app.Login = uieditfield(app.Grid2, 'text');
            app.Login.Value = 'root';
            app.Password = uihtml(app.Grid2);
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Media_PlayWhite.png';
            app.Button.Text = 'Confirmar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta_winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end