classdef appColeta_winEditNode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Grid1                    matlab.ui.container.GridLayout
        Button_2                 matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        Button                   matlab.ui.control.Button
        Panel1                   matlab.ui.container.Panel
        Grid2                    matlab.ui.container.GridLayout
        Node                     matlab.ui.control.DropDown
        ReceptorLabel            matlab.ui.control.Label
        Panel2                   matlab.ui.container.Panel
        Grid3                    matlab.ui.container.GridLayout
        IP2                      matlab.ui.control.EditField
        IPPblicoIntranetLabel_2  matlab.ui.control.Label
        IP1                      matlab.ui.control.EditField
        IPPblicoIntranetLabel    matlab.ui.control.Label
        Sublabel1                matlab.ui.control.Label
        Panel3                   matlab.ui.container.Panel
        Grid4                    matlab.ui.container.GridLayout
        webPass                  matlab.ui.control.HTML
        webPassLabel             matlab.ui.control.Label
        webLogin                 matlab.ui.control.EditField
        UsurioEditFieldLabel_2   matlab.ui.control.Label
        Sublabel2                matlab.ui.control.Label
        Sublabel3                matlab.ui.control.Label
        Panel4                   matlab.ui.container.Panel
        Grid5                    matlab.ui.container.GridLayout
        rootPass                 matlab.ui.control.HTML
        rootPassLabel            matlab.ui.control.Label
        ftpPort                  matlab.ui.control.Spinner
        PortaFTPSpinnerLabel     matlab.ui.control.Label
        sftpPort                 matlab.ui.control.Spinner
        PortaSFTPSpinnerLabel    matlab.ui.control.Label
        rootLogin                matlab.ui.control.EditField
        UsurioEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        Status
        
    end
    
    methods (Access = private)

        function result = Fcn_IP(app)

            IP1Status = Fcn_IPValidation(app, app.IP1.Value);
            IP2Status = Fcn_IPValidation(app, app.IP2.Value);

            msg = '';
            if (~IP1Status && ~IP2Status) || (IP1Status + IP2Status == -2)
                msg = 'Endereços IP inválidos.';
            elseif ~IP1Status
                msg = 'Endereço IP do tipo "Público / Intranet" inválido.';
            elseif ~IP2Status
                msg = 'Endereço IP do tipo "Open VPN" inválido.';
            end

            if ~isempty(msg)
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', 'error')
            end

            if ~IP1Status || ~IP2Status || (IP1Status + IP2Status == -2)
                result = 0;
            else
                result = 1;
            end

        end
        
        function result = Fcn_IPValidation(app, IP)

            if isempty(IP)
                result = -1;

            else
                IP = strsplit(IP, '.');
                IP = cellfun(@(x) str2double(x), IP);
        
                if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                    result = 0;
                else
                    result = 1;
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, Node)
            
            app.CallingApp = mainapp;
            app.webPass.HTMLSource  = 'Misc_PasswordBox2.html';
            app.rootPass.HTMLSource = 'Misc_PasswordBox2.html';
            
            x0 = fix(Position(1)+Position(3)/2-app.UIFigure.Position(3)/2);
            x1 = fix(Position(2)+Position(4)/2-app.UIFigure.Position(4)/2);
            app.UIFigure.Position(1:2) = [x0, x1];           

            ind = find(strcmp(app.CallingApp.RFeyeList.ID, Node), 1);
            app.Node.Items = app.CallingApp.RFeyeList.ID;
            app.Node.Value = app.Node.Items{ind};
            NodeValueChanged(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            result = Fcn_IP(app);
            if result
                d = uiprogressdlg(app.UIFigure, "Message", "Em andamento...", "Indeterminate", "on");
    
                app.Status = Misc_RFeyeDatabase(app.CallingApp.RootFolder,              ...
                                                app.IP1.Value, app.IP2.Value,           ...
                                                app.webLogin.Value, app.webPass.Data,   ...
                                                app.rootLogin.Value, app.rootPass.Data, ...
                                                app.ftpPort.Value, app.sftpPort.Value);
    
                if app.Status.ConnectFlag
                    app.Lamp.Color = [0,1,0];
                    app.Button_2.Enable = 1;

                    if isempty(app.Status.ID)
                        msg  = 'Não identificado o sensor sob análise.';
                        icon = 'warning';
                    else
                        if strcmp(app.Status.ID, app.Node.Value)
                            msg  = sprintf('Confirmado se tratar do sensor %s.', app.Status.ID); 
                            icon = 'success';
                        else
                            msg  = sprintf('Confirmado se tratar do sensor %s, diferente do selecionado (%s).', app.Status.ID, app.Node.Value);
                            icon = 'error'; 
                            app.Button_2.Enable = 0;
                        end
                    end
                    msg = sprintf('%s\n\n%s', msg, jsonencode(rmfield(app.Status, {'IP1', 'IP2', 'webPass', 'rootPass', 'ftpPort', 'sftpPort', 'ID', 'ConnectFlag', 'LogDate'})));
                else
                    msg = 'Sensor não respondeu às tentativas de conexão.';
                    icon = 'error'; 
                    
                    app.Lamp.Color = [1,0,0];
                    app.Button_2.Enable = 0;
                end
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', icon)

            end
            
        end

        % Value changed function: Node
        function NodeValueChanged(app, event)
            
            app.Status = [];
            app.Button_2.Enable = 0;

            ind = find(strcmp(app.CallingApp.RFeyeList.ID, app.Node.Value), 1);

            app.IP1.Value = app.CallingApp.RFeyeList.IP1{ind};
            app.IP2.Value = app.CallingApp.RFeyeList.IP2{ind};

            if ~isempty(app.CallingApp.RFeyeList.webPass{ind})
                app.webPass.Data = app.CallingApp.RFeyeList.webPass{ind};
            end

            app.webLogin.Value  = 'admin';
            if ~isempty(app.CallingApp.RFeyeList.webPass{ind})
                app.rootPass.Data = app.CallingApp.RFeyeList.rootPass{ind};
            end

            app.rootLogin.Value = 'root';
            if ~isnan(app.CallingApp.RFeyeList.ftpPort(ind))
                app.ftpPort.Value = app.CallingApp.RFeyeList.ftpPort(ind);
            else
                app.ftpPort.Value = 21;
            end

            if ~isnan(app.CallingApp.RFeyeList.sftpPort(ind))
                app.sftpPort.Value = app.CallingApp.RFeyeList.sftpPort(ind);
            else
                app.sftpPort.Value = 2828;
            end

            if app.CallingApp.RFeyeList.ConnectFlag(ind)
                app.Lamp.Color = [0,1,0];
            else
                app.Lamp.Color = [1,0,0];
            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            ind = find(strcmp(app.CallingApp.RFeyeList.ID, app.Node.Value), 1);

            try
                app.CallingApp.RFeyeList(ind, 2:21) = {app.Status.IP1, ...
                                                       app.Status.IP2, ...
                                                       app.Status.webPass, ...
                                                       app.Status.rootPass, ...
                                                       app.Status.ftpPort, ...
                                                       app.Status.sftpPort, ...
                                                       app.Status.sftpHostname, ...
                                                       app.Status.LoggerNodeInfo, ...
                                                       app.Status.Latitude, ...
                                                       app.Status.Longitude, ...
                                                       app.Status.City, ...
                                                       app.Status.State, ...
                                                       app.Status.webNodeStatus, ...
                                                       app.Status.webCoordinatesStatus, ...
                                                       app.Status.ftpStatus, ...
                                                       app.Status.sftpStatus, ...
                                                       app.Status.ftpStatus_OpenVPN, ...
                                                       app.Status.sftpStatus_OpenVPN, ...
                                                       app.Status.LogDate, ...
                                                       app.Status.ConnectFlag};
    
                app.CallingApp.udpLogger_NodeCreation
                closeFcn(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Panel1 = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel1);
            app.Panel4 = uipanel(app.Grid2);
            app.Grid5 = uigridlayout(app.Panel4);
            app.UsurioEditFieldLabel = uilabel(app.Grid5);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.rootLogin = uieditfield(app.Grid5, 'text');
            app.rootLogin.Value = 'root';
            app.PortaSFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaSFTPSpinnerLabel.Text = 'Porta SFTP:';
            app.sftpPort = uispinner(app.Grid5);
            app.sftpPort.Limits = [0 65535];
            app.sftpPort.RoundFractionalValues = 'on';
            app.sftpPort.ValueDisplayFormat = '%.0f';
            app.sftpPort.Value = 2828;
            app.PortaFTPSpinnerLabel = uilabel(app.Grid5);
            app.PortaFTPSpinnerLabel.Text = 'Porta FTP:';
            app.ftpPort = uispinner(app.Grid5);
            app.ftpPort.Limits = [0 65535];
            app.ftpPort.RoundFractionalValues = 'on';
            app.ftpPort.ValueDisplayFormat = '%.0f';
            app.ftpPort.Value = 21;
            app.rootPassLabel = uilabel(app.Grid5);
            app.rootPassLabel.Text = 'Senha:';
            app.rootPass = uihtml(app.Grid5);
            app.Sublabel3 = uilabel(app.Grid2);
            app.Sublabel3.Text = 'FTP/SFTP';
            app.Sublabel2 = uilabel(app.Grid2);
            app.Sublabel2.Text = 'Web';
            app.Panel3 = uipanel(app.Grid2);
            app.Grid4 = uigridlayout(app.Panel3);
            app.UsurioEditFieldLabel_2 = uilabel(app.Grid4);
            app.UsurioEditFieldLabel_2.Text = 'Usuário:';
            app.webLogin = uieditfield(app.Grid4, 'text');
            app.webLogin.Value = 'admin';
            app.webPassLabel = uilabel(app.Grid4);
            app.webPassLabel.Text = 'Senha:';
            app.webPass = uihtml(app.Grid4);
            app.Sublabel1 = uilabel(app.Grid2);
            app.Sublabel1.Text = 'Endereço(s) IP';
            app.Panel2 = uipanel(app.Grid2);
            app.Grid3 = uigridlayout(app.Panel2);
            app.IPPblicoIntranetLabel = uilabel(app.Grid3);
            app.IPPblicoIntranetLabel.Text = 'Público / Intranet:';
            app.IP1 = uieditfield(app.Grid3, 'text');
            app.IPPblicoIntranetLabel_2 = uilabel(app.Grid3);
            app.IPPblicoIntranetLabel_2.Text = 'OpenVPN:';
            app.IP2 = uieditfield(app.Grid3, 'text');
            app.ReceptorLabel = uilabel(app.Grid2);
            app.ReceptorLabel.Text = 'Receptor:';
            app.Node = uidropdown(app.Grid2);
            app.Node.Items = {};
            app.Node.ValueChangedFcn = createCallbackFcn(app, @NodeValueChanged, true);
            app.Node.Value = {};
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'ConnectWhite.png';
            app.Button.Text = 'Conectividade';
            app.Lamp = uilamp(app.Grid1);
            app.Lamp.Enable = 'off';
            app.Button_2 = uibutton(app.Grid1, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'White_Edit.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = 'Editar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta_winEditNode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end