classdef appColeta_winMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid1              matlab.ui.container.GridLayout
        Grid1_Right        matlab.ui.container.GridLayout
        Label              matlab.ui.control.Label
        Button_Start       matlab.ui.control.StateButton
        SortRows           SortRows
        Table_Grid         matlab.ui.container.GridLayout
        Table_Label8       matlab.ui.control.Label
        Table_Label7       matlab.ui.control.Label
        Table_Label6       matlab.ui.control.Label
        Table_Label5       matlab.ui.control.Label
        Table_Label4       matlab.ui.control.Label
        Table_Label3       matlab.ui.control.Label
        Table_Label2       matlab.ui.control.Label
        Table_Label1       matlab.ui.control.Label
        Table              matlab.ui.control.Table
        Panel3             matlab.ui.container.Panel
        Panel3_Grid        matlab.ui.container.GridLayout
        Qtd_UnknownLabel   matlab.ui.control.Label
        Qtd_Unknown        matlab.ui.control.Label
        Panel2             matlab.ui.container.Panel
        Panel2_Grid        matlab.ui.container.GridLayout
        Qtd_UnmaskedLabel  matlab.ui.control.Label
        Qtd_Unmasked       matlab.ui.control.Label
        Panel1             matlab.ui.container.Panel
        Panel1_Grid        matlab.ui.container.GridLayout
        Qtd_Nodes          matlab.ui.control.Label
        Qtd_NodesLabel     matlab.ui.control.Label
        Subtitle           matlab.ui.control.Label
        Title              matlab.ui.control.Label
        Grid1_Left         matlab.ui.container.GridLayout
        Button_Integra     matlab.ui.control.StateButton
        Button_Log         matlab.ui.control.Button
        Button_Map         matlab.ui.control.Button
        Separator          matlab.ui.control.Image
        Button_Delete      matlab.ui.control.Button
        TreeLabel          matlab.ui.control.Label
        RadioGroupLabel    matlab.ui.control.Label
        RadioGroup         matlab.ui.container.ButtonGroup
        Option1            matlab.ui.control.RadioButton
        Option2            matlab.ui.control.RadioButton
        Tree               matlab.ui.container.CheckBoxTree
        Logo               matlab.ui.control.Image
    end

    
    properties (Access = private)

        CallingApp
        RootFolder

        ftpObj
        
        fig
        axes1

        UnMaskedWebSheet
        PersistentWebSheet
        UnMaskedWebSheetPath = ''
        
        ScriptVersionSheet

        UnMaskedEmissions    = table('Size', [0 9],                                                                                 ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'single', 'uint32', 'cell', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'OCC', 'Description', 'TimeStamp', 'Tag'})

        PersistentEmissions  = table('Size', [0 9],                                                                                  ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'single', 'uint32', 'uint8', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'OCC', 'Description', 'TimeStamp', 'Tag'})

        rfeyeData                                                           % app.CallingApp.RFeyeList filtrada pelo campo ConnectFlag igual a 1.
        rfNodes                                                             % app.rfeyeData filtrada pela seleção da árvore.
        interestBands
        
        maskStruct                                                          % Aspectos gerais da máscara espectral.
        bandList

        % Thresholds:
        % - THR1: Valor absoluto que indica a quantidade média de 
        % rompimentos de mascára espectral em relação ao número de
        % traços capturados enquanto se esperava as x mensagens 
        % do tipo 4 (x definido por BrokenMsg).
        % - THR2: Valor percentual usado para identificar as emissões
        % persistentes (uma espécia de ocupação mínima).
        MonitorSettings                                                     % Estrutura com campos "MaxDistance", "BrokenMsg", "ThreadIDList", "THR1" e "THR2".

    end
    
    
    methods (Access = private)

        function TreeBuilding(app)
            
            delete(app.Tree.Children)
            
            States = unique(app.rfeyeData.State);
            for ii = 1:height(States)
                ind = find(strcmp(app.rfeyeData.State, States{ii}));

                ParentNode = uitreenode(app.Tree, 'Text', States{ii}, ...
                                                  'NodeData', ind);

                for jj = ind'
                    ChildNode = uitreenode(ParentNode, 'Text', app.rfeyeData.ID{jj}, ...
                                                       'NodeData', jj);
                end
                expand(ParentNode)
            end
            
        end


        function maskMonitor(app)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
            
            for ii = 1:height(app.rfNodes)
                set(d, 'Title', app.rfNodes.ID{ii}, 'Message', 'Em andamento tentativa de conexão FTP/SFTP ao sensor...')

                % Criação do objeto FTP/SFTP.
                Misc_ftpObject(app, ii)
                if isempty(app.ftpObj)
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                end
                
                % Download dos arquivos de script e máscara espectral e a 
                % sua leitura, possibilitando atualização das variáveis
                % app.ScriptVersionSheet (informação de uma das abas do arquivo
                % "monitorGeral.xlsx" constante no Integra) e app.maskStruct 
                % (antiga app.maskParam).
                d.Message = 'Em andamento tentativa de download dos arquivos de script e máscara espectral carregados no sensor...';
                errorFlag = maskMonitor_FileDownload(app, ii);

                if errorFlag
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                else
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [.5 .5 .5])
                end

                % Comparar app.maskStruct com as faixas de interesse (as
                % quais constam no arquivo "insterestBands.cfg", criando a 
                % lista de emissões desmascaradas.
                freqList = Misc_BandCheck(app);
                if ~isempty(freqList)
                    freqList(~freqList(:,2),:) = [];
                end

                if ~isempty(freqList)
                    app.Qtd_Nodes.Text = num2str(str2double(app.Qtd_Nodes.Text) + 1);
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, sprintf('Emissões desmascaradas (frequência central em MHz e largura em kHz): %s', jsonencode([freqList(:,1)/1000 freqList(:,2)])))
                            
                    % Processo de truncamento das frequências centrais das
                    % emissões desmascaradas, possibilitando comparação com
                    % base legal (Mosaico, Stel e SRD).
                    d.Message =  'Em andamento consulta à base legal da Agência (Mosaico, Stel e SRD)...';
                    
                    TruncatedFreqs = Misc_TruncatedFrequency(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
                    bwTruncated    = ceil(freqList(:,2));
    
                    nowTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    indNode = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.ID{ii}));
                    for jj = 1:numel(TruncatedFreqs)
                        [~, Information, ~] = Misc_closestStation(app, TruncatedFreqs(jj), [app.rfNodes.Latitude(ii), app.rfNodes.Longitude(ii)]);
                        
                        Tag = sprintf('%s_%.0f_%.0f', app.rfNodes.ID{ii}, TruncatedFreqs(jj), bwTruncated(jj));
                        if isempty(find(strcmp(app.UnMaskedEmissions.Tag, Tag), 1))
                            app.UnMaskedEmissions(end+1, :) = {app.rfNodes.State{ii},             ...
                                                               app.rfNodes.City{ii},              ...
                                                               app.rfNodes.ID{ii},                ...
                                                               round(TruncatedFreqs(jj)/1000, 3), ...
                                                               bwTruncated(jj),                   ...
                                                               '-', Information, nowTime, Tag};
                        end
    
                        app.Qtd_Unmasked.Text = num2str(str2double(app.Qtd_Unmasked.Text) + 1);
                        if isempty(Information)
                            app.Qtd_Unknown.Text = num2str(str2double(app.Qtd_Unknown.Text) + 1);
                        end
                        drawnow
    
                        if bwTruncated(jj) > 0                        
                            if isempty(indNode)
                                position = height(app.UnMaskedWebSheet) + 1;
                                maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                
                            else
                                indFreq = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.ID{ii}) & ...
                                              (abs(TruncatedFreqs(jj) - app.UnMaskedWebSheet.FreqCenter*1000) < 15), 1);
                                
                                if isempty(indFreq)
                                    position = height(app.UnMaskedWebSheet) + 1;
                                    maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                else
                                    maskMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), indFreq, Information, nowTime);
                                end
                            end
                        end
                    end
                    
                    % if the emission is in UnMaskedWebSheet but not in the mask... update
                    % emission "InMask" field accordingly
                    for jj = indNode'
                        indEmiss = find(TruncatedFreqs == int32(app.UnMaskedWebSheet.FreqCenter(jj)*1000), 1);
    
                        if isempty(indEmiss)
                            app.UnMaskedWebSheet.Status(jj) = "Resolvido";
                            app.UnMaskedWebSheet.InMask(jj) = "Não";
                        end
                    end

                    app.Table.Data = app.UnMaskedEmissions(:,1:8);
                    pause(.5)
                    drawnow nocallbacks
                else
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, 'Não identificada emissão desmascarada.')
                end

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];
            Misc_integraUpdate(app, d)

        end


        function errorFlag = maskMonitor_FileDownload(app, ind)
            
            errorFlag = 0;

            try
                cd(app.ftpObj,   '/etc/drops/');
                mget(app.ftpObj, 'rfeyed.cfg');

                scriptFilename = sprintf('%s_script.cfg', app.rfNodes.ID{ind});
                movefile('rfeyed.cfg', fullfile(app.RootFolder, 'Temp', scriptFilename));

                [maskName, methodField] = app.CallingApp.udpLogger_ScriptRead(scriptFilename);
                for ii = 1:numel(maskName)
                    cd(app.ftpObj,   '/etc/drops/logger_masks/');
                    mget(app.ftpObj, maskName{ii});

                    maskFileName = sprintf('%s_mask.csv', app.rfNodes.ID{ind});
                    movefile(maskName{ii}, fullfile(app.RootFolder, 'Temp', maskFileName));

% %                     % TESTE COM MÁSCARA PLANA EM -80DBM%
%                     maskFileName = 'RFeye002198_mask.csv';
% %                     % TESTE %

                    maskMonitor_FileRead(app, maskFileName)

                    % Atualizando estrutura para planilha de controle de versões com dados atuais                
                    ind1 = find(strcmp(app.ScriptVersionSheet.Node, app.rfNodes.ID{ind}));
                    ind2 = find(strcmp(app.ScriptVersionSheet.MaskName, maskName{ii}));

                    ind3 = intersect(ind1, ind2);
                    if ~isempty(ind3)
                        app.ScriptVersionSheet.ScriptName(ind3)  = methodField;
                        app.ScriptVersionSheet.DateUpdated(ind3) = datetime("now");
                        app.ScriptVersionSheet.VisitCount(ind3)  = app.ScriptVersionSheet.VisitCount(ind3) + 1;

                    else
                        app.ScriptVersionSheet(end+1,:) = {app.rfNodes.ID{ind}, ...
                                                           methodField, ...
                                                           maskName{ii}, ...
                                                           datetime("now"), ...
                                                           datetime("now"), ...
                                                           1};
                    end
                end

            catch
                errorFlag = 1;
            end

        end


        function maskMonitor_FileRead(app, maskFileName)

            inData = readtable(maskFileName, 'VariableNamingRule', 'preserve');
            inData.Properties.VariableNames{1} = 'Start';
            inData.Properties.VariableNames{2} = 'Stop';

            app.maskStruct = struct('raw', inData, 'freq', [], 'FreqStart', [], 'FreqStop', []);
                     
            app.maskStruct.FreqStart = inData.Start(1);
            app.maskStruct.FreqStop  = inData.Stop(end);
             
            bw = inData.Start(2:end) - inData.Stop(1:end-1);
            app.maskStruct.freq = [(inData.Stop(1:end-1) + bw/2)  bw];

        end

        
        function maskMonitor_AddInfoToSheet(app, rfNode, frequency, bw, position, Information, nowTime)

            InitialHeight = height(app.UnMaskedWebSheet);
            
            if isempty(Information)
                RegulatoryInfo = "Não licenciado";
                Description    = "";

                if position <= InitialHeight
                    Uploaded    = app.UnMaskedWebSheet.Uploaded(position);
                    Status      = app.UnMaskedWebSheet.Status(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status      = "Pendente identificação";
                    dateCreated = nowTime;                                
                end
                
            else
                RegulatoryInfo = "Licenciado";
                Description    = Information;
                
                if position <= InitialHeight
                    Uploaded    = app.UnMaskedWebSheet.Uploaded(position);
                    Status      = app.UnMaskedWebSheet.Status(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status       = "Pendente confirmação";
                    dateCreated = nowTime;                    
                end
            end

            app.UnMaskedWebSheet(position,:) = {position,       ...
                                                rfNode,         ...
                                                round(frequency, 3), ...
                                                bw,             ...
                                                RegulatoryInfo, ...
                                                Description,    ...
                                                Status,         ...
                                                "Sim",          ...
                                                Uploaded,       ...
                                                dateCreated};

        end

        
        function Misc_SaveLog(app)

            indFind = find(~strcmp(app.rfeyeData.Log, ''));
            if ~isempty(indFind)
                fileID = fopen(fullfile(app.RootFolder, 'Temp', sprintf('%s_Log.txt', datestr(now, 'yyyymmdd_HHMMSS'))), "wt");

                logicalArray = contains(app.rfeyeData.Log(indFind), 'matlab.io');
                SuccessNodes = strjoin(app.rfeyeData.ID(indFind(find( logicalArray))), ', ');
                if isempty(SuccessNodes)
                    SuccessNodes = '-';
                end

                UnSuccessNodes = strjoin(app.rfeyeData.ID(indFind(find(~logicalArray))), ', ');
                if isempty(UnSuccessNodes)
                    UnSuccessNodes = '-';
                end
    
                fullLog = sprintf(['appColeta v. %s (Monitor)\n' ... 
                                   '%s\n'                        ...
                                   '- Sensores que apresentaram conectividade: %s\n'        ...
                                   '- Sensores que não apresentaram conectividade: %s\n\n', ...
                                   '%s'], app.CallingApp.Version.appColeta,    ...
                                          datestr(now, 'dd/mm/yyyy HH:MM:SS'), ...
                                          SuccessNodes,  ...
                                          UnSuccessNodes, ...
                                          replace(jsonencode(app.rfeyeData(indFind, [1,22]), 'PrettyPrint', true), '\\fontname{Calibri}\\fontsize{10}', ''));

                fwrite(fileID, fullLog, 'char');
                fclose(fileID);
            end

        end


        function Misc_integraUpdate(app, d)
         
            if app.Button_Integra.Value
                set(d, 'Cancelable', 'off', 'Message', 'Em andamento tentativa de atualizar planilha carregada no Integra...')

                switch app.RadioGroup.SelectedObject.Text
                    case 'Máscaras espectrais'
                        if ~issorted(app.ScriptVersionSheet.Node)
                            app.ScriptVersionSheet = sortrows(app.ScriptVersionSheet, 'Node');
                        end

                        writetable(app.UnMaskedWebSheet,   app.UnMaskedWebSheetPath, 'Sheet', 'desmascarados', 'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                        writetable(app.ScriptVersionSheet, app.UnMaskedWebSheetPath, 'Sheet', 'versions',      'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);

                    case 'Tráfego UDP'
                        writetable(app.PersistentWebSheet, app.UnMaskedWebSheetPath, 'Sheet', 'persistentes',  'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                end

                app.Button_Integra.Value = 0;
            end

        end


        function Misc_integraImport(app)
            
            opts1 = spreadsheetImportOptions("Sheet", "desmascarados",                                                                      ...
                                             "NumVariables", 10,                                                                            ...
                                             "VariableTypes", ["int32", "string", "double", "double", "string", "string", "string",         ...
                                                               "string", "string", "datetime"],                                             ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "RegulatoryInfo", "Description", "Status", ...
                                                               "InMask", "Uploaded", "dateCreated"],                                        ...
                                             "DataRange", "A2");
            
            app.UnMaskedWebSheet = readtable(app.UnMaskedWebSheetPath, opts1);
            
            opts2 = spreadsheetImportOptions("Sheet", "persistentes",                                                                    ...
                                             "NumVariables", 10,                                                                         ...
                                             "VariableTypes", ["int32", "string", "double", "double", "double", "string", "string",      ...
                                                               "string", "string", "datetime"],                                          ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "OCC", "RegulatoryInfo", "Description", ...
                                                               "Status", "Uploaded", "dateCreated"],                                     ...
                                             "DataRange", "A2");
            
            app.PersistentWebSheet = readtable(app.UnMaskedWebSheetPath, opts2);
            
            opts3 = spreadsheetImportOptions("Sheet", "versions",                                                                            ...
                                            "NumVariables", 6,                                                                               ...
                                            "VariableTypes", ["string", "string", "string", "datetime", "datetime", "int32"],                ...
                                            "VariableNames", ["Node", "ScriptName", "MaskName", "DateCreated", "DateUpdated", "VisitCount"], ...
                                            "DataRange", "A2", "MissingRule", "omitrow");
            
            app.ScriptVersionSheet = readtable(app.UnMaskedWebSheetPath, opts3);
            
        end


        function Misc_ftpObject(app, ind)

            if strcmp(app.rfNodes.ftpStatus{ind}, 'Success') || strcmp(app.rfNodes.sftpStatus{ind}, 'Success')
                IPs = {app.rfNodes.IP1{ind}, app.rfNodes.IP2{ind}};
            else
                IPs = {app.rfNodes.IP2{ind}, app.rfNodes.IP1{ind}};
            end

            if strcmp(app.rfNodes.ftpStatus{ind}, 'Success') || strcmp(app.rfNodes.ftpStatus_OpenVPN{ind}, 'Success')
                Type = {'ftp', 'sftp'}; 
            else
                Type = {'sftp', 'ftp'}; 
            end

            app.ftpObj = [];
            for ii = 1:numel(IPs)
                for jj = 1:numel(Type)
                    try
                        switch Type{jj}
                            case  'ftp'; app.ftpObj =  ftp([IPs{ii}   ':21'], 'root',             app.rfNodes.rootPass{ind});
                            case 'sftp'; app.ftpObj = sftp([IPs{ii} ':2828'], 'root', 'Password', app.rfNodes.rootPass{ind});
                        end

                        Misc_LogWrite(app, app.rfNodes.ID{ind}, sprintf('%s (%s:%.0f)', class(app.ftpObj), app.ftpObj.Host, app.ftpObj.Port))
                        return
                    catch ME
                        Misc_LogWrite(app, app.rfNodes.ID{ind}, ME.message)
                    end
                end
            end
            
        end


        function Misc_LogWrite(app, Node, msg)

            ind = find(strcmp(app.rfeyeData.ID, Node), 1);

            if isempty(app.rfeyeData.Log{ind})
                app.rfeyeData.Log{ind} = ['\fontname{Calibri}' '\fontsize{10}' msg];
            else
                app.rfeyeData.Log{ind} = sprintf('%s\n\\fontname{Calibri}\\fontsize{10}%s', app.rfeyeData.Log{ind}, msg);
            end

        end


        function Misc_TreeNodeColor(app, Node, color)

            % Atualiza o estado do sensor (que influencia no mapa)
            ind0 = find(strcmp(app.rfeyeData.ID, Node), 1);
            if color == [.5 .5 .5]                
                app.rfeyeData.ConnectFlag(ind0) = 1;
            else
                app.rfeyeData.ConnectFlag(ind0) = 0;
            end

            % Atualiza a cor do nome do sensor na árvore
            ind1 = [];
            ind2 = [];

            for ii = 1:numel(app.Tree.Children)
                for jj = 1:numel(app.Tree.Children(ii).Children)
                    if strcmp(app.Tree.Children(ii).Children(jj).Text, Node)
                        ind1 = ii;
                        ind2 = jj;

                        break
                    end
                end

                if ~isempty(ind1)
                    addStyle(app.Tree, uistyle('FontColor', color, 'FontWeight', 'bold'), 'node', app.Tree.Children(ind1).Children(ind2));
                    break
                end
            end            

        end

        
        function freqList = Misc_BandCheck(app)

            freqList = [];
            if ~isempty(app.maskStruct.freq)
                for ii = 1:height(app.maskStruct.freq)
                    if ~isempty(intersect(find(app.interestBands.FreqStart <= app.maskStruct.freq(ii,1)), find(app.interestBands.FreqStop >= app.maskStruct.freq(ii,1))))
                        freqList = [freqList; app.maskStruct.freq(ii,:)];
                    end           
                end
            end

        end   

        
        function TruncatedPeaks = Misc_TruncatedFrequency(app, FrequencyPeaks, F0, F1)

            global peaksReference
            if isempty(peaksReference)
                Misc_FindPeaksDefaultValues(app.RootFolder)
            end
            
            TruncatedPeaks = [];
            
            for ii = numel(FrequencyPeaks):-1:1
                ind = [];
                for jj = 1:numel(peaksReference)
                    if (peaksReference(jj).Band(1)/1000 <= FrequencyPeaks(ii)) && (peaksReference(jj).Band(2)/1000 >= FrequencyPeaks(ii))
                        ind = jj;
                        break
                    end
                end
                
                if ~isempty(ind)
                    FrequencyChannels = peaksReference(ind).Channels .* 1000;
                else
                    FrequencyChannels = single(F0:25:F1);
                end

                [~, auxIndex]        = min(abs(FrequencyChannels - FrequencyPeaks(ii)));
                TruncatedPeaks(ii,1) = FrequencyChannels(auxIndex);                
            end
            TruncatedPeaks = round(TruncatedPeaks);
            
        end
        
        
        function [Distance, Information, Station] = Misc_closestStation(app, freq, GPS)
            
            global AnatelDB
            if isempty(AnatelDB)
                Misc_DataBaseRead(app.RootFolder)
            end
            
            auxIndex = find(AnatelDB.Frequency == single(freq)/1000);
            
            Distance    = [];
            Information = '';
            Station     = [];
            
            if ~isempty(auxIndex)
                ll = 0;
                for kk = auxIndex'
                    ll = ll+1;
                    
                    auxDistance    = Misc_lldistkm(GPS, [AnatelDB.Latitude(kk), AnatelDB.Longitude(kk)]);
                    auxInformation = AnatelDB.Description{kk};
                    auxStation     = AnatelDB.Station(kk);
                    
                    if ll == 1
                        Distance    = auxDistance;
                        Information = auxInformation;
                        Station     = auxStation;
                    else
                        if auxDistance < Distance
                            Distance    = auxDistance;
                            Information = auxInformation;
                            Station     = auxStation;
                        end
                    end
                end

                if Distance > app.MonitorSettings.MaxDistance
                    Distance    = [];
                    Information = '';
                    Station     = [];
                else
                    Information = sprintf('%s (%.1f km)', Information, Distance);
                end
            end
            
        end
        

        function udpMonitor(app)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
            
            for ii = 1:height(app.rfNodes)
                set(d, 'Title', app.rfNodes.ID{ii}, 'Message', 'Em andamento tentativa de extrair dados do sensor...')

                % Criação do objeto FTP/SFTP.
                Misc_ftpObject(app, ii)
                if isempty(app.ftpObj)
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                end
                
                % Download dos arquivos de script e máscara espectral e a 
                % sua leitura, possibilitando atualização das variáveis
                % app.ScriptVersionSheet (informação de uma das abas do arquivo
                % "monitorGeral.xlsx" constante no Integra) e app.maskStruct 
                % (antiga app.maskParam).
                d.Message = 'Em andamento tentativa de download dos arquivos de script e máscara espectral carregados no sensor...';
                errorFlag = maskMonitor_FileDownload(app, ii);

                if errorFlag
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [0.6350 0.0780 0.1840])
                    continue
                else
                    Misc_TreeNodeColor(app, app.rfNodes.ID{ii}, [.5 .5 .5])
                end

                % Leitura do fluxo UDP e identificação das emissões
                % persistentes que rompem a máscara espectral e que estão 
                % em uma das faixas de interesse (as quais constam no arquivo 
                % "insterestBands.cfg")
                freqList = udpMonitor_udpLoggerRead(app, app.rfNodes.ID{ii}, d);

                if ~isempty(freqList)
                    app.Qtd_Nodes.Text = num2str(str2double(app.Qtd_Nodes.Text) + 1);
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, sprintf('Emissões persistentes (frequência central em MHz, largura em kHz e ocupação em %%): %s', jsonencode([round(freqList(:,1)/1000, 3) ceil(freqList(:,2)) ceil(freqList(:,3)*100)])))
                            
                    % Processo de truncamento das frequências centrais das
                    % emissões desmascaradas, possibilitando comparação com
                    % base legal (Mosaico, Stel e SRD).
                    d.Message =  'Em andamento consulta à base legal da Agência (Mosaico, Stel e SRD)...';
                    
                    TruncatedFreqs = Misc_TruncatedFrequency(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
                    bwTruncated    = ceil(freqList(:,2));
    
                    nowTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    indNode = find(strcmp(app.PersistentWebSheet.Node, app.rfNodes.ID{ii}));
                    for jj = 1:numel(TruncatedFreqs)
                        [~, Information, ~] = Misc_closestStation(app, TruncatedFreqs(jj), [app.rfNodes.Latitude(ii), app.rfNodes.Longitude(ii)]);
                        
                        Tag = sprintf('%s_%.0f_%.0f', app.rfNodes.ID{ii}, TruncatedFreqs(jj), bwTruncated(jj));
                        if isempty(find(strcmp(app.PersistentEmissions.Tag, Tag), 1))
                            app.PersistentEmissions(end+1, :) = {app.rfNodes.State{ii},             ...
                                                                 app.rfNodes.City{ii},              ...
                                                                 app.rfNodes.ID{ii},                ...
                                                                 round(TruncatedFreqs(jj)/1000, 3), ...
                                                                 bwTruncated(jj),                   ...
                                                                 round(freqList(jj,3) * 100),       ...
                                                                 Information, nowTime, Tag};
                        end
    
                        app.Qtd_Unmasked.Text = num2str(str2double(app.Qtd_Unmasked.Text) + 1);
                        if isempty(Information)
                            app.Qtd_Unknown.Text = num2str(str2double(app.Qtd_Unknown.Text) + 1);
                        end
                        drawnow
    
                        if bwTruncated(jj) > 0                        
                            if isempty(indNode)
                                position = height(app.PersistentWebSheet) + 1;
                                udpMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, position, Information, nowTime);
                                
                            else
                                indFreq = find(strcmp(app.PersistentWebSheet.Node, app.rfNodes.ID{ii}) & ...
                                              (abs(TruncatedFreqs(jj) - app.PersistentWebSheet.FreqCenter*1000) < 15), 1);
                                
                                if isempty(indFreq)
                                    position = height(app.PersistentWebSheet) + 1;
                                    udpMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, position, Information, nowTime);
                                else
                                    udpMonitor_AddInfoToSheet(app, app.rfNodes.ID{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, indFreq, Information, nowTime);
                                end
                            end
                        end
                    end

                    app.Table.Data = app.PersistentEmissions(:,1:8);
                    pause(.5)
                    drawnow nocallbacks
                else
                    Misc_LogWrite(app, app.rfNodes.ID{ii}, 'Não identificada emissão persistente.')
                end

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];
            Misc_integraUpdate(app, d)

        end


        function freqList = udpMonitor_udpLoggerRead(app, Node, d)

            freqList     = [];
            app.bandList = [];

            udpPortArray = app.CallingApp.udpPortArray;
            IP           = app.ftpObj.Host;
            Port         = 8910;
            
            udpConfig    = struct('ConnectionTime', 60*app.MonitorSettings.BrokenMsg, 'HandshakePause', 1, 'Timeout', 5);            

            try
                [app.CallingApp.udpPortArray, ind] = Connection_udpLogger(udpPortArray, Port, udpConfig.Timeout);
                udpPort = app.CallingApp.udpPortArray{ind};
                flush(udpPort)
                
                udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)                
            catch ME
                uialert(app.UIFigure, ME.message, Node, 'Icon', 'error')
                return
            end
            
            app.bandList       = [];
            countBroken        = [];
            AvailableDatagrams = 0;
            nZeroDatagrams     = 0;

            set(d, 'Indeterminate', 'off', 'Value', 0)
            
            tic
            t1 = toc;
            while (width(countBroken) < app.MonitorSettings.BrokenMsg) && (nZeroDatagrams < 3)
                if t1 > udpConfig.ConnectionTime
                    udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)
        
                    if ~udpPort.NumDatagramsAvailable
                        nZeroDatagrams = nZeroDatagrams + 1;
                    end
                    
                elseif udpPort.NumDatagramsAvailable
                    AvailableDatagrams = AvailableDatagrams + udpPort.NumDatagramsAvailable;
                    d.Message = sprintf('%.0f datagramas lidos...', AvailableDatagrams);
                    drawnow

                    SpecInfo = read(udpPort, udpPort.NumDatagramsAvailable, 'uint8');

                    for ii = 1:numel(SpecInfo)
                        if ~strcmp(SpecInfo(ii).SenderAddress, IP) || numel(SpecInfo(ii).Data) < 96
                            continue
                        end
        
                        MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                        ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                        DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                        EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                        
                        if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                            switch DataType  
                                case 42
                                    countBroken = udpMonitor_udpDataType42(app, SpecInfo(ii).Data, countBroken);
                                    d.Value = width(countBroken)/app.MonitorSettings.BrokenMsg;
                                    
                                case 67
                                    if ismember(ThreadID, app.MonitorSettings.ThreadIDList) 
                                        udpMonitor_udpDataType67(app, SpecInfo(ii).Data, ThreadID); 
                                    else 
                                        continue
                                    end
                                    
                                otherwise
                                    continue
                            end    
                        end    
                    end    
                end
                t1 = toc;
                pause(0.1)
            end

            band = app.bandList;
            save(fullfile(app.RootFolder, 'Temp', sprintf('bandList_%s.mat', Node)), 'countBroken', 'band')

%             load(sprintf('bandList_%s.mat', Node), 'countBroken', 'band')
%             app.bandList = band;
            
            if isempty(app.bandList) || numel(app.bandList) ~= numel(app.MonitorSettings.ThreadIDList)
                uialert(app.UIFigure, sprintf('Não foram recebidos datagramas válidos para ao menos um dos TheadID de interesse. Favor verificar arquivo %s.', sprintf('bandList_%s.mat', Node)), Node, 'Icon', 'warning')
                return
            else
                if ~issorted([app.bandList.ThreadID])
                    [~, indSort] = sort([app.bandList.ThreadID]);
                    app.bandList = app.bandList(indSort);
                end
            end

            countBroken_Mean = ceil((countBroken(:,end)-countBroken(:,1))/width(countBroken));
            indThreadID      = find(countBroken_Mean > app.MonitorSettings.THR1);

            Misc_LogWrite(app, Node, sprintf('Contador médio de rompimento de máscara: %s', jsonencode(countBroken_Mean)))

            for ii = indThreadID'
                F0 = app.bandList(ii).FreqStart/1000;
                F1 = app.bandList(ii).FreqStop/1000;
                
                maskArray = ones([app.bandList(ii).DataPoints, 1])*1000;
                maskFreq  = linspace(F0, F1, app.bandList(ii).DataPoints)';
                        
                for jj = 1:height(app.maskStruct.freq)
                    maskArray(maskFreq >= (app.maskStruct.raw.Start(jj) - app.bandList(ii).StepWidth/2000) & ...
                              maskFreq <= (app.maskStruct.raw.Stop(jj)  + app.bandList(ii).StepWidth/2000)) = app.maskStruct.raw.Threshold(jj);
                end

                occMat = zeros([app.bandList(ii).DataPoints app.bandList(ii).WritedTraces], 'single');
                occMat(bsxfun(@gt, app.bandList(ii).Traces(:, 1:app.bandList(ii).WritedTraces), maskArray)) = 1;

                occMatEdited = sum(occMat,2)/app.bandList(ii).WritedTraces;
                indFreq      = find(occMatEdited);

                InitialHeight = height(freqList);
                for kk = indFreq'
                    refFreq = [kk 1];
                
                    if isempty(freqList) || refFreq(1) ~= sum(freqList(end,:))
                        freqList(end+1,:) = refFreq;
                    else
                        freqList(end,2)   = freqList(end,2)+1;
                    end
                end
                
                if ~isempty(indFreq)
                    for ll = InitialHeight+1:height(freqList)
                        [Max, indMax] = max(occMatEdited(freqList(ll,1):sum(freqList(ll,1:2))-1));
    
                        freqList(ll, 1) = maskFreq(freqList(ll, 1)+indMax-1);
                        freqList(ll, 2) = (freqList(ll, 2) + 1) * app.bandList(ii).StepWidth/1000;
                        freqList(ll, 3) = Max;
                    end
                    freqList(freqList(:,3) < app.MonitorSettings.THR2, :) = [];
                end
            end
            
        end


        function udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)
            
            msg = sprintf('open %.0f', udpConfig.ConnectionTime);
        
            write(udpPort, msg, 'uint8', IP, Port);
            pause(udpConfig.HandshakePause)
        
        end


        function countBroken = udpMonitor_udpDataType42(app, Datagram, countBroken)

            FreeTextLength = double(typecast(uint8(Datagram(73:76)), 'uint32'));
            if FreeTextLength
                FreeText = deblank(char(Datagram(77:76+FreeTextLength)));

                try
                    jsonStruct = jsondecode(FreeText);
                    if jsonStruct.type == 4
                        countBroken(:,end+1) = jsonStruct.values;
                    end
                catch
                end
            end

        end


       function udpMonitor_udpDataType67(app, Datagram, ThreadID)

            Bin.ThreadID = ThreadID;

            DescriptionLength = double(typecast(uint8(Datagram(41:44)), 'uint32'));
            Bin.Description   = deblank(char(Datagram(45:45+DescriptionLength-1)));

            IntegerPart    = double(typecast(uint8(Datagram(45+DescriptionLength:46+DescriptionLength)), 'uint16'));
            DecimalPart    = double(typecast(uint8(Datagram(47+DescriptionLength:50+DescriptionLength)), 'int32'));
            Bin.FreqStart  = (IntegerPart + DecimalPart./1e+9) .* 1e+6;

            IntegerPart    = double(typecast(uint8(Datagram(51+DescriptionLength:52+DescriptionLength)), 'uint16'));
            DecimalPart    = double(typecast(uint8(Datagram(53+DescriptionLength:56+DescriptionLength)), 'int32'));
            Bin.FreqStop   = (IntegerPart + DecimalPart./1e+9) .* 1e+6;

            Bin.Resolution = double(typecast(uint8(Datagram(57+DescriptionLength:60+DescriptionLength)), 'uint32'));


            switch Datagram(74+DescriptionLength)
                case 0; Bin.Operation = 'Single Measurement';
                case 1; Bin.Operation = 'Mean';
                case 2; Bin.Operation = 'Peak';
                case 3; Bin.Operation = 'Minimum';
            end

            switch Datagram(75+DescriptionLength)
                case 0; Bin.LevelUnit = 'dBm';
                case 1; Bin.LevelUnit = 'dBµV/m';
            end

            OFFSET = double(typecast(uint8(Datagram(76+DescriptionLength)), 'int8'));
            NTUN   = typecast(uint8(Datagram(80+DescriptionLength:81+DescriptionLength)), 'uint16');
            NAGC   = typecast(uint8(Datagram(82+DescriptionLength:83+DescriptionLength)), 'uint16');

            Bin.DataPoints = double(typecast(uint8(Datagram(85+DescriptionLength:88+DescriptionLength)), 'uint32'));
            Bin.StepWidth  = (Bin.FreqStop-Bin.FreqStart)/(Bin.DataPoints-1);
            
            Bin.WritedTraces = 0;
            Bin.Traces       = [];

            if isempty(app.bandList)
                app.bandList = Bin;
                app.bandList.Traces = zeros(Bin.DataPoints, 60*app.MonitorSettings.BrokenMsg, 'single');

                indCurr   = 1;
            else
                indFind = find([app.bandList.ThreadID] == Bin.ThreadID);
                if isempty(indFind)
                    app.bandList(end+1) = Bin;
                    app.bandList(end).Traces = zeros(Bin.DataPoints, 60*app.MonitorSettings.BrokenMsg, 'single');

                    indCurr = numel(app.bandList);
                else
                    indCurr = indFind;
                end
            end
            OFFSET   = OFFSET - 127.5;
            newArray = Datagram(89+DescriptionLength+4*NTUN+NAGC:89+DescriptionLength+4*NTUN+NAGC+Bin.DataPoints-1)./2 + OFFSET;
            
            app.bandList(indCurr).WritedTraces = app.bandList(indCurr).WritedTraces + 1;
            app.bandList(indCurr).Traces(:, app.bandList(indCurr).WritedTraces) = newArray;

       end


       function udpMonitor_AddInfoToSheet(app, rfNode, frequency, bw, occ, position, Information, nowTime)

            InitialHeight = height(app.PersistentWebSheet);
            
            if isempty(Information)
                RegulatoryInfo = "Não licenciado";
                Description    = "";

                if position <= InitialHeight
                    Uploaded    = "Sim";
                    Status      = app.PersistentWebSheet.Status(position);
                    dateCreated = app.PersistentWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status      = "Pendente identificação";
                    dateCreated = nowTime;                                
                end
                
            else
                RegulatoryInfo = "Licenciado";
                Description    = Information;
                Status         = "Pendente confirmação";

                if position <= InitialHeight
                    Uploaded    = app.PersistentWebSheet.Uploaded(position);
                    dateCreated = app.PersistentWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    dateCreated = nowTime;                    
                end
            end

            app.PersistentWebSheet(position,:) = {position,       ...
                                                  rfNode,         ...
                                                  round(frequency, 3), ...
                                                  bw,             ...
                                                  occ,            ...
                                                  RegulatoryInfo, ...
                                                  Description,    ...
                                                  Status,         ...
                                                  Uploaded,       ...
                                                  dateCreated};

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
 
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.rfeyeData     = app.CallingApp.RFeyeList(find(app.CallingApp.RFeyeList.ConnectFlag), :);
            app.rfeyeData.Log = repmat({''}, height(app.rfeyeData), 1);

            app.MonitorSettings = jsondecode(fileread('Monitor.cfg'));
            app.interestBands   = readtable('interestBands.cfg', 'FileType', 'text');

            if isfile(app.MonitorSettings.WebSheetPath)
                app.UnMaskedWebSheetPath = app.MonitorSettings.WebSheetPath;
            else
                app.UnMaskedWebSheetPath = fullfile(app.RootFolder, 'DataBase', 'monitorGeral.xlsx');
            end
            Misc_integraImport(app)

            TreeBuilding(app)

            app.Table.Data = app.UnMaskedEmissions(:,1:8);
            RadioGroupSelectionChanged(app)

        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            
            Misc_SaveLog(app)

            app.CallingApp.auxWin2 = [];
            delete(app.fig)
            delete(app)
            
        end

        % Value changed function: Button_Start
        function Button_StartValueChanged(app, event)
            
            ind = [];
            for ii = 1:numel(app.Tree.CheckedNodes)
                ind = [ind; app.Tree.CheckedNodes(ii).NodeData];
            end
            ind = unique(ind);

            if isempty(ind)
                if app.Button_Integra.Value
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
                    Misc_integraUpdate(app, d)
                else
                    uialert(app.UIFigure, 'É preciso selecionar ao menos um sensor.', 'appColeta', 'Icon', 'warning')
                end

                return
            end
            app.rfNodes = app.rfeyeData(ind,:);

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    maskMonitor(app)

                case 'Tráfego UDP'
                    udpMonitor(app)
            end
            TreeSelectionChanged(app)
                     
        end

        % Button pushed function: Button_Map
        function Button_MapPushed(app, event)
            
            obj = findobj('Type', 'Figure', 'Tag', 'rfeyeMapView');
            if isempty(obj)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on');

                mainMonitor = get(0, 'MonitorPositions');

                [~, ind] = max(mainMonitor(:,3));
                mainMonitor = mainMonitor(ind,:);

                app.fig   = figure('Name', 'appColeta', 'NumberTitle', 'off', 'Position', [mainMonitor(1)+round((mainMonitor(3)-852)/2), mainMonitor(2)+round((mainMonitor(4)-696)/2), 852, 696],  ...
                                   'Visible', 'on', 'Color', [0.94,0.94,0.94], 'GraphicsSmoothing', 'on', 'Tag', 'rfeyeMapView',                ...
                                   'MenuBar', 'none', 'ToolBar', 'none', 'CloseRequestFcn', "delete(findobj('Type', 'Figure', 'Tag', 'rfeyeMapView'))");
                
                warning('off')
                jFrame = get(app.fig, 'javaframe');
                jIcon  = javax.swing.ImageIcon(fullfile(app.RootFolder, 'Icons', 'Anatel.png'));
                jFrame.setFigureIcon(jIcon);
                warning('on')
                
                app.axes1 = geoaxes('FontSize', 9);                
                hold(app.axes1, 'on')
                
                app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                enableDefaultInteractivity(app.axes1)
                    
                for ii = 1:height(app.rfeyeData)
                    if app.rfeyeData.ConnectFlag(ii)
                        color = [0.50,0.50,0.50];
                    else
                        color = [0.6350 0.0780 0.1840];
                    end

                    newObj = geoscatter(app.rfeyeData.Latitude(ii), app.rfeyeData.Longitude(ii), 'Tag', app.rfeyeData.ID{ii}, ...
                                                                                                 'MarkerEdgeColor', color,    ...
                                                                                                 'MarkerFaceColor', color);
            
                    set(newObj.DataTipTemplate, FontName='Calibri', FontSize=10)
            
                    newObj.DataTipTemplate.DataTipRows(1).Label = '';
                    newObj.DataTipTemplate.DataTipRows(2).Label = '';
                    
                    newObj.DataTipTemplate.DataTipRows(end+1) = dataTipTextRow(app.rfeyeData.ID{ii}, 'Tag', 'auto');
                    newObj.DataTipTemplate.DataTipRows        = newObj.DataTipTemplate.DataTipRows([3 1 2]);
                end
            else
                uialert(app.UIFigure, 'Janela com disposição dos sensores no mapa está aberta...', 'appColeta', 'Icon', 'info')
            end

        end

        % Button pushed function: Button_Log
        function Button_LogPushed(app, event)

            ind = find(strcmp(app.rfeyeData.ID, app.Tree.SelectedNodes.Text), 1);

            if ~isempty(app.rfeyeData.Log{ind})
                uialert(app.UIFigure, app.rfeyeData.Log{ind}, app.rfeyeData.ID{ind}, 'Icon', 'info', 'Interpreter','tex')
            end

        end

        % Button pushed function: Button_Delete
        function Button_DeletePushed(app, event)
            
            removeStyle(app.Tree)
            app.Tree.CheckedNodes = [];
            app.rfeyeData.Log     = repmat({''}, height(app.rfeyeData), 1);

            app.UnMaskedEmissions(:,:)   = [];
            app.PersistentEmissions(:,:) = [];
            app.Table.Data               = [];

            app.Qtd_Nodes.Text    = '0';
            app.Qtd_Unmasked.Text = '0';
            app.Qtd_Unknown.Text  = '0';

        end

        % Callback function: SortRows, SortRows
        function SortRowsValueChanged(app, event)
            
            if strcmp(app.SortRows.ImageSource, 'ascend.png')
                type = 'ascend';
            else
                type = 'descend';
            end

            switch app.SortRows.Value
                case 'UF';                    app.Table.Data = sortrows(app.Table.Data, [1 2 3 4],   type);
                case 'Município';             app.Table.Data = sortrows(app.Table.Data, [2 3 4],     type);
                case 'Sensor';                app.Table.Data = sortrows(app.Table.Data, [3 4],       type);
                case 'Frequência (MHz)';      app.Table.Data = sortrows(app.Table.Data, [4 1 2 3],   type);
                case 'Largura (kHz)';         app.Table.Data = sortrows(app.Table.Data, [5 4 1 2 3], type);
                case 'OCC (%)';               app.Table.Data = sortrows(app.Table.Data, [6 4 1 2 3], type);
                case 'Provável emissor';      app.Table.Data = sortrows(app.Table.Data, [7 1 2 3 4], type);
                case 'Data/hora de registro'; app.Table.Data = sortrows(app.Table.Data, [8 1 2 3 4], type);
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            ind = find(strcmp(app.rfeyeData.ID, app.Tree.SelectedNodes.Text), 1);

            if isempty(ind) || isempty(app.rfeyeData.Log{ind})
                app.Button_Log.Enable = 0;
            else
                app.Button_Log.Enable = 1;
            end            

        end

        % Selection changed function: RadioGroup
        function RadioGroupSelectionChanged(app, event)

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    app.Title.Text = 'Emissões desmascaradas';
                    app.Table.Data = app.UnMaskedEmissions(:,1:8);

                    msg = sprintf('%.3f-%.3f, ', ([app.interestBands.FreqStart app.interestBands.FreqStop]/1000)');
                    app.Label.Text = sprintf('Faixa(s) de interesse (MHz): %s; Distância máxima: %.0f km', ...
                                             msg(1:end-2), app.MonitorSettings.MaxDistance);

                case 'Tráfego UDP'
                    app.Title.Text = 'Emissões persistentes rompendo as máscaras espectrais';
                    app.Table.Data = app.PersistentEmissions(:,1:8);
                    app.Label.Text = sprintf('Fluxo(s) de interesse: %s\nQtd. ciclos: %.0f; Threshold 1: %.0f; Threshold 2: %.0f%%; Distância máxima: %.0f km', ...
                                             jsonencode(app.MonitorSettings.ThreadIDList), app.MonitorSettings.BrokenMsg, app.MonitorSettings.THR1, app.MonitorSettings.THR2*100, app.MonitorSettings.MaxDistance);
            end

            app.Qtd_Nodes.Text    = num2str(numel(unique(app.Table.Data.Node)));
            app.Qtd_Unmasked.Text = num2str(height(app.Table.Data));
            app.Qtd_Unknown.Text  = num2str(numel(find(strcmp(app.Table.Data.Description, ''))));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Monitor';
            app.UIFigure.Icon = 'Anatel.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid1_Left = uigridlayout(app.Grid1);
            app.Logo = uiimage(app.Grid1_Left);
            app.Logo.ImageSource = 'Puzzle.png';
            app.Tree = uitree(app.Grid1_Left, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RadioGroup = uibuttongroup(app.Grid1_Left);
            app.RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupSelectionChanged, true);
            app.Option2 = uiradiobutton(app.RadioGroup);
            app.Option2.Text = 'Tráfego UDP';
            app.Option1 = uiradiobutton(app.RadioGroup);
            app.Option1.Text = 'Máscaras espectrais';
            app.Option1.Value = true;
            app.RadioGroupLabel = uilabel(app.Grid1_Left);
            app.RadioGroupLabel.Text = 'Tipo de monitoração:';
            app.TreeLabel = uilabel(app.Grid1_Left);
            app.TreeLabel.Text = 'Sensores:';
            app.Button_Delete = uibutton(app.Grid1_Left, 'push');
            app.Button_Delete.ButtonPushedFcn = createCallbackFcn(app, @Button_DeletePushed, true);
            app.Button_Delete.Icon = 'Check2.png';
            app.Button_Delete.Tooltip = {'Excluir informações coletadas'};
            app.Button_Delete.Text = '';
            app.Separator = uiimage(app.Grid1_Left);
            app.Separator.ImageSource = 'Menu_Line.png';
            app.Button_Map = uibutton(app.Grid1_Left, 'push');
            app.Button_Map.ButtonPushedFcn = createCallbackFcn(app, @Button_MapPushed, true);
            app.Button_Map.Icon = 'Map2.png';
            app.Button_Map.Tooltip = {'Disposição dos sensores em mapa'};
            app.Button_Map.Text = '';
            app.Button_Log = uibutton(app.Grid1_Left, 'push');
            app.Button_Log.ButtonPushedFcn = createCallbackFcn(app, @Button_LogPushed, true);
            app.Button_Log.Icon = 'Log2.png';
            app.Button_Log.Enable = 'off';
            app.Button_Log.Tooltip = {'Log'};
            app.Button_Log.Text = '';
            app.Button_Integra = uibutton(app.Grid1_Left, 'state');
            app.Button_Integra.Tooltip = {'Atualizar planilha Integra'};
            app.Button_Integra.Icon = 'Flow.png';
            app.Button_Integra.Text = '';
            app.Grid1_Right = uigridlayout(app.Grid1);
            app.Title = uilabel(app.Grid1_Right);
            app.Title.Text = 'Emissões desmascaradas';
            app.Subtitle = uilabel(app.Grid1_Right);
            app.Subtitle.Text = 'Consulta';
            app.Panel1 = uipanel(app.Grid1_Right);
            app.Panel1_Grid = uigridlayout(app.Panel1);
            app.Qtd_NodesLabel = uilabel(app.Panel1_Grid);
            app.Qtd_NodesLabel.Text = 'sensores';
            app.Qtd_Nodes = uilabel(app.Panel1_Grid);
            app.Qtd_Nodes.Text = '-';
            app.Panel2 = uipanel(app.Grid1_Right);
            app.Panel2_Grid = uigridlayout(app.Panel2);
            app.Qtd_Unmasked = uilabel(app.Panel2_Grid);
            app.Qtd_Unmasked.Text = '-';
            app.Qtd_UnmaskedLabel = uilabel(app.Panel2_Grid);
            app.Qtd_UnmaskedLabel.Text = 'emissões desmascaradas';
            app.Panel3 = uipanel(app.Grid1_Right);
            app.Panel3_Grid = uigridlayout(app.Panel3);
            app.Qtd_Unknown = uilabel(app.Panel3_Grid);
            app.Qtd_Unknown.Text = '-';
            app.Qtd_UnknownLabel = uilabel(app.Panel3_Grid);
            app.Qtd_UnknownLabel.Text = 'emissões não identificadas';
            app.Table_Grid = uigridlayout(app.Grid1_Right);
            app.Table = uitable(app.Table_Grid);
            app.Table.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.Table.RowName = {};
            app.Table.SelectionType = 'row';
            app.Table_Label1 = uilabel(app.Table_Grid);
            app.Table_Label1.Text = '  UF';
            app.Table_Label2 = uilabel(app.Table_Grid);
            app.Table_Label2.Text = '  Município';
            app.Table_Label3 = uilabel(app.Table_Grid);
            app.Table_Label3.Text = '  Sensor';
            app.Table_Label4 = uilabel(app.Table_Grid);
            app.Table_Label4.Text = {'  Frequência'; '  (MHz)'};
            app.Table_Label5 = uilabel(app.Table_Grid);
            app.Table_Label5.Text = {'  Largura '; '  (kHz)'};
            app.Table_Label6 = uilabel(app.Table_Grid);
            app.Table_Label6.Text = {'  OCC '; '  (%)'};
            app.Table_Label7 = uilabel(app.Table_Grid);
            app.Table_Label7.Text = '  Provável emissor';
            app.Table_Label8 = uilabel(app.Table_Grid);
            app.Table_Label8.Text = '  Data/hora de registro';
            app.SortRows = SortRows(app.Grid1_Right);
            app.SortRows.Items = {'UF', 'Município', 'Sensor', 'Frequência (MHz)', 'Largura (kHz)', 'OCC (%)', 'Provável emissor', 'Data/hora de registro'};
            app.SortRows.Value = 'Data/hora de registro';
            app.SortRows.ImageClickedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            app.SortRows.ValueChangedFcn = createCallbackFcn(app, @SortRowsValueChanged, true);
            app.Button_Start = uibutton(app.Grid1_Right, 'state');
            app.Button_Start.ValueChangedFcn = createCallbackFcn(app, @Button_StartValueChanged, true);
            app.Button_Start.Icon = 'Media_PlayWhite.png';
            app.Button_Start.Text = 'Iniciar';
            app.Label = uilabel(app.Grid1_Right);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta_winMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end