classdef appColeta_winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Grid1               matlab.ui.container.GridLayout
        GridLayout5         matlab.ui.container.GridLayout
        Panel_7             matlab.ui.container.Panel
        GridLayout8         matlab.ui.container.GridLayout
        FatordeintegraoparaaferiodotempodeescritamdioSpinnerLabel  matlab.ui.control.Label
        IntegrationFactor2  matlab.ui.control.Spinner
        FatordeintegraoparaaferiodotraomdioSpinnerLabel  matlab.ui.control.Label
        IntegrationFactor1  matlab.ui.control.Spinner
        InfoLabel_5         matlab.ui.control.Label
        Panel_5             matlab.ui.container.Panel
        GridLayout7         matlab.ui.container.GridLayout
        HistoryDepth        matlab.ui.control.DropDown
        ProfundidadeLabel   matlab.ui.control.Label
        EstiloLabel         matlab.ui.control.Label
        ColorMap            matlab.ui.control.DropDown
        InfoLabel_4         matlab.ui.control.Label
        Panel_3             matlab.ui.container.Panel
        GridLayout6         matlab.ui.container.GridLayout
        FileSize_RT         matlab.ui.control.Spinner
        TamanhomximodearquivosalvonastarefasdotipoRTeLTMBLabel  matlab.ui.control.Label
        FileSize_WPT        matlab.ui.control.Spinner
        TamanhomximodearquivosalvonatarefadotipoWPTMBLabel  matlab.ui.control.Label
        InfoLabel_3         matlab.ui.control.Label
        Info                matlab.ui.control.TextArea
        Button2             matlab.ui.control.Button
        Button4             matlab.ui.control.Button
        VariablesLabel_2    matlab.ui.control.Label
        Panel_4             matlab.ui.container.Panel
        Grid2               matlab.ui.container.GridLayout
        Variables_Label1    matlab.ui.control.Label
        Variables_Label2    matlab.ui.control.Label
        Variables_Label3    matlab.ui.control.Label
        CheckBox6           matlab.ui.control.CheckBox
        CheckBox5           matlab.ui.control.CheckBox
        CheckBox4           matlab.ui.control.CheckBox
        CheckBox3           matlab.ui.control.CheckBox
        CheckBox2           matlab.ui.control.CheckBox
        CheckBox1           matlab.ui.control.CheckBox
        Toolbar3            matlab.ui.control.Button
        GridLayout2         matlab.ui.container.GridLayout
        TipodeunidadedeprocessamentogrficoDropDownLabel  matlab.ui.control.Label
        GPUType             matlab.ui.control.DropDown
        GPUInfo             matlab.ui.control.TextArea
        VariablesLabel      matlab.ui.control.Label
        Button1             matlab.ui.control.Button
        Button3             matlab.ui.control.Button
        InfoLabel_2         matlab.ui.control.Label
        Tree                matlab.ui.container.Tree
        ParentNode          matlab.ui.container.TreeNode
        Node1               matlab.ui.container.TreeNode
        Node2               matlab.ui.container.TreeNode
        Node3               matlab.ui.container.TreeNode
        GridLayout          matlab.ui.container.GridLayout
        InfoLabel           matlab.ui.control.Label
        Warn                matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        
        newVersion
        PublicLinks
        
    end
    
    methods (Access = private)
        
        function Fcn1_VersionInfo(app)
            
            [Version, msgConsole] = Misc_appColetaVersion(app.CallingApp.RootFolder);
            app.Info.Value = sprintf('Informações acerca das versões instaladas dos módulos do appColeta:\n%s\n\n%s', ...
                                      jsonencode(Version, 'PrettyPrint', true), msgConsole);
            
            app.Warn.Visible    = 0;
            app.Button1.Enable  = 1;
            app.Toolbar3.Enable = 0;
            
        end
        
        
        function Fcn2_PublicLinks(app)
            
            fileID = fopen(fullfile(app.CallingApp.RootFolder, 'DataBase', 'PublicLinks.cfg'), 'rt');
            app.PublicLinks = jsondecode(fread(fileID, [1, Inf], '*char'));
            fclose(fileID);            
            
        end
        
        function Fcn3_Graphics(app)
            
            graphRender = opengl('data');
            app.GPUInfo.Value = jsonencode(rmfield(graphRender, 'Extensions'), 'PrettyPrint', true);
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.GPUType.Value = 'Hardware Basic';
                case 'full';  app.GPUType.Value = 'Hardware Full';
                case 'none';  app.GPUType.Value = 'Software';
            end
            app.Button4.Enable = 0;
            
        end       
        
        
        function Fcn4_Startup(app)
            
            app.FileSize_WPT.Value = app.CallingApp.FileSize_WPT ./ 1e+6;
            app.FileSize_RT.Value  = app.CallingApp.FileSize_RT  ./ 1e+6;
            app.ColorMap.Value     = app.CallingApp.ColorMap;
            app.HistoryDepth.Value = num2str(app.CallingApp.HistoryDepth);
            
            app.IntegrationFactor1.Value = app.CallingApp.AverageFactor;
            app.IntegrationFactor2.Value = app.CallingApp.SampleTimeFactor;
            
            app.Button2.Enable = 0;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.CallingApp = mainapp;
            
            if isdeployed
                set(app.Grid2.Children, 'Enable', 0)
            end
            
            expand(app.Tree, 'all')
            app.Tree.SelectedNodes = app.Tree.Children.Children(1);
            TreeSelectionChanged(app)
            
            Fcn1_VersionInfo(app)
            Fcn2_PublicLinks(app)
            Fcn3_Graphics(app)
            Fcn4_Startup(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin = [];
            delete(app)
            
        end

        % Value changed function: CheckBox1, CheckBox2, CheckBox3, 
        % CheckBox6
        function CheckBoxes(app, event)
            
            if app.CheckBox1.Value || app.CheckBox2.Value || app.CheckBox3.Value || app.CheckBox4.Value || ...
               app.CheckBox5.Value || app.CheckBox6.Value
                app.Button3.Enable = 1;
            else
                app.Button3.Enable = 0;
            end
            
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
            try
                FileVersion = jsondecode(webread(app.PublicLinks.FileVersion));
                app.newVersion = struct('appColeta', FileVersion.appColeta.Version, ...
                                        'anateldb',  FileVersion.anateldb.ReleaseDate);
                
                if isequal(app.CallingApp.Version, app.newVersion)
                    app.Warn.Visible = 0;
                    uialert(app.UIFigure, sprintf('Módulos atualizados:\n• appColeta, anateldb'), 'appColeta', 'Icon', 'success')
                    
                else
                    app.Info.Value{end+1} = sprintf('Informações acerca das versões disponíveis dos módulos do appColeta:\n%s\n\n%s\n\n', ...
                                                     jsonencode(app.newVersion, 'PrettyPrint', true));
                                    
                    str1 = {};
                    str2 = {};
                    if strcmp(app.CallingApp.Version.appColeta, app.newVersion.appColeta); str1(end+1) = {'appColeta'};
                    else;                                                                  str2(end+1) = {'appColeta'};
                    end
                    if strcmp(app.CallingApp.Version.anateldb,   app.newVersion.anateldb);   str1(end+1) = {'anateldb'};
                    else;                                                                    str2(end+1) = {'anateldb'};  app.Toolbar3.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) desatualizado(s):\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) desatualizado(s):\n• %s', strjoin(str2, ', '));
                    end                    
                    uialert(app.UIFigure, msg, 'appColeta', 'Icon', 'warning')
                    
                    set(app.Warn, 'Visible', 1, 'Tooltip', strjoin(str2, ', '))
                end
                app.Button1.Enable = 0;
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
            msg = {};
            if app.CallingApp.taskFlag ~= 1
                app.CallingApp.FileSize_WPT = app.FileSize_WPT.Value .* 1e+6;
            else
                msg{end+1} = '- Não é possível alterar o "tamanho máximo arquivo salvo na Tarefa WPT" se estiver em andamento uma Tarefa WFT.';
            end
            
            if app.CallingApp.taskFlag ~= 2
                app.CallingApp.FileSize_RT = app.FileSize_RT.Value .* 1e+6;
            else
                msg{end+1} = '- Não é possível alterar o "tamanho máximo arquivo salvo na Tarefa RT" se estiver em andamento uma Tarefa RT.';
            end
            
            app.CallingApp.ColorMap = app.ColorMap.Value;

            if ~app.CallingApp.plotFlag
                app.CallingApp.HistoryDepth = str2double(app.HistoryDepth.Value);
            else
                colormap(app.CallingApp.axes2, app.ColorMap.Value);
                msg{end+1} = '- Não é possível alterar a "Profundidade do Waterfall" se estiver sendo apresentado o plot de alguma tarefa.';
            end
            
            app.CallingApp.AverageFactor    = app.IntegrationFactor1.Value;
            app.CallingApp.SampleTimeFactor = app.IntegrationFactor2.Value;
            
            if ~isempty(msg)
                uialert(app.UIFigure, ['Ressalvas em relação aos ajustes:' msg], 'appColeta', 'Icon', 'warning')
            end
            
            Fcn4_Startup(app)
            
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            
            if app.CheckBox1.Value
                assignin('base', 'instrInfo', app.CallingApp.instrInfo)
                openvar('instrInfo')
            end
            
            if app.CheckBox2.Value
                assignin('base', 'instrList', app.CallingApp.instrList)
                openvar('instrList')
            end
            
            if app.CheckBox3.Value
                assignin('base', 'taskList', app.CallingApp.taskList)
                openvar('taskList')
            end
            
            if app.CheckBox4.Value
                assignin('base', 'specInfo', app.CallingApp.specInfo)
                openvar('specInfo')
            end
            
            if app.CheckBox5.Value
                assignin('base', 'specMatrix', app.CallingApp.specMatrix)
                openvar('specMatrix')
            end
            
            if app.CheckBox6.Value
                assignin('base', 'bandList', app.CallingApp.bandList)
                openvar('bandList')
            end
            
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            
            switch app.GPUType.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end
            
            Fcn3_Graphics(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            NodeData = app.Tree.SelectedNodes.NodeData;
            
            if ~isempty(NodeData)
                switch NodeData
                    case 1; app.Grid1.ColumnWidth = {256, 10, 24,'1x', 110, 110,    0,   0,    0,   0};
                    case 2; app.Grid1.ColumnWidth = {256, 10,  0,   0,   0,   0, '1x', 110,    0,   0};
                    case 3; app.Grid1.ColumnWidth = {256, 10,  0,   0,   0,   0,    0,   0, '1x', 110};
                end
                
            else
                app.Tree.SelectedNodes = app.Tree.Children.Children(1);
                TreeSelectionChanged(app)
            end
            
        end

        % Value changed function: GPUType
        function GPUNodeValueChanged(app, event)
            
            app.Button4.Enable = 1;
            
        end

        % Button pushed function: Toolbar3
        function Toolbar3ButtonPushed(app, event)
            
            try
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Tentando atualizar a base de dados offline da Agência...');
                
                websave(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.xlsx'), app.PublicLinks.AnatelDB);
                
                delete(fullfile(app.CallingApp.RootFolder, 'DataBase', 'AnatelDB.mat'))
                clear global AnatelDB
                clear global AnatelDB_ExtractDate
                drawnow nocallbacks
                
                Misc_DataBaseRead(app.CallingApp.RootFolder)
                
                global AnatelDB_ExtractDate
                app.CallingApp.Version.anateldb = AnatelDB_ExtractDate;
                
                Fcn1_VersionInfo(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end
            
        end

        % Value changed function: ColorMap, FileSize_RT, 
        % FileSize_WPT, HistoryDepth, IntegrationFactor1, 
        % IntegrationFactor2
        function StartupNodeValueChanged(app, event)
            
            app.Button2.Enable = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.GridLayout = uigridlayout(app.Grid1);
            app.Warn = uiimage(app.GridLayout);
            app.Warn.ImageSource = 'Alarm2.png';
            app.InfoLabel = uilabel(app.GridLayout);
            app.InfoLabel.Text = 'Geral';
            app.Tree = uitree(app.Grid1);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ParentNode = uitreenode(app.Tree);
            app.ParentNode.Text = 'appColeta';
            app.Node1 = uitreenode(app.ParentNode);
            app.Node1.NodeData = 1;
            app.Node1.Text = 'Geral';
            app.Node2 = uitreenode(app.ParentNode);
            app.Node2.NodeData = 2;
            app.Node2.Text = 'Variáveis';
            app.Node3 = uitreenode(app.ParentNode);
            app.Node3.NodeData = 3;
            app.Node3.Text = 'Unidade gráfica';
            app.InfoLabel_2 = uilabel(app.Grid1);
            app.InfoLabel_2.Text = 'Tipo';
            app.Button3 = uibutton(app.Grid1, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Icon = 'White_Play.png';
            app.Button3.Enable = 'off';
            app.Button3.Text = 'Explorar dados';
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Icon = 'White_Reload.png';
            app.Button1.Text = 'Verificar versões';
            app.VariablesLabel = uilabel(app.Grid1);
            app.VariablesLabel.Text = 'Variáveis';
            app.GridLayout2 = uigridlayout(app.Grid1);
            app.GPUInfo = uitextarea(app.GridLayout2);
            app.GPUType = uidropdown(app.GridLayout2);
            app.GPUType.Items = {'Software', 'Hardware Full', 'Hardware Basic'};
            app.GPUType.ValueChangedFcn = createCallbackFcn(app, @GPUNodeValueChanged, true);
            app.GPUType.Value = 'Hardware Basic';
            app.TipodeunidadedeprocessamentogrficoDropDownLabel = uilabel(app.GridLayout2);
            app.TipodeunidadedeprocessamentogrficoDropDownLabel.Text = 'Tipo de unidade de processamento gráfico:';
            app.Toolbar3 = uibutton(app.Grid1, 'push');
            app.Toolbar3.ButtonPushedFcn = createCallbackFcn(app, @Toolbar3ButtonPushed, true);
            app.Toolbar3.Icon = 'Settings_Sheet.png';
            app.Toolbar3.Enable = 'off';
            app.Toolbar3.Tooltip = {'Atualizar base de dados offline'};
            app.Toolbar3.Text = '';
            app.Panel_4 = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel_4);
            app.CheckBox1 = uicheckbox(app.Grid2);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox1.Text = 'instrInfo';
            app.CheckBox2 = uicheckbox(app.Grid2);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox2.Text = 'instrList';
            app.CheckBox3 = uicheckbox(app.Grid2);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox3.Text = 'taskList';
            app.CheckBox4 = uicheckbox(app.Grid2);
            app.CheckBox4.Text = 'specInfo';
            app.CheckBox5 = uicheckbox(app.Grid2);
            app.CheckBox5.Text = 'specMatrix';
            app.CheckBox6 = uicheckbox(app.Grid2);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxes, true);
            app.CheckBox6.Text = 'bandList';
            app.Variables_Label3 = uilabel(app.Grid2);
            app.Variables_Label3.Text = 'Relacionadas ao modo "Tarefa LT (Logger)":';
            app.Variables_Label2 = uilabel(app.Grid2);
            app.Variables_Label2.Text = 'Relacionadas ao modo "Tarefas WPT e RT (SCPI)":';
            app.Variables_Label1 = uilabel(app.Grid2);
            app.Variables_Label1.Text = 'Relacionadas ao modo "Conectividade":';
            app.VariablesLabel_2 = uilabel(app.Grid1);
            app.VariablesLabel_2.Text = 'Unidade gráfica';
            app.Button4 = uibutton(app.Grid1, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Icon = 'White_Edit.png';
            app.Button4.Enable = 'off';
            app.Button4.Text = 'Alterar';
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Icon = 'White_Edit.png';
            app.Button2.Enable = 'off';
            app.Button2.Text = 'Alterar';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.Info = uitextarea(app.GridLayout5);
            app.InfoLabel_3 = uilabel(app.GridLayout5);
            app.InfoLabel_3.Text = 'Arquivo';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel_3);
            app.TamanhomximodearquivosalvonatarefadotipoWPTMBLabel = uilabel(app.GridLayout6);
            app.TamanhomximodearquivosalvonatarefadotipoWPTMBLabel.Text = 'Tamanho máximo de arquivo salvo na tarefa do tipo WPT (MB):';
            app.FileSize_WPT = uispinner(app.GridLayout6);
            app.FileSize_WPT.Step = 10;
            app.FileSize_WPT.Limits = [1 100];
            app.FileSize_WPT.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.FileSize_WPT.Value = 10;
            app.TamanhomximodearquivosalvonastarefasdotipoRTeLTMBLabel = uilabel(app.GridLayout6);
            app.TamanhomximodearquivosalvonastarefasdotipoRTeLTMBLabel.Text = 'Tamanho máximo de arquivo salvo nas tarefas do tipo RT e LT (MB):';
            app.FileSize_RT = uispinner(app.GridLayout6);
            app.FileSize_RT.Step = 50;
            app.FileSize_RT.Limits = [100 500];
            app.FileSize_RT.RoundFractionalValues = 'on';
            app.FileSize_RT.ValueDisplayFormat = '%.0f';
            app.FileSize_RT.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.FileSize_RT.Value = 100;
            app.InfoLabel_4 = uilabel(app.GridLayout5);
            app.InfoLabel_4.Text = 'Waterfall';
            app.Panel_5 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_5);
            app.ColorMap = uidropdown(app.GridLayout7);
            app.ColorMap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.ColorMap.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.ColorMap.Value = 'jet';
            app.EstiloLabel = uilabel(app.GridLayout7);
            app.EstiloLabel.Text = 'Estilo:';
            app.ProfundidadeLabel = uilabel(app.GridLayout7);
            app.ProfundidadeLabel.Text = 'Profundidade:';
            app.HistoryDepth = uidropdown(app.GridLayout7);
            app.HistoryDepth.Items = {'64', '128', '256', '512'};
            app.HistoryDepth.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.HistoryDepth.Value = '128';
            app.InfoLabel_5 = uilabel(app.GridLayout5);
            app.InfoLabel_5.Text = 'Fator de integração';
            app.Panel_7 = uipanel(app.GridLayout5);
            app.GridLayout8 = uigridlayout(app.Panel_7);
            app.IntegrationFactor1 = uispinner(app.GridLayout8);
            app.IntegrationFactor1.Limits = [2 100];
            app.IntegrationFactor1.RoundFractionalValues = 'on';
            app.IntegrationFactor1.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor1.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.IntegrationFactor1.Value = 10;
            app.FatordeintegraoparaaferiodotraomdioSpinnerLabel = uilabel(app.GridLayout8);
            app.FatordeintegraoparaaferiodotraomdioSpinnerLabel.Text = 'Fator de integração para aferição do traço médio:';
            app.IntegrationFactor2 = uispinner(app.GridLayout8);
            app.IntegrationFactor2.Limits = [2 100];
            app.IntegrationFactor2.RoundFractionalValues = 'on';
            app.IntegrationFactor2.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor2.ValueChangedFcn = createCallbackFcn(app, @StartupNodeValueChanged, true);
            app.IntegrationFactor2.Value = 20;
            app.FatordeintegraoparaaferiodotempodeescritamdioSpinnerLabel = uilabel(app.GridLayout8);
            app.FatordeintegraoparaaferiodotempodeescritamdioSpinnerLabel.Text = 'Fator de integração para aferição do tempo de escrita médio:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appColeta_winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end