classdef winAnatelDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid               matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        Version_Panel      matlab.ui.container.Panel
        Version_Grid       matlab.ui.container.GridLayout
        Version            matlab.ui.control.Label
        tool_Grid          matlab.ui.container.GridLayout
        Button_3           matlab.ui.control.Button
        GridLayout         matlab.ui.container.GridLayout
        SortRows_DropDown  matlab.ui.control.DropDown
        SortRows_Image     matlab.ui.control.Image
        Image2             matlab.ui.control.Image
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        Sumarry_Panel      matlab.ui.container.Panel
        Summary_Grid       matlab.ui.container.GridLayout
        Summary            matlab.ui.control.Label
        SummaryLabel       matlab.ui.control.Label
        table_Grid         matlab.ui.container.GridLayout
        Column10           matlab.ui.control.Label
        Column9            matlab.ui.control.Label
        Column8            matlab.ui.control.Label
        Column7            matlab.ui.control.Label
        Column6            matlab.ui.control.Label
        Column5            matlab.ui.control.Label
        Column4            matlab.ui.control.Label
        Column3            matlab.ui.control.Label
        Column2            matlab.ui.control.Label
        Column1            matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        filter_Panel       matlab.ui.container.Panel
        filter_Grid        matlab.ui.container.GridLayout
        Location_Grid      matlab.ui.container.GridLayout
        LocationRefresh    matlab.ui.control.Image
        Location           matlab.ui.control.DropDown
        LocationLabel      matlab.ui.control.Label
        BaseMapLabel       matlab.ui.control.Label
        BaseMap            matlab.ui.control.DropDown
        AddFilter_Grid     matlab.ui.container.GridLayout
        AddFilter          matlab.ui.control.Image
        Value3             matlab.ui.control.NumericEditField
        Value2             matlab.ui.control.NumericEditField
        Hifen              matlab.ui.control.Label
        Value1             matlab.ui.control.EditField
        Tree               matlab.ui.container.Tree
        Family             matlab.ui.control.DropDown
        FamilyLabel        matlab.ui.control.Label
        TypeLabel          matlab.ui.control.Label
        Type               matlab.ui.control.DropDown
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        ContextMenu        matlab.ui.container.ContextMenu
        Delete             matlab.ui.container.Menu
        DeleteAll          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        UIAxes1
        Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
        nodeTable
        
    end
    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            app.UIAxes1 = geoaxes(app.Grid, 'FontSize', 6, 'Units', 'pixels', 'Basemap', app.BaseMap.Value);

            while true
                try
                    app.UIAxes1.Layout.Row    = [1,3];
                    app.UIAxes1.Layout.Column = 3;
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';                    

                    break
                catch
                end
            end

            app.UIAxes2.Interactions = dataTipInteraction;
            app.UIAxes3.Interactions = dataTipInteraction;
            
            enableDefaultInteractivity(app.UIAxes2)
            enableDefaultInteractivity(app.UIAxes3)

        end


        function Fcn1_NodeList(app)

            app.nodeTable = table('Size', [0, 3],                                ...
                                  'VariableTypes', {'double', 'double', 'cell'}, ...
                                  'VariableNames', {'lat', 'long', 'location'});

            app.Location.Items = {};
            % appAnálise
%             specData  = app.CallingApp.specData;
%             for ii = 1:numel(specData)
%                 if specData(ii).gps.Status
%                     msg = sprintf('%.6f, %.6f (%s)', specData(ii).gps.Latitude, ...
%                                                      specData(ii).gps.Longitude, ...
%                                                      specData(ii).gps.Location);
% 
%                     if isempty(app.Location.Items) | ~contains(app.Location.Items, msg)
%                         app.Location.Items{end+1} = msg;
%                         app.nodeTable(end+1,:) = {specData(ii).gps.Latitude, specData(ii).gps.Longitude, specData(ii).gps.Location};
%                     end
%                 end
%             end

            % appColeta
            gpsInfo = struct('Status', {}, 'Latitude', {}, 'Longitude', {}, 'Location', {});
            
            gpsTemp = app.CallingApp.gpsInfo;
            if isstruct(gpsTemp) && gpsTemp.Status
                gpsInfo(end+1) = struct('Status', gpsTemp.Status, 'Latitude', gpsTemp.Latitude, 'Longitude', gpsTemp.Longitude, 'Location', geo_FindCity(gpsTemp));
            end

            gpsTemp = app.CallingApp.Misc_winAux('winAnatelDB');
            if isstruct(gpsTemp) && gpsTemp.Status
                gpsInfo(end+1) = struct('Status', gpsTemp.Status, 'Latitude', gpsTemp.Latitude, 'Longitude', gpsTemp.Longitude, 'Location', geo_FindCity(gpsTemp));
            end

            for ii = 1:numel(gpsInfo)
                msg = sprintf('%.6f, %.6f (%s)', gpsInfo(ii).Latitude, ...
                                                 gpsInfo(ii).Longitude, ...
                                                 gpsInfo(ii).Location);
    
                if isempty(app.Location.Items) | ~contains(app.Location.Items, msg)
                    app.Location.Items{end+1} = msg;
                    app.nodeTable(end+1,:) = {gpsInfo(ii).Latitude, gpsInfo(ii).Longitude, gpsInfo(ii).Location};
                end
            end

        end


        function Fcn2_TreeBuilding(app)

            if ~isempty(app.Tree.Children)
                delete(app.Tree.Children)
            end

            Parameters_table = struct2table(app.Parameters);
            FamilyItems      = {};
            if ischar(Parameters_table.Family)
                FamilyItems = {Parameters_table.Family};
            elseif iscell(Parameters_table.Family)
                FamilyItems = unique(Parameters_table.Family);
            end

            for ii = 1:numel(FamilyItems)
                idx1 = find(Parameters_table.Family == string(FamilyItems{ii}));
                
                NodeTree = uitreenode(app.Tree, 'Text', upper(FamilyItems{ii}), ...
                                                'NodeData', idx1, 'ContextMenu', app.ContextMenu);
                
                for jj = idx1'
                    if isnumeric(app.Parameters(jj).Value)
                        if numel(app.Parameters(jj).Value) == 1; Value = sprintf('%g', app.Parameters(jj).Value);
                        else;                                    Value = sprintf('%g e %g', app.Parameters(jj).Value(1), app.Parameters(jj).Value(2));
                        end
                    else
                        Value = sprintf('"%s"', app.Parameters(jj).Value);
                    end

                    uitreenode(NodeTree, 'Text', sprintf('%s %s', app.Parameters(jj).Type(1:end-3), Value), ...
                                         'NodeData', jj, 'ContextMenu', app.ContextMenu);
                end
            end

            expand(app.Tree, 'all')

        end


        function Fcn2_AnatelDB(app)

            global AnatelDB

            anatelDB = AnatelDB;

            anatelDB.Frequency = double(anatelDB.Frequency);
            anatelDB.Distance  = -1*ones(height(anatelDB), 1);

            if ~isempty(app.Tree.Children)
                Parameters_table = struct2table(app.Parameters);
                Tolerance = 1e-5;
                kk = 0;
                idx2 = zeros(height(anatelDB), 4, 'logical');

                % Frequência
                idx1 = find(Parameters_table.Family == "Frequência");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | (abs(anatelDB.Frequency-app.Parameters(ii).Value) <= Tolerance);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Frequency >= app.Parameters(ii).Value(1) - Tolerance) & (anatelDB.Frequency <= app.Parameters(ii).Value(2) + Tolerance));
                        end
                    end
                end

                % Descrição
                idx1 = find(Parameters_table.Family == "Descrição");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        idx2(:,kk) = idx2(:,kk) | contains(string(anatelDB.Description), app.Parameters(ii).Value);
                    end
                end

                % Serviço
                idx1 = find(Parameters_table.Family == "Serviço");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Service == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Service >= int16(app.Parameters(ii).Value(1))) & (anatelDB.Service <= int16(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                % Estação
                idx1 = find(Parameters_table.Family == "Estação");
                if ~isempty(idx1)
                    kk=kk+1;

                    for ii = idx1'
                        switch app.Parameters(ii).Type
                            case 'É igual a...'
                                idx2(:,kk) = idx2(:,kk) | anatelDB.Station == int16(app.Parameters(ii).Value);
    
                            case 'Está entre...'
                                idx2(:,kk) = idx2(:,kk) | ((anatelDB.Station >= int32(app.Parameters(ii).Value(1))) & (anatelDB.Station <= int32(app.Parameters(ii).Value(2))));
                        end
                    end
                end

                idx3 = all(idx2(:,1:kk), 2);
                anatelDB(~idx3,:) = [];
                
                % Distância
                if ~isempty(app.Location.Value)
                    if height(anatelDB) & (height(anatelDB) <= 10000)
                        Node = regexp(app.Location.Value, '(?<lat>[-]\d+[.]{1}\d{6}), (?<lon>[-]\d+[.]{1}\d{6}) .*', 'names');
                        Node.lat = str2double(Node.lat);
                        Node.lon = str2double(Node.lon);

                        anatelDB.Distance = double(round(geo_lldistkm_v2(Node, anatelDB(:, 3:4))));
    
                        idx1 = find(Parameters_table.Family == "Distância");                        
                        if ~isempty(idx1)
                            idx2 = zeros(height(anatelDB), 1, 'logical');

                            for ii = idx1'
                                idx2 = idx2 | (anatelDB.Distance <= app.Parameters(ii).Value + Tolerance);
                            end

                            anatelDB(~idx2,:) = [];
                        end                        
                    end
                end
            end            

            [SortRows_idx, SortRows_direction] = Fcn4_SortRows(app);
            app.UITable.Data = sortrows(anatelDB, [SortRows_idx, 2], SortRows_direction);
            app.UITable.Selection = [];

            scroll(app.UITable, 'top')
            UITableSelectionChanged(app)

            app.Summary.Text = {sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> REGISTROS</font>', height(anatelDB));                ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> ESTAÇÕES</font>',  numel(unique(anatelDB.Station))); ...
                                sprintf('<b style="color: #000000; font-size: 16;">%d</b><font> SERVIÇOS</font>',  numel(unique(anatelDB.Service)))};

            Fcn3_Plot(app)
            Fcn3_PlotNodes(app)

        end


        function Fcn2_AnatelDB_info(app)

            global AnatelDB_info

            app.Version.Text = {'<b style="color: #000000; font-size: 14;">anateldb </b>'; ...
                                sprintf('<font> v. %s </font>', AnatelDB_info.Version);    ...
                                sprintf('%s ', extractBefore(AnatelDB_info.ReleaseDate, ' '))};

        end
        
        
        function Fcn3_Plot(app)

            cla(app.UIAxes1)
            hold(app.UIAxes1, "on")            
            p1 = geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude);
            hold(app.UIAxes1, "off")

            p2 = histogram(app.UIAxes2, app.UITable.Data.Frequency);
            p3 = histogram(app.UIAxes3, categorical(app.UITable.Data.Service));
            
            while true
                try
                    app.UIAxes2.Children.EdgeColor = 'none';
                    app.UIAxes2.Children.FaceColor = [0.38,0.60,0.73];

                    app.UIAxes3.Children.EdgeColor = 'none';
                    app.UIAxes3.Children.FaceColor = [0.38,0.60,0.73];

                    break
                catch
                end
            end

            % DataTip            
            if height(app.UITable.Data)
                set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
                p1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('', app.UITable.Data.Frequency, '%.3f MHz');
                p1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('', app.UITable.Data.Distance, '%.0f km');
                if height(app.UITable.Data) == 1
                    p1.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', {app.UITable.Data.Id});               
                    p1.DataTipTemplate.DataTipRows(4) = dataTipTextRow('', {app.UITable.Data.Description});                
                elseif height(app.UITable.Data) > 1
                    p1.DataTipTemplate.DataTipRows(3) = dataTipTextRow('ID:', app.UITable.Data.Id);
                    p1.DataTipTemplate.DataTipRows(4) = dataTipTextRow('', app.UITable.Data.Description);
                end
                p1.DataTipTemplate.DataTipRows = p1.DataTipTemplate.DataTipRows([3,1,4,2]);

                set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
                p2.DataTipTemplate.DataTipRows = flip(p2.DataTipTemplate.DataTipRows);
                p2.DataTipTemplate.DataTipRows(1).Label = 'Banda (MHz):';
                p2.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
    
                set(p3.DataTipTemplate, FontName='Calibri', FontSize=10)
                p3.DataTipTemplate.DataTipRows = flip(p3.DataTipTemplate.DataTipRows);
                p3.DataTipTemplate.DataTipRows(1).Label = 'Serviço:';
                p3.DataTipTemplate.DataTipRows(2).Label = 'Registros:';
            end

        end


        function Fcn3_PlotNodes(app)

            if ~isempty(app.nodeTable)
                hold(app.UIAxes1, 'on')

                if height(app.nodeTable) > 1
                    p1 = geoscatter(app.UIAxes1, app.nodeTable.lat, app.nodeTable.long, 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'Tag', 'OthersNodes');
                    
                    set(p1.DataTipTemplate, FontName='Calibri', FontSize=10)
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Lon:';
                end

                idx = find(strcmp(app.Location.Items, app.Location.Value), 1);
                p2 = geoscatter(app.UIAxes1, app.nodeTable.lat(idx), app.nodeTable.long(idx), 'Marker', '^', 'MarkerEdgeColor', '#A2142F', 'MarkerFaceColor', '#A2142F', 'Tag', 'SelectedNode');
                
                set(p2.DataTipTemplate, FontName='Calibri', FontSize=10)
                p2.DataTipTemplate.DataTipRows(1).Label = 'Lat:';
                p2.DataTipTemplate.DataTipRows(2).Label = 'Lon:';

                hold(app.UIAxes1, 'off')
            end

        end


        function [idx, direction] = Fcn4_SortRows(app)

            switch app.SortRows_DropDown.Value
                case 'Frequência';       idx =  2;
                case 'Serviço';          idx =  6;
                case 'Estação';          idx =  7;
                case 'Largura de banda'; idx =  9;
                case 'Distância';        idx = 10;
            end

            switch app.SortRows_Image.ImageSource
                case 'LT_SortRows_ascend.png'; direction = 'ascend';
                otherwise;                     direction = 'descend';
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end
            
            Fcn1_AxesCreation(app)
            Fcn1_NodeList(app)
            
            Fcn2_AnatelDB(app)
            Fcn2_AnatelDB_info(app)
            
            FamilyValueChanged(app)            

        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin8 = [];
            delete(app)
            
        end

        % Button pushed function: Button
        function Button_info(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winAnatelDB>', '</winAnatelDB>')));

            MessageBox(app, 'info', msg)

        end

        % Image clicked function: AddFilter
        function AddFilterClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            try
                switch app.Family.Value
                    case {'Frequência', 'Distância'}
                        switch app.Type.Value
                            case {'É igual a...', 'É igual ou inferior a...'}
                                if app.Value2.Value <= 0
                                    error('O valor deve ser maior do que zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value <= 0) || (app.Value3.Value <= 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser maiores do que zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end

                    case {'Serviço', 'Estação'}
                        switch app.Type.Value
                            case 'É igual a...'
                                if app.Value2.Value == 0
                                    error('O valor deve ser diferente de zero.')
                                end
                                app.Parameters(end+1).Value = app.Value2.Value;
            
                            case 'Está entre...'
                                if (app.Value2.Value == 0) || (app.Value3.Value == 0) || (app.Value2.Value >= app.Value3.Value)
                                    error('Os valores devem ser diferentes de zero e crescentes.')
                                end            
                                app.Parameters(end+1).Value = [app.Value2.Value, app.Value3.Value];
                        end
                    
                    case 'Descrição'
                        if isempty(deblank(app.Value1.Value))
                            error('O campo textual não pode estar vazio.')
                        end
                        app.Parameters(end+1).Value = app.Value1.Value;
                end
    
                app.Parameters(end).Family = app.Family.Value;
                app.Parameters(end).Type   = app.Type.Value;
                
                Fcn2_TreeBuilding(app)
                Fcn2_AnatelDB(app)

            catch ME
                MessageBox(app, 'warning', ME.message)
            end

            delete(d)

        end

        % Value changed function: Family
        function FamilyValueChanged(app, event)
            
            switch app.Family.Value
                case 'Frequência'
                    app.Type.Items = {'É igual a...', 'Está entre...'};
                    set(app.Value2, 'RoundFractionalValues', 0, 'ValueDisplayFormat', '%.3f')
                    set(app.Value3, 'RoundFractionalValues', 0, 'ValueDisplayFormat', '%.3f')

                case 'Descrição'
                    app.Type.Items = {'Contém...'};

                case {'Serviço', 'Estação'}
                    app.Type.Items = {'É igual a...', 'Está entre...'};
                    set(app.Value2, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
                    set(app.Value3, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')

                case 'Distância'
                    app.Type.Items = {'É igual ou inferior a...'};
                    set(app.Value2, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
                    set(app.Value3, 'RoundFractionalValues', 1, 'ValueDisplayFormat', '%.0f')
            end

            TypeValueChanged(app)

        end

        % Value changed function: Type
        function TypeValueChanged(app, event)
            
            switch app.Type.Value
                case {'É igual a...', 'É igual ou inferior a...'}
                    app.filter_Grid.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 0;

                case 'Está entre...'
                    app.filter_Grid.RowHeight(7:8) = {0, 22};
                    app.Value1.Enable = 0;
                    app.Value2.Enable = 1;
                    app.Value3.Enable = 1;

                case 'Contém...'
                    app.filter_Grid.RowHeight(7:8) = {22, 0};
                    app.Value1.Enable = 1;
                    app.Value2.Enable = 0;
                    app.Value3.Enable = 0;
            end
            
        end

        % Menu selected function: Delete, DeleteAll
        function DeleteFilterClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            switch event.Source
                case app.Delete
                    if isempty(app.Tree.SelectedNodes)
                        return
                    end

                    idx = [app.Tree.SelectedNodes.NodeData];
                    app.Parameters(idx) = [];

                case app.DeleteAll
                    app.Parameters = struct('Family', {}, 'Type', {}, 'Value', {});
            end

            Fcn2_TreeBuilding(app)
            Fcn2_AnatelDB(app)

            delete(d)

        end

        % Value changed function: Location
        function LocationValueChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");
            Fcn2_AnatelDB(app)
            delete(d)

        end

        % Value changed function: BaseMap
        function BaseMapValueChanged(app, event)
            
            app.UIAxes1.Basemap = app.BaseMap.Value;

        end

        % Image clicked function: LocationRefresh
        function LocationRefreshClicked(app, event)
            
            InitialValue = app.Location.Value;
            Fcn1_NodeList(app)

            if ~isempty(InitialValue)
                idx = find(strcmp(app.Location.Items, InitialValue), 1);
                if ~isempty(idx)
                    app.Location.Value = InitialValue;
                    LocationValueChanged(app)
                else
                    LocationValueChanged(app)
                end

            else
                if ~isempty(app.Location.Value)
                    LocationValueChanged(app)
                end
            end
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            if app.Grid.ColumnWidth{1} == 259
                app.Image.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.Image.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 259;
            end

        end

        % Callback function: SortRows_DropDown, SortRows_Image
        function SortRowsCallback(app, event)
            
            switch event.Source
                case app.SortRows_DropDown
                    [idx, direction] = Fcn4_SortRows(app);
                    app.UITable.Data = sortrows(app.UITable.Data, idx, direction);
                    app.UITable.Selection = [];
                    
                    scroll(app.UITable, 'top')
                    UITableSelectionChanged(app)
                
                case app.SortRows_Image
                    if app.SortRows_Image.ImageSource == "LT_SortRows_ascend.png"; app.SortRows_Image.ImageSource = "LT_SortRows_descend.png";
                    else;                                                          app.SortRows_Image.ImageSource = "LT_SortRows_ascend.png";
                    end

                    SortRowsCallback(app, struct('Source', app.SortRows_DropDown))
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            if ~isempty(app.UITable.Selection)
                idx = app.UITable.Selection;
                clipboard('copy', app.UITable.Data.Id{idx})

                MessageBox(app, 'info', sprintf('Copiado para <i>clipboard</i> o ID do registro selecionado: "%s"', app.UITable.Data.Id{idx}))
            end

        end

        % Callback function: UITable
        function UITableSelectionChanged(app, event)

            if ~isempty(app.UITable.Selection)
                app.Button_2.Enable = 1;
            else
                app.Button_2.Enable = 0;
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
            global AnatelDB
            global AnatelDB_info

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "Em andamento...", "Interpreter", "html");

            userPath = app.CallingApp.menu_userPath.Value;
            tag1 = '';
            if height(AnatelDB) ~= height(app.UITable.Data); tag1 = ' (F)';
            end
            
            kk = 0;
            while true
                try
                    if kk == 0; tag2 = '';
                    else;       tag2 = sprintf(' (%d)', kk);
                    end

                    writetable(app.UITable.Data, fullfile(userPath, sprintf('anateldb v. %s%s%s.xlsx', AnatelDB_info.Version, tag1, tag2)), 'WriteMode', 'overwritesheet')
                    break
                catch
                    kk = kk+1;
                end
            end

            delete(d)
            MessageBox(app, 'info', sprintf('Salvo arquivo "<b>%s</b>" na pasta de trabalho.', sprintf('anateldb v. %s %s%s.xlsx', AnatelDB_info.Version, tag1, tag2)))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.Grid);
            xlabel(app.UIAxes3, 'Serviço')
            ylabel(app.UIAxes3, 'Registros')
            app.UIAxes3.YScale = 'log';
            app.UIAxes2 = uiaxes(app.Grid);
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Registros')
            app.UIAxes2.YScale = 'log';
            app.filter_Panel = uipanel(app.Grid);
            app.filter_Panel.Title = 'CONFIGURAÇÕES';
            app.filter_Grid = uigridlayout(app.filter_Panel);
            app.Type = uidropdown(app.filter_Grid);
            app.Type.Items = {'É igual a...', 'Está entre...', 'Contém...'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.Type.Value = 'É igual a...';
            app.TypeLabel = uilabel(app.filter_Grid);
            app.TypeLabel.Text = 'Tipo:';
            app.FamilyLabel = uilabel(app.filter_Grid);
            app.FamilyLabel.Text = 'Filtro:';
            app.Family = uidropdown(app.filter_Grid);
            app.Family.Items = {'Frequência', 'Descrição', 'Serviço', 'Estação', 'Distância'};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @FamilyValueChanged, true);
            app.Family.Value = 'Frequência';
            app.Tree = uitree(app.filter_Grid);
            app.Tree.Multiselect = 'on';
            app.Value1 = uieditfield(app.filter_Grid, 'text');
            app.Hifen = uilabel(app.filter_Grid);
            app.Hifen.Text = '-';
            app.Value2 = uieditfield(app.filter_Grid, 'numeric');
            app.Value2.Limits = [-1 Inf];
            app.Value2.Value = -1;
            app.Value3 = uieditfield(app.filter_Grid, 'numeric');
            app.Value3.Limits = [-1 Inf];
            app.Value3.Value = -1;
            app.AddFilter_Grid = uigridlayout(app.filter_Grid);
            app.AddFilter = uiimage(app.AddFilter_Grid);
            app.AddFilter.ImageClickedFcn = createCallbackFcn(app, @AddFilterClicked, true);
            app.AddFilter.Tooltip = {'Adicionar filtro'};
            app.AddFilter.ImageSource = 'LT_Add.png';
            app.BaseMap = uidropdown(app.filter_Grid);
            app.BaseMap.Items = {'streets-light', 'satellite', 'topographic', 'darkwater'};
            app.BaseMap.ValueChangedFcn = createCallbackFcn(app, @BaseMapValueChanged, true);
            app.BaseMap.Value = 'darkwater';
            app.BaseMapLabel = uilabel(app.filter_Grid);
            app.BaseMapLabel.Interpreter = 'html';
            app.BaseMapLabel.Text = {'<b style="color: #262626; font-size: 10;">Mapa base:</b>'; '(requer conexão à <i>internet</i>, exceto "darkwater")'};
            app.Location_Grid = uigridlayout(app.filter_Grid);
            app.LocationLabel = uilabel(app.Location_Grid);
            app.LocationLabel.Text = 'Local da monitoração:';
            app.Location = uidropdown(app.Location_Grid);
            app.Location.Items = {};
            app.Location.ValueChangedFcn = createCallbackFcn(app, @LocationValueChanged, true);
            app.Location.Value = {};
            app.LocationRefresh = uiimage(app.Location_Grid);
            app.LocationRefresh.ImageClickedFcn = createCallbackFcn(app, @LocationRefreshClicked, true);
            app.LocationRefresh.ImageSource = 'LT_refresh.png';
            app.table_Grid = uigridlayout(app.Grid);
            app.table_Grid.Tag = 'COLORLOCKED';
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false true true false false true];
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Frequência'; '  (MHz)'};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'  Latitude'; '  '};
            app.Column4 = uilabel(app.table_Grid);
            app.Column4.Text = {'  Longitude'; '  '};
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Descrição'; '  '};
            app.Column6 = uilabel(app.table_Grid);
            app.Column6.Text = {'  Serviço'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Estação'; '  '};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Classe'; '  designação'};
            app.Column9 = uilabel(app.table_Grid);
            app.Column9.Text = {'  Largura de'; '  banda (kHz)'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Distância'; '  (km)'};
            app.Sumarry_Panel = uipanel(app.Grid);
            app.Summary_Grid = uigridlayout(app.Sumarry_Panel);
            app.Summary_Grid.Tag = 'COLORLOCKED';
            app.SummaryLabel = uilabel(app.Summary_Grid);
            app.SummaryLabel.Text = 'SUMÁRIO';
            app.Summary = uilabel(app.Summary_Grid);
            app.Summary.Interpreter = 'html';
            app.Summary.Text = '';
            app.tool_Grid = uigridlayout(app.Grid);
            app.Button = uibutton(app.tool_Grid, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_info, true);
            app.Button.Icon = 'LT_info.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.tool_Grid, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'LT_copy.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Tooltip = {'Clipboard'};
            app.Button_2.Text = '';
            app.Image2 = uiimage(app.tool_Grid);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'LT_LineV.png';
            app.GridLayout = uigridlayout(app.tool_Grid);
            app.SortRows_Image = uiimage(app.GridLayout);
            app.SortRows_Image.ImageClickedFcn = createCallbackFcn(app, @SortRowsCallback, true);
            app.SortRows_Image.ImageSource = 'LT_SortRows_ascend.png';
            app.SortRows_DropDown = uidropdown(app.GridLayout);
            app.SortRows_DropDown.Items = {'Frequência', 'Serviço', 'Estação', 'Largura de banda', 'Distância'};
            app.SortRows_DropDown.ValueChangedFcn = createCallbackFcn(app, @SortRowsCallback, true);
            app.SortRows_DropDown.Value = 'Frequência';
            app.Button_3 = uibutton(app.tool_Grid, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'LT_save.png';
            app.Button_3.Tooltip = {'Exportar tabela'};
            app.Button_3.Text = '';
            app.Version_Panel = uipanel(app.Grid);
            app.Version_Grid = uigridlayout(app.Version_Panel);
            app.Version = uilabel(app.Version_Grid);
            app.Version.Interpreter = 'html';
            app.Version.Text = '';
            app.Image = uiimage(app.Grid);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LT_HideLeft.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Delete = uimenu(app.ContextMenu);
            app.Delete.MenuSelectedFcn = createCallbackFcn(app, @DeleteFilterClicked, true);
            app.Delete.Text = 'Excluir';
            app.DeleteAll = uimenu(app.ContextMenu);
            app.DeleteAll.MenuSelectedFcn = createCallbackFcn(app, @DeleteFilterClicked, true);
            app.DeleteAll.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAnatelDB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end