classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid1                      matlab.ui.container.GridLayout
        menu_Grid2                      matlab.ui.container.GridLayout
        menu_Grid2A                     matlab.ui.container.GridLayout
        menu_Separator2_2               matlab.ui.control.Image
        Button_Logger_3                 matlab.ui.control.StateButton
        Button_Logger_2                 matlab.ui.control.StateButton
        Button_Plot                     matlab.ui.control.StateButton
        Button_Logger                   matlab.ui.control.StateButton
        menu_Separator2                 matlab.ui.control.Image
        Button_Home                     matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        Button_Connect                  matlab.ui.control.StateButton
        Button_Task                     matlab.ui.control.StateButton
        menu_Grid2B                     matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_searchPath                 matlab.ui.control.Image
        tool_Panel                      matlab.ui.container.Panel
        tool_Grid1                      matlab.ui.container.GridLayout
        tool_Grid2                      matlab.ui.container.GridLayout
        tool_Button3                    matlab.ui.control.Button
        tool_Task                       matlab.ui.control.Label
        tool_REC                        matlab.ui.control.Image
        tool_Button1                    matlab.ui.control.Button
        tool_Button2                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Home_Grid0_2                    matlab.ui.container.GridLayout
        Image9                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Tab2_Connection                 matlab.ui.container.Tab
        connect_Grid0                   matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        connect_Button1                 matlab.ui.control.Button
        GridLayout26                    matlab.ui.container.GridLayout
        connect_Button4                 matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        tool_connect                    matlab.ui.control.Button
        connect_Button2                 matlab.ui.control.StateButton
        connect_Button3                 matlab.ui.control.StateButton
        connect_Label1                  matlab.ui.control.Label
        connect_Panel1                  matlab.ui.container.Panel
        connect_Grid1                   matlab.ui.container.GridLayout
        connect_Type                    matlab.ui.control.DropDown
        connect_TypeLabel               matlab.ui.control.Label
        connect_FamilyLabel             matlab.ui.control.Label
        connect_NameLabel               matlab.ui.control.Label
        connect_IPLabel                 matlab.ui.control.Label
        connect_PortLabel               matlab.ui.control.Label
        connect_TimeoutLabel            matlab.ui.control.Label
        connect_BufferLabel             matlab.ui.control.Label
        connect_DescriptionLabel        matlab.ui.control.Label
        connect_Panel                   matlab.ui.container.Panel
        connect_Grid2                   matlab.ui.container.GridLayout
        connect_publicIP                matlab.ui.control.EditField
        connect_publicIPLabel           matlab.ui.control.Label
        connect_localIPLabel            matlab.ui.control.Label
        connect_localIP                 matlab.ui.control.EditField
        connect_Grid3                   matlab.ui.container.GridLayout
        connect_CheckBoxLabel           matlab.ui.control.Label
        connect_CheckBox                matlab.ui.control.CheckBox
        connect_Description             matlab.ui.control.TextArea
        connect_Buffer                  matlab.ui.control.NumericEditField
        connect_Timeout                 matlab.ui.control.NumericEditField
        connect_Port                    matlab.ui.control.EditField
        connect_IP                      matlab.ui.control.EditField
        connect_Name                    matlab.ui.control.DropDown
        connect_Family                  matlab.ui.control.DropDown
        connect_Grid4                   matlab.ui.container.GridLayout
        connect_instrImage              matlab.ui.control.Image
        connect_instrName               matlab.ui.control.Label
        connect_instrDescription        matlab.ui.control.Label
        connect_Tree                    matlab.ui.container.Tree
        Tab3_Task                       matlab.ui.container.Tab
        Task_Grid0                      matlab.ui.container.GridLayout
        Task_Grid6                      matlab.ui.container.GridLayout
        Task_executeTask                matlab.ui.control.Button
        GridLayout25                    matlab.ui.container.GridLayout
        Task_Label9_2                   matlab.ui.control.Label
        Task_Warning2                   matlab.ui.control.Image
        GridLayout20                    matlab.ui.container.GridLayout
        Task_Info                       matlab.ui.control.Hyperlink
        Task_RefreshButton              matlab.ui.control.Button
        Task_Separator2                 matlab.ui.control.Image
        Task_editBand                   matlab.ui.control.StateButton
        Task_newBand                    matlab.ui.control.StateButton
        Task_Tree2                      matlab.ui.container.Tree
        Panel_4                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Task_PreampLabel                matlab.ui.control.Label
        Task_Preamp                     matlab.ui.control.DropDown
        Task_attFactorLabel             matlab.ui.control.Label
        Task_attFactor                  matlab.ui.control.DropDown
        Task_attModeLabel               matlab.ui.control.Label
        Task_attMode                    matlab.ui.control.DropDown
        DetectorDropDownLabel_2         matlab.ui.control.Label
        Task_Detector_2                 matlab.ui.control.DropDown
        UnidadeEditFieldLabel           matlab.ui.control.Label
        Task_LevelUnit_2                matlab.ui.control.EditField
        ResoluokHzLabel_2               matlab.ui.control.Label
        Task_Resolution2                matlab.ui.control.DropDown
        Task_Selectivity                matlab.ui.control.DropDown
        Task_SelectivityLabel           matlab.ui.control.Label
        Task_DataPointsLabel            matlab.ui.control.Label
        Task_StepWidthLabel             matlab.ui.control.Label
        Task_DataPoints2                matlab.ui.control.NumericEditField
        Task_DataPoints1                matlab.ui.control.Spinner
        Task_StepWidth2                 matlab.ui.control.DropDown
        Task_StepWidth1                 matlab.ui.control.NumericEditField
        Task_addBand                    matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Task_RFMode                     matlab.ui.control.DropDown
        MododeRFLabel                   matlab.ui.control.Label
        UnidadeDropDownLabel            matlab.ui.control.Label
        Task_LevelUnit                  matlab.ui.control.DropDown
        DetectorDropDownLabel           matlab.ui.control.Label
        Task_Detector                   matlab.ui.control.DropDown
        MododotraoLabel                 matlab.ui.control.Label
        Task_TraceMode                  matlab.ui.control.DropDown
        FrequnciafinalMHzLabel          matlab.ui.control.Label
        FrequnciainicialMHzLabel        matlab.ui.control.Label
        ThreadIDEditFieldLabel          matlab.ui.control.Label
        Task_RevisitTime                matlab.ui.control.NumericEditField
        Task_RevisitTimeLabel           matlab.ui.control.Label
        Task_IntegrationFactorLabel     matlab.ui.control.Label
        Task_IntegrationFactor          matlab.ui.control.NumericEditField
        Task_Resolution1                matlab.ui.control.NumericEditField
        Task_Resolution1Label           matlab.ui.control.Label
        Task_StepWidth                  matlab.ui.control.NumericEditField
        PassodavarredurakHzLabel        matlab.ui.control.Label
        DescrioEditFieldLabel           matlab.ui.control.Label
        Task_F1                         matlab.ui.control.NumericEditField
        Task_F0                         matlab.ui.control.NumericEditField
        Task_Description                matlab.ui.control.EditField
        Task_ThreadID                   matlab.ui.control.NumericEditField
        Task_Label9                     matlab.ui.control.Label
        Task_Grid2                      matlab.ui.container.GridLayout
        Task_Label1                     matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        Task_ResetCommand               matlab.ui.control.DropDown
        Task_ResetCommandLabel          matlab.ui.control.Label
        Task_SyncRef                    matlab.ui.control.DropDown
        Task_SyncRefLabel               matlab.ui.control.Label
        GridLayout19                    matlab.ui.container.GridLayout
        tool_task                       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Task_NewTask                    matlab.ui.control.StateButton
        Task_OpenTask                   matlab.ui.control.Button
        Task_SaveTask                   matlab.ui.control.Button
        Task_Panel2                     matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        Task_ElevationLabel             matlab.ui.control.Label
        Task_Elevation                  matlab.ui.control.NumericEditField
        Task_AzimuthLabel               matlab.ui.control.Label
        Task_Azimuth                    matlab.ui.control.NumericEditField
        Task_HeightLabel                matlab.ui.control.Label
        Task_Height                     matlab.ui.control.NumericEditField
        Task_Antenna                    matlab.ui.control.DropDown
        Task_Panel3                     matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Task_gpsRevisitTimeLabel        matlab.ui.control.Label
        Task_manualLongitudeLabel       matlab.ui.control.Label
        Task_manualLongitude            matlab.ui.control.NumericEditField
        Task_manualLatitude             matlab.ui.control.NumericEditField
        Task_manualLatitudeLabel        matlab.ui.control.Label
        Task_gpsRevisitTime             matlab.ui.control.NumericEditField
        Task_InstrumentLabel            matlab.ui.control.Label
        Task_GPS                        matlab.ui.control.DropDown
        Task_Instrument                 matlab.ui.control.DropDown
        Task_Panel1                     matlab.ui.container.Panel
        Task_Grid3                      matlab.ui.container.GridLayout
        Task_BitsPerPoint               matlab.ui.control.DropDown
        Task_BitsPerPointLabel          matlab.ui.control.Label
        Task_DurationUnit               matlab.ui.control.DropDown
        Task_DurationLabel              matlab.ui.control.Label
        Task_Duration                   matlab.ui.control.NumericEditField
        Task_NameLabel                  matlab.ui.control.Label
        Task_Name                       matlab.ui.control.EditField
        Task_GPSLabel                   matlab.ui.control.Label
        Task_Label7_2                   matlab.ui.control.Label
        Task_AntennaLabel               matlab.ui.control.Label
        Task_Tree1                      matlab.ui.container.Tree
        Task_Label6                     matlab.ui.control.Label
        Tab4_logger                     matlab.ui.container.Tab
        logger_Grid1                    matlab.ui.container.GridLayout
        logger_Grid1B                   matlab.ui.container.GridLayout
        logger_ButtonPlay               matlab.ui.control.Button
        logger_TextInfo                 matlab.ui.control.TextArea
        logger_TextInfoLabel            matlab.ui.control.Label
        logger_Panel4                   matlab.ui.container.Panel
        logger_Panel4_Grid              matlab.ui.container.GridLayout
        logger_DataPoints               matlab.ui.control.NumericEditField
        logger_DataPointsLabel          matlab.ui.control.Label
        logger_Operation                matlab.ui.control.EditField
        logger_OperationLabel           matlab.ui.control.Label
        logger_StepWidth                matlab.ui.control.NumericEditField
        logger_StepWidthLabel           matlab.ui.control.Label
        logger_FreqStopLabel            matlab.ui.control.Label
        logger_FreqStop                 matlab.ui.control.NumericEditField
        logger_FreqStart                matlab.ui.control.NumericEditField
        logger_FreqStartLabel           matlab.ui.control.Label
        logger_ThreadID                 matlab.ui.control.NumericEditField
        logger_ThreadIDLabel            matlab.ui.control.Label
        logger_LevelUnitLabel           matlab.ui.control.Label
        logger_LevelUnit                matlab.ui.control.EditField
        logger_ResolutionLabel          matlab.ui.control.Label
        logger_Resolution               matlab.ui.control.NumericEditField
        logger_DescriptionLabel         matlab.ui.control.Label
        logger_Description              matlab.ui.control.EditField
        logger_Label3                   matlab.ui.control.Label
        logger_Tree                     matlab.ui.container.Tree
        logger_Grid1A                   matlab.ui.container.GridLayout
        logger_Label1                   matlab.ui.control.Label
        logger_Grid1A_Grid              matlab.ui.container.GridLayout
        logger_Panel3                   matlab.ui.container.Panel
        logger_Panel3_Grid              matlab.ui.container.GridLayout
        logger_MaskName                 matlab.ui.control.DropDown
        logger_MaskNameLabel            matlab.ui.control.Label
        logger_ScriptName               matlab.ui.control.EditField
        logger_ScriptNameLabel          matlab.ui.control.Label
        logger_Sublabel1                matlab.ui.control.Label
        logger_Panel2                   matlab.ui.container.Panel
        logger_Panel2_Grid              matlab.ui.container.GridLayout
        logger_gpsAltitude              matlab.ui.control.NumericEditField
        logger_gpsAltitudeLabel         matlab.ui.control.Label
        logger_gpsLongitude             matlab.ui.control.NumericEditField
        logger_gpsLongitudeLabel        matlab.ui.control.Label
        logger_gpsLatitude              matlab.ui.control.NumericEditField
        logger_gpsLatitudeLabel         matlab.ui.control.Label
        logger_gpsTimeLabel             matlab.ui.control.Label
        logger_gpsTime                  matlab.ui.control.EditField
        logger_gpsCount                 matlab.ui.control.NumericEditField
        logger_gpsCountLabel            matlab.ui.control.Label
        logger_gpsLabel                 matlab.ui.control.Label
        logger_Hostname                 matlab.ui.control.EditField
        logger_generalLabel             matlab.ui.control.Label
        logger_PauseLabel               matlab.ui.control.Label
        logger_Pause                    matlab.ui.control.Spinner
        logger_AvailableDatagramsLabel  matlab.ui.control.Label
        logger_AvailableDatagrams       matlab.ui.control.NumericEditField
        logger_General                  matlab.ui.control.TextArea
        logger_GeneralLabel             matlab.ui.control.Label
        logger_MethodLabel              matlab.ui.control.Label
        logger_Method                   matlab.ui.control.EditField
        logger_UnitInfo                 matlab.ui.control.EditField
        logger_UnitInfoLabel            matlab.ui.control.Label
        logger_Label2                   matlab.ui.control.Label
        logger_toolbar                  matlab.ui.container.GridLayout
        logger_Separator1_2             matlab.ui.control.Image
        tool_logger                     matlab.ui.control.Button
        logger_Separator1               matlab.ui.control.Image
        logger_winSCP                   matlab.ui.control.Button
        logger_EditRFeyeList            matlab.ui.control.Button
        logger_Mask                     matlab.ui.control.Button
        logger_ftpStatus                matlab.ui.control.Label
        logger_Script                   matlab.ui.control.Button
        logger_Panel1                   matlab.ui.container.Panel
        logger_Panel1_Grid              matlab.ui.container.GridLayout
        logger_Port                     matlab.ui.control.NumericEditField
        logger_PortLabel                matlab.ui.control.Label
        logger_Qtd                      matlab.ui.control.Label
        logger_IPLabel                  matlab.ui.control.Label
        logger_IP                       matlab.ui.control.DropDown
        logger_NodeLabel                matlab.ui.control.Label
        logger_Node                     matlab.ui.control.DropDown
        Tab_2                           matlab.ui.container.Tab
        play_Grid1                      matlab.ui.container.GridLayout
        play_Grid1A                     matlab.ui.container.GridLayout
        GridLayout40                    matlab.ui.container.GridLayout
        mainInfo_Label1                 matlab.ui.control.Label
        mainInfo_Visibility             matlab.ui.control.Image
        mainInfo_Tree                   matlab.ui.container.Tree
        GridLayout39                    matlab.ui.container.GridLayout
        mainInfo_TextLabel              matlab.ui.control.Label
        mainInfo_Expand                 matlab.ui.control.Image
        mainInfo_Toolbar                matlab.ui.container.GridLayout
        mainInfo_Info                   matlab.ui.control.Button
        mainInfo_Separator              matlab.ui.control.Image
        mainInfo_Delete2                matlab.ui.control.Button
        mainInfo_Delete1                matlab.ui.control.Button
        mainInfo_Text                   matlab.ui.control.TextArea
        play_Grid1B                     matlab.ui.container.GridLayout
        play_Label                      matlab.ui.control.Label
        play_Panel                      matlab.ui.container.Panel
        play_Grid2                      matlab.ui.container.GridLayout
        play_Grid2A                     matlab.ui.container.GridLayout
        PeakBWLabel                     matlab.ui.control.Label
        PeakBW                          matlab.ui.control.NumericEditField
        PeakCFLabel                     matlab.ui.control.Label
        PeakCF                          matlab.ui.control.NumericEditField
        play_Label3                     matlab.ui.control.Label
        play_Panel1                     matlab.ui.container.Panel
        play_Grid3                      matlab.ui.container.GridLayout
        Playback_yLim1                  matlab.ui.control.Spinner
        Playback_yLim2                  matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        Playback_xLim2                  matlab.ui.control.Spinner
        xSpinnerLabel                   matlab.ui.control.Label
        Playback_xLim1                  matlab.ui.control.Spinner
        Playback_peakTree               matlab.ui.container.Tree
        Playback_addMarker              matlab.ui.control.Image
        Playback_Panel2                 matlab.ui.container.Panel
        Playback_peakGrid               matlab.ui.container.GridLayout
        Playback_peakTrace              matlab.ui.control.DropDown
        Playback_peakTraceLabel         matlab.ui.control.Label
        Playback_peakNumbersLabel       matlab.ui.control.Label
        Playback_peakNumbers            matlab.ui.control.Spinner
        Playback_peakTHR                matlab.ui.control.Spinner
        Playback_peakTHRLabel           matlab.ui.control.Label
        Playback_peakProeminence        matlab.ui.control.Spinner
        Playback_peakProeminenceLabel   matlab.ui.control.Label
        Playback_peakDistance           matlab.ui.control.Spinner
        Playback_peakDistanceLabel      matlab.ui.control.Label
        Playback_peakBW                 matlab.ui.control.Spinner
        Playback_peakBWLabel            matlab.ui.control.Label
        Playback_peakRadioGroup         matlab.ui.container.ButtonGroup
        Playback_peakRadio3             matlab.ui.control.RadioButton
        Playback_peakRadio1             matlab.ui.control.RadioButton
        Playback_peakRadio2             matlab.ui.control.RadioButton
        play_Label2                     matlab.ui.control.Label
        play_Grid2B                     matlab.ui.container.GridLayout
        Playback_peakLabel0_2           matlab.ui.control.Label
        Playback_peakLabel0             matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        GridLayout23_2                  matlab.ui.container.GridLayout
        Playback_occPanel               matlab.ui.container.Panel
        Playback_occGrid2               matlab.ui.container.GridLayout
        Playback_tSamplesLabel          matlab.ui.control.Label
        Playback_tSamples               matlab.ui.control.Spinner
        Playback_occFcnLabel            matlab.ui.control.Label
        Playback_occFcn                 matlab.ui.control.DropDown
        Playback_uSamplesLabel          matlab.ui.control.Label
        Playback_uSamples               matlab.ui.control.Spinner
        Playback_occLabel               matlab.ui.control.Label
        Playback_occConfLevelLabel      matlab.ui.control.Label
        Playback_occConfLevel           matlab.ui.control.DropDown
        Playback_occTimeLabel           matlab.ui.control.Label
        Playback_occTime                matlab.ui.control.Spinner
        Playback_occOffsetLabel         matlab.ui.control.Label
        Playback_occOffset              matlab.ui.control.Spinner
        Playback_occValue               matlab.ui.control.Spinner
        Playback_occValueLabel          matlab.ui.control.Label
        Playback_occType                matlab.ui.control.DropDown
        Playback_occTypeLabel           matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        Playback_wfLineLabel_2          matlab.ui.control.Label
        Playback_AxesHoles              matlab.ui.control.DropDown
        Playback_wfLineLabel            matlab.ui.control.Label
        Playback_wfLine                 matlab.ui.control.DropDown
        EstiloLabel_2                   matlab.ui.control.Label
        Playback_DecimateFactor         matlab.ui.control.DropDown
        EstiloLabel                     matlab.ui.control.Label
        Playback_ColorMap               matlab.ui.control.DropDown
        ContextMenu_logger              matlab.ui.container.ContextMenu
        logger_addRECList               matlab.ui.container.Menu
        logger_deleteRECList            matlab.ui.container.Menu
        logger_PlotMask                 matlab.ui.container.Menu
        ContextMenu_Connection          matlab.ui.container.ContextMenu
        Connect_delete                  matlab.ui.container.Menu
        ContextMenu_Task                matlab.ui.container.ContextMenu
        Task_delete                     matlab.ui.container.Menu
        ContextMenu_Band                matlab.ui.container.ContextMenu
        Task_deleteBand                 matlab.ui.container.Menu
    end


    properties (Access = public)
        
        Version
        RootFolder = [];

        % Variáveis lidas no início da aplicação:
        instrInfo
        instrList
        taskList
        bandList        
        RFeyeList
        EB500Map

        % Instrumentos e sockets:
        Instrument    = [];
        udpPortArray  = {};
        udpPort       = [];
        gpsInstrument = [];
        instrTimeout
        ftpObj  = [];

        % Related to live plot
        traceInfo     = struct('SelectedNode', 0, ...
                               'Mode', [0, 0, 0], ...
                               'plotLayout', 0);

        fig = [];

        tb_refreshView = [];
        tb_interaction = [];
        tb_layout      = [];
        tb_minHold     = [];
        tb_meanHold    = [];
        tb_maxHold     = [];
        tb_waterfall   = [];
        tb_playback    = [];
        tb_rec         = [];

        axes1 = [];
        axes2 = [];

        line_ClrWrite = [];
        line_minHold  = [];
        line_Average  = [];
        line_maxHold  = [];
        surface_wFall = [];
        cBar1         = [];

        General = struct('Version', '',                         ...
                         'Image', struct('Visibility', 'on',    ...
                                         'Format', 'jpeg',      ...         % Image format - JPEG (default) and PNG
                                         'Resolution', 120),    ...         % Image resolution - 100, 120 (default), 150 e 200 dpi 
                         'Colors', [0.07, 0.07, 0.75;  ...                  % color1 (MinHold)
                                    0.39, 0.83, 0.07;  ...                  % color2 (Median or Mean)
                                    1.00, 0.07, 0.65;  ...                  % color3 (MaxHold)
                                    0.93, 0.69, 0.13], ...                  % color4 (ClearWrite)
                         'Models', [],                 ...                  % Report templates (app.InspectionType)
                         'FileSize', 100e+6,           ...
                         'ColorMap', 'jet',            ...
                         'HistoryDepth', 128,          ...
                         'AverageFactor', 10,          ...
                         'SampleTimeFactor', 10,       ...
                         'logger', struct('ConnectionTime', 30, 'HandshakePause', 1, 'Timeout', 5))

        RestoreView = [];

        taskFlag = 0;
        plotFlag = 0;
        recFlag  = 0;
        axesFlag = 0;
        axesFlagType = '';
        
        x

        timeInfo   = [];
        specInfo   = [];
        specMatrix = [];
        gpsInfo    = [];
        
        auxWin1 = [];           % winSettings
        auxWin2 = [];           % winMonitor

    end


    methods (Access = private)

        function startupError(app)
            
            delete(app.fig)
            delete(app)
            
        end


        function [mainMonitor, x0] = startup_appPosition(app)

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
                        
            if mainMonitor(3) >= 1016; x0 = 1016;
            else;                      x0 = mainMonitor(3);
            end
            
            if mainMonitor(4) > 768;   y0 = mainMonitor(4) - 696 - 30;
            else;                      y0 = 42;
            end

            app.MainApp.Position = [mainMonitor(1), mainMonitor(2)+y0, x0, 696];

        end


        function [x1, y1] = startup_figPosition(app, mainMonitor, x0)

                if mainMonitor(3) > x0 + 640; x1 = x0+6;
                else;                         x1 = mainMonitor(3)-640;
                end
                y1 = mainMonitor(4) - 480 - 28 - 28; % window figure height, figure & toolbar panels

        end


        function startup_figCreation(app)

            [mainMonitor, x0] = startup_appPosition(app);
            [x1, y1] = startup_figPosition(app, mainMonitor, x0);
            
            app.fig = figure('Name', 'appColeta: Plot', 'NumberTitle', 'off', 'Position', [x1, y1, 640, 480], ...
                             'Visible', 'off', 'Color', [0.94,0.94,0.94], 'GraphicsSmoothing', 'on', 'CloseRequestFcn', @app.Playback_CloseFigure);
            delete(findall(app.fig, '-not', 'Type', 'Figure'));

            tb = uitoolbar(app.fig);            
            app.tb_refreshView =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_refreshView, 'CData', startup_toolbarIcon(app, 'refreshView'));
            app.tb_interaction =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_interaction, 'CData', startup_toolbarIcon(app, 'interaction_pan'),                      'Tag', 'pan');
            app.tb_layout      =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_layout,      'CData', startup_toolbarIcon(app, 'layout1'),           'Separator', 'on', 'Tag', '1:1');
            app.tb_minHold     = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_minHold,     'CData', startup_toolbarIcon(app, 'minHold'),           'Separator', 'on');
            app.tb_meanHold    = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_meanHold,    'CData', startup_toolbarIcon(app, 'meanHold'));
            app.tb_maxHold     = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_maxHold,     'CData', startup_toolbarIcon(app, 'maxHold'));
            app.tb_waterfall   = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_waterfall,   'CData', startup_toolbarIcon(app, 'waterfall'),         'Separator', 'on');
            app.tb_playback    =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_playback,    'CData', startup_toolbarIcon(app, 'playback_play'),     'Separator', 'on', 'Tag', 'play');
            app.tb_rec         = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_rec,         'CData', startup_toolbarIcon(app, 'rec'));
                        
            warning('off')
            jFrame = get(app.fig, 'javaframe');
            jIcon  = javax.swing.ImageIcon(fullfile(app.RootFolder, 'Icons', 'Anatel.png'));
            jFrame.setFigureIcon(jIcon);
            warning('on')
            
            app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Helvetica', ...
                                      'FontSize', 7, 'FontSmoothing', 'on',                   ...
                                      'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',   ...
                                      'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',   ...
                                      'XTickLabel', {}, 'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94], ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit',  'normalized', 'InnerPosition',  [.08 .1 .85 .82], ...
                                      'TitleHorizontalAlignment', 'right', 'Toolbar', []);
            
            app.axes2 = axes(app.fig, 'Box', 'on', 'LineWidth', 1, 'Color', [0.94,0.94,0.94],        ...
                                      'FontName', 'Helvetica', 'FontSize', 7, 'FontSmoothing', 'on', ...
                                      'XColor', [0,0,0], 'YColor', [0,0,0], 'XTickLabel', {},        ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);

            set(app.axes1.Title,    'FontSize', 7, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.Subtitle, 'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])

            set(app.axes2.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes2.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')

            linkaxes([app.axes1, app.axes2], 'x')
            
            app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            app.axes2.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            
            enableDefaultInteractivity(app.axes1)
            enableDefaultInteractivity(app.axes2)

            addlistener(app.axes1, 'XLim', 'PostSet', @app.Playback_xLimits);
            addlistener(app.axes1, 'YLim', 'PostSet', @app.Playback_yLimits);                
            
            app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                 'AxisLocation', 'out', 'FontName', 'Helvetica', 'FontSize', 7, 'Color', [0,0,0]);

        end


        function icon = startup_toolbarIcon(app, Flag)

            switch Flag
                case 'refreshView';      filename = 'tb_refreshView.gif';
                case 'interaction_pan';  filename = 'tb_interaction_pan.gif';
                case 'interaction_zoom'; filename = 'tb_interaction_zoom.gif';
                case 'layout1';          filename = 'tb_layout1.gif';
                case 'layout2';          filename = 'tb_layout2.gif';
                case 'minHold';          filename = 'tb_minHold.gif';
                case 'meanHold';         filename = 'tb_meanHold.gif';
                case 'maxHold';          filename = 'tb_maxHold.gif';
                case 'waterfall';        filename = 'tb_waterfall.gif';
                case 'playback_play';    filename = 'tb_playback_play.gif';
                case 'playback_stop';    filename = 'tb_playback_stop.gif';
                case 'rec';              filename = 'tb_rec.gif';
            end
            
            [img, map] = imread(fullfile(app.RootFolder, 'Icons', filename));
            icon = ind2rgb(img, map);

        end


        function userPath = startup_userInitialFolder(app)

                if isfolder(fullfile(getenv('USERPROFILE'), 'Downloads'))
                    userPath = {fullfile(getenv('USERPROFILE'), 'Downloads')};
                else
                    userPath = {fullfile(getenv('USERPROFILE'), 'Documents')};                        
                end

        end
        
        
        function startup_userSelectedFolder(app)
            
            if isfile(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'))
                fileID   = fopen(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'), 'rt');
                userPath = fread(fileID, [1, Inf], '*char');
                fclose(fileID);
                
                userPath_ind = find(strcmp(app.menu_userPath.Items, userPath), 1);
                if isempty(userPath_ind)
                    app.menu_userPath.Items(end+1) = {userPath};
                    app.menu_userPath.Value        = userPath;
                else
                    app.menu_userPath.Value        = userPath;                        
                end                    
            end
        
        end


        function startup_InspectionType(app)
            
            app.General.Models    = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            
        end


        function ModeOperation(app)

            app.plotFlag = 0;

            if app.Button_Home.Value
                idx = 1;

            elseif app.Button_Connect.Value
                idx = 2;

                if isempty(app.connect_Tree.Children)
                    connect_TreeBuilding(app)
                end

            elseif app.Button_Task.Value
                idx = 3;
                
                Task_Tree1Building(app)
                Task_Tree1SelectionChanged(app)
                Task_RightPanel_Grid(app)

            elseif app.Button_Logger.Value
                idx = 4;

            elseif app.Button_Plot.Value
                idx = 5;

            end
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);
            drawnow

        end


        function Layout_TaskScreen(app)
            
            if app.Button_Task.Value
                if ismember(app.taskFlag, [1, 2])
                    % Painel à esquerda:
                    set(app.Task_Tree1,            'Enable', 0)
                    set(app.Task_Grid3.Children,   'Enable', 0)
                    set(app.GridLayout23.Children, 'Enable', 0)

                    app.Task_Instrument.Enable = 0;
                    app.Task_GPS.Enable        = 0;
                    app.Task_Antenna.Enable    = 0;

                    set(app.GridLayout18.Children, 'Enable', 0)
                    set(app.GridLayout17.Children, 'Enable', 0)
                    set(app.GridLayout19.Children, 'Enable', 0)

                    % Painel à direita:
                    set(app.GridLayout5.Children, 'Enable', 0)

                    % Outras peculiaridades:
                    app.Task_deleteBand.Enable = 0;
                    
                    if app.plotFlag
                        set(app.GridLayout20.Children, 'Enable', 1)

                        if app.taskFlag == 2
                            set(app.tb_rec, 'Visible', 0, 'State', 1)
                        end
                        set(app.tb_playback, 'CData', startup_toolbarIcon(app, 'playback_stop'))
                        app.Task_Tree2.SelectedNodes.Icon = 'mainMenu_Playback.png';
                    else
                        set(app.GridLayout20.Children, 'Enable', 0)
                        set(app.tb_playback, 'Visible', 1, 'CData', startup_toolbarIcon(app, 'playback_play'))
                        set(app.tb_rec,      'Visible', 0, 'State', 0)

                        app.Task_Tree2.SelectedNodes.Icon = '';
                    end

                    set(app.Task_Info, 'Text', '', 'URL', '')
                    if app.taskFlag == 1
                        app.Task_Tree2.Enable = 1;
                        
                        app.tool_REC.Visible     = 0;
                        app.tool_Task.Text  = 'WPT';
                        set(app.Task_executeTask, 'Enable', 0, 'Text', 'Executar tarefa', 'Icon', 'General_play.png')                        
                    else
                        if app.plotFlag; app.Task_Tree2.Enable = 1;
                        else;            app.Task_Tree2.Enable = 0;
                        end
                        
                        app.tool_REC.Visible     = 1;
                        app.tool_Task.Text  = 'RT';
                        set(app.Task_executeTask, 'Enable', 1, 'Text', 'Parar tarefa', 'Icon', 'Media_StopWhite.png')
                    end

                else
                    set(app.Task_Tree1,            'Enable', 1)
                    set(app.Task_Tree2,            'Enable', 1)
                    set(app.Task_Grid3.Children,   'Enable', 1)
                    set(app.GridLayout23.Children, 'Enable', 1)

                    app.Task_Instrument.Enable = 1;
                    app.Task_GPS.Enable        = 1;
                    app.Task_Antenna.Enable    = 1;

                    set(app.GridLayout18.Children, 'Enable', 1)
                    set(app.GridLayout17.Children, 'Enable', 1)
                    set(app.GridLayout19.Children, 'Enable', 1)

                    Task_GPSValueChanged(app)
                    Task_AntennaValueChanged(app)

                    set(app.GridLayout5.Children, 'Enable', 1)
                    app.Task_Tree2.Enable = 1;
                    
                    if numel(app.Task_Tree2.Children) > 1; app.Task_deleteBand.Enable = 1;
                    else;                                  app.Task_deleteBand.Enable = 0;
                    end

                    set(app.GridLayout20.Children, 'Enable', 0)
                    set(app.tb_playback,       'Visible', 1, 'CData', startup_toolbarIcon(app, 'playback_stop'))

                    app.tb_rec.State       = 0;
                    app.tb_minHold.State   = 0;
                    app.tb_meanHold.State  = 0;
                    app.tb_maxHold.State   = 0;
                    app.tb_waterfall.State = 0;

                    set(app.Task_executeTask, 'Enable', 1, 'Text', 'Executar tarefa', 'Icon', 'General_play.png')

                    app.tool_REC.Visible     = 0;
                    app.tool_Task.Text         = '';
                    app.Task_Tree2.SelectedNodes.Icon = '';
                end

            elseif app.Button_Logger.Value
                if app.taskFlag == 3
                    set(app.logger_Panel1_Grid.Children, 'Enable', 0)

                    set(app.logger_Grid1A_Grid.Children, 'Enable', 'on', 'Visible', 1)
                    if isempty(app.logger_ftpStatus.Text)
                        app.logger_Sublabel1.Enable = 0;
                    end

                    set(app.logger_Panel2_Grid.Children, 'Enable', 1)
                    set(app.logger_Panel4_Grid.Children, 'Enable', 1)
                    
                    app.logger_Hostname.Value = '';                    
                    app.logger_UnitInfo.Value = '';
                    app.logger_Method.Value   = '';
                    app.logger_General.Value  = '';                    
                    
                    app.logger_gpsCount.Value     = 0;
                    app.logger_gpsTime.Value      = '';
                    app.logger_gpsLatitude.Value  = -1;
                    app.logger_gpsLongitude.Value = -1;
                    app.logger_gpsAltitude.Value  = 0;
                    
                    app.logger_ThreadID.Value    = -1;
                    app.logger_Description.Value = '';
                    app.logger_FreqStart.Value   = -1;
                    app.logger_FreqStop.Value    = -1;
                    app.logger_StepWidth.Value   = -1;
                    app.logger_Resolution.Value  = -1;
                    app.logger_Operation.Value   = '';
                    app.logger_DataPoints.Value  = -1;
                    app.logger_LevelUnit.Value   = '';
                    
                    set(app.logger_Tree, 'Enable', 1, 'BackgroundColor', 'white')                
                    set(app.logger_TextInfo, 'Enable', 1, 'Value', '')
                    
                    set(app.logger_ButtonPlay, 'Text', 'Parar tarefa', 'Icon', 'Media_StopWhite.png')
                    app.tool_Task.Text = 'LT';
                    
                    set(app.GridLayout20.Children, 'Enable', 1)
                    app.tb_playback.CData = 'General_Stop.png';
                    app.tb_rec.Visible  = 0;

                else
                    instrreset
                    
                    set(app.logger_Panel1_Grid.Children,  'Enable', 1)
                    set(app.logger_AvailableDatagrams, 'Visible', 0, 'Value', 0)
                    app.logger_AvailableDatagramsLabel.Visible   = 0;
                    app.logger_PauseLabel.Visible = 0;
                    app.logger_Pause.Visible      = 0;
                    
                    set(app.logger_ButtonPlay, 'Text', 'Executar tarefa', 'Icon', 'General_play.png')
                    app.tool_Task.Text = '';
                    
                    set(app.GridLayout20.Children, 'Enable', 0)
                    app.tb_playback.CData = 'Ícone (Play4).png';
                end
            end

        end


        function connect_infoPanel(app)

            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                SelectedNode = app.connect_Tree.SelectedNodes;

                if ~isempty(SelectedNode)
                    if SelectedNode.Parent == app.connect_Tree
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Text), 1);
                    else
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Parent.Text), 1);
                    end
                else
                    indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
                end
            else
                indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            end

            app.connect_instrName.Text         = {app.instrInfo.Tag{indFind}; app.instrInfo.Band{indFind}};
            app.connect_instrImage.ImageSource = app.instrInfo.Image{indFind};
            app.connect_instrDescription.Text  = app.instrInfo.Description{indFind};

        end


        function connect_TreeBuilding(app)

            delete(app.connect_Tree.Children);

            app.Task_Instrument.Items = {};

            app.instrList = sortrows(app.instrList, 'Name', 'ascend');
            instrUnique   = unique(app.instrList.Name);
            
            indNode = [1, 1];
            for ii = 1:numel(instrUnique)
                indFind1 = find(strcmp(app.instrList.Name, instrUnique{ii}))';
                indFind2 = find(strcmp(app.instrInfo.Name, instrUnique{ii}), 1);

                ParentNode = uitreenode(app.connect_Tree, 'Text', sprintf('%s', instrUnique{ii}), ...
                    'NodeData', indFind1,                   ...
                    'Icon', app.instrInfo.Logo{indFind2},   ...
                    'ContextMenu', app.ContextMenu_Connection);
                
                kk = 0;
                for jj = indFind1
                    kk = kk+1;
                    
                    msg = sprintf('{"Família": "%s", "Tipo de conexão": "%s", "Endereço IP": "%s", "Porta": "%s", "Descrição": "%s"}', ...
                        app.instrList.Family{jj}, app.instrList.Type{jj}, app.instrList.IP{jj}, app.instrList.Port{jj}, app.instrList.Description{jj});

                    if app.instrList.Flag(jj) == 0
                        uitreenode(ParentNode, 'Text', sprintf('ID %d: %s', jj, msg), 'NodeData', jj, 'ContextMenu', app.ContextMenu_Connection);

                    elseif app.instrList.Flag(jj) == 1
                        indNode = [ii, kk];
                        uitreenode(ParentNode, 'Text', sprintf('ID %d: %s', jj, msg), 'NodeData', jj, 'ContextMenu', app.ContextMenu_Connection, 'Icon', 'GreenCircle.png');

                        if strcmp(app.instrList.Family{jj}, 'Receptor')
                            app.Task_Instrument.Items = [app.Task_Instrument.Items, sprintf('ID %d: %s', jj, app.instrList.Name{jj})];
                        end
                        expand(ParentNode)

                    elseif app.instrList.Flag(jj) == -1
                        uitreenode(ParentNode, 'Text', sprintf('ID %d: %s', jj, msg), 'NodeData', jj, 'ContextMenu', app.ContextMenu_Connection, 'Icon', 'RedCircle.png');
                    end
                end
            end

            if ~isempty(app.connect_Tree.Children)
                expand(app.connect_Tree.Children(indNode(1)))
                app.connect_Tree.SelectedNodes = app.connect_Tree.Children(indNode(1)).Children(indNode(2));
            end
            connect_TreeSelectionChanged(app)

        end


        function connect_Layout(app)
            
            % Visibilidade relacionada ao modo de apresentação do painel -
            % "Adicionar instrumento", "Editar instrumento" e "Visualizar".
            if app.connect_Button2.Value || app.connect_Button3.Value

                app.connect_Grid0.RowHeight = {22, 254, 1, 24, 1, '1x', 1, 24};
                set(app.connect_Tree, 'Enable', 'off')

                if app.connect_Button2.Value
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Incluir', 'Icon', 'AddWhite3.png')

                    set(app.connect_Family, 'BackgroundColor', 'white')
                    set(app.connect_Name,   'BackgroundColor', 'white')
                else
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Editar', 'Icon', 'White_Edit.png')

                    set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                    set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                end

                set(app.connect_Type,        'BackgroundColor', 'white')
                set(app.connect_IP,          'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Port,        'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Timeout,     'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Buffer,      'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Description, 'BackgroundColor', 'white', 'Editable', 'on')

                set(app.connect_CheckBox, 'Enable', 1)
                set(app.connect_localIP,  'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_publicIP, 'BackgroundColor', 'white', 'Editable', 'on')
                
                app.connect_Button4.Enable = 0;

            else
                app.connect_Grid0.RowHeight = {22, 254, 0, 0, 1, '1x', 1, 24};
                set(app.connect_Tree,    'Enable', 1);
                set(app.connect_Button1, 'Enable', 0)

                set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                set(app.connect_Type,   'BackgroundColor', [.94,.94,.94])

                set(app.connect_IP,          'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Port,        'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Timeout,     'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Buffer,      'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Description, 'BackgroundColor', [.80,.80,.80], 'Editable', 'off')

                set(app.connect_CheckBox, 'Enable', 0, 'Value', 0)
                set(app.connect_localIP,  'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                set(app.connect_publicIP, 'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                
                ind = app.connect_Tree.SelectedNodes.NodeData;
                if numel(ind) == 1
                    app.connect_Button3.Enable = 1;
                    app.connect_Button4.Enable = 1;
                    
                    connect_PanelValues(app, ind)
                end
            end
            
            % Visibilidade relacionada ao tipo da conexão (IP, Porta).
            set(app.connect_Grid1.Children, 'Visible', 1)
            switch app.connect_Type.Value
                case 'Serial'
                    app.connect_IPLabel.Visible = 0;
                    set(app.connect_IP, 'Visible', 0, 'Value', '')
                
                    app.connect_PortLabel.Layout.Column = 1;
                    app.connect_Port.Layout.Column      = 1;
                    
                    app.connect_CheckBox.Enable = 0;
                    
                case 'Visa TCPIP'
                    app.connect_PortLabel.Visible = 0;
                    app.connect_Port.Visible      = 0;
                    
                otherwise
                    app.connect_PortLabel.Layout.Column = 2;
                    app.connect_Port.Layout.Column      = 2;
            end

        end
        
        
        function connect_PanelValues(app, ind)
            
            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                app.connect_Family.Items  = app.instrList.Family(ind);
                app.connect_Name.Items    = app.instrList.Name(ind);
            end
            
            app.connect_Type.Items        = app.instrList.Type(ind);
            app.connect_IP.Value          = app.instrList.IP{ind};
            app.connect_Port.Value        = app.instrList.Port{ind};
            
            set(app.connect_Timeout, 'Value', app.instrList.Timeout(ind),  'FontColor', 'black')
            set(app.connect_Buffer,  'Value', app.instrList.inBuffer(ind), 'FontColor', 'black')
            
            app.connect_Description.Value = app.instrList.Description{ind};
            
            app.connect_CheckBox.Value    = app.instrList.Localhost_Flag(ind);
            app.connect_localIP.Value     = app.instrList.Localhost_localIP{ind};
            app.connect_publicIP.Value    = app.instrList.Localhost_publicIP{ind};

            if app.connect_CheckBox.Value; set(app.connect_Grid2.Children, 'Enable', 'on')
            else;                          set(app.connect_Grid2.Children, 'Enable', 'off')
            end
                    
        end


        function connect_infoValidation(app, Type, IP)

            switch Type
                case 'Only Instrument IP'
                    IP = strsplit(IP, '.');
                    IP = cellfun(@(x) str2double(x), IP);

                    if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                        error('Endereço IP inválido.')
                    end

                otherwise
                    if contains(app.connect_Type.Value, 'IP')
                        if ~app.connect_CheckBox.Value
                            IPValues = {app.connect_IP.Value};
                        else
                            IPValues = {app.connect_IP.Value;      ...
                                app.connect_localIP.Value; ...
                                app.connect_publicIP.Value};
                        end

                        for ii = 1:numel(IPValues)
                            IP = strsplit(IPValues{ii}, '.');

                            if (ii == 3) && isempty(IPValues{ii})
                                continue
                            end

                            IP = cellfun(@(x) str2double(x), IP);

                            if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                                error('Endereço IP inválido.')
                            end
                        end

                        if ~isempty(app.connect_Port.Value)
                            Port = str2double(app.connect_Port.Value);

                            if isnan(Port) || Port < 0 || Port >= 65536
                                error('Número da porta inválido.')
                            end
                        end
                    end
            end

        end


        function Task_Tree1Building(app)

            delete(app.Task_Tree1.Children);            

            value = numel(app.taskList);
            if value > 1; app.Task_delete.Enable = 1;
            else;         app.Task_delete.Enable = 0;
            end

            for ii = 1:value
                uitreenode(app.Task_Tree1, 'Text', app.taskList{ii}.Name, 'NodeData', ii, 'ContextMenu', app.ContextMenu_Task);
            end

            if ~app.plotFlag; app.Task_Tree1.SelectedNodes = app.Task_Tree1.Children(1);
            else;             app.Task_Tree1.SelectedNodes = app.Task_Tree1.Children(app.specMatrix.Index(3));
            end

        end


        function Task_Tree2Building(app, ind1)

            delete(app.Task_Tree2.Children);

            value = numel(app.taskList{ind1}.Band);
            if value > 1; app.Task_deleteBand.Enable = 1;
            else;         app.Task_deleteBand.Enable = 0;
            end

            for ii = 1:value
                str = sprintf('ThreadID %d: %.3f - %.3f MHz',                  ...
                                app.taskList{ind1}.Band(ii).ThreadID,          ...
                                app.taskList{ind1}.Band(ii).FreqStart ./ 1e+6, ...
                                app.taskList{ind1}.Band(ii).FreqStop  ./ 1e+6);

                uitreenode(app.Task_Tree2, 'Text', str, 'NodeData', ii, 'ContextMenu', app.ContextMenu_Band);
            end

            if ~app.plotFlag; app.Task_Tree2.SelectedNodes = app.Task_Tree2.Children(1);
            else;             app.Task_Tree2.SelectedNodes = app.Task_Tree2.Children(app.specMatrix.Index(4));
            end

        end


        function Task_RightPanel_Grid(app)

            if app.Task_NewTask.Value || app.Task_newBand.Value || app.Task_editBand.Value
                if ~isempty(app.Task_Instrument.Items); app.Task_Grid6.RowHeight = {22, 211, 22, 168, 1, 24, 1, '1x', 1, 24};
                else;                                   app.Task_Grid6.RowHeight = {22, 211,  0,   0, 1, 24, 1, '1x', 1, 24};
                end
            else
                if ~isempty(app.Task_Instrument.Items); app.Task_Grid6.RowHeight = {22, 211, 22, 168, 0,  0, 1, '1x', 1, 24};
                else;                                   app.Task_Grid6.RowHeight = {22, 211,  0,   0, 0,  0, 1, '1x', 1, 24};
                end
            end

        end


        function Task_RightPanel_Main(app, ind1, ind2)

            app.Task_ThreadID.Value          =  app.taskList{ind1}.Band(ind2).ThreadID;
            app.Task_Description.Value       =  app.taskList{ind1}.Band(ind2).Description;
            app.Task_F0.Value                =  app.taskList{ind1}.Band(ind2).FreqStart / 1e+6;
            app.Task_F1.Value                =  app.taskList{ind1}.Band(ind2).FreqStop / 1e+6;
            app.Task_RFMode.Items            = {app.taskList{ind1}.Band(ind2).RFMode};
            app.Task_StepWidth.Value         =  app.taskList{ind1}.Band(ind2).StepWidth / 1e+3;
            app.Task_Resolution1.Value       =  app.taskList{ind1}.Band(ind2).Resolution / 1e+3;
            app.Task_LevelUnit.Items         = {app.taskList{ind1}.Band(ind2).LevelUnit};
            app.Task_TraceMode.Items         = {app.taskList{ind1}.Band(ind2).TraceMode};
            app.Task_Detector.Items          = {app.taskList{ind1}.Band(ind2).Detector};
            app.Task_RevisitTime.Value       =  app.taskList{ind1}.Band(ind2).RevisitTime;
            app.Task_IntegrationFactor.Value = app.taskList{ind1}.Band(ind2).IntegrationFactor;

        end


        function Task_RightPanel_Band_Visibility(app)

            ind = find(strcmp(app.instrInfo.Name, extractAfter(app.Task_Instrument.Value,   ': ')), 1);
            switch app.instrInfo.connectFlag{ind}
                case 1
                    app.GridLayout5.RowHeight = {25,22,0,15,22,15,22};

                    app.Task_PreampLabel.Visible = 1;
                    app.Task_Preamp.Visible      = 1;

                    app.Task_Resolution2.Layout.Row   = 2;
                    app.Task_SelectivityLabel.Visible = 0;
                    app.Task_Selectivity.Visible      = 0;

                case 2
                    app.GridLayout5.RowHeight = {25,0,22,15,22,15,22};

                    app.Task_PreampLabel.Visible = 0;
                    app.Task_Preamp.Visible      = 0;

                    app.Task_Resolution2.Layout.Row   = 3;
                    app.Task_SelectivityLabel.Visible = 1;
                    app.Task_Selectivity.Visible      = 1;

                case 3
                    %% Pendente RFeye

            end

        end


        function instrSettings = Task_RightPanel_Band_GetValues(app, ind1, ind2)

            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'SensitivityMode',   [], 'Preamp',          [], ...
                                   'AttMode',           [], 'AttFactor_Items', [], ...
                                   'AttFactor',         [], 'LevelUnit',       [], ...
                                   'Detector_Items',    []);

            span = app.taskList{ind1}.Band(ind2).FreqStop - app.taskList{ind1}.Band(ind2).FreqStart;

            ind0 = find(strcmp(app.instrInfo.Name, extractAfter(app.Task_Instrument.Value,   ': ')), 1);
            
            instrSettings.AttMode         = 'Auto';
            instrSettings.AttFactor_Items = strsplit(app.instrInfo.Attenuation_Values{ind0}, ',');
            instrSettings.AttFactor       = instrSettings.AttFactor_Items{1};
            instrSettings.Detector_Items  = strsplit(app.instrInfo.Detector_Items{ind0}, ',');

            switch app.instrInfo.connectFlag{ind0}
                case 1                                                                                              % Analisadores de espectro
                    instrSettings.Resolution_Items = strsplit(app.instrInfo.Resolution_Values{ind0}, ',');

                    rbwValues = [];
                    for ii = 1:numel(instrSettings.Resolution_Items)
                        rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                    end

                    instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{ind0};

                    DataPoints = round(span/app.taskList{ind1}.Band(ind2).StepWidth + 1);
                    if     DataPoints < instrSettings.DataPoints_Limits(1); instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                    elseif DataPoints > instrSettings.DataPoints_Limits(2); instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                    else;                                                   instrSettings.DataPoints = DataPoints;
                    end
                    instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);

                    rbwIndex  = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};

                    instrSettings.Selectivity = '';

                    instrSettings.SensitivityMode = '0';
                    instrSettings.Preamp          = 'On';

                    instrSettings.LevelUnit       = app.taskList{ind1}.Band(ind2).LevelUnit;
                    if contains(app.Task_Instrument.Value, 'N9344C')
                        instrSettings.LevelUnit   = 'dBm';
                    end

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode         = 'Manual';
                            instrSettings.SensitivityMode = '1';
                        case 'Low Distortion'
                            instrSettings.Preamp          = 'Off';
                    end

                case 2                                                                                              % EB500
                    instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{ind0}, ',');
                    instrSettings.Selectivity_Items = {'Normal', 'Narrow', 'Sharp'};

                    stepValues = [];
                    for ii = 1:length(instrSettings.StepWidth_Items)
                        stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                    end

                    stepIndex  = find(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth) == min(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth)));
                    instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};

                    instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;

                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Map{instrSettings.StepWidth, ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    instrSettings.Resolution_Items = rbwItems;

                    rbwIndex = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                    instrSettings.Selectivity = app.Task_Selectivity.Items{rbwIndex};

                    instrSettings.SensitivityMode = 'NORM';
                    instrSettings.Preamp          = 'Off';
                    instrSettings.LevelUnit       = 'dBμV';

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode = 'Manual';
                        case 'Low Distortion'
                            instrSettings.SensitivityMode = 'LOWD';
                    end
            end

        end


        function Task_RightPanel_Band_SaveValues(app, ind1, ind2)

            instrSettings = Task_RightPanel_Band_GetValues(app, ind1, ind2);

            app.taskList{ind1}.Band(ind2).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{ind1}.Band(ind2).instrStepWidth         = instrSettings.StepWidth;

            app.taskList{ind1}.Band(ind2).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{ind1}.Band(ind2).instrDataPoints        = instrSettings.DataPoints;

            app.taskList{ind1}.Band(ind2).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{ind1}.Band(ind2).instrResolution        = instrSettings.Resolution;
            
            app.taskList{ind1}.Band(ind2).instrSelectivity       = instrSettings.Selectivity;
            app.taskList{ind1}.Band(ind2).instrSensitivityMode   = instrSettings.SensitivityMode;
            
            app.taskList{ind1}.Band(ind2).instrPreamp            = instrSettings.Preamp;
            app.taskList{ind1}.Band(ind2).instrAttMode           = instrSettings.AttMode;
            app.taskList{ind1}.Band(ind2).instrAttFactor_Items   = instrSettings.AttFactor_Items;
            app.taskList{ind1}.Band(ind2).instrAttFactor         = instrSettings.AttFactor;

            app.taskList{ind1}.Band(ind2).instrDetector_Items    = instrSettings.Detector_Items;
            indDetector = find(strcmp(app.taskList{ind1}.Band(ind2).instrDetector_Items, app.taskList{ind1}.Band(ind2).Detector), 1);
            if isempty(indDetector)
                indDetector = 1;
            end
            app.taskList{ind1}.Band(ind2).instrDetector          = instrSettings.Detector_Items{indDetector};
            app.taskList{ind1}.Band(ind2).instrLevelUnit         = instrSettings.LevelUnit;

            app.taskList{ind1}.Band(ind2).Flag = 0;
            app.Task_Warning2.Visible          = 0;
            app.Task_RefreshButton.Enable      = 0;

        end


        function Task_RightPanel_Band_ShowValues(app, ind1, ind2)

            if isempty(app.taskList{ind1}.Band(ind2).Flag)
                Task_RightPanel_Band_SaveValues(app, ind1, ind2)
            elseif ~app.taskList{ind1}.Band(ind2).Flag
                app.Task_Warning2.Visible     = 0;
                app.Task_RefreshButton.Enable = 0;
            else
                app.Task_Warning2.Visible     = 1;
                if ~app.taskFlag
                    app.Task_RefreshButton.Enable = 1;
                end
            end

            if contains(app.Task_Instrument.Value, 'MS2720T')
                set(app.Task_StepWidth1,  'Editable', 0, ...
                                          'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                          'Value',  551)

            elseif contains(app.Task_Instrument.Value, 'SA2500')
                set(app.Task_StepWidth1,  'Editable', 0, ...
                                          'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                          'Value',  501)

            elseif contains(app.Task_Instrument.Value, 'N9344C')
                set(app.Task_StepWidth1,  'Editable', 0, ...
                                          'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                          'Value',  461)

            elseif contains(app.Task_Instrument.Value, {'FSL', 'FSVR', 'N9936B'})
                set(app.Task_StepWidth1,  'Editable', 1, ...
                                          'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                set(app.Task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                          'Value',  app.taskList{ind1}.Band(ind2).instrDataPoints)

            elseif contains(app.Task_Instrument.Value, 'EB500')
                set(app.Task_StepWidth2, 'Items', app.taskList{ind1}.Band(ind2).instrStepWidth_Items, ...
                                         'Value', app.taskList{ind1}.Band(ind2).instrStepWidth)
                app.Task_DataPoints2.Value = app.taskList{ind1}.Band(ind2).instrDataPoints;
                app.Task_Selectivity.Value = app.taskList{ind1}.Band(ind2).instrSelectivity;

            end
            set(app.Task_Resolution2, 'Items', app.taskList{ind1}.Band(ind2).instrResolution_Items, ...
                                      'Value', app.taskList{ind1}.Band(ind2).instrResolution)

            app.Task_Preamp.Value      = app.taskList{ind1}.Band(ind2).instrPreamp;
            app.Task_attMode.Value     = app.taskList{ind1}.Band(ind2).instrAttMode;
            app.Task_LevelUnit_2.Value = app.taskList{ind1}.Band(ind2).instrLevelUnit;

            set(app.Task_attFactor, 'Items', app.taskList{ind1}.Band(ind2).instrAttFactor_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrAttFactor)

            set(app.Task_Detector_2, 'Items', app.taskList{ind1}.Band(ind2).instrDetector_Items, ...
                                     'Value', app.taskList{ind1}.Band(ind2).instrDetector)

            if strcmp(app.Task_attMode.Value, 'Auto')
                app.Task_attFactorLabel.Visible = 0;
                app.Task_attFactor.Visible      = 0;
            else
                app.Task_attFactorLabel.Visible = 1;
                app.Task_attFactor.Visible      = 1;
            end

        end


        function Task_EditInfo(app)

            if app.Task_NewTask.Value || app.Task_newBand.Value || app.Task_editBand.Value
                set(app.Task_Name,              'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_BitsPerPoint,                     'BackgroundColor', [1,1,1], 'Items', {'8 bits', '16 bits', '32 bits'})
                set(app.Task_Description,       'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_F0,                'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_F1,                'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_StepWidth,         'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_Resolution1,       'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_IntegrationFactor, 'Editable', 1, 'BackgroundColor', [1,1,1])
                set(app.Task_RevisitTime,       'Editable', 1, 'BackgroundColor', [1,1,1])

                set(app.Task_RFMode,    'BackgroundColor', [1,1,1], 'Items', {'High Sensitivity', 'Normal', 'Low Distortion'})
                set(app.Task_TraceMode, 'BackgroundColor', [1,1,1], 'Items', {'ClearWrite', 'Average', 'MaxHold', 'MinHold'})
                set(app.Task_Detector,  'BackgroundColor', [1,1,1], 'Items', {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'})
                set(app.Task_LevelUnit, 'BackgroundColor', [1,1,1], 'Items', {'dBm', 'dBμV'})

                if     app.Task_NewTask.Value; set(app.Task_addBand, 'Text', 'Incluir tarefa', 'Icon', 'AddWhite3.png')
                elseif app.Task_newBand.Value; set(app.Task_addBand, 'Text', 'Incluir faixa',  'Icon', 'AddWhite3.png')
                else;                          set(app.Task_addBand, 'Text', 'Editar faixa',   'Icon', 'White_Edit.png')
                end

                app.Task_Tree1.Enable      = 0;
                app.Task_Tree2.Enable      = 0;
                app.Task_Instrument.Enable = 0;

                set(app.GridLayout5.Children, 'Enable', 0)
                
                app.tb_playback.Visible  = 0;
                app.Task_executeTask.Enable = 0;

            else
                ind1 = app.Task_Tree1.SelectedNodes.NodeData;
                set(app.Task_Name,              'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', app.taskList{ind1}.Name)
                set(app.Task_BitsPerPoint,                     'BackgroundColor', [.94,.94,.94], 'Items', {sprintf('%d bits', app.taskList{ind1}.BitsPerSample)})

                set(app.Task_Description,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_F0,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_F1,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_StepWidth,         'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_Resolution1,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_IntegrationFactor, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                set(app.Task_RevisitTime,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])

                set(app.Task_RFMode,    'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_RFMode.Value})
                set(app.Task_TraceMode, 'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_TraceMode.Value})
                set(app.Task_Detector,  'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_Detector.Value})
                set(app.Task_LevelUnit, 'BackgroundColor', [.94,.94,.94], 'Items', {app.Task_LevelUnit.Value})

                app.Task_Tree1.Enable      = 1;
                app.Task_Tree2.Enable      = 1;
                app.Task_Instrument.Enable = 1;

                set(app.GridLayout5.Children, 'Enable', 1)

                if ~isempty(app.Task_Instrument.Items)
                    ind2 = find(strcmp(app.instrInfo.Name, extractAfter(app.Task_Instrument.Value, ': ')), 1);
                    if isempty(app.instrInfo.StepWidth_Values{ind2}) && ~fix(diff(app.instrInfo.DataPoints_Limits{ind2}))
                        set(app.Task_StepWidth1, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
                    else
                        set(app.Task_StepWidth1, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    end
                    
                    app.tb_playback.Visible    = 1;
                    app.Task_executeTask.Enable   = 1;
                else
                    app.tb_playback.Visible    = 0;
                    app.Task_executeTask.Enable   = 0;
                end
                
                set(app.GridLayout19.Children, 'Enable', 1)
                
                if ~isempty(app.Task_Tree2.SelectedNodes)
                    ind3 = app.Task_Tree2.SelectedNodes.NodeData;
                    if isempty(app.taskList{ind1}.Band(ind3).Flag) || ~app.taskList{ind1}.Band(ind3).Flag
                        app.Task_RefreshButton.Enable = 0;
                    end

                    Task_Tree2SelectionChanged(app)
                end
            end
            Task_RightPanel_Grid(app)

        end


        function [ind1, ind2] = Task_WarnVisibility(app)

            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            ind2 = app.Task_Tree2.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).Flag = 1;
            app.Task_Warning2.Visible          = 1;
            app.Task_RefreshButton.Enable      = 1;

            app.specInfo = [];

        end


        function [Node, Localhost_publicIP] = Task_Startup(app, fileLog, Type)
            
            cla(app.axes1)
            cla(app.axes2)
            
            ind1 = str2double(extractBetween(app.Task_Instrument.Value, 'ID', ':'));        % app.instrList(ind1, :)
            ind3 = app.Task_Tree1.SelectedNodes.NodeData;                                   % app.taskList{ind3}
            ind4 = app.Task_Tree2.SelectedNodes.NodeData;                                   % app.taskList{ind3}.Band(ind4)

            % Garantir que o Anritsu não será utilizado numa tarefa com
            % mais de uma faixa...
            if contains(app.Task_Instrument.Value, 'MS2720T') && (numel(app.taskList{ind3}.Band) > 1)
                error(['O analisador de espectro fabricado pela Anritsu, modelo MS2720T, somente pode ser usado em tarefas '        ...
                       'que contemplem a monitoração de  uma única faixa de frequência, tendo em vista que a sua resposta ' ...
                       'à configuração dos parâmetros demora cerca de noventa segundos.'])
            end
            
            if strcmp(Type, 'InitialConnection')
                app.specInfo     = [];
                app.instrTimeout = app.instrList.Timeout(ind1);
            end
            
            [app.Instrument, Node, app.udpPortArray, ind, Localhost_publicIP] = Connection_Instrument(app.instrList(ind1,:), ...
                                                                                                      app.instrTimeout,      ...
                                                                                                      app.instrInfo,         ...
                                                                                                      app.udpPortArray,      ...
                                                                                                      'Task',                ...
                                                                                                      app.Task_ResetCommand.Value, ...
                                                                                                      app.Task_SyncRef.Value);
            
            if ~isempty(ind)
                app.udpPort = app.udpPortArray{ind};
                flush(app.udpPort)
            end

            ind2 = [];
            for ii = 1:height(app.instrInfo)
                if ~isempty(app.instrInfo.Tag{ii}) && contains(Node, app.instrInfo.Tag{ii})
                    ind2 = ii;                                                              % app.instrInfo(ind2, :)
                    break
                end
            end
            app.traceInfo.Index = [ind1, ind2, ind3, ind4];
            
            Plot_TraceInfo(app)
            
            if ~isempty(app.specInfo) && (app.taskFlag == 1 || numel(app.taskList{ind3}.Band) == 1)
                fprintf(app.Instrument, app.specInfo.scpiGeneral{ind4});
                
                if ~isempty(app.instrInfo.scpiAttenuation{ind2})
                    fprintf(app.Instrument, Misc_strReplaces(app.instrInfo.scpiAttenuation{ind2}, {'%AttenuationValue%'}, {num2str(app.specInfo.AttenuationValue(ind4))}));
                end
            end
            
            if app.taskFlag == 2
                Write_logFile(app, 'Body', fileLog, 'Equipament',  struct('Name',         Node,                                       ...
                                                                          'ConnectType',  app.instrList.Type{app.traceInfo.Index(1)}, ...
                                                                          'IP',           app.instrList.IP{app.traceInfo.Index(1)},   ...
                                                                          'Port',         str2double(app.instrList.Port{app.traceInfo.Index(1)}),           ...
                                                                          'Timeout',      app.instrTimeout,                                                 ...
                                                                          'InputBuffer',  sprintf('%d MB', app.instrList.inBuffer(app.traceInfo.Index(1))), ...
                                                                          'ResetCommand', app.Task_ResetCommand.Value,                                    ...
                                                                          'SyncMode',     app.Task_SyncRef.Value))
            end

        end


        function WaterfallPlotTask(app)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Iniciando...');
            try
                [Node, Localhost_publicIP] = Task_Startup(app, '', 'InitialConnection');
%               ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
                ind4 = app.traceInfo.Index(4);

                if isempty(app.specInfo)
                    app.specInfo = Task_MetaDataRead(app.Instrument, Node, app.instrInfo(ind2,:), app.taskList{ind3}.Band);
                end

                flushinput(app.Instrument)
                lastwarn('')

                % EB500 training...
                if app.instrInfo.connectFlag{ind2} == 2
                    while true
                        RegularTask_EB500_1(app, Localhost_publicIP, d)
                        break
                    end
                end
            
            catch ME
                instrreset

                Misc_uialertCreation(app, 'error', getReport(ME))
                Plot_PlayButtonPushed(app)
                
                return
            end
            delete(d)
            
            while app.plotFlag
                try
                    if app.axesFlag
                        ind4 = app.Task_Tree2.SelectedNodes.NodeData;
                        Plot_axesFlagValueChanged(app)                        
                    end

                    switch app.instrInfo.connectFlag{ind2}
                        case 1
                            fprintf(app.Instrument, app.instrInfo.scpiTraceData{ind2});
                            newArray = binblockread(app.Instrument, 'single');

                            if app.Instrument.BytesAvailable
                                flushinput(app.Instrument)
                            end

                            if isempty(newArray) || ...
                                    (numel(newArray) ~= app.specInfo.DataPoints(ind4)) || ...
                                    (sum(newArray)   == app.specInfo.SyncRef(ind4))
                                continue
                            else
                                app.specInfo.SyncRef(ind4) = sum(newArray);
                            end

                        case 2
                            newArray = RegularTask_EB500_2(app, ind4, Localhost_publicIP);
                    end

                    if ~isempty(app.axes1.Children); Plot_Update(app, newArray')
                    else;                            Plot_Creation(app, Node, newArray', ind3, ind4)
                    end
                    drawnow

                    % %         Recording...
                    if app.recFlag
                        if app.specMatrix.RecordSamples == app.specMatrix.HistoryDepth
                            Task_FileWriter_MAT73(app.specMatrix, app.menu_userPath.Value)

                            app.specMatrix.RecordSamples = 0;
                            drawnow
                        end
                    end

                catch ME
                    Selection = uiconfirm(app.MainApp, sprintf('%s\n\nDeseja tentar extrair dados do instrumento mais uma vez?', ME.message), 'appColeta', 'Icon', 'question', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1);
                    
                    switch Selection
                        case 'Sim'
                            try
                                Task_Startup(app, '', 'ErrorHandle');
                            catch ME
                                Misc_uialertCreation(app, 'error', getReport(ME))
                                Plot_PlayButtonPushed(app)
                            end
                    
                        case 'Não'
                            Plot_PlayButtonPushed(app)
                    end
                end
            end

            if app.instrInfo.connectFlag{ind2} == 2
                try
                    fprintf(app.Instrument, 'ABORT');
                catch 
                end
            end
            instrreset

        end
        
        
        function RegularTask(app, Duration_sec)

            % Antenna selection
            if isempty(app.Task_Antenna.Value)
                Misc_uialertCreation(app, 'error', 'Pendente indicação da antena...')
                Task_ButtonPushed(app)
                return
            end

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Iniciando...');

            try
                % Log file creation
                baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));
                
                fileLog  = fopen(fullfile(app.menu_userPath.Value, [baseName '_LogFile.json']), 'wt');
                fprintf(fileLog, sprintf('[\n'));
                
                app.Task_Info.URL = ['file:///' fullfile(app.menu_userPath.Value, [baseName '_LogFile.json'])];
                
                % Instrument connection
                [Node, Localhost_publicIP] = Task_Startup(app, fileLog, 'InitialConnection');
%               ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
%               ind4 = app.traceInfo.Index(4);

                % Receiver metadata
                app.specInfo = Task_MetaDataRead(app.Instrument, Node, app.instrInfo(ind2,:), app.taskList{ind3}.Band);

                % TimeIssue (mainRevisitTime and RevisitFactor)
                app.timeInfo = Task_timeIssue(app.Task_gpsRevisitTime.Value, app.taskList{ind3}, Duration_sec);
                Write_logFile(app, 'Body', fileLog, 'TimeIssue', struct('DesiredObservationTime', Duration_sec,                          ...
                                                                        'DesiredRevisitTime',     [app.taskList{ind3}.Band.RevisitTime], ...
                                                                        'GlobalRevisitTime',      app.timeInfo.RevisitTime,              ...
                                                                        'RevisitFactors',         app.timeInfo.RevisitFactor(2:end),     ...
                                                                        'EstimatedLoops',         max(app.timeInfo.EstimatedLoops)))

                % Timeout validation
                SampleTime_Max = ceil(max(app.specInfo.SampleTimeValue .* app.specInfo.AverageCount));
                if app.instrTimeout < 2*SampleTime_Max
                    app.instrTimeout = 2*SampleTime_Max;
                    Task_Startup(app, fileLog, 'ErrorHandle');
                end

                % GPS Data
                RegularTask_gps(app, Node)
                Write_logFile(app, 'Body', fileLog, 'gpsInfo', struct('Type',      app.gpsInfo.Type,     ...
                                                                      'Status',    app.gpsInfo.Status,   ...
                                                                      'Latitude',  app.gpsInfo.Latitude, ...
                                                                      'Longitude', app.gpsInfo.Longitude))

                % appColeta training (EB500 only)
                if contains(Node, 'EB500')
                    while true
                        RegularTask_EB500_1(app, Localhost_publicIP, d)
                        break
                    end
                end
                
            catch ME
                instrreset
                
                RegularTask_ErrorHandle1(app, fileLog, ME)
                Task_ButtonPushed(app)
                
                return
            end
            delete(d)

            % Bin file(s) creation
            RegularTask_FileCreation(app, fileLog, baseName)
            RegularTask_spec(app, Node, fileLog, Localhost_publicIP, Duration_sec)

            if app.instrInfo.connectFlag{ind2} == 2
                try
                    fprintf(app.Instrument, 'ABORT');
                catch                    
                end
            end
            instrreset

            app.taskFlag = 0;
            app.plotFlag = 0;
            app.axesFlag = 0;
            app.recFlag  = 0;
            
            Layout_TaskScreen(app)

        end


        function RegularTask_FileCreation(app, fileLog, baseName)

            ind3 = app.traceInfo.Index(3);
            
            for ii = 1:numel(app.taskList{ind3}.Band)
                app.specInfo.Filename{ii}  = sprintf('%s_ThreadID%d', baseName, app.taskList{ind3}.Band(ii).ThreadID);
                app.specInfo.FileCount(ii) = 1;

                RegularTask_FileCreationAux(app, fileLog, ii, 'InitialConnection')
            end

        end


        function RegularTask_FileCreationAux(app, fileLog, ii, Type)

            ind2 = app.traceInfo.Index(2);
            ind3 = app.traceInfo.Index(3);

            if     strcmp(app.Task_BitsPerPoint.Value,  '8 bits'); dataFormat = 'uint8';
            elseif strcmp(app.Task_BitsPerPoint.Value, '16 bits'); dataFormat = 'int16';
            elseif strcmp(app.Task_BitsPerPoint.Value, '32 bits'); dataFormat = 'single';
            end

            fileName = fullfile(app.menu_userPath.Value, sprintf('%s_%.0f.bin', app.specInfo.Filename{ii}, app.specInfo.FileCount(ii)));
            fileID   = fopen(fileName, 'w');

            switch Type
                case 'InitialConnection'
                    Write_logFile(app, 'Body', fileLog, sprintf('ThreadID%d', app.taskList{ind3}.Band(ii).ThreadID), ...
                                                        struct('File',           replace(fileName, '\', '/'), ...
                                                               'StartUp',        app.instrInfo.StartUp{ind2},   ...
                                                               'MainSet',        app.specInfo.scpiGeneral{ii},  ...
                                                               'MetaDataQuery',  app.instrInfo.scpiQuery{ind2}, ...
                                                               'TraceDataQuery', app.instrInfo.scpiTraceData{ind2}, ...
                                                               'MetaDataValues', jsonencode(app.specInfo(ii,2:19))))
                otherwise
                    Write_logFile(app, 'Body', fileLog, sprintf('ThreadID%d', app.taskList{ind3}.Band(ii).ThreadID), ...
                                                        struct('File',           replace(fileName, '\', '/')))
            end

            % MaxFileSize issue
            AlocatedSamples = ceil(app.General.FileSize ./ (app.taskList{ind3}.BitsPerSample/8 * app.taskList{ind3}.Band(ii).instrDataPoints));
            if AlocatedSamples > app.timeInfo.EstimatedLoops(ii+1)
                AlocatedSamples = app.timeInfo.EstimatedLoops(ii+1);
            end
            
            Write_formatHeader(app, fileID, AlocatedSamples)
            Write_metaHeader(app, fileID, app.specInfo(ii,:))
            Write_gpsHeader(app, fileID)

            [Offset1, Offset2] = Write_Body(app, fileID, ...
                                                 AlocatedSamples, ...
                                                 app.specInfo.DataPoints(ii), ...
                                                 app.taskList{ind3}.Name, ...
                                                 app.taskList{ind3}.Band(ii), ...
                                                 app.specInfo.Node{ii});
            fclose(fileID);

            % Memory Map File
            app.specInfo.AlocatedSamples{ii} = memmapfile(fileName, 'Writable', false, ...
                                                                    'Offset', 16,     ...
                                                                    'Format', {'uint32', 1, 'Value'}, ...
                                                                    'Repeat', 1);

            app.specInfo.WritedSamples{ii}   = memmapfile(fileName, 'Writable', true, ...
                                                                    'Offset', 20,     ...
                                                                    'Format', {'uint32', 1, 'Value'}, ...
                                                                    'Repeat', 1);

            app.specInfo.memmapData{ii}{1}   = memmapfile(fileName, 'Writable', true,  ...
                                                                    'Offset', Offset1, ...
                                                                    'Format', {'int8',   [1  6], 'localTimeStamp';    ...
                                                                               'int16',  [1  1], 'localTimeStamp_ms'; ...
                                                                               'int16',  [1  1], 'RefLevel';          ...
                                                                               'uint8',  [1  1], 'attFactor';         ...
                                                                               'uint8',  [1  1], 'gpsStatus';         ...
                                                                               'single', [1  1], 'Latitude';          ...
                                                                               'single', [1  1], 'Longitude'},        ...
                                                                    'Repeat', AlocatedSamples);

            app.specInfo.memmapData{ii}{2}   = memmapfile(fileName, 'Writable', true,  ...
                                                                    'Offset', Offset2, ...
                                                                    'Format', {dataFormat, [app.specInfo.DataPoints(ii) AlocatedSamples], 'Array'}, ...
                                                                    'Repeat', 1);

        end


        function RegularTask_ErrorHandle1(app, fileLog, ME)

            Write_logFile(app, 'Trailer', fileLog, Misc_strReplaces(ME.identifier, {'"', ',', newline}, {'', '', ''}), ...
                                                   Misc_strReplaces(ME.message,    {'"', ',', newline}, {'', '', ''}))
            Misc_uialertCreation(app, 'error', getReport(ME))

        end
        

        function RegularTask_ErrorHandle2(app, fileLog, ME)

            Write_logFile(app, 'Body', fileLog, 'Equipament', Misc_strReplaces(ME.message, {'"', ',', newline}, {'', '', ''}))

            while true
                lastwarn('')
                if ~app.taskFlag; return
                end

                app.instrTimeout = app.instrTimeout + 1;
                try
                    Task_Startup(app, fileLog, 'ErrorHandle');
                    break
                catch
                end
            end

        end


        function RegularTask_gps(app, Node)

            lastwarn('')
            try
                gpsIndex   = str2double(extractBetween(app.Task_GPS.Value, 'ID', ':'));

                if gpsIndex == 0
                    app.gpsInfo.Status    = -1;
                    app.gpsInfo.Latitude  = app.Task_manualLatitude.Value;
                    app.gpsInfo.Longitude = app.Task_manualLongitude.Value;
                    app.gpsInfo.TimeStamp = [];
                    app.gpsInfo.Type      =  0;

                elseif gpsIndex == app.traceInfo.Index(1)
                    d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Title', 'appColeta',                                        ...
                                                   'Message', 'Tentando extrair informação válida do GPS embarcado no instrumento...', ...
                                                   'Cancelable', 'on',                                                                 ...
                                                   'CancelText', 'Cancelar');
                    while true
                        app.gpsInfo = Task_gpsDecode(app.Instrument, Node, 'Full');
                        app.gpsInfo.Type = 1;
                        
                        if app.gpsInfo.Status || d.CancelRequested
                            break
                        else
                            pause(3)
                        end
                    end

                else
                    [app.gpsInstrument, app.gpsInfo] = Connection_gps(app.instrList.Type{gpsIndex}, app.instrList.IP{gpsIndex}, app.instrList.Port{gpsIndex}, app.instrList.Timeout(gpsIndex), app.instrList.inBuffer(gpsIndex));
                    app.gpsInfo.Type = 2;
                end

            catch ME
                error(struct('message', ME.message, 'identifier', ME.identifier))
            end

        end

        function RegularTask_spec(app, Node, fileLog, Localhost_publicIP, Duration_sec)
            
%           ind1 = app.traceInfo.Index(1);
            ind2 = app.traceInfo.Index(2);
            ind3 = app.traceInfo.Index(3);
            ind4 = app.traceInfo.Index(4);
            Band = numel(app.taskList{ind3}.Band);

            flushinput(app.Instrument)
            lastwarn('')

            TimeFinish     = datetime('now') + seconds(Duration_sec);
            EstimatedLoops = max(app.timeInfo.EstimatedLoops);
                        
            app.Task_Info.Text = sprintf('Loop %d de %d (%.0f%%)', 0, EstimatedLoops, 0);
            Write_logFile(app, 'Body', fileLog, 'WritedSamples', sprintf('%d of %d', 0, EstimatedLoops))
            
%             statusFlag = 1;
%             if isempty(app.instrInfo.scpiStatus{ind2})
%                 statusFlag = 0;
%             end
            
            ii = 0;
            while app.taskFlag && (datetime('now') < TimeFinish)
%                 if statusFlag
%                     while true
%                         errorMsg = deblank(query(app.Instrument, app.instrInfo.scpiStatus{ind2}));
%                         
%                         if isempty(errorMsg) || contains(errorMsg, '0,"No error"')
%                             break
%                         else
%                             Write_logFile(app, 'Body', fileLog, 'Equipament', errorMsg)
%                         end
%                     end
%                 end

                tic
                for jj = 0:Band
                    if mod(ii, app.timeInfo.RevisitFactor(jj+1))
                        continue
                    end

                    if jj == 0
                        try
                            switch app.gpsInfo.Type
                                case 0; gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                                case 1; gpsData = Task_gpsDecode(app.Instrument, Node, 'Essential');
                                case 2; gpsData = Task_gpsReader(app.gpsInstrument, 1);
                            end
                        catch ME
                            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                            Write_logFile(app, 'Body', fileLog, 'gpsInfo', Misc_strReplaces(ME.message, {'"', ',', newline}, {'', '', ''}))
                        end
                    else
                        while true
                            try
                                if Band > 1
                                    fprintf(app.Instrument, app.specInfo.scpiGeneral{jj});
                                    pause(0.001)
                                    
                                    if ~isempty(app.instrInfo.scpiAttenuation{ind2})
                                        fprintf(app.Instrument, Misc_strReplaces(app.instrInfo.scpiAttenuation{ind2}, {'%AttenuationValue%'}, {num2str(app.specInfo.AttenuationValue(jj))}));
                                    end
                                end
                                
                                switch app.instrInfo.connectFlag{ind2}
                                    case 1
                                        fprintf(app.Instrument, app.instrInfo.scpiTraceData{ind2});
                                        newArray = binblockread(app.Instrument, 'single');

                                        if app.Instrument.BytesAvailable
                                            flushinput(app.Instrument)
                                        end                                        

                                        if isempty(newArray) || ...
                                                (numel(newArray) ~= app.specInfo.DataPoints(jj)) || ...
                                                (sum(newArray)   == app.specInfo.SyncRef(jj))
                                            continue
                                        else
                                            app.specInfo.SyncRef(jj) = sum(newArray);
                                        end
            
                                    case 2
                                        newArray = RegularTask_EB500_2(app, jj, Localhost_publicIP);
                                end
                                
                                if app.plotFlag && (jj == ind4)
                                    if app.axesFlag
                                        ind4 = app.Task_Tree2.SelectedNodes.NodeData;
                                        Plot_axesFlagValueChanged(app)
                                        
                                    else
                                        if ~isempty(app.axes1.Children)
                                            Plot_Update(app, newArray')
                                        else
                                            Plot_Creation(app, Node, newArray', ind3, ind4)
                                        end
                                    end
                                    drawnow
                                end
                                
                                attFactor = str2double(deblank(query(app.Instrument, app.instrInfo.scpiQuery_Attenuation{ind2})));
                                Offset    = max(newArray);

                                if isempty(lastwarn)
                                    Write_specData(app, jj, Offset, attFactor, gpsData, newArray, fileLog)
                                    break
                                else
                                    [msg, warnID] = lastwarn;
                                    error(struct('message', msg, 'identifier', warnID))
                                end

                            catch ME
                                RegularTask_ErrorHandle2(app, fileLog, ME)
                            end
                        end
                    end
                end
                ii = ii+1;

                app.Task_Info.Text = sprintf('Loop %d de %d (%.0f%%)', ii, EstimatedLoops, ceil(100*ii/EstimatedLoops));
                drawnow limitrate
                
                pause(app.timeInfo.RevisitTime - toc)
            end
            Write_logFile(app, 'Trailer', fileLog, 'WritedSamples', sprintf('%d of %d', ii, EstimatedLoops))

        end


        function RegularTask_EB500_1(app, Localhost_publicIP, d)

            lastwarn('')

            for ii = 1:numel(app.taskList{app.traceInfo.Index(3)}.Band)
                set(d, 'Cancelable', 'on', 'Indeterminate', 'off', 'Value', 0, ...
                       'Title', sprintf('ThreadID %.0f: %.3f - %.3f MHz', app.taskList{app.traceInfo.Index(3)}.Band(ii).ThreadID, ...
                                                                          app.taskList{app.traceInfo.Index(3)}.Band(ii).FreqStart/1e+6, ...
                                                                          app.taskList{app.traceInfo.Index(3)}.Band(ii).FreqStop /1e+6), ...
                       'Message', 'Em andamento a estimativa do número de datagramas que representam um único traço...', ...
                       'CancelRequested', 0, 'CancelText', 'Cancelar')

                fprintf(app.Instrument, app.specInfo.scpiGeneral{ii});

                fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:ON "%s", 24001, PSCAN', Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:ON "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
                
                pause(1)
                while true
                    if d.CancelRequested
                        error('Cancelado pelo usuário.')
                    end

                    value = app.udpPort.NumDatagramsAvailable/100;
                    if value > 1; value = 1;
                    end
                    set(d, 'Value', value)

                    if app.udpPort.NumDatagramsAvailable > 100
                        break
                    else
                        pause(1)
                    end
                end
                specDatagrama = read(app.udpPort, app.udpPort.NumDatagramsAvailable);

                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    error(struct('message', msg, 'identifier', warnID))
                end

                Terminator = [];
                for jj = 1:numel(specDatagrama)
                    specDatagrama(jj).Data = uint8(specDatagrama(jj).Data);

                    MagicNumber = typecast(specDatagrama(jj).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(jj).Data(16:-1:13), 'uint32');

                    F0_low    = double(typecast(specDatagrama(jj).Data(32:-1:29), 'uint32'));
                    F0_high   = double(typecast(specDatagrama(jj).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth = typecast(specDatagrama(jj).Data(40:-1:37), 'uint32');

                    F0 = F0_low + F0_high;

                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(jj).Data)) || (app.specInfo.FreqStart(ii) ~= F0) || (app.specInfo.StepWidth(ii) ~= StepWidth)
                        continue
                    end

                    Terminator(end+1, 1) = typecast(specDatagrama(jj).Data(end:-1:end-1), 'uint16');
                end

                if isempty(Terminator)
                    app.specInfo.DatagramasPerTrace(ii) = 1;
                else
                    app.specInfo.DatagramasPerTrace(ii) = round(numel(specDatagrama)./numel(find(Terminator(:,1) == 2000)));
                end
                
                fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:OFF "%s", 24001, PSCAN', Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:OFF "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:DEL "%s", 24001', Localhost_publicIP));
                
                flush(app.udpPort)
            end
            set(d, 'Cancelable', 'off', 'Title', '', 'Value', 0, 'Indeterminate', 'on')

        end


        function specData = RegularTask_EB500_2(app, ind, Localhost_publicIP)

            lastwarn('')
            specData = zeros(app.specInfo.DataPoints(ind), 1);
            N = 0;
            
            fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:ON "%s", 24001, PSCAN', Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:ON "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));

            while true
                NumberOfDatagrams = (2+N) .* app.specInfo.DatagramasPerTrace(ind) - 1;
                specDatagrama     = read(app.udpPort, NumberOfDatagrams);

                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    error(struct('message', msg, 'identifier', warnID))
                end

                udpFlag         = 0;
                Points          = 0;
                DatagramaNumber = 0;

                for ii = 1:numel(specDatagrama)
                    specDatagrama(ii).Data = uint8(specDatagrama(ii).Data);

                    SequenceNumber_low  = double(typecast(specDatagrama(ii).Data(10:-1: 9), 'uint16'));
                    SequenceNumber_high = double(typecast(specDatagrama(ii).Data(12:-1:11), 'uint16')).*2^16;
                    SequenceNumber      = SequenceNumber_low + SequenceNumber_high;

                    if udpFlag & (SequenceNumber ~= DatagramaNumber+1)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;

                        if ii ~= numel(specDatagrama)
                            continue
                        else
                            N = N+1;
                            break
                        end
                    end

                    MagicNumber = typecast(specDatagrama(ii).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(ii).Data(16:-1:13), 'uint32');
                    F0_low      = double(typecast(specDatagrama(ii).Data(32:-1:29), 'uint32'));
                    F0_high     = double(typecast(specDatagrama(ii).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth   = typecast(specDatagrama(ii).Data(40:-1:37), 'uint32');

                    F0 = F0_low + F0_high;

                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(ii).Data)) || (app.specInfo.FreqStart(ind) ~= F0) || (app.specInfo.StepWidth(ind) ~= StepWidth)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;

                        continue
                    end
                    Terminator = typecast(specDatagrama(ii).Data(end:-1:end-1), 'uint16');

                    auxIndex1 = Points + 1;
                    Points    = Points + typecast(specDatagrama(ii).Data(22:-1:21), 'uint16');
                    auxIndex2 = Points;

                    if Terminator == 2000
                        if Points == app.specInfo.DataPoints(ind)+1
                            specData(auxIndex1:auxIndex2-1) = (double(flip(typecast(specDatagrama(ii).Data((end-2):-1:81),'int16')))./10)';

                            N       = 0;
                            udpFlag = 2;
                            break

                        else
                            udpFlag = 0;
                        end
                        Points = 0;

                    else
                        udpFlag = 1;
                        DatagramaNumber = SequenceNumber;
                        specData(auxIndex1:auxIndex2) = (double(flip(typecast(specDatagrama(ii).Data(end:-1:81),'int16')))./10)';
                    end
                end

                if udpFlag == 2
                    break
                end
            end
            
            fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:OFF "%s", 24001, PSCAN', Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:OFF "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:DEL "%s", 24001', Localhost_publicIP));
            
            flush(app.udpPort)

        end


        function Plot_TraceInfo(app)

            if app.tb_minHold.State
                app.traceInfo.Mode(1) = 1;
            else
                app.traceInfo.Mode(1) = 0;
                
                app.line_minHold = [];
                delete(findobj('Tag', 'MinHold'))
            end
            
            if app.tb_meanHold.State
                app.traceInfo.Mode(2) = 2;
            else
                app.traceInfo.Mode(2) = 0;
                
                app.line_Average = [];
                delete(findobj('Tag', 'Average'))
            end
            
            if app.tb_maxHold.State
                app.traceInfo.Mode(3) = 3;
            else
                app.traceInfo.Mode(3) = 0;
                
                app.line_maxHold = [];
                delete(findobj('Tag', 'MaxHold'))
            end

            if app.tb_waterfall.State
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
                
                app.surface_wFall = [];
                delete(findobj('Tag', 'wFall'))
            end

        end


        function Plot_SpecMatrix(app, Node, newArray)

            app.specMatrix = struct('Node',        '', 'ThreadID',     [], 'MetaData',        [], 'Samples',       [], ...
                                    'Data',        {}, 'statsData',    [], 'FileFormat',      '', 'TaskName',      '', ...
                                    'Description', '', 'gps',          [], 'ObservationTime', '', 'Blocks',        [], ...
                                    'Index',       [], 'HistoryDepth', [], 'RecordSamples',   [], 'WritedSamples', [], 'SampleTime', []);

            app.specMatrix(1).Node  = Node;
            app.specMatrix.MetaData = struct('DataType',   [], 'ThreadID',   [], 'FreqStart',  [], 'FreqStop',   [], 'LevelUnit',  [], 'DataPoints', [], ...
                                             'Resolution', [], 'SampleTime', [], 'Threshold',  [], 'TraceMode',  [], 'Detector',   [], 'metaString', []);

            app.specMatrix.MetaData(1).DataType = 1;

            if app.Button_Task.Value
                ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
                ind4 = app.traceInfo.Index(4);
                
                app.specMatrix(1).Index = [ind1, ind2, ind3, ind4];
                
                if app.taskFlag == 1
                    Samples = fix(app.General.FileSize/(4*app.specInfo.DataPoints(ind4)));
                    if Samples < app.General.HistoryDepth; Samples = app.General.HistoryDepth;
                    end
                    
                    app.specMatrix.FileFormat  = 'RFlookMAT v.1/1';
                    app.specMatrix.TaskName    = app.taskList{ind3}.Name;
                    app.specMatrix.Description = app.taskList{ind3}.Band(ind4).Description;

                    app.specMatrix.gps = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'Location', '', 'Count', 0);
                    if strcmp(app.Task_GPS.Value, 'ID 0: Manual') && (app.Task_manualLatitude.Value ~= -1) && (app.Task_manualLongitude.Value ~= -1)
                        City = Misc_FindCity(struct('Latitude', app.Task_manualLatitude.Value, 'Longitude', app.Task_manualLongitude.Value));

                        app.specMatrix.gps = struct('Status', -1, 'Latitude', app.Task_manualLatitude.Value, 'Longitude', app.Task_manualLongitude.Value, 'Location',  City, 'Count', 1);
                    end
                    
                else
                    Samples = app.General.HistoryDepth;
                end
                
                app.specMatrix.ThreadID = app.taskList{ind3}.Band(ind4).ThreadID;

                app.specMatrix.MetaData.ThreadID   = app.specMatrix.ThreadID;
                app.specMatrix.MetaData.FreqStart  = app.specInfo.FreqStart(1);
                app.specMatrix.MetaData.FreqStop   = app.specInfo.FreqStop(1);

                app.specMatrix.MetaData.DataPoints = app.taskList{ind3}.Band(ind4).instrDataPoints;
                app.specMatrix.MetaData.Resolution = app.specInfo.ResolutionValue(1);
                app.specMatrix.MetaData.SampleTime = app.specInfo.SampleTimeValue(1);
                
                switch app.taskList{ind3}.Band(ind4).instrLevelUnit
                    case 'dBm';    app.specMatrix.MetaData.LevelUnit = 1;
                    case 'dBμV';   app.specMatrix.MetaData.LevelUnit = 2;
                    case 'dBμV/m'; app.specMatrix.MetaData.LevelUnit = 3;
                end
                app.specMatrix.MetaData.metaString    = {app.taskList{ind3}.Band(ind4).instrLevelUnit};
                
                app.specMatrix.MetaData.metaString{2} = app.taskList{ind3}.Band(ind4).instrResolution;
                
                switch app.taskList{ind3}.Band(ind4).TraceMode
                    case 'ClearWrite'; app.specMatrix.MetaData.TraceMode = 1;
                    case 'Average';    app.specMatrix.MetaData.TraceMode = 2;
                    case 'MaxHold';    app.specMatrix.MetaData.TraceMode = 3;
                    case 'MinHold';    app.specMatrix.MetaData.TraceMode = 4;
                end
                app.specMatrix.MetaData.metaString{3} = app.taskList{ind3}.Band(ind4).TraceMode;

                switch app.taskList{ind3}.Band(ind4).Detector
                    case 'Sample';        app.specMatrix.MetaData.Detector = 1;
                    case 'Average/RMS';   app.specMatrix.MetaData.Detector = 2;
                    case 'Positive Peak'; app.specMatrix.MetaData.Detector = 3;
                    case 'Negative Peak'; app.specMatrix.MetaData.Detector = 4;
                end
                app.specMatrix.MetaData.metaString{4} = app.taskList{ind3}.Band(ind4).Detector;
                app.specMatrix.MetaData.metaString{5} = app.Task_Antenna.Value;

                DataPoints = app.specInfo.DataPoints(ind4);

            elseif app.Button_Logger.Value
                ind = app.traceInfo.Index;
                
                app.specMatrix.Index    = ind;
                app.specMatrix.ThreadID = app.bandList(ind).ThreadID;

                app.specMatrix.MetaData.ThreadID  = app.bandList(ind).ThreadID;
                app.specMatrix.MetaData.FreqStart = app.bandList(ind).FreqStart;
                app.specMatrix.MetaData.FreqStop  = app.bandList(ind).FreqStop;

                switch app.bandList(ind).LevelUnit
                    case 'dBm';    app.specMatrix.MetaData.LevelUnit = 1;
                    case 'dBμV';   app.specMatrix.MetaData.LevelUnit = 2;
                    case 'dBμV/m'; app.specMatrix.MetaData.LevelUnit = 3;
                end
                
                app.specMatrix.MetaData.DataPoints    = app.bandList(ind).DataPoints;
                app.specMatrix.MetaData.Resolution    = app.bandList(ind).Resolution;
                
                app.specMatrix.MetaData.metaString    = {app.bandList(ind).LevelUnit};
                app.specMatrix.MetaData.metaString{2} = sprintf('%.3f kHz', app.bandList(ind).Resolution / 1000);
                app.specMatrix.MetaData.metaString{3} = app.bandList(ind).Operation;
                app.specMatrix.MetaData.metaString{4} = '';
                app.specMatrix.MetaData.metaString{5} = app.Task_Antenna.Value;
                
                DataPoints = app.bandList(ind).DataPoints;
                Samples    = app.General.HistoryDepth;
            end
            
            app.specMatrix.Data         = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, Samples), ...
                                           min(newArray) .* ones(Samples, DataPoints, 'single')};
            app.specMatrix.Data{1}(1)   = datetime('now');
            app.specMatrix.Data{2}(1,:) = newArray;

            app.specMatrix.HistoryDepth  = Samples;
            app.specMatrix.RecordSamples = 1;
            app.specMatrix.WritedSamples = 1;
            app.specMatrix.SampleTime    = 0;

        end


        function Plot_Creation(app, Node, newArray, ind3,  ind4)

            Plot_StartUp(app)
            Plot_Layout1(app)
            Plot_Layout2(app, Node, newArray, ind3, ind4)

            if app.Button_Task.Value
                app.x = round(linspace(app.specInfo.FreqStart(ind4) ./ 1e+6, ...
                                       app.specInfo.FreqStop(ind4)  ./ 1e+6, ...
                                       app.specInfo.DataPoints(ind4)), 3);
            else
                app.x = round(linspace(app.bandList(ind4).FreqStart ./ 1e+6, ...
                                       app.bandList(ind4).FreqStop  ./ 1e+6, ...
                                       app.bandList(ind4).DataPoints), 3);
            end


            % %         Axes 1: main lines
            app.line_ClrWrite = plot(app.axes1, app.x, newArray, 'Tag', 'ClearWrite', 'Color', app.General.Colors(4,:));
            Misc_PlotDataTip(app.line_ClrWrite, app.Task_LevelUnit_2.Value)

            if app.traceInfo.Mode(1)
                app.line_minHold = plot(app.axes1, app.x, newArray, 'Tag', 'MinHold', 'Color', app.General.Colors(1,:));
                Misc_PlotDataTip(app.line_minHold, app.Task_LevelUnit_2.Value)
            end

            if app.traceInfo.Mode(2)
                app.line_Average = plot(app.axes1, app.x, newArray, 'Tag', 'Average', 'Color', app.General.Colors(2,:));
                Misc_PlotDataTip(app.line_Average, app.Task_LevelUnit_2.Value)
            end

            if app.traceInfo.Mode(3)
                app.line_maxHold = plot(app.axes1, app.x, newArray, 'Tag', 'MaxHold', 'Color', app.General.Colors(3,:));
                Misc_PlotDataTip(app.line_maxHold, app.Task_LevelUnit_2.Value)
            end


            % %         Axes2: Waterfall
            if app.traceInfo.plotLayout; Plot_Creation_wFall(app)
            end

        end


        function Plot_Creation_wFall(app)

            ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
            if ~ind; ind = app.specMatrix.HistoryDepth;
            end

            indMatrix = ind-app.General.HistoryDepth+1:ind;
            indFind   = find(indMatrix <= 0);
            if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
            end

            app.surface_wFall = image(app.axes2, app.x, 1:app.General.HistoryDepth, app.specMatrix.Data{2}(indMatrix,:), 'CDataMapping', 'scaled', 'Tag', 'wFall');
            set(app.axes2, 'XLim', app.RestoreView{1}, 'View', [0, 90]);
            drawnow nocallbacks

        end


        function Plot_StartUp(app)

            app.fig.Visible = 1;
            figure(app.fig)

            app.line_ClrWrite = [];
            app.line_minHold  = [];
            app.line_Average  = [];
            app.line_maxHold  = [];
            app.surface_wFall = [];

        end


        function Plot_Layout1(app)

            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .08 .85 .8];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.cBar1.Position      = [0 0 0 0];

                    app.axes1.XTickLabelMode = 'auto';

                    xlabel(app.axes1, 'Frequência (MHz)')

                case 1
                    app.axes1.InnerPosition = [.08   .48 .85   .4];
                    app.axes2.InnerPosition = [.08   .08 .85   .38];
                    app.cBar1.Position      = [.9405 .08 .0145 .10];

                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabelMode = 'auto';

                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (MHz)')
            end

        end


        function Plot_Layout2(app, Node, newArray, ind3, ind4)
            
            if app.Button_Task.Value
                LevelUnit  = app.specInfo.LevelUnit{ind4};
                FreqStart  = app.specInfo.FreqStart(ind4) ./ 1e+6;
                FreqStop   = app.specInfo.FreqStop(ind4)  ./ 1e+6;
                TraceMode  = app.taskList{ind3}.Band(ind4).TraceMode;
                Detector   = app.taskList{ind3}.Band(ind4).instrDetector;
                Resolution = app.taskList{ind3}.Band(ind4).instrResolution;
                DataPoints = app.taskList{ind3}.Band(ind4).instrDataPoints;
                
                ThreadID   = ind4;
            else
                LevelUnit  = app.bandList(ind4).LevelUnit;
                FreqStart  = app.bandList(ind4).FreqStart ./ 1e+6;
                FreqStop   = app.bandList(ind4).FreqStop  ./ 1e+6;
                TraceMode  = app.bandList(ind4).Operation;
                Detector   = '';
                Resolution = sprintf('%.3f kHz', app.bandList(ind4).Resolution  ./ 1000);
                DataPoints = app.bandList(ind4).DataPoints;
                
                ThreadID   = app.bandList(ind4).ThreadID;
            end

            % %         SpecMatrix creation
            Plot_SpecMatrix(app, Node, newArray)

            switch LevelUnit
                case {'dBm',  'DBM'};  upYLim = -20; strUnit = 'dBm';
                case {'dBµV', 'DBUV'}; upYLim =  87; strUnit = 'dBµV';
                case 'dBµV/m';         upYLim = 100; strUnit = 'dBµV/m';
            end
            downYLim = min(newArray) - mod(min(newArray), 10);
            app.RestoreView = {[FreqStart, FreqStop], [downYLim, upYLim]};

            % % Axes1 (Spectrum plot): Figure name, title and subtitle.
            app.fig.Name = sprintf('ThreadID %.0f: %.3f - %.3f MHz', ThreadID, FreqStart, FreqStop);

            if app.Button_Task.Value
                titleStr = sprintf('%s\nModo: %s. Detector: %s. Resolução: %s. %d pontos por traço.', Node, TraceMode, Detector, Resolution, DataPoints);
            else
                titleStr = sprintf('%s\nOperação: %s. Resolução: %s. %d pontos por traço.',           Node, TraceMode,           Resolution, DataPoints);
            end         
            subtitleStr = sprintf('%s (%d de %d)', datestr(app.specMatrix.Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), ...
                                                   1, app.specMatrix.HistoryDepth);

            title(app.axes1, titleStr, subtitleStr);

            set(app.axes1, XLim=app.RestoreView{1}, YLim=app.RestoreView{2})
            ylabel(app.axes1, sprintf('Nível (%s)', strUnit));

            % %          Axes 2 (WaterFall plot)
            if app.tb_waterfall.State; Plot_Layout2_wFall(app)
            end

        end


        function Plot_Layout2_wFall(app)

            if diff(app.axes1.YLim) > 100
                Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                Ls = app.axes1.YLim(2);
            else
                Li = app.axes1.YLim(1);
                Ls = app.axes1.YLim(2);
            end
            caxis(app.axes2, [Li Ls])
            colormap(app.axes2, app.General.ColorMap);

            set(app.cBar1, Limits=[Li, Ls], Ticks=[Li, Ls], TickLabels={num2str(Li), num2str(Ls)})
            set(app.axes2, XLim=app.axes1.XLim, YLim=[1, app.General.HistoryDepth], YTick=round(linspace(1,app.General.HistoryDepth, 6)))
            ylabel(app.axes2, 'Amostras');

        end


        function Plot_Update(app, newArray)

            app.specMatrix.WritedSamples = app.specMatrix.WritedSamples+1;
            app.specMatrix.RecordSamples = app.specMatrix.RecordSamples+1;
            if app.specMatrix.RecordSamples > app.specMatrix.HistoryDepth; app.specMatrix.RecordSamples = app.specMatrix.HistoryDepth;
            end

            ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
            if ~ind; ind = app.specMatrix.HistoryDepth;
            end

            indMatrix = ind-app.General.HistoryDepth+1:ind;
            indFind   = find(indMatrix <= 0);
            if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
            end

            app.specMatrix.Data{1}(ind)   = datetime('now');
            app.specMatrix.Data{2}(ind,:) = newArray;

            app.specMatrix.SampleTime  = ((app.General.SampleTimeFactor-1)*app.specMatrix.SampleTime + str2double(datestr(app.specMatrix.Data{1}(indMatrix(end))-app.specMatrix.Data{1}(indMatrix(end-1)), 'ss.FFF'))*1000) / app.General.SampleTimeFactor;

            % %         Axes 1: main lines
            app.line_ClrWrite.YData = app.specMatrix.Data{2}(ind,:);

            if ~isempty(app.line_minHold); app.line_minHold.YData = min(app.line_minHold.YData, app.specMatrix.Data{2}(ind,:));
            end
            if ~isempty(app.line_Average); app.line_Average.YData = ((app.General.AverageFactor-1)*app.line_Average.YData + app.specMatrix.Data{2}(ind,:)) / app.General.AverageFactor;
            end            
            if ~isempty(app.line_maxHold); app.line_maxHold.YData = max(app.line_maxHold.YData, app.specMatrix.Data{2}(ind,:));
            end
            
            % %         Axes2: Waterfall
            if ~isempty(app.surface_wFall); app.surface_wFall.CData = app.specMatrix.Data{2}(indMatrix,:);
            end

            % %         Subtitle
            app.axes1.Subtitle.String = sprintf('%s (%d de %d). Tempo de escrita: %.0f ms.',                 ...
                                                datestr(app.specMatrix.Data{1}(ind), 'dd/mm/yyyy HH:MM:SS'), ...
                                                app.specMatrix.RecordSamples, app.specMatrix.HistoryDepth, app.specMatrix.SampleTime);

        end


        function Plot_axesFlagValueChanged(app)
            
            switch app.axesFlagType
                case 'SelectedNode'
                    cla(app.axes1)
                    cla(app.axes2)
                    
                    if app.Button_Task.Value
                        ind4 = app.Task_Tree2.SelectedNodes.NodeData;
                        
                        app.Task_Tree2.Children(app.traceInfo.Index(4)).Icon = '';
                        app.Task_Tree2.SelectedNodes.Icon = 'mainMenu_Playback.png';

                        app.traceInfo.Index(4) = ind4;
                        
                        if app.taskFlag == 1
                            fprintf(app.Instrument, app.specInfo.scpiGeneral{ind4});
                            
                            if ~isempty(app.instrInfo.scpiAttenuation{app.traceInfo.Index(2)})
                                fprintf(app.Instrument, sprintf('%s %d', app.instrInfo.scpiAttenuation{app.traceInfo.Index(2)}, app.specInfo.AttenuationValue(ind4)));
                            end
                        end
                        
                    else
                        ind4 = app.logger_Tree.SelectedNodes.NodeData;
                        
                        if isempty(app.bandList(app.traceInfo.Index).REC); app.logger_Tree.Children(app.traceInfo.Index).Icon = '';
                        else;                                              app.logger_Tree.Children(app.traceInfo.Index).Icon = 'REC2a.png';
                        end
                        
                        if isempty(app.bandList(ind4).REC); app.logger_Tree.SelectedNodes.Icon = 'mainMenu_Playback.png';
                        else;                               app.logger_Tree.SelectedNodes.Icon = 'mainMenu_REC.png';
                        end

                        app.traceInfo.Index = ind4;
                    end
                    
                case 'TraceProcessing'
                    Plot_TraceInfo(app)
                    
                case 'Waterfall'
                    Plot_TraceInfo(app)
                    Plot_Layout1(app)
                    Plot_Layout2_wFall(app)
            end
            
            app.axesFlag = 0;
            drawnow nocallbacks

        end


        function Playback_xLimits(app, src, evt)

            app.Playback_xLim1.Value = round(app.axes1.XLim(1), 3);
            app.Playback_xLim2.Value = round(app.axes1.XLim(2), 3);

        end
                
        
        function Playback_yLimits(app, src, evt)

            app.Playback_yLim1.Value = round(double(app.axes1.YLim(1)), 1);
            app.Playback_yLim2.Value = round(double(app.axes1.YLim(2)), 1);

        end


        function Playback_CloseFigure(app, src, event)

            switch event.EventName
                case 'Close'
                    if app.plotFlag
                        toolbarCallbacks_playback(app)
                    end
                    
                    app.fig.Visible       = 0;
                    app.mainInfo_Visibility.ImageSource = 'play_Unview.png';

                case 'Open'
                    app.fig.Visible       = 1;
                    app.mainInfo_Visibility.ImageSource = 'play_View.png';
            end
            
        end


        function Playback_PlotReload(app)

            set(app.fig, 'Visible', 0, 'UserData', [])
            app.mainInfo_Visibility.ImageSource = 'view4.png';

            app.axes1.Title.String    = '';
            app.axes1.Subtitle.String = '';
            
            cla(app.axes1)
            cla(app.axes2)

        end


        function Write_logFile(app, Type, fileLog, identifier, message)

            strPattern = '%s,\n';
            if strcmp(Type, 'Trailer'); strPattern = '%s\n]';
            end

            fprintf(fileLog, sprintf(strPattern, jsonencode(struct('time', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ...
                                                                   'identifier', identifier,                        ...
                                                                   'message',    message), 'PrettyPrint', true)));

            if strcmp(Type, 'Trailer'); fclose(fileLog);
            end

        end


        function Write_specData(app, ind1, RefLevel, attFactor, gpsData, specData, fileLog)

            TimeStamp = datetime('now');

            if app.specInfo.WritedSamples{ind1}.Data.Value == app.specInfo.AlocatedSamples{ind1}.Data.Value
                app.specInfo.FileCount(ind1) = app.specInfo.FileCount(ind1)+1;
                RegularTask_FileCreationAux(app, fileLog, ind1, 'NewFile')
            end

            ind2 = app.specInfo.WritedSamples{ind1}.Data.Value + 1;

            app.specInfo.WritedSamples{ind1}.Data.Value = ind2;
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp    = int8([year(TimeStamp)-2000, month(TimeStamp), day(TimeStamp), hour(TimeStamp), minute(TimeStamp), fix(second(TimeStamp))]);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp_ms = int16((second(TimeStamp) - fix(second(TimeStamp))).*1000);

            app.specInfo.memmapData{ind1}{1}.Data(ind2).RefLevel  = int16(RefLevel);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).attFactor = uint8(attFactor);

            app.specInfo.memmapData{ind1}{1}.Data(ind2).gpsStatus = uint8(gpsData.Status);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Latitude  = single(gpsData.Latitude);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Longitude = single(gpsData.Longitude);

            % Codificação
            switch app.Task_BitsPerPoint.Value
                case '8 bits'
                    value = -2*RefLevel+255;
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = uint8(2.*specData + value); % 0.5 dB

                case '16 bits'
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = int16(100.*specData);       % 0.01 dB

                case '32 bits'
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = single(specData);           % 0.0000000001 dB
            end

        end


        % %     Task Data
        function Write_formatHeader(app, fileID, AlocatedSamples)

            fwrite(fileID, 'RFlookBin v.1/1', 'char*1');
            fwrite(fileID, str2double(extractBefore(app.Task_BitsPerPoint.Value, 'bits')));         % 8 | 16 | 32 bits per point
            fwrite(fileID, AlocatedSamples, 'uint32');                                              % AlocatedSamples
            fwrite(fileID, 0,               'uint32');                                              % WritedSamples

        end


        % %     Spectrum analyzer (or receiver) MetaData
        function Write_metaHeader(app, fileID, MetaData)

            fwrite(fileID, MetaData.FreqStart,       'single');
            fwrite(fileID, MetaData.FreqStop,        'single');
            fwrite(fileID, MetaData.ResolutionValue, 'single');
            fwrite(fileID, MetaData.DataPoints,      'uint16');                  % Sweep Points (0-65535 pontos)
            fwrite(fileID, MetaData.Trace_ID,        'int8');
            fwrite(fileID, MetaData.Detector_ID,     'int8');
            fwrite(fileID, MetaData.LevelUnit_ID,    'int8');                    % LevelUnit (1: dBm)
            fwrite(fileID, MetaData.Preamp,          'int8');
            fwrite(fileID, MetaData.AttenuationMode, 'int8');

            if MetaData.AttenuationMode; attFactor = -1;
            else;                        attFactor = MetaData.AttenuationValue;
            end
            fwrite(fileID, attFactor,                'int8');

            fwrite(fileID, MetaData.SampleTimeValue, 'single');
            fwrite(fileID, zeros(1, 2));                                    % Alignment

        end


        % %     GPS Data
        function Write_gpsHeader(app, fileID)
        
            fwrite(fileID, app.gpsInfo.Type);
            
            if app.gpsInfo.Type
                fwrite(fileID, app.gpsInfo.Status,    'int8');
        
                fwrite(fileID, app.gpsInfo.Latitude,  'single');
                fwrite(fileID, app.gpsInfo.Longitude, 'single');
                
                if ~isempty(app.gpsInfo.TimeStamp)
                    fwrite(fileID, year(app.gpsInfo.TimeStamp)-2000, 'int8');
                    fwrite(fileID, month(app.gpsInfo.TimeStamp),     'int8');
                    fwrite(fileID, day(app.gpsInfo.TimeStamp),       'int8');
        
                    fwrite(fileID, hour(app.gpsInfo.TimeStamp),      'int8');
                    fwrite(fileID, minute(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, second(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, (second(app.gpsInfo.TimeStamp) - ...
                                fix(second(app.gpsInfo.TimeStamp))).*1000, 'int16');
                else
                    fwrite(fileID, -ones(6, 1), 'int8');
                    fwrite(fileID, -1,          'int16');
                end
        
            else
                fwrite(fileID, -1,                    'int8');
                fwrite(fileID, app.gpsInfo.Latitude,  'single');
                fwrite(fileID, app.gpsInfo.Longitude, 'single');
                fwrite(fileID, -ones(6, 1),           'int8');
                fwrite(fileID, -1,                    'int16');
            end
        
        end


        % %     Body Block
        function [Offset1, Offset2] = Write_Body(app, fileID, AlocatedSamples, DataPoints, TaskName, Task, Node)

            Offset1 = ftell(fileID) + 12;
            Offset2 = Offset1 + 20*AlocatedSamples;
            Offset3 = Offset2 + (str2double(extractBefore(app.Task_BitsPerPoint.Value, 'bits'))/8)*DataPoints.*AlocatedSamples;

            fwrite(fileID, Offset1, 'uint32');
            fwrite(fileID, Offset2, 'uint32');
            fwrite(fileID, Offset3, 'uint32');

            fwrite(fileID, zeros(1, (20 + (str2double(extractBefore(app.Task_BitsPerPoint.Value, 'bits'))/8)*DataPoints).*AlocatedSamples, 'uint8'));
            
            oldStr = {'"', ',', newline};
            newStr = {'', '', ''};

            fwrite(fileID, jsonencode(struct('TaskName',          Misc_strReplaces(TaskName, oldStr, newStr),         ...
                                             'ThreadID',          Task.ThreadID,                                      ...
                                             'Description',       Misc_strReplaces(Task.Description, oldStr, newStr), ...
                                             'Node',              Node,                                         ...
                                             'Antenna',           app.Task_Antenna.Value,                       ...
                                             'AntennaHeight',     app.Task_Height.Value,                        ...
                                             'IntegrationFactor', Task.IntegrationFactor,                       ...
                                             'RevisitTime',       Task.RevisitTime)), 'char*1');

        end


        function logger(app)

            try
                [app.udpPortArray, ind] = Connection_udpLogger(app.udpPortArray, app.logger_Port.Value, app.General.logger.Timeout);
                app.udpPort = app.udpPortArray{ind};
                flush(app.udpPort)
                
                logger_Startup(app)
                d = uiprogressdlg(app.MainApp, 'Message', 'Tentando se conectar ao instrumento...', 'Title', 'appColeta', 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
                while ~app.udpPort.NumDatagramsAvailable
                    if d.CancelRequested
                        logger_ButtonPlayValueChanged(app)
                        return
                    end
                end
                Layout_TaskScreen(app)
                delete(d)
                
                cla(app.axes1)
                cla(app.axes2) 
                drawnow nocallbacks
                
                app.traceInfo.Index = 1;
                Plot_TraceInfo(app)
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end
            
            fileID = [];

            tic
            t1 = toc;
            while app.taskFlag
                try
                    if t1 > app.General.logger.ConnectionTime
                        tic
                        logger_Startup(app)
    
                        if ~app.udpPort.NumDatagramsAvailable
                            error('Não foi recebido datagrama do sensor.')
                        end
    
                    elseif app.udpPort.NumDatagramsAvailable
                        app.logger_AvailableDatagrams.Value = app.udpPort.NumDatagramsAvailable;

                        SpecInfo = read(app.udpPort, 1, 'uint8');
                        for ii = 1:numel(SpecInfo)
                            if ~strcmp(SpecInfo(ii).SenderAddress, app.logger_IP.Value) && numel(SpecInfo(ii).Data) < 96
                                continue
                            end
    
                            MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                            ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                            DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                            EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                            
                            if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                                switch DataType
                                    case 40
                                        [~, ~, GPS] = udpLogger_Read_DataType(DataType, SpecInfo(ii).Data, ThreadID);
                                        logger_GPSUpdate(app, GPS);

                                    case 42
                                        logger_Read_DataType42(app, SpecInfo(ii).Data)
                                        
                                    case {67, 68}
                                       [Bin, newArray] = udpLogger_Read_DataType(DataType, SpecInfo(ii).Data, ThreadID);
                                        indPlot  = logger_BandListUpdate(app, Bin);
                                        logger_Plot(app, indPlot, newArray)
                                        
                                    otherwise
                                        TimeStamp  = datestr(now, 'dd/mm/yyyy HH:MM:ss');
            
                                        if isempty(app.logger_TextInfo.Value{1})
                                            app.logger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', sprintf('Não implementado leitor do DataType %d. Favor entrar em contato com Eric (GR08).', DataType));
                                        else
                                            app.logger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', sprintf('Não implementado leitor do DataType %d. Favor entrar em contato com Eric (GR08).', DataType));
                                            scroll(app.logger_TextInfo, 'bottom')
                                        end
                                        continue
                                end
                                
                                if app.recFlag
                                    if isempty(fileID)
                                        fileID = logger_Write_FileHeader(app, app.logger_IP.Value, app.logger_Port.Value);
                                    end
    
                                    if ismember(ThreadID, [app.bandList.REC])
                                        fwrite(fileID, uint8((SpecInfo(ii).Data(9:end))));
                                    end
                                else
                                    if ~isempty(fileID)
                                        fclose(fileID);
                                        fileID = [];
                                    end
                                end
                            end
                        end
                    end
                    t1 = toc;
                    pause(app.logger_Pause.Value/1000)
                    
                catch ME
                    Selection = uiconfirm(app.MainApp, sprintf('%s\n\nDeseja tentar extrair dados do instrumento mais uma vez?', ME.message), 'appColeta', 'Icon', 'question', 'Options', {'Sim', 'Não'}, 'DefaultOption', 1);
                    
                    switch Selection
                        case 'Sim'; continue
                        case 'Não'; logger_ButtonPlayValueChanged(app)
                    end
                end
            end

            if ~isempty(fileID)
                fclose(fileID);
            end

        end


        function fileID = logger_Write_FileHeader(app, UDP_IP, UDP_PORT)

            % Leitura de informações e escrita em arquivo.
            filename = ['appColeta_' datestr(now,'yymmdd_THHMMSS') '.bin'];
            fileID = fopen(fullfile(app.menu_userPath.Value, filename), 'w+');

            % File Format Header
            fwrite(fileID, 23, 'int32');
            fwrite(fileID, ['CRFS DATA FILE V023' zeros(1, 13)], 'char*1');

            % Unit Information
            if isempty(app.logger_Hostname.Value)
                Node     = app.logger_Node.Value;
                unitInfo = 'Stationary';
                method   = sprintf('appColeta (%s:%.0f)', UDP_IP, UDP_PORT);
            else
                Node     = app.logger_Hostname.Value;
                unitInfo = app.logger_UnitInfo.Value;
                method   = sprintf('%s (%s:%.0f)', app.logger_Method.Value, UDP_IP, UDP_PORT);
            end
            udpLogger_Write_DataType21(fileID, Node, unitInfo, method)

            if app.logger_gpsCount.Value
                udpLogger_Write_DataType40(fileID, datetime(app.logger_gpsTime.Value, 'InputFormat', 'dd/MM/yyyy HH:mm:SS'), ...
                    app.logger_gpsLatitude.Value, app.logger_gpsLongitude.Value, app.logger_gpsAltitude.Value)
            end

        end


        function logger_TreeBuilding(app)
            
            SelectedNode = [];
            if ~isempty(app.logger_Tree.SelectedNodes)
                SelectedNode = app.bandList(app.logger_Tree.SelectedNodes.NodeData);
            end
            
            delete(app.logger_Tree.Children);

            if ~issorted([app.bandList.ThreadID])
                [~, ind]     = sort([app.bandList.ThreadID]);
                app.bandList = app.bandList(ind);
            end

            for ii = 1:numel(app.bandList)
                str = sprintf('ThreadID %d: %.3f - %.3f MHz', app.bandList(ii).ThreadID,         ...
                                                              app.bandList(ii).FreqStart / 1e+6, ...
                                                              app.bandList(ii).FreqStop  / 1e+6);

                treeChild = uitreenode(app.logger_Tree, 'Text', str, 'NodeData', ii, 'ContextMenu', app.ContextMenu_logger);
                if ~isempty(app.bandList(ii).REC)
                    treeChild.Icon = 'REC2a.png';
                end
            end
            
            if isempty(SelectedNode)
                ind = 1;
            else
                for jj = 1:numel(app.bandList)
                    if isequal(app.bandList(jj), SelectedNode)
                        ind = jj;
                        break
                    end
                end
            end
            app.logger_Tree.SelectedNodes = app.logger_Tree.Children(ind);
            
            if isempty(app.bandList(ind).REC); app.logger_Tree.SelectedNodes.Icon = 'mainMenu_Playback.png';
            else;                              app.logger_Tree.SelectedNodes.Icon = 'mainMenu_REC.png';
            end
            
            if isempty([app.bandList.REC]); app.tb_rec.Visible = 0;
            else;                           app.tb_rec.Visible = 1;
            end
            
        end

    end
    
    methods (Access = public)

        function logger_Startup(app)
            
            msg = sprintf('open %.0f', app.General.logger.ConnectionTime);
        
            write(app.udpPort, msg, 'uint8', app.logger_IP.Value, app.logger_Port.Value);
            pause(app.General.logger.HandshakePause)
        
        end


        function logger_GPSUpdate(app, GPS)
            
            if GPS.Status
                if app.logger_gpsCount.Value == 0
                    app.logger_gpsLatitude.Value  = GPS.Latitude;
                    app.logger_gpsLongitude.Value = GPS.Longitude;
                    app.logger_gpsAltitude.Value  = GPS.Altitude;

                else
                    app.logger_gpsLatitude.Value  = (app.logger_gpsCount.Value * app.logger_gpsLatitude.Value  + GPS.Latitude)  ./ (app.logger_gpsCount.Value + 1);
                    app.logger_gpsLongitude.Value = (app.logger_gpsCount.Value * app.logger_gpsLongitude.Value + GPS.Longitude) ./ (app.logger_gpsCount.Value + 1);
                    app.logger_gpsAltitude.Value  = (app.logger_gpsCount.Value * app.logger_gpsAltitude.Value  + GPS.Altitude)  ./ (app.logger_gpsCount.Value + 1);
                end
                app.logger_gpsCount.Value = app.logger_gpsCount.Value + 1;
                app.logger_gpsTime.Value  = GPS.TimeStamp;
            end

        end


        function ind = logger_BandListUpdate(app, Bin)

            if isempty(app.bandList)
                app.bandList = Bin;
                
                logger_TreeBuilding(app)
                logger_TreeSelectionChanged(app)
                                
                ind = 1;                
            else
                indFind = find([app.bandList.ThreadID] == Bin.ThreadID);
                if isempty(indFind)
                    app.bandList(end+1) = Bin;
                    logger_TreeBuilding(app)
                    
                    ind = numel(app.bandList);
                else
                    Flag = 0;
                    for ii = indFind
                        if isequal(rmfield(app.bandList(ii), 'REC'), rmfield(Bin, 'REC'))
                            Flag = 1;
                            
                            ind = ii;
                            break
                        end
                    end

                    if ~Flag
                        app.bandList(end+1) = Bin;
                        logger_TreeBuilding(app)
                        
                        ind = numel(app.bandList);
                    end
                end
            end

        end


        function logger_Plot(app, indPlot, newArray)
            
            if app.plotFlag && (app.traceInfo.Index == indPlot)
                if app.axesFlag
                    Plot_axesFlagValueChanged(app)
                    app.traceInfo.Index = app.logger_Tree.SelectedNodes.NodeData;
                                        
                else
                    if ~isempty(app.axes1.Children)
                        Plot_Update(app, newArray')
                    else
                        Plot_Creation(app, app.logger_Node.Value, newArray', [], app.traceInfo.Index)
                    end
                end
                drawnow
            end

        end


        function logger_Read_DataType42(app, Datagram)

            Date_Day     = Datagram(21);
            Date_Month   = Datagram(22);
            Date_Year    = Datagram(23) + 2000;

            Time_Hours   = Datagram(25);
            Time_Minutes = Datagram(26);
            Time_Seconds = Datagram(27);
            TimeStamp    = datestr(datetime([Date_Year, Date_Month, Date_Day, Time_Hours, Time_Minutes, Time_Seconds]), 'dd/mm/yyyy HH:MM:ss');

            Identifier     = deblank(char(Datagram(41:72)));
            FreeTextLength = double(typecast(uint8(Datagram(73:76)), 'uint32'));
            if FreeTextLength
                FreeText = deblank(char(Datagram(77:76+FreeTextLength)));
            end

            try
                jsonStruct = jsondecode(FreeText);

                if isfield(jsonStruct, 'type')
                    if jsonStruct.type == 1
                        if isempty(app.logger_Hostname.Value)
                            app.logger_Hostname.Value = jsonStruct.hostname;
                            app.logger_UnitInfo.Value = jsonStruct.unitInfo;
                            app.logger_Method.Value   = jsonStruct.method;
                        else
                            return
                        end

                    elseif jsonStruct.type == 2
                        if isempty(app.logger_Hostname.Value)
                            app.logger_Hostname.Value = jsonStruct.hostname;
                        end
                        app.logger_General.Value = jsonStruct.message;
                        
%                     elseif jsonStruct.type == 3
%                         if isempty(app.logger_Hostname.Value)
%                             app.logger_Hostname.Value = jsonStruct.hostname;
%                         end
%                         
%                         if jsonStruct.gpsStatus
%                             gpsLatitude  = jsonStruct.gpsLatitude  ./ 1e+6;
%                             gpsLongitude = jsonStruct.gpsLongitude ./ 1e+6;
% 
%                             if app.logger_gpsCount.Value == 0
%                                 app.logger_gpsLatitude.Value  = gpsLatitude;
%                                 app.logger_gpsLongitude.Value = gpsLongitude;
% 
%                             else
%                                 app.logger_gpsLatitude.Value  = (app.logger_gpsCount.Value * app.logger_gpsLatitude.Value  + gpsLatitude)  ./ (app.logger_gpsCount.Value + 1);
%                                 app.logger_gpsLongitude.Value = (app.logger_gpsCount.Value * app.logger_gpsLongitude.Value + gpsLongitude) ./ (app.logger_gpsCount.Value + 1);
%                             end
%                             app.logger_gpsCount.Value = app.logger_gpsCount.Value + 1;
%                             app.logger_gpsTime.Value  = TimeStamp;
%                         end
                    end
                end
            catch
            end

            if isempty(app.logger_TextInfo.Value{1})
                app.logger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
            else
                app.logger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
                scroll(app.logger_TextInfo, 'bottom')
            end
            drawnow

        end

        
        function logger_ftpInfo(app, status, info)

            app.logger_ftpStatus.Text   = info;
            app.logger_ScriptName.Value = '';
            app.logger_MaskName.Items   = {};
                    
            switch status
                case 'sucess'                    
                    app.logger_Script.Enable  = 1;
                    app.logger_Mask.Enable    = 1;

                    set(app.logger_Panel3_Grid.Children, 'Enable', 1)
                    app.logger_Sublabel1.Enable = 1;

                case 'error'
                    app.logger_Script.Enable  = 0;
                    app.logger_Mask.Enable    = 0;

                    set(app.logger_Panel3_Grid.Children, 'Enable', 0)
                    app.logger_Sublabel1.Enable = 0;
            end
            
        end


        function [maskName, methodField] = logger_ScriptRead(app, filename)

            maskName    = {};
            methodField = '';
            
            ii  = 0;
            fid = fopen(filename);
            while ~feof(fid)
                tline = fgetl(fid);

                if ~isempty(regexpi(tline,'method\s*=', 'match'))
                   methodField = extractAfter(tline, '=');
                   methodField = replace(methodField,{'"',' '}, '');
                end

                if ~isempty(regexpi(tline, 'mask\s*=', 'match'))
                    ii = ii+1;

                    tlineSplit   = split(tline, ',');
                    maskName{ii} = replace(tlineSplit{5}, ' ', '');
                end
            end
            fclose(fid); 

            maskName = unique(maskName);

        end

        
        function logger_NodeCreation(app)

            app.logger_Node.Items = app.RFeyeList.ID(app.RFeyeList.ConnectFlag);
            app.logger_Qtd.Text   = sprintf('%.0f de %.0f receptores', sum(app.RFeyeList.ConnectFlag), height(app.RFeyeList));

            logger_NodeValueChanged(app)

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Info.png'))
                case 'startup'; uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'CloseFcn', @(~,~)startupError(app));
            end

        end


        % TOOLBAR CALLBACKS
        function toolbarCallbacks_refreshView(app, src, event)

            if ~isempty(app.RestoreView)
                set(app.axes1, 'XLim', app.RestoreView{1}, 'YLim', app.RestoreView{2})
                set(app.axes2, 'YLim', [0,app.General.HistoryDepth])
            end

        end


        function toolbarCallbacks_interaction(app, src, event)

            if app.tb_interaction.Tag == "zoom"
                app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [regionZoomInteraction, zoomInteraction];

                set(app.tb_interaction, 'CData', startup_toolbarIcon(app, 'interaction_pan'), 'Tag', 'pan')
            else
                app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [panInteraction, zoomInteraction];

                set(app.tb_interaction, 'CData', startup_toolbarIcon(app, 'interaction_zoom'), 'Tag', 'zoom')
            end

        end


        function toolbarCallbacks_layout(app, src, event)

            if app.tb_layout.Tag == "1:1"
                set(app.tb_layout, 'CData', startup_toolbarIcon(app, 'layout2'), 'Tag', "1:3")
            else
                set(app.tb_layout, 'CData', startup_toolbarIcon(app, 'layout1'), 'Tag', "1:1")
            end

            Plot_Layout2(app)

        end


        function toolbarCallbacks_minHold(app, src, event)

            if app.tb_minHold.State
                app.traceInfo.Mode(1) = 1;

                ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);

                app.line_minHold = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'MinHold', 'Color', app.General.Colors(1,:));
                Misc_PlotDataTip(app.line_minHold, app.Task_LevelUnit_2.Value)

            else
                app.axesFlagType = 'TraceProcessing';
                app.axesFlag     = 1;
            end
            drawnow nocallbacks

        end


        function toolbarCallbacks_meanHold(app, src, event)

            if app.tb_meanHold.State
                app.traceInfo.Mode(2) = 2;
                
                ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);

                app.line_Average = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'Average', 'Color', app.General.Colors(2,:));
                Misc_PlotDataTip(app.line_Average, app.Task_LevelUnit_2.Value)

            else
                app.axesFlagType = 'TraceProcessing';
                app.axesFlag     = 1;
            end
            drawnow nocallbacks

        end


        function toolbarCallbacks_maxHold(app, src, event)

            if app.tb_maxHold.State
                app.traceInfo.Mode(3) = 3;
                
                ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);

                app.line_maxHold = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'MaxHold', 'Color', app.General.Colors(3,:));
                Misc_PlotDataTip(app.line_maxHold, app.Task_LevelUnit_2.Value)

            else
                app.axesFlagType = 'TraceProcessing';
                app.axesFlag     = 1;
            end
            drawnow nocallbacks

        end


        function toolbarCallbacks_waterfall(app, src, event)

            if app.tb_waterfall.State
                app.traceInfo.plotLayout       = 1;
                
                ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
                if ~ind; ind = app.specMatrix.HistoryDepth;
                end

                indMatrix = ind-app.General.HistoryDepth+1:ind;
                indFind   = find(indMatrix <= 0);
                if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
                end

                app.surface_wFall = image(app.axes2, app.x, 1:app.General.HistoryDepth, app.specMatrix.Data{2}(indMatrix,:), 'CDataMapping', 'scaled', 'Tag', 'wFall');
                set(app.axes2, 'XLim', app.RestoreView{1}, 'View', [0, 90]);
            end
            drawnow nocallbacks
            
            app.axesFlagType = 'Waterfall';
            app.axesFlag     = 1;

        end


        function toolbarCallbacks_playback(app, src, event)

            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Waterfall plot task" (1)
                    app.taskFlag = 1;
                    app.plotFlag = 1;
                    app.recFlag  = 0;
                    
                    app.axesFlagType = 'SelectedNode';
                    app.axesFlag     = 1;
                    
                    Layout_TaskScreen(app)
                             
                    app.fig.Visible = 0;
                    WaterfallPlotTask(app)

                case 1                                                                  % "Waterfall plot task" (1) >> Off (0)
                    if app.recFlag
                        if app.specMatrix.RecordSamples && (app.specMatrix.RecordSamples < app.specMatrix.HistoryDepth)
                            Task_FileWriter_MAT73(app.specMatrix, app.menu_userPath.Value)
                            drawnow
                        end
                    end
                    app.taskFlag = 0;
                    app.plotFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    Layout_TaskScreen(app)
                    
                case 2
                    if app.plotFlag
                        app.plotFlag = 0;
                        app.axesFlag = 0;
                    else
                        app.plotFlag = 1;
                        
                        app.axesFlagType = 'SelectedNode';
                        app.axesFlag     = 1;
                    end
                    Layout_TaskScreen(app)
                    
                case 3
                    if app.plotFlag
                        app.plotFlag = 0;
                        app.axesFlag = 0;
                        
                        app.tb_playback.CData = 'Ícone (Play4).png';
                        app.logger_Tree.SelectedNodes.Icon = '';
                        
                        if isempty(app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC)
                            app.logger_Tree.SelectedNodes.Icon = '';
                        else
                            app.logger_Tree.SelectedNodes.Icon = 'REC2a.png';
                        end
                        
                    else
                        app.plotFlag = 1;
                        
                        app.axesFlagType = 'SelectedNode';
                        app.axesFlag     = 1;
                        
                        app.tb_playback.CData = 'General_Stop.png';
                        
                        if isempty(app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC)
                            app.logger_Tree.SelectedNodes.Icon = 'mainMenu_Playback.png';
                        else
                            app.logger_Tree.SelectedNodes.Icon = 'mainMenu_REC.png';
                        end
                    end
            end

        end


        function toolbarCallbacks_rec(app, srd, event)

            if app.tb_rec.State
                app.recFlag = 1;
                app.tool_REC.Visible = 1;
                
                if app.Button_Task.Value
                    app.Task_Tree2.Enable = 0;
                end
            else
                app.recFlag = 0;
                app.tool_REC.Visible = 0;
                
                if app.Button_Task.Value
                    app.Task_Tree2.Enable = 1;
                end                
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)

            app.MainApp.Visible = 0;
            
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, 'appColeta.exe'))
                    Flag = 0;
                    
                    msg = 'O appColeta não identificou o seu local de instalação (PATH). Independente disso, ele pode ser iniciado clicando diretamente no seu executável (e não em algum atalho). Favor reportar o <i>bug</i>.';
                    Misc_uialertCreation(app, 'startup', msg)
                end
                app.menu_userPath.Items = startup_userInitialFolder(app);
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), 'appColeta');
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, 'appColeta')
                    app.RootFolder          = char(prjPath.RootFolder);
                    app.menu_userPath.Items = {fullfile(app.RootFolder, 'Temp')};
                    
                elseif isfolder(appPath)
                    app.RootFolder          = appPath;
                    app.menu_userPath.Items = startup_userInitialFolder(app);
                    
                else
                    Flag = 0;
                    
                    msg = 'O appColeta somente poderá ser executado se o projeto "appColeta.prj" estiver aberto ou instalado no Matlab.';
                    Misc_uialertCreation(app, 'startup', msg)
                end
            end
            
            if Flag
                % Temp folder creation...
                if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                    mkdir(fullfile(app.RootFolder, 'Temp'))
                end

                % appColeta, Fiscaliza and AnatelDB versions.
                [app.General.Version, msgConsole] = appVersion(app.RootFolder);

                % Console message...
                if isdeployed
                    fprintf('\n%s\n', msgConsole);
                end

                % UserPath, InspectionType & FindPeaksDefaultValues
                startup_userSelectedFolder(app)
                [app.instrList, app.taskList, app.EB500Map, app.instrInfo, app.RFeyeList] = Misc_Startup('Full', app.RootFolder);
                startup_InspectionType(app)
                Misc_FindPeaksDefaultValues(app.RootFolder)
                
                % App position & figure/axes creation
                startup_figCreation(app)

                app.connect_Grid0.RowHeight = {22, 254, 0, 0, 1, '1x', 1, 24};
                app.Task_Grid6.RowHeight    = {22, 211,  0,   0, 0, 0, 1, '1x', 1, 24};

                app.MainApp.Visible = 1;
                drawnow nocallbacks
            end

        end

        % Close request function: MainApp
        function CloseRequest(app, event)

            if app.taskFlag
                msg = 'Existe uma tarefa em execução...';
                Misc_uialertCreation(app, 'warning', msg)

                return
            end
            
            Misc_CloseRequest(app.instrList, app.taskList, app.RFeyeList, app.RootFolder, 'Full')
            
            instrreset
            clear app.udpPortArray

            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.fig)
            delete(app)

        end

        % Value changed function: Button_Home
        function Button_HomeValueChanged(app, event)

            if app.Button_Home.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'HOME'), 'Value', 0)
            else
                app.Button_Home.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Connect
        function Button_ConnectValueChanged(app, event)
            
            if app.Button_Connect.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'CONNECT'), 'Value', 0)
            else
                app.Button_Connect.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Task
        function Button_TaskValueChanged(app, event)

            if app.Button_Task.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'TASK'), 'Value', 0)
            else
                app.Button_Task.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Logger
        function Button_LoggerValueChanged(app, event)

            if app.Button_Logger.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'LOGGER'), 'Value', 0)
            else
                app.Button_Logger.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Plot
        function Button_PlotValueChanged(app, event)
            
            if app.Button_Plot.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'PLOT'), 'Value', 0)
            else
                app.Button_Plot.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Image clicked function: menu_searchPath
        function menu_searchPathImageClicked(app, event)

            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.MainApp)

            if newUserPath
                ind = find(strcmp(app.menu_userPath.Items, newUserPath), 1);

                if isempty(ind)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    menu_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(ind);
                end
            end

        end

        % Button pushed function: tool_Button1
        function tool_Button1Pushed(app, event)

            [mainMonitor, x0] = startup_appPosition(app);
            
            if ~isempty(app.fig)
                [x1, y1] = startup_figPosition(app, mainMonitor, x0);
            
                app.fig.Position = [x1, y1, 640, 480];
                if app.fig.Visible
                    figure(app.fig)
                end
            end

        end

        % Callback function: Image9, tool_Button2
        function tool_Button2Pushed(app, event)

            if isempty(app.auxWin1)
                app.auxWin1 = winSettings(app);
            else
                figure(app.auxWin1)
            end

        end

        % Button pushed function: tool_Button3, tool_connect, tool_logger, 
        % tool_task
        function tool_Button3Pushed(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));

            switch event.Source
                case app.tool_Button3; flag = 'winMain_General';
                case app.tool_connect; flag = 'winMain_connect';
                case app.tool_task;    flag = 'winMain_task';
                case app.tool_logger;  flag = 'winMain_logger';
            end

            msg = char(extractBetween(info, sprintf('<%s>\n', flag), sprintf('\n</%s>', flag)));
            Misc_uialertCreation(app, 'info', msg)

        end

        % Selection changed function: connect_Tree
        function connect_TreeSelectionChanged(app, event)

            app.connect_Button2.Enable = 1;
            app.connect_Button3.Enable = 0;
            app.connect_Button4.Enable = 0;
            
            if ~isempty(app.connect_Tree.SelectedNodes)
                ind = app.connect_Tree.SelectedNodes.NodeData;
                
                app.connect_Family.Items = app.instrList.Family(ind(1));
                app.connect_Name.Items   = app.instrList.Name(ind(1));

                if numel(ind) == 1
                    app.connect_Button3.Enable = 1;
                    app.connect_Button4.Enable = 1;
    
                    connect_PanelValues(app, ind)
                    connect_TypeValueChanged(app)
    
                else
                    set(app.connect_Grid1.Children, 'Visible', 1)

                    app.connect_Type.Items = {''};
                    app.connect_IP.Value   = '';
                    app.connect_Port.Value = '';
                    
                    set(app.connect_Timeout, 'FontColor', [.94,.94,.94])
                    set(app.connect_Buffer,  'FontColor', [.94,.94,.94])
                    
                    app.connect_Description.Value = '';
    
                    app.connect_CheckBox.Value = 0;
                    app.connect_localIP.Value  = '';
                    app.connect_publicIP.Value = '';
                    
                    connect_Layout(app)
                    connect_infoPanel(app)
                end
                
            else
                app.connect_Button2.Value = 1;
                connect_Button2Pushed(app)
            end

        end

        % Value changed function: connect_Family
        function connect_FamilyValueChanged(app, event)

            ind = find(strcmp(app.instrInfo.Family, app.connect_Family.Value));

            app.connect_Name.Items = unique(app.instrInfo(ind,:).Name);
            connect_NameValueChanged(app)

        end

        % Value changed function: connect_Name
        function connect_NameValueChanged(app, event)

            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            
            app.connect_Type.Items = app.instrInfo.connectType{ind};
            connect_TypeValueChanged(app)

        end

        % Value changed function: connect_Type
        function connect_TypeValueChanged(app, event)

            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);

            app.connect_Port.Value = app.instrInfo.connectPort{ind};
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Value changed function: connect_CheckBox
        function connect_CheckBoxValueChanged(app, event)

            if app.connect_CheckBox.Value
                try
                    connect_infoValidation(app, 'Only Instrument IP', app.connect_IP.Value)
                    set(app.connect_Grid2.Children, 'Enable', 'on')

                catch ME
                    app.connect_CheckBox.Value = 0;
                    Misc_uialertCreation(app, 'error', getReport(ME))

                    return
                end

            else
                set(app.connect_Grid2.Children, 'Enable', 'off')
                app.connect_localIP.Value  = '';
                app.connect_publicIP.Value = '';
            end

        end

        % Button pushed function: connect_Button1
        function connect_Button1Pushed(app, event)

            try
                if strcmp(app.connect_Name.Value, 'CRFS RFeye')
                    error(['Implementada apenas a leitura do fluxo UDP gerado pelo Logger, o que é acessível no modo "RFeye", ' ...
                        'restando pendente a comunicação com o RFeye por meio do protocolo NCP.'])
                end

                connect_infoValidation(app, 'All', '')

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end

            if app.connect_Button3.Value; ind = app.connect_Tree.SelectedNodes.NodeData;
            else;                         ind = height(app.instrList)+1;
            end

            app.instrList(ind,:) = {app.connect_Family.Value, ...
                                    app.connect_Name.Value,        ...
                                    app.connect_instrName.Text{1}, ...
                                    app.connect_Type.Value,        ...
                                    app.connect_IP.Value,          ...
                                    app.connect_Port.Value,        ...
                                    app.connect_Timeout.Value,     ...
                                    app.connect_Buffer.Value,      ...
                                    app.connect_Description.Value, ...
                                    app.connect_CheckBox.Value,    ...
                                    app.connect_localIP.Value,     ...
                                    app.connect_publicIP.Value, 0};

            app.connect_Button2.Value  = 0;
            app.connect_Button3.Value  = 0;

            connect_TreeBuilding(app)

        end

        % Value changed function: connect_Button2
        function connect_Button2Pushed(app, event)

            if app.connect_Button2.Value
                app.connect_Button3.Enable = 0;

                app.connect_Family.Items      = unique(app.instrInfo.Family);
                app.connect_Family.Value      = 'Receptor';
                app.connect_Description.Value = '';

                connect_FamilyValueChanged(app)
            else
                if isempty(app.connect_Tree.Children)
                    app.connect_Button2.Value = 1;
                    return
                end
                
                connect_Layout(app)
                connect_infoPanel(app)
            end

        end

        % Value changed function: connect_Button3
        function connect_Button3Pushed(app, event)

            if app.connect_Button3.Value
                app.connect_Button2.Enable = 0;
                
                ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);                
                app.connect_Type.Items = app.instrInfo.connectType{ind};
            else
                app.connect_Button2.Enable = 1;
            end
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Button pushed function: connect_Button4
        function connect_Button4Pushed(app, event)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Tentando se conectar ao instrumento...');

            ind = app.connect_Tree.SelectedNodes.NodeData;
            if strcmp(app.instrList.Family{ind}, 'Receptor')
                try
                    [app.Instrument, Node] = Connection_Instrument(app.instrList(ind,:), app.instrList.Timeout(ind), app.instrInfo, app.udpPortArray, 'JustConnectivity', [], []);

                    if ~isempty(Node)
                        msg = sprintf('%s\n%s', app.instrList.Name{ind}, Node);

                        if contains(Node, app.connect_instrName.Text{1})
                            app.instrList.Flag(ind) = 1;
                        end
                        Misc_uialertCreation(app, 'info', msg)

                    else
                        error('Apesar de criado objeto, não foi recebida resposta ao comando "*IDN?".')
                    end

                catch ME
                    app.instrList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end

            else
                try
                    [~, gps] = Connection_gps(app.instrList.Type{ind},    ...
                                              app.instrList.IP{ind},      ...
                                              app.instrList.Port{ind},    ...
                                              app.instrList.Timeout(ind), ...
                                              app.instrList.inBuffer(ind));

                    if ~isempty(gps)
                        app.instrList.Flag(ind) = 1;

                        msg = sprintf('Status: %.0f\nLatitude: %.6f\nLongitude: %.6f\nHorário: %s (UTC)', gps.Status, gps.Latitude, gps.Longitude, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                        Misc_uialertCreation(app, 'info', msg)
                        
                        if gps.Status
                            app.Task_manualLatitude.Value  = round(gps.Latitude,  6);
                            app.Task_manualLongitude.Value = round(gps.Longitude, 6);
                        end
                    else
                        error('Apesar de criado objeto, não foi recebida informação NMEA/RMC.')
                    end

                catch ME
                    app.instrList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end
            connect_TreeBuilding(app)

        end

        % Menu selected function: Connect_delete
        function connect_deleteInstrument(app, event)

            ind = app.connect_Tree.SelectedNodes.NodeData;
            
            app.instrList(ind,:) = [];
            connect_TreeBuilding(app)

        end

        % Selection changed function: Task_Tree1
        function Task_Tree1SelectionChanged(app, event)

            delete(app.Task_Tree2.Children);
            
            ind1 = app.Task_Tree1.SelectedNodes.NodeData;

            app.Task_Name.Value             = app.taskList{ind1}.Name;
            app.Task_BitsPerPoint.Items     = {sprintf('%d bits', app.taskList{ind1}.BitsPerSample)};

            Duration_sec = app.taskList{ind1}.Duration;
            if Duration_sec >= 3600
                app.Task_Duration.Value     = Duration_sec ./ 3600;
                app.Task_DurationUnit.Value = 'hr';
            else
                app.Task_Duration.Value     = Duration_sec ./ 60;
                app.Task_DurationUnit.Value = 'min';
            end
            
            if ~isempty(app.Task_Instrument.Items)
                Task_InstrumentValueChanged(app)
            end
            
            Task_Tree2Building(app, ind1)
            Task_Tree2SelectionChanged(app)
            
            if ~isempty(app.Task_Instrument.Items)
                app.Task_RefreshButton.Enable = 1;
                app.tb_playback.Visible    = 1;
                app.Task_executeTask.Enable   = 1;
            else
                app.Task_RefreshButton.Enable = 0;
                app.tb_playback.Visible    = 0;
                app.Task_executeTask.Enable   = 0;
            end

            app.specInfo = [];

        end

        % Selection changed function: Task_Tree2
        function Task_Tree2SelectionChanged(app, event)

            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            ind2 = app.Task_Tree2.SelectedNodes.NodeData;

            Task_RightPanel_Main(app, ind1, ind2)

            if ~isempty(app.Task_Instrument.Items)
                Task_RightPanel_Band_ShowValues(app, ind1, ind2)
            end

            if app.plotFlag
                app.axesFlagType = 'SelectedNode';
                app.axesFlag     = 1;
            end

        end

        % Value changed function: Task_Instrument
        function Task_InstrumentValueChanged(app, event)
            
            InitialGPS         = app.Task_GPS.Value;
            app.Task_GPS.Items = {'ID 0: Manual'};

            indGPSs = intersect(find(strcmp(app.instrList.Family, 'GPS')), find(app.instrList.Flag));
            for jj = 1:numel(indGPSs)
                app.Task_GPS.Items = [app.Task_GPS.Items, sprintf('ID %d: %s', indGPSs(jj), app.instrList.Name{indGPSs(jj)})];
            end

            % Lista de GPSs (incluso instrumento que possua embarcado nele um GPS).
            indFind1 = find(strcmp(app.instrInfo.Name, extractAfter(app.Task_Instrument.Value, ': ')), 1);

            app.Task_SyncRef.Items = strsplit(app.instrInfo.SyncOptions{indFind1}, ',');

            if ~isempty(app.instrInfo.scpiGPS{indFind1})
                app.Task_GPS.Items = sort([app.Task_GPS.Items, app.Task_Instrument.Value]);
                app.Task_GPS.Value = app.Task_Instrument.Value;
                
            elseif find(strcmp(app.Task_GPS.Items, InitialGPS), 1)
                app.Task_GPS.Value = InitialGPS;
            end
            Task_GPSValueChanged(app)

            % Peculiaridades dos receptores.
            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            for kk = 1:numel(app.taskList{ind1}.Band)
                Task_RightPanel_Band_SaveValues(app, ind1, kk)
            end

            if isempty(app.Task_Tree2.SelectedNodes); ind2 = 1;
            else;                                     ind2 = app.Task_Tree2.SelectedNodes.NodeData;
            end

            % Controla visibilidade dos elementos passíveis de alteração
            % das faixas a monitorar.
            Task_EditInfo(app)
            Task_RightPanel_Band_Visibility(app)
            Task_RightPanel_Band_ShowValues(app, ind1, ind2)

        end

        % Value changed function: Task_GPS
        function Task_GPSValueChanged(app, event)

            if strcmp(app.Task_GPS.Value, 'ID 0: Manual')
                set(app.GridLayout18.Children, 'Enable', 1)
                
                app.Task_gpsRevisitTimeLabel.Enable = 0;
                app.Task_gpsRevisitTime.Enable = 0;
            else
                set(app.GridLayout18.Children, 'Enable', 0)
                
                app.Task_gpsRevisitTimeLabel.Enable = 1;
                app.Task_gpsRevisitTime.Enable = 1;
            end

        end

        % Value changed function: Task_Antenna
        function Task_AntennaValueChanged(app, event)

            if strcmp(app.Task_Antenna.Value, 'Unlisted (Directional)')
                app.Task_Azimuth.Enable      = 1;
                app.Task_AzimuthLabel.Enable = 1;

                app.Task_Elevation.Enable      = 1;
                app.Task_ElevationLabel.Enable = 1;
            else
                app.Task_Azimuth.Enable      = 0;
                app.Task_AzimuthLabel.Enable = 0;

                app.Task_Elevation.Enable      = 0;
                app.Task_ElevationLabel.Enable = 0;
            end

        end

        % Button pushed function: Task_SaveTask
        function Task_SaveTaskPushed(app, event)

            Folder = uigetdir(app.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de tarefas');
            figure(app.MainApp)

            if Folder
                Misc_CloseRequest([], app.taskList, Folder, 'JustTask')
            end

        end

        % Button pushed function: Task_OpenTask
        function Task_OpenTaskPushed(app, event)

            [File, Path] = uigetfile({'*.cfg', '*.cfg'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.MainApp)

            if File
                [~, Tasks] = Misc_Startup('TasksOnly', app.RootFolder, fullfile(Path, File));

                if ~isempty(Tasks)
                    app.taskList = Tasks;

                    delete(app.Task_Tree2.Children)
                    ModeOperation(app)
                end
            end

        end

        % Value changed function: Task_NewTask
        function Task_NewTaskValueChanged(app, event)

            if app.Task_NewTask.Value
                Task_EditInfo(app)

                app.Task_Name.Value              = 'Nova tarefa';
                app.Task_BitsPerPoint.Value      = '8 bits';

                app.Task_Duration.Value          = 10;
                app.Task_DurationUnit.Value      = 'min';

                app.Task_ThreadID.Value          = 1;
                app.Task_Description.Value       = 'Faixa 1 de 1';
                app.Task_F0.Value                = 88;
                app.Task_F1.Value                = 108;
                app.Task_RFMode.Value            = 'Normal';
                app.Task_StepWidth.Value         = 25;
                app.Task_Resolution1.Value       = 100;
                app.Task_LevelUnit.Value         = 'dBm';
                app.Task_TraceMode.Value         = 'ClearWrite';
                app.Task_Detector.Value          = 'Average/RMS';
                app.Task_RevisitTime.Value       = 10;
                app.Task_IntegrationFactor.Value = 1;

                delete(app.Task_Tree2.Children)

                set(app.GridLayout19.Children,  'Enable', 0)
                app.Task_NewTask.Enable = 1;

            else
                ModeOperation(app)
                Task_EditInfo(app)
            end

        end

        % Menu selected function: Task_delete
        function Task_deleteTaskImageClicked(app, event)

            ind = app.Task_Tree1.SelectedNodes.NodeData;
            app.taskList(ind) = [];

            Task_Tree1Building(app)
            Task_Tree1SelectionChanged(app)

        end

        % Menu selected function: Task_deleteBand
        function Task_deleteBandImageClicked(app, event)

            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            ind2 = app.Task_Tree2.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2) = [];

            value = numel(app.taskList{ind1}.Band);
            for ii = 1:value
                app.taskList{ind1}.Band(ii).ThreadID = ii;
            end

            Task_Tree2Building(app, ind1)
            Task_Tree2SelectionChanged(app)

            if value > 1; app.Task_deleteBand.Enable = 1;
            end

        end

        % Value changed function: Task_newBand
        function Task_newBandButtonPushed(app, event)

            ind = app.Task_Tree1.SelectedNodes.NodeData;
            if app.Task_newBand.Value
                app.Task_ThreadID.Value = numel(app.taskList{ind}.Band)+1;
                
                set(app.GridLayout19.Children, 'Enable', 0)
                app.Task_newBand.Enable = 1;
            end
            Task_EditInfo(app)

        end

        % Value changed function: Task_editBand
        function Task_editBandValueChanged(app, event)

            if app.Task_editBand.Value
                app.Task_ThreadID.Value  = app.Task_Tree2.SelectedNodes.NodeData;
                
                set(app.GridLayout19.Children, 'Enable', 0)
                app.Task_editBand.Enable = 1;
            end
            Task_EditInfo(app)

        end

        % Button pushed function: Task_addBand
        function Task_addBandImageClicked(app, event)

            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            if app.Task_editBand.Value
                ind2 = app.Task_Tree2.SelectedNodes.NodeData;

            elseif app.Task_newBand.Value
                ind2 = length(app.taskList{ind1}.Band) + 1;

            elseif app.Task_NewTask.Value
                ind1 = length(app.Task_Tree1.Children)+1;
                ind2 = 1;
            end

            app.taskList{ind1}.Name          = app.Task_Name.Value;
            app.taskList{ind1}.BitsPerSample = str2double(extractBefore(app.Task_BitsPerPoint.Value, 'bits'));

            switch app.Task_DurationUnit.Value
                case 'min'; app.taskList{ind1}.Duration = app.Task_Duration.Value .* 60;
                case 'hr';  app.taskList{ind1}.Duration = app.Task_Duration.Value .* 3600;
            end

            app.taskList{ind1}.Band(ind2).ThreadID    = app.Task_ThreadID.Value;
            app.taskList{ind1}.Band(ind2).Description = app.Task_Description.Value;

            app.taskList{ind1}.Band(ind2).FreqStart  = app.Task_F0.Value * 1e+6;
            app.taskList{ind1}.Band(ind2).FreqStop   = app.Task_F1.Value * 1e+6;
            app.taskList{ind1}.Band(ind2).StepWidth  = app.Task_StepWidth.Value * 1e+3;
            app.taskList{ind1}.Band(ind2).Resolution = app.Task_Resolution1.Value * 1e+3;

            app.taskList{ind1}.Band(ind2).RFMode     = app.Task_RFMode.Value;
            app.taskList{ind1}.Band(ind2).TraceMode  = app.Task_TraceMode.Value;
            app.taskList{ind1}.Band(ind2).Detector   = app.Task_Detector.Value;
            app.taskList{ind1}.Band(ind2).LevelUnit  = app.Task_LevelUnit.Value;

            app.taskList{ind1}.Band(ind2).RevisitTime       = app.Task_RevisitTime.Value;
            app.taskList{ind1}.Band(ind2).IntegrationFactor = app.Task_IntegrationFactor.Value;
            app.taskList{ind1}.Band(ind2).Flag              = [];

            if ~isempty(app.Task_Instrument.Items)
                Task_RightPanel_Band_SaveValues(app, ind1, ind2)
            end

            if app.Task_NewTask.Value || ~strcmp(app.taskList{ind1}.Name, app.Task_Name.Value)
                Task_Tree1Building(app)
            end
            Task_Tree1SelectionChanged(app)

            app.Task_Instrument.Enable = 1;
            set(app.Task_editBand, 'Enable', 1, 'Value', 0)
            app.Task_NewTask.Value  = 0;

            if ~app.plotFlag
                set(app.GridLayout5.Children, 'Enable', 1)
                app.Task_Tree2.Enable      = 1;
                set(app.Task_newBand,  'Enable', 1, 'Value', 0)
            end

            Task_EditInfo(app)

        end

        % Button pushed function: Task_RefreshButton
        function Task_RefreshButtonPushed(app, event)

            ind1 = app.Task_Tree1.SelectedNodes.NodeData;
            ind2 = app.Task_Tree2.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).Flag = [];
            Task_RightPanel_Band_ShowValues(app, ind1, ind2)

        end

        % Value changed function: Task_F0, Task_F1
        function Task_SpanValueChanged(app, event)

            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;

            if span <= 0
                app.Task_Warning1.Visible = 1;
                app.Task_StepWidth.Enable = 0;

            else
                app.Task_Warning1.Visible = 0;
                app.Task_StepWidth.Enable = 1;
            end

        end

        % Value changed function: Task_TraceMode
        function Task_TraceModeValueChanged(app, event)

            switch app.Task_TraceMode.Value
                case 'ClearWrite'
                    app.Task_IntegrationFactor.Value = 1;

                otherwise
                    if app.Task_IntegrationFactor.Value == 1
                        app.Task_IntegrationFactor.Value = 3;
                    end
            end

        end

        % Value changed function: Task_StepWidth1
        function Task_StepWidth1ValueChanged(app, event)

            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;

            DataPoints = round(span/(1000*app.Task_StepWidth1.Value) + 1);
            if DataPoints < app.Task_DataPoints1.Limits(1)
                app.Task_DataPoints1.Value = app.Task_DataPoints1.Limits(1);
            elseif DataPoints > app.Task_DataPoints1.Limits(2)
                app.Task_DataPoints1.Value = fix(app.Task_DataPoints1.Limits(2));
            else
                app.Task_DataPoints1.Value = DataPoints;
            end

            app.Task_StepWidth1.Value = (span/(app.Task_DataPoints1.Value-1)) / 1000;

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth  = 1000*app.Task_StepWidth1.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints = app.Task_DataPoints1.Value;

        end

        % Value changed function: Task_StepWidth2
        function Task_StepWidth2ValueChanged(app, event)

            app.Task_Warning2.Visible = 1;

            span      = (app.Task_F1.Value-app.Task_F0.Value)*1e+6;
            stepValue = extractBefore(app.Task_StepWidth2.Value, ' kHz');

            % DataPoints
            app.Task_DataPoints2.Value = span/(1000*str2double(stepValue)) + 1;

            % Resolution, Selectivity
            rbwValues = [];
            rbwItems  = {};
            for ii = 1:3
                rbwValues = [rbwValues, app.EB500Map{app.Task_StepWidth2.Value,ii}];
                rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
            end
            app.Task_Resolution2.Items = rbwItems;

            rbwIndex = find(abs(rbwValues - app.Task_Resolution1.Value*1000) == min(abs(rbwValues - app.Task_Resolution1.Value*1000)));
            app.Task_Resolution2.Value = app.Task_Resolution2.Items{rbwIndex};
            app.Task_Selectivity.Value = app.Task_Selectivity.Items{rbwIndex};

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth   = app.Task_StepWidth2.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints  = app.Task_DataPoints2.Value;
            app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;

            app.taskList{ind1}.Band(ind2).instrResolution_Items = app.Task_Resolution2.Items;
            app.taskList{ind1}.Band(ind2).instrResolution       = app.Task_Resolution2.Value;

        end

        % Value changed function: Task_DataPoints1
        function Task_DataPoints1ValueChanged(app, event)

            if ~fix(diff(app.Task_DataPoints1.Limits))
                return
            end

            span = (app.Task_F1.Value - app.Task_F0.Value)*1e+6;
            app.Task_StepWidth1.Value = (span/(app.Task_DataPoints1.Value-1)) / 1000;

            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDataPoints = app.Task_DataPoints1.Value;
            app.taskList{ind1}.Band(ind2).instrStepWidth  = app.Task_StepWidth1.Value*1000;

        end

        % Value changed function: Task_Selectivity
        function Task_SelectivityValueChanged(app, event)

            app.Task_Warning2.Visible    = 1;
            app.Task_Resolution2.Value = sprintf('%.3f kHz', app.EB500Map{app.Task_StepWidth2.Value, app.Task_Selectivity.Value}/1000);

            % Registra edição dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;
            app.taskList{ind1}.Band(ind2).instrResolution  = app.Task_Resolution2.Value;

        end

        % Value changed function: Task_Resolution2
        function Task_Resolution2ValueChanged(app, event)

            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrResolution  = app.Task_Resolution2.Value;

            if contains(app.Task_Instrument.Value, 'EB500')
                if app.Task_Selectivity.Visible
                    ind3 = find(strcmp(app.Task_Resolution2.Items, app.Task_Resolution2.Value));
                    app.Task_Selectivity.Value = app.Task_Selectivity.Items{ind3};
                end
                app.taskList{ind1}.Band(ind2).instrSelectivity = app.Task_Selectivity.Value;
            end

        end

        % Value changed function: Task_Preamp
        function Task_PreampValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrPreamp = app.Task_Preamp.Value;

        end

        % Value changed function: Task_attMode
        function Task_attModeValueChanged(app, event)

            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttMode = app.Task_attMode.Value;

            % Visibilidade do valor do atenuador.
            if strcmp(app.Task_attMode.Value, 'Auto')
                app.Task_attFactorLabel.Visible = 0;
                app.Task_attFactor.Visible      = 0;
            else
                app.Task_attFactorLabel.Visible = 1;
                app.Task_attFactor.Visible      = 1;
            end

        end

        % Value changed function: Task_attFactor
        function Task_attFactorValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttFactor = app.Task_attFactor.Value;

        end

        % Button pushed function: Task_executeTask
        function Task_ButtonPushed(app, event)
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Regular task" (2)
                    app.taskFlag = 2;
                    app.plotFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 1;
                    
                    Layout_TaskScreen(app)
                    app.fig.Visible = 0;

                    switch app.Task_DurationUnit.Value
                        case 'min'; Duration = duration(0, app.Task_Duration.Value, 0);
                        case 'hr';  Duration = duration(app.Task_Duration.Value, 0, 0);
                    end

                    RegularTask(app, seconds(Duration))

                case 2                                                              % "Regular task" (2) >> Off (0)
                    app.taskFlag = 0;
                    app.plotFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;                
                    drawnow nocallbacks
                    
                    Layout_TaskScreen(app)
            end

        end

        % Selection changed function: logger_Tree
        function logger_TreeSelectionChanged(app, event)

            try
                ind = app.logger_Tree.SelectedNodes.NodeData;
    
                app.logger_ThreadID.Value    = double(app.bandList(ind).ThreadID);
                app.logger_FreqStart.Value   = app.bandList(ind).FreqStart ./ 1e+6;
                app.logger_FreqStop.Value    = app.bandList(ind).FreqStop  ./ 1e+6;
                app.logger_Description.Value = app.bandList(ind).Description;
                app.logger_DataPoints.Value  = app.bandList(ind).DataPoints;
                app.logger_StepWidth.Value   = app.bandList(ind).StepWidth  ./ 1e+3;
                app.logger_Resolution.Value  = app.bandList(ind).Resolution ./ 1e+3;
                app.logger_LevelUnit.Value   = app.bandList(ind).LevelUnit;
                app.logger_Operation.Value   = app.bandList(ind).Operation;
                
                if app.plotFlag
                    app.axesFlagType = 'SelectedNode';
                    app.axesFlag     = 1;
                end
                drawnow nocallbacks
            catch
            end

        end

        % Button pushed function: logger_ButtonPlay
        function logger_ButtonPlayValueChanged(app, event)

            try
                connect_infoValidation(app, 'Only Instrument IP', app.logger_IP.Value)
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Logger task" (3)
                    app.taskFlag = 3;
                    app.plotFlag = 1;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    app.bandList = [];
                    delete(app.logger_Tree.Children)
                    
                    app.fig.Visible = 0;                    
                    logger(app)

                case 3                                                                  % "Logger task" (3) >> Off (0)
                    app.taskFlag = 0;
                    app.plotFlag = 0;
                    app.axesFlag = 0;
                    
                    if app.recFlag
                        app.tb_rec.State = 0;
                        Plot_RECButtonPushed(app)
                    else
                        app.recFlag  = 0;
                    end
                    drawnow nocallbacks
                    
                    Layout_TaskScreen(app)
            end

        end

        % Value changed function: logger_Node
        function logger_NodeValueChanged(app, event)

            if ~isempty(app.logger_Node.Value)
                ind = find(strcmp(app.RFeyeList.ID, app.logger_Node.Value), 1);
                IPs = {app.RFeyeList.IP1{ind} app.RFeyeList.IP2{ind}};
                IPs(cellfun(@(x) isempty(x), IPs)) = [];
    
                app.logger_IP.Items = IPs;
    
                if ~isempty(app.ftpObj)
                    close(app.ftpObj)
                    app.ftpObj = [];
                    logger_ftpInfo(app, 'error', '')
                    
                    drawnow nocallbacks
                end

            else
                app.logger_IP.Value = '';
            end

        end

        % Menu selected function: logger_addRECList
        function logger_AddRECList(app, event)

            if isempty(app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC)
                app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC = app.bandList(app.logger_Tree.SelectedNodes.NodeData).ThreadID;
                logger_TreeBuilding(app)
            end

        end

        % Menu selected function: logger_deleteRECList
        function logger_DelRECList(app, event)

            if ~isempty(app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC)
                app.bandList(app.logger_Tree.SelectedNodes.NodeData).REC = [];
                logger_TreeBuilding(app)
            end

        end

        % Button pushed function: logger_winSCP
        function logger_winSCPValueChanged(app, event)

            NodePass = app.RFeyeList.rootPass{find(strcmp(app.RFeyeList.ID, app.logger_Node.Value), 1)};

            winRFeyeCredential(app, app.MainApp.Position, app.logger_IP.Value, NodePass);
            drawnow nocallbacks

        end

        % Value changed function: menu_userPath
        function menu_userPathValueChanged(app, event)
            
            fileID = fopen(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'), 'wt');
            fwrite(fileID, app.menu_userPath.Value, 'char');
            fclose(fileID);
            
        end

        % Value changed function: Task_Detector_2
        function Task_Detector_2ValueChanged(app, event)

            [ind1, ind2] = Task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDetector = app.Task_Detector_2.Value;

        end

        % Button pushed function: logger_Mask, logger_Script
        function logger_ScriptButtonPushed(app, event)
            
            try
                if isempty(app.logger_ScriptName.Value)
                    d = uiprogressdlg(app.MainApp, 'Message', 'Em andamento...', 'Indeterminate', 'on');

                    cd(app.ftpObj,   '/etc/drops/');
                    mget(app.ftpObj, 'rfeyed.cfg');

                    movefile('rfeyed.cfg', fullfile(app.RootFolder, 'Temp'));
                    app.logger_ScriptName.Value = 'rfeyed.cfg';

                    app.logger_MaskName.Items = logger_ScriptRead(app, fullfile(app.RootFolder, 'Temp', 'rfeyed.cfg'));
                    for ii = 1:numel(app.logger_MaskName.Items)
                        cd(app.ftpObj,   '/etc/drops/logger_masks/');
                        mget(app.ftpObj, app.logger_MaskName.Items{ii});
    
                        movefile(app.logger_MaskName.Items{ii}, fullfile(app.RootFolder, 'Temp'));
                    end

                    delete(d)
                else
                    switch event.Source.Icon
                        case 'Script.png'
                            system(sprintf('notepad %s &', fullfile(app.RootFolder, 'Temp', 'rfeyed.cfg')));

                        case 'Mask2.png'
                            system(sprintf('notepad %s &', fullfile(app.RootFolder, 'Temp', app.logger_MaskName.Value)));
                    end
                end

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end

        % Menu selected function: logger_PlotMask
        function logger_PlotMaskSelected(app, event)
            
            if ~isempty(app.logger_MaskName.Value)
                obj = findobj('Tag', 'LoggerMask');
                if ~isempty(obj)
                    delete(obj)
                end
    
                maskTable = readtable(fullfile(app.RootFolder, 'Temp', app.logger_MaskName.Value), 'VariableNamingRule', 'preserve');
                maskTable.Properties.VariableNames = {'FreqStart', 'FreqStop', 'Level'};
    
                for ii = 1:height(maskTable)
                    newObj = plot(app.axes1, [maskTable.FreqStart(ii)/1000, maskTable.FreqStop(ii)/1000], [maskTable.Level(ii), maskTable.Level(ii)], 'red', ...
                                  'Marker', 'o', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red', 'MarkerSize', 4, 'Tag', 'LoggerMask');
    
                    Misc_PlotDataTip(newObj, 'dBm')
                end
            end

        end

        % Callback function: Button_Logger_2, Image6, logger_EditRFeyeList
        function logger_EditRFeyeListButtonPushed(app, event)
            
            winRFeyeEdit(app);
            drawnow nocallbacks

        end

        % Callback function: Button_Logger_3, Image8
        function logger_winMonitorButtonPushed(app, event)
            
            if isempty(app.auxWin2)
                app.auxWin2 = winMonitor(app);
            else
                figure(app.auxWin2)
            end

        end

        % Image clicked function: mainInfo_Visibility
        function mainInfo_VisibilityClicked(app, event)
            
            if app.fig.Visible
                Playback_CloseFigure(app, [], struct('EventName', 'Close'))
            else
                Playback_CloseFigure(app, [], struct('EventName', 'Open'))
            end

        end

        % Image clicked function: Image7
        function Image7Clicked(app, event)
            
            app.Button_Logger.Value = 1;
            Button_LoggerValueChanged(app)

        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            
            app.Button_Task.Value = 1;
            Button_TaskValueChanged(app)

        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            
            app.Button_Connect.Value = 1;
            Button_ConnectValueChanged(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appColeta R2022a';
            app.MainApp.Icon = 'home_Puzzle.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab = uitab(app.TabGroup);
            app.Home_Grid0_2 = uigridlayout(app.Tab);
            app.Image4 = uiimage(app.Home_Grid0_2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'home_button1.png';
            app.Image5 = uiimage(app.Home_Grid0_2);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'home_button2.png';
            app.Image6 = uiimage(app.Home_Grid0_2);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @logger_EditRFeyeListButtonPushed, true);
            app.Image6.ImageSource = 'home_button3.png';
            app.Image7 = uiimage(app.Home_Grid0_2);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @Image7Clicked, true);
            app.Image7.ImageSource = 'home_button4.png';
            app.Image8 = uiimage(app.Home_Grid0_2);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @logger_winMonitorButtonPushed, true);
            app.Image8.ImageSource = 'home_button5.png';
            app.Image9 = uiimage(app.Home_Grid0_2);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @tool_Button2Pushed, true);
            app.Image9.ImageSource = 'home_button6.png';
            app.Tab2_Connection = uitab(app.TabGroup);
            app.connect_Grid0 = uigridlayout(app.Tab2_Connection);
            app.connect_Tree = uitree(app.connect_Grid0);
            app.connect_Tree.SelectionChangedFcn = createCallbackFcn(app, @connect_TreeSelectionChanged, true);
            app.connect_Grid4 = uigridlayout(app.connect_Grid0);
            app.connect_instrDescription = uilabel(app.connect_Grid4);
            app.connect_instrDescription.Text = 'Descrição';
            app.connect_instrName = uilabel(app.connect_Grid4);
            app.connect_instrName.Text = 'Título';
            app.connect_instrImage = uiimage(app.connect_Grid4);
            app.connect_Panel1 = uipanel(app.connect_Grid0);
            app.connect_Grid1 = uigridlayout(app.connect_Panel1);
            app.connect_Family = uidropdown(app.connect_Grid1);
            app.connect_Family.Items = {};
            app.connect_Family.ValueChangedFcn = createCallbackFcn(app, @connect_FamilyValueChanged, true);
            app.connect_Family.Value = {};
            app.connect_Name = uidropdown(app.connect_Grid1);
            app.connect_Name.Items = {};
            app.connect_Name.ValueChangedFcn = createCallbackFcn(app, @connect_NameValueChanged, true);
            app.connect_Name.Value = {};
            app.connect_IP = uieditfield(app.connect_Grid1, 'text');
            app.connect_IP.Editable = 'off';
            app.connect_Port = uieditfield(app.connect_Grid1, 'text');
            app.connect_Port.Editable = 'off';
            app.connect_Timeout = uieditfield(app.connect_Grid1, 'numeric');
            app.connect_Timeout.Limits = [1 10];
            app.connect_Timeout.Editable = 'off';
            app.connect_Timeout.Value = 5;
            app.connect_Buffer = uieditfield(app.connect_Grid1, 'numeric');
            app.connect_Buffer.Limits = [1 200];
            app.connect_Buffer.RoundFractionalValues = 'on';
            app.connect_Buffer.ValueDisplayFormat = '%.0f';
            app.connect_Buffer.Editable = 'off';
            app.connect_Buffer.Value = 10;
            app.connect_Description = uitextarea(app.connect_Grid1);
            app.connect_Description.Editable = 'off';
            app.connect_Grid3 = uigridlayout(app.connect_Grid1);
            app.connect_CheckBox = uicheckbox(app.connect_Grid3);
            app.connect_CheckBox.ValueChangedFcn = createCallbackFcn(app, @connect_CheckBoxValueChanged, true);
            app.connect_CheckBox.Enable = 'off';
            app.connect_CheckBox.Text = '';
            app.connect_CheckBoxLabel = uilabel(app.connect_Grid3);
            app.connect_CheckBoxLabel.Text = 'Localhost';
            app.connect_Panel = uipanel(app.connect_Grid1);
            app.connect_Grid2 = uigridlayout(app.connect_Panel);
            app.connect_localIP = uieditfield(app.connect_Grid2, 'text');
            app.connect_localIP.Editable = 'off';
            app.connect_localIP.Enable = 'off';
            app.connect_localIPLabel = uilabel(app.connect_Grid2);
            app.connect_localIPLabel.Enable = 'off';
            app.connect_localIPLabel.Text = 'Endereço IP local:';
            app.connect_publicIPLabel = uilabel(app.connect_Grid2);
            app.connect_publicIPLabel.Enable = 'off';
            app.connect_publicIPLabel.Text = 'Endereço IP público:';
            app.connect_publicIP = uieditfield(app.connect_Grid2, 'text');
            app.connect_publicIP.Editable = 'off';
            app.connect_publicIP.Enable = 'off';
            app.connect_DescriptionLabel = uilabel(app.connect_Grid1);
            app.connect_DescriptionLabel.Text = 'Descrição (opcional):';
            app.connect_BufferLabel = uilabel(app.connect_Grid1);
            app.connect_BufferLabel.Text = 'Buffer entrada (MB):';
            app.connect_TimeoutLabel = uilabel(app.connect_Grid1);
            app.connect_TimeoutLabel.Text = 'Timeout (seg):';
            app.connect_PortLabel = uilabel(app.connect_Grid1);
            app.connect_PortLabel.Text = 'Porta:';
            app.connect_IPLabel = uilabel(app.connect_Grid1);
            app.connect_IPLabel.Text = 'Endereço IP:';
            app.connect_NameLabel = uilabel(app.connect_Grid1);
            app.connect_NameLabel.Text = 'Fabricante e modelo';
            app.connect_FamilyLabel = uilabel(app.connect_Grid1);
            app.connect_FamilyLabel.Text = 'Família:';
            app.connect_TypeLabel = uilabel(app.connect_Grid1);
            app.connect_TypeLabel.Text = 'Tipo de conexão:';
            app.connect_Type = uidropdown(app.connect_Grid1);
            app.connect_Type.Items = {};
            app.connect_Type.ValueChangedFcn = createCallbackFcn(app, @connect_TypeValueChanged, true);
            app.connect_Type.Value = {};
            app.connect_Label1 = uilabel(app.connect_Grid0);
            app.connect_Label1.Text = 'Instrumento';
            app.GridLayout26 = uigridlayout(app.connect_Grid0);
            app.connect_Button3 = uibutton(app.GridLayout26, 'state');
            app.connect_Button3.ValueChangedFcn = createCallbackFcn(app, @connect_Button3Pushed, true);
            app.connect_Button3.Enable = 'off';
            app.connect_Button3.Tooltip = {'Editar instrumento'};
            app.connect_Button3.Icon = 'edit.png';
            app.connect_Button3.Text = '';
            app.connect_Button2 = uibutton(app.GridLayout26, 'state');
            app.connect_Button2.ValueChangedFcn = createCallbackFcn(app, @connect_Button2Pushed, true);
            app.connect_Button2.Tooltip = {'Incluir instrumento'};
            app.connect_Button2.Icon = 'Edit2.png';
            app.connect_Button2.Text = '';
            app.tool_connect = uibutton(app.GridLayout26, 'push');
            app.tool_connect.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_connect.Icon = 'toolbar_info.png';
            app.tool_connect.Text = '';
            app.Image2 = uiimage(app.GridLayout26);
            app.Image2.ImageSource = 'Line.png';
            app.connect_Button4 = uibutton(app.GridLayout26, 'push');
            app.connect_Button4.ButtonPushedFcn = createCallbackFcn(app, @connect_Button4Pushed, true);
            app.connect_Button4.Icon = 'ConnectWhite.png';
            app.connect_Button4.Enable = 'off';
            app.connect_Button4.Text = 'Conectividade';
            app.GridLayout27 = uigridlayout(app.connect_Grid0);
            app.connect_Button1 = uibutton(app.GridLayout27, 'push');
            app.connect_Button1.ButtonPushedFcn = createCallbackFcn(app, @connect_Button1Pushed, true);
            app.connect_Button1.Icon = 'General_editWhite.png';
            app.connect_Button1.Enable = 'off';
            app.connect_Button1.Text = 'Editar';
            app.Tab3_Task = uitab(app.TabGroup);
            app.Task_Grid0 = uigridlayout(app.Tab3_Task);
            app.Task_Grid2 = uigridlayout(app.Task_Grid0);
            app.Task_Label6 = uilabel(app.Task_Grid2);
            app.Task_Label6.Text = 'Aspectos gerais';
            app.Task_Tree1 = uitree(app.Task_Grid2);
            app.Task_Tree1.SelectionChangedFcn = createCallbackFcn(app, @Task_Tree1SelectionChanged, true);
            app.Task_AntennaLabel = uilabel(app.Task_Grid2);
            app.Task_AntennaLabel.Text = 'Antena:';
            app.Task_Label7_2 = uilabel(app.Task_Grid2);
            app.Task_Label7_2.Text = 'Instrumentos';
            app.Task_GPSLabel = uilabel(app.Task_Grid2);
            app.Task_GPSLabel.Text = 'GPS:';
            app.Task_Name = uieditfield(app.Task_Grid2, 'text');
            app.Task_Name.Editable = 'off';
            app.Task_NameLabel = uilabel(app.Task_Grid2);
            app.Task_NameLabel.Text = 'Nome:';
            app.Task_Panel1 = uipanel(app.Task_Grid2);
            app.Task_Grid3 = uigridlayout(app.Task_Panel1);
            app.Task_Duration = uieditfield(app.Task_Grid3, 'numeric');
            app.Task_Duration.Limits = [0 Inf];
            app.Task_Duration.ValueDisplayFormat = '%.3f';
            app.Task_Duration.Value = 10;
            app.Task_DurationLabel = uilabel(app.Task_Grid3);
            app.Task_DurationLabel.Text = 'Duração:';
            app.Task_DurationUnit = uidropdown(app.Task_Grid3);
            app.Task_DurationUnit.Items = {'min', 'hr'};
            app.Task_DurationUnit.Value = 'min';
            app.Task_BitsPerPointLabel = uilabel(app.Task_Grid3);
            app.Task_BitsPerPointLabel.Text = 'Codificação:';
            app.Task_BitsPerPoint = uidropdown(app.Task_Grid3);
            app.Task_BitsPerPoint.Items = {'8 bits'};
            app.Task_BitsPerPoint.Value = '8 bits';
            app.Task_Instrument = uidropdown(app.Task_Grid2);
            app.Task_Instrument.Items = {};
            app.Task_Instrument.ValueChangedFcn = createCallbackFcn(app, @Task_InstrumentValueChanged, true);
            app.Task_Instrument.Value = {};
            app.Task_GPS = uidropdown(app.Task_Grid2);
            app.Task_GPS.Items = {'ID 0: Manual'};
            app.Task_GPS.ValueChangedFcn = createCallbackFcn(app, @Task_GPSValueChanged, true);
            app.Task_GPS.Value = 'ID 0: Manual';
            app.Task_InstrumentLabel = uilabel(app.Task_Grid2);
            app.Task_InstrumentLabel.Text = 'Receptor:';
            app.Task_Panel3 = uipanel(app.Task_Grid2);
            app.GridLayout18 = uigridlayout(app.Task_Panel3);
            app.Task_gpsRevisitTime = uieditfield(app.GridLayout18, 'numeric');
            app.Task_gpsRevisitTime.Limits = [1 Inf];
            app.Task_gpsRevisitTime.RoundFractionalValues = 'on';
            app.Task_gpsRevisitTime.ValueDisplayFormat = '%.0f';
            app.Task_gpsRevisitTime.Value = 10;
            app.Task_manualLatitudeLabel = uilabel(app.GridLayout18);
            app.Task_manualLatitudeLabel.Text = 'Latitude (graus):';
            app.Task_manualLatitude = uieditfield(app.GridLayout18, 'numeric');
            app.Task_manualLatitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLatitude.Value = -1;
            app.Task_manualLongitude = uieditfield(app.GridLayout18, 'numeric');
            app.Task_manualLongitude.ValueDisplayFormat = '%.6f';
            app.Task_manualLongitude.Value = -1;
            app.Task_manualLongitudeLabel = uilabel(app.GridLayout18);
            app.Task_manualLongitudeLabel.Text = 'Longitude (graus):';
            app.Task_gpsRevisitTimeLabel = uilabel(app.GridLayout18);
            app.Task_gpsRevisitTimeLabel.Text = 'Tempo de revisita (seg):';
            app.Task_Antenna = uidropdown(app.Task_Grid2);
            app.Task_Antenna.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.Task_Antenna.ValueChangedFcn = createCallbackFcn(app, @Task_AntennaValueChanged, true);
            app.Task_Antenna.Value = '';
            app.Task_Panel2 = uipanel(app.Task_Grid2);
            app.GridLayout17 = uigridlayout(app.Task_Panel2);
            app.Task_Height = uieditfield(app.GridLayout17, 'numeric');
            app.Task_Height.Limits = [0 127];
            app.Task_Height.RoundFractionalValues = 'on';
            app.Task_Height.ValueDisplayFormat = '%.0f';
            app.Task_Height.Value = 2;
            app.Task_HeightLabel = uilabel(app.GridLayout17);
            app.Task_HeightLabel.Text = 'Altura de instalação (m):';
            app.Task_Azimuth = uieditfield(app.GridLayout17, 'numeric');
            app.Task_Azimuth.Limits = [0 359];
            app.Task_Azimuth.RoundFractionalValues = 'on';
            app.Task_Azimuth.ValueDisplayFormat = '%.0f';
            app.Task_Azimuth.Enable = 'off';
            app.Task_AzimuthLabel = uilabel(app.GridLayout17);
            app.Task_AzimuthLabel.Enable = 'off';
            app.Task_AzimuthLabel.Text = 'Azimute (graus):';
            app.Task_Elevation = uieditfield(app.GridLayout17, 'numeric');
            app.Task_Elevation.Limits = [0 90];
            app.Task_Elevation.RoundFractionalValues = 'on';
            app.Task_Elevation.ValueDisplayFormat = '%.0f';
            app.Task_Elevation.Enable = 'off';
            app.Task_ElevationLabel = uilabel(app.GridLayout17);
            app.Task_ElevationLabel.Enable = 'off';
            app.Task_ElevationLabel.Text = 'Elevação (graus):';
            app.GridLayout19 = uigridlayout(app.Task_Grid2);
            app.Task_SaveTask = uibutton(app.GridLayout19, 'push');
            app.Task_SaveTask.ButtonPushedFcn = createCallbackFcn(app, @Task_SaveTaskPushed, true);
            app.Task_SaveTask.Icon = 'General_save.png';
            app.Task_SaveTask.Tooltip = {'Salvar tarefa(s)'};
            app.Task_SaveTask.Text = '';
            app.Task_OpenTask = uibutton(app.GridLayout19, 'push');
            app.Task_OpenTask.ButtonPushedFcn = createCallbackFcn(app, @Task_OpenTaskPushed, true);
            app.Task_OpenTask.Icon = 'file.png';
            app.Task_OpenTask.Tooltip = {'Abrir tarefa(s)'};
            app.Task_OpenTask.Text = '';
            app.Task_NewTask = uibutton(app.GridLayout19, 'state');
            app.Task_NewTask.ValueChangedFcn = createCallbackFcn(app, @Task_NewTaskValueChanged, true);
            app.Task_NewTask.Tooltip = {'Criar tarefa'};
            app.Task_NewTask.Icon = 'Menu_NewTask.png';
            app.Task_NewTask.Text = '';
            app.Image = uiimage(app.GridLayout19);
            app.Image.ImageSource = 'Line.png';
            app.tool_task = uibutton(app.GridLayout19, 'push');
            app.tool_task.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_task.Icon = 'toolbar_info.png';
            app.tool_task.Text = '';
            app.Panel_9 = uipanel(app.Task_Grid2);
            app.GridLayout23 = uigridlayout(app.Panel_9);
            app.Task_SyncRefLabel = uilabel(app.GridLayout23);
            app.Task_SyncRefLabel.Text = 'Sincronismo:';
            app.Task_SyncRef = uidropdown(app.GridLayout23);
            app.Task_SyncRef.Items = {'Single Sweep', 'Continuous Sweep', 'Streaming'};
            app.Task_SyncRef.Value = 'Single Sweep';
            app.Task_ResetCommandLabel = uilabel(app.GridLayout23);
            app.Task_ResetCommandLabel.Text = 'Reset:';
            app.Task_ResetCommand = uidropdown(app.GridLayout23);
            app.Task_ResetCommand.Items = {'On', 'Off'};
            app.Task_ResetCommand.Value = 'On';
            app.Task_Label1 = uilabel(app.Task_Grid2);
            app.Task_Label1.Text = 'Tarefa(s)';
            app.Task_Grid6 = uigridlayout(app.Task_Grid0);
            app.Task_Label9 = uilabel(app.Task_Grid6);
            app.Task_Label9.Text = 'Faixa(s) de frequência a monitorar';
            app.Panel_5 = uipanel(app.Task_Grid6);
            app.GridLayout6 = uigridlayout(app.Panel_5);
            app.Task_ThreadID = uieditfield(app.GridLayout6, 'numeric');
            app.Task_ThreadID.Limits = [1 255];
            app.Task_ThreadID.RoundFractionalValues = 'on';
            app.Task_ThreadID.ValueDisplayFormat = '%.0f';
            app.Task_ThreadID.Editable = 'off';
            app.Task_ThreadID.Value = 1;
            app.Task_Description = uieditfield(app.GridLayout6, 'text');
            app.Task_Description.Editable = 'off';
            app.Task_F0 = uieditfield(app.GridLayout6, 'numeric');
            app.Task_F0.Limits = [0 Inf];
            app.Task_F0.ValueDisplayFormat = '%.6f';
            app.Task_F0.ValueChangedFcn = createCallbackFcn(app, @Task_SpanValueChanged, true);
            app.Task_F0.Editable = 'off';
            app.Task_F0.Value = 108;
            app.Task_F1 = uieditfield(app.GridLayout6, 'numeric');
            app.Task_F1.Limits = [0 Inf];
            app.Task_F1.ValueDisplayFormat = '%.6f';
            app.Task_F1.ValueChangedFcn = createCallbackFcn(app, @Task_SpanValueChanged, true);
            app.Task_F1.Editable = 'off';
            app.Task_F1.Value = 108;
            app.DescrioEditFieldLabel = uilabel(app.GridLayout6);
            app.DescrioEditFieldLabel.Text = 'Descrição:';
            app.PassodavarredurakHzLabel = uilabel(app.GridLayout6);
            app.PassodavarredurakHzLabel.Text = {'Passo da varredura'; '(kHz):'};
            app.Task_StepWidth = uieditfield(app.GridLayout6, 'numeric');
            app.Task_StepWidth.Limits = [0 Inf];
            app.Task_StepWidth.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth.Editable = 'off';
            app.Task_Resolution1Label = uilabel(app.GridLayout6);
            app.Task_Resolution1Label.Text = {'Resolução'; '(kHz):'};
            app.Task_Resolution1 = uieditfield(app.GridLayout6, 'numeric');
            app.Task_Resolution1.Limits = [0 Inf];
            app.Task_Resolution1.ValueDisplayFormat = '%.3f';
            app.Task_Resolution1.Editable = 'off';
            app.Task_IntegrationFactor = uieditfield(app.GridLayout6, 'numeric');
            app.Task_IntegrationFactor.Limits = [1 Inf];
            app.Task_IntegrationFactor.RoundFractionalValues = 'on';
            app.Task_IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.Task_IntegrationFactor.Editable = 'off';
            app.Task_IntegrationFactor.Value = 1;
            app.Task_IntegrationFactorLabel = uilabel(app.GridLayout6);
            app.Task_IntegrationFactorLabel.Text = 'Fator de integração:';
            app.Task_RevisitTimeLabel = uilabel(app.GridLayout6);
            app.Task_RevisitTimeLabel.Text = 'Tempo de revisita (seg):';
            app.Task_RevisitTime = uieditfield(app.GridLayout6, 'numeric');
            app.Task_RevisitTime.Limits = [0.001 Inf];
            app.Task_RevisitTime.ValueDisplayFormat = '%.3f';
            app.Task_RevisitTime.Editable = 'off';
            app.Task_RevisitTime.Value = 1;
            app.ThreadIDEditFieldLabel = uilabel(app.GridLayout6);
            app.ThreadIDEditFieldLabel.Text = 'ThreadID';
            app.FrequnciainicialMHzLabel = uilabel(app.GridLayout6);
            app.FrequnciainicialMHzLabel.Text = {'Frequência inicial'; '(MHz):'};
            app.FrequnciafinalMHzLabel = uilabel(app.GridLayout6);
            app.FrequnciafinalMHzLabel.Text = {'Frequência final'; '(MHz):'};
            app.Task_TraceMode = uidropdown(app.GridLayout6);
            app.Task_TraceMode.Items = {'ClearWrite', 'Average', 'MaxHold', 'MinHold'};
            app.Task_TraceMode.ValueChangedFcn = createCallbackFcn(app, @Task_TraceModeValueChanged, true);
            app.Task_TraceMode.Value = 'Average';
            app.MododotraoLabel = uilabel(app.GridLayout6);
            app.MododotraoLabel.Text = 'Modo do traço:';
            app.Task_Detector = uidropdown(app.GridLayout6);
            app.Task_Detector.Items = {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'};
            app.Task_Detector.Value = 'Sample';
            app.DetectorDropDownLabel = uilabel(app.GridLayout6);
            app.DetectorDropDownLabel.Text = 'Detector:';
            app.Task_LevelUnit = uidropdown(app.GridLayout6);
            app.Task_LevelUnit.Items = {'dBm', 'dBuV', 'dBuV/m'};
            app.Task_LevelUnit.Value = 'dBm';
            app.UnidadeDropDownLabel = uilabel(app.GridLayout6);
            app.UnidadeDropDownLabel.Text = 'Unidade:';
            app.MododeRFLabel = uilabel(app.GridLayout6);
            app.MododeRFLabel.Text = 'Modo de RF:';
            app.Task_RFMode = uidropdown(app.GridLayout6);
            app.Task_RFMode.Items = {'High Sensitivity', 'Low Distortion', 'Normal'};
            app.Task_RFMode.Value = 'High Sensitivity';
            app.Task_addBand = uibutton(app.Task_Grid6, 'push');
            app.Task_addBand.ButtonPushedFcn = createCallbackFcn(app, @Task_addBandImageClicked, true);
            app.Task_addBand.Icon = 'General_addWhite.png';
            app.Task_addBand.Text = 'Incluir faixa';
            app.Panel_4 = uipanel(app.Task_Grid6);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.Task_StepWidth1 = uieditfield(app.GridLayout5, 'numeric');
            app.Task_StepWidth1.Limits = [0 Inf];
            app.Task_StepWidth1.ValueDisplayFormat = '%.3f';
            app.Task_StepWidth1.ValueChangedFcn = createCallbackFcn(app, @Task_StepWidth1ValueChanged, true);
            app.Task_StepWidth1.Editable = 'off';
            app.Task_StepWidth2 = uidropdown(app.GridLayout5);
            app.Task_StepWidth2.Items = {};
            app.Task_StepWidth2.ValueChangedFcn = createCallbackFcn(app, @Task_StepWidth2ValueChanged, true);
            app.Task_StepWidth2.Value = {};
            app.Task_DataPoints1 = uispinner(app.GridLayout5);
            app.Task_DataPoints1.Step = 100;
            app.Task_DataPoints1.Limits = [101 32001];
            app.Task_DataPoints1.RoundFractionalValues = 'on';
            app.Task_DataPoints1.ValueDisplayFormat = '%.0f';
            app.Task_DataPoints1.ValueChangedFcn = createCallbackFcn(app, @Task_DataPoints1ValueChanged, true);
            app.Task_DataPoints1.Value = 101;
            app.Task_DataPoints2 = uieditfield(app.GridLayout5, 'numeric');
            app.Task_DataPoints2.ValueDisplayFormat = '%.0f';
            app.Task_DataPoints2.Editable = 'off';
            app.Task_StepWidthLabel = uilabel(app.GridLayout5);
            app.Task_StepWidthLabel.Text = {'Passo da varredura '; '(kHz):'};
            app.Task_DataPointsLabel = uilabel(app.GridLayout5);
            app.Task_DataPointsLabel.Text = {'Pontos '; 'por traço:'};
            app.Task_SelectivityLabel = uilabel(app.GridLayout5);
            app.Task_SelectivityLabel.Text = {'Seletividade'; 'FFT:'};
            app.Task_Selectivity = uidropdown(app.GridLayout5);
            app.Task_Selectivity.Items = {'Normal', 'Narrow', 'Sharp'};
            app.Task_Selectivity.ValueChangedFcn = createCallbackFcn(app, @Task_SelectivityValueChanged, true);
            app.Task_Selectivity.Value = 'Normal';
            app.Task_Resolution2 = uidropdown(app.GridLayout5);
            app.Task_Resolution2.Items = {};
            app.Task_Resolution2.ValueChangedFcn = createCallbackFcn(app, @Task_Resolution2ValueChanged, true);
            app.Task_Resolution2.Value = {};
            app.ResoluokHzLabel_2 = uilabel(app.GridLayout5);
            app.ResoluokHzLabel_2.Text = {'Resolução'; '(kHz):'};
            app.Task_LevelUnit_2 = uieditfield(app.GridLayout5, 'text');
            app.Task_LevelUnit_2.Editable = 'off';
            app.Task_LevelUnit_2.Value = 'dBm';
            app.UnidadeEditFieldLabel = uilabel(app.GridLayout5);
            app.UnidadeEditFieldLabel.Text = 'Unidade:';
            app.Task_Detector_2 = uidropdown(app.GridLayout5);
            app.Task_Detector_2.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.Task_Detector_2.ValueChangedFcn = createCallbackFcn(app, @Task_Detector_2ValueChanged, true);
            app.Task_Detector_2.Value = 'Positive Peak';
            app.DetectorDropDownLabel_2 = uilabel(app.GridLayout5);
            app.DetectorDropDownLabel_2.Text = 'Detector:';
            app.Task_attMode = uidropdown(app.GridLayout5);
            app.Task_attMode.Items = {'Manual', 'Auto'};
            app.Task_attMode.ValueChangedFcn = createCallbackFcn(app, @Task_attModeValueChanged, true);
            app.Task_attMode.Value = 'Manual';
            app.Task_attModeLabel = uilabel(app.GridLayout5);
            app.Task_attModeLabel.Text = 'Modo do atenuador:';
            app.Task_attFactor = uidropdown(app.GridLayout5);
            app.Task_attFactor.Items = {};
            app.Task_attFactor.ValueChangedFcn = createCallbackFcn(app, @Task_attFactorValueChanged, true);
            app.Task_attFactor.Value = {};
            app.Task_attFactorLabel = uilabel(app.GridLayout5);
            app.Task_attFactorLabel.Text = 'Atenuador:';
            app.Task_Preamp = uidropdown(app.GridLayout5);
            app.Task_Preamp.Items = {'On', 'Off'};
            app.Task_Preamp.ValueChangedFcn = createCallbackFcn(app, @Task_PreampValueChanged, true);
            app.Task_Preamp.Value = 'Off';
            app.Task_PreampLabel = uilabel(app.GridLayout5);
            app.Task_PreampLabel.Text = 'Pré-amplificador:';
            app.Task_Tree2 = uitree(app.Task_Grid6);
            app.Task_Tree2.SelectionChangedFcn = createCallbackFcn(app, @Task_Tree2SelectionChanged, true);
            app.GridLayout20 = uigridlayout(app.Task_Grid6);
            app.Task_newBand = uibutton(app.GridLayout20, 'state');
            app.Task_newBand.ValueChangedFcn = createCallbackFcn(app, @Task_newBandButtonPushed, true);
            app.Task_newBand.Tooltip = {'Incluir faixa'};
            app.Task_newBand.Icon = 'Edit2.png';
            app.Task_newBand.Text = '';
            app.Task_editBand = uibutton(app.GridLayout20, 'state');
            app.Task_editBand.ValueChangedFcn = createCallbackFcn(app, @Task_editBandValueChanged, true);
            app.Task_editBand.Tooltip = {'Editar faixa'};
            app.Task_editBand.Icon = 'edit.png';
            app.Task_editBand.Text = '';
            app.Task_Separator2 = uiimage(app.GridLayout20);
            app.Task_Separator2.Enable = 'off';
            app.Task_Separator2.ImageSource = 'Line.png';
            app.Task_RefreshButton = uibutton(app.GridLayout20, 'push');
            app.Task_RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Task_RefreshButtonPushed, true);
            app.Task_RefreshButton.Icon = 'General_Refresh.png';
            app.Task_RefreshButton.Enable = 'off';
            app.Task_RefreshButton.Tooltip = {'Retornar valores padrões'};
            app.Task_RefreshButton.Text = '';
            app.Task_Info = uihyperlink(app.GridLayout20);
            app.Task_Info.Text = '';
            app.GridLayout25 = uigridlayout(app.Task_Grid6);
            app.Task_Warning2 = uiimage(app.GridLayout25);
            app.Task_Warning2.Enable = 'off';
            app.Task_Warning2.Visible = 'off';
            app.Task_Warning2.ImageSource = 'Warn_Yellow.png';
            app.Task_Label9_2 = uilabel(app.GridLayout25);
            app.Task_Label9_2.Text = 'Parâmetros relacionados ao receptor selecionado';
            app.Task_executeTask = uibutton(app.Task_Grid6, 'push');
            app.Task_executeTask.ButtonPushedFcn = createCallbackFcn(app, @Task_ButtonPushed, true);
            app.Task_executeTask.Icon = 'General_play.png';
            app.Task_executeTask.Enable = 'off';
            app.Task_executeTask.Text = 'Executar tarefa';
            app.Tab4_logger = uitab(app.TabGroup);
            app.logger_Grid1 = uigridlayout(app.Tab4_logger);
            app.logger_Grid1A = uigridlayout(app.logger_Grid1);
            app.logger_Panel1 = uipanel(app.logger_Grid1A);
            app.logger_Panel1_Grid = uigridlayout(app.logger_Panel1);
            app.logger_Node = uidropdown(app.logger_Panel1_Grid);
            app.logger_Node.Items = {};
            app.logger_Node.ValueChangedFcn = createCallbackFcn(app, @logger_NodeValueChanged, true);
            app.logger_Node.Value = {};
            app.logger_NodeLabel = uilabel(app.logger_Panel1_Grid);
            app.logger_NodeLabel.Text = 'Receptor:';
            app.logger_IP = uidropdown(app.logger_Panel1_Grid);
            app.logger_IP.Items = {};
            app.logger_IP.Value = {};
            app.logger_IPLabel = uilabel(app.logger_Panel1_Grid);
            app.logger_IPLabel.Text = 'Endereço IP:';
            app.logger_Qtd = uilabel(app.logger_Panel1_Grid);
            app.logger_Qtd.Text = '86 de 190 sensores';
            app.logger_PortLabel = uilabel(app.logger_Panel1_Grid);
            app.logger_PortLabel.Text = 'Porta:';
            app.logger_Port = uieditfield(app.logger_Panel1_Grid, 'numeric');
            app.logger_Port.Limits = [0 65535];
            app.logger_Port.RoundFractionalValues = 'on';
            app.logger_Port.ValueDisplayFormat = '%.0f';
            app.logger_Port.Value = 8910;
            app.logger_toolbar = uigridlayout(app.logger_Grid1A);
            app.logger_Script = uibutton(app.logger_toolbar, 'push');
            app.logger_Script.ButtonPushedFcn = createCallbackFcn(app, @logger_ScriptButtonPushed, true);
            app.logger_Script.Icon = 'Script.png';
            app.logger_Script.Enable = 'off';
            app.logger_Script.Tooltip = {'Script'};
            app.logger_Script.Text = '';
            app.logger_ftpStatus = uilabel(app.logger_toolbar);
            app.logger_ftpStatus.Text = '';
            app.logger_Mask = uibutton(app.logger_toolbar, 'push');
            app.logger_Mask.ButtonPushedFcn = createCallbackFcn(app, @logger_ScriptButtonPushed, true);
            app.logger_Mask.Icon = 'winMaskDraw.png';
            app.logger_Mask.Enable = 'off';
            app.logger_Mask.Tooltip = {'Máscara espectral'};
            app.logger_Mask.Text = '';
            app.logger_EditRFeyeList = uibutton(app.logger_toolbar, 'push');
            app.logger_EditRFeyeList.ButtonPushedFcn = createCallbackFcn(app, @logger_EditRFeyeListButtonPushed, true);
            app.logger_EditRFeyeList.Icon = 'edit.png';
            app.logger_EditRFeyeList.Tooltip = {'Editar lista de sensores'};
            app.logger_EditRFeyeList.Text = '';
            app.logger_winSCP = uibutton(app.logger_toolbar, 'push');
            app.logger_winSCP.ButtonPushedFcn = createCallbackFcn(app, @logger_winSCPValueChanged, true);
            app.logger_winSCP.Icon = 'winSCP.png';
            app.logger_winSCP.Tooltip = {'FTP/SFTP'};
            app.logger_winSCP.Text = '';
            app.logger_Separator1 = uiimage(app.logger_toolbar);
            app.logger_Separator1.ImageSource = 'Line.png';
            app.tool_logger = uibutton(app.logger_toolbar, 'push');
            app.tool_logger.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_logger.Icon = 'toolbar_info.png';
            app.tool_logger.Text = '';
            app.logger_Separator1_2 = uiimage(app.logger_toolbar);
            app.logger_Separator1_2.ImageSource = 'Line.png';
            app.logger_Label2 = uilabel(app.logger_Grid1A);
            app.logger_Label2.Text = 'Aspectos gerais';
            app.logger_Grid1A_Grid = uigridlayout(app.logger_Grid1A);
            app.logger_UnitInfoLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_UnitInfoLabel.Enable = 'off';
            app.logger_UnitInfoLabel.Text = {'Tipo de medição:'; '("unit_info" do script carregado no Logger)'};
            app.logger_UnitInfo = uieditfield(app.logger_Grid1A_Grid, 'text');
            app.logger_UnitInfo.Editable = 'off';
            app.logger_UnitInfo.Enable = 'off';
            app.logger_Method = uieditfield(app.logger_Grid1A_Grid, 'text');
            app.logger_Method.Editable = 'off';
            app.logger_Method.Enable = 'off';
            app.logger_MethodLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_MethodLabel.Enable = 'off';
            app.logger_MethodLabel.Text = {'Nome:'; '("method" do script carregado no Logger)'};
            app.logger_GeneralLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_GeneralLabel.Enable = 'off';
            app.logger_GeneralLabel.Text = 'Mensagem ambiente web:';
            app.logger_General = uitextarea(app.logger_Grid1A_Grid);
            app.logger_General.Editable = 'off';
            app.logger_General.Enable = 'off';
            app.logger_AvailableDatagrams = uieditfield(app.logger_Grid1A_Grid, 'numeric');
            app.logger_AvailableDatagrams.Editable = 'off';
            app.logger_AvailableDatagrams.Enable = 'off';
            app.logger_AvailableDatagrams.Visible = 'off';
            app.logger_AvailableDatagramsLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_AvailableDatagramsLabel.Enable = 'off';
            app.logger_AvailableDatagramsLabel.Visible = 'off';
            app.logger_AvailableDatagramsLabel.Text = 'Datagramas disponíveis leitura:';
            app.logger_Pause = uispinner(app.logger_Grid1A_Grid);
            app.logger_Pause.Step = 50;
            app.logger_Pause.Limits = [0 1000];
            app.logger_Pause.RoundFractionalValues = 'on';
            app.logger_Pause.ValueDisplayFormat = '%.0f';
            app.logger_Pause.Enable = 'off';
            app.logger_Pause.Visible = 'off';
            app.logger_Pause.Value = 100;
            app.logger_PauseLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_PauseLabel.Enable = 'off';
            app.logger_PauseLabel.Visible = 'off';
            app.logger_PauseLabel.Text = 'Pausa entre leitura de datagramas (ms):';
            app.logger_generalLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_generalLabel.Enable = 'off';
            app.logger_generalLabel.Text = 'Receptor:';
            app.logger_Hostname = uieditfield(app.logger_Grid1A_Grid, 'text');
            app.logger_Hostname.Editable = 'off';
            app.logger_Hostname.Enable = 'off';
            app.logger_gpsLabel = uilabel(app.logger_Grid1A_Grid);
            app.logger_gpsLabel.Enable = 'off';
            app.logger_gpsLabel.Text = 'GPS';
            app.logger_Panel2 = uipanel(app.logger_Grid1A_Grid);
            app.logger_Panel2_Grid = uigridlayout(app.logger_Panel2);
            app.logger_gpsCountLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_gpsCountLabel.Enable = 'off';
            app.logger_gpsCountLabel.Text = 'Qtd. blocos decodificados:';
            app.logger_gpsCount = uieditfield(app.logger_Panel2_Grid, 'numeric');
            app.logger_gpsCount.Limits = [0 Inf];
            app.logger_gpsCount.ValueDisplayFormat = '%.0f';
            app.logger_gpsCount.Editable = 'off';
            app.logger_gpsCount.Enable = 'off';
            app.logger_gpsTime = uieditfield(app.logger_Panel2_Grid, 'text');
            app.logger_gpsTime.Editable = 'off';
            app.logger_gpsTime.Enable = 'off';
            app.logger_gpsTimeLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_gpsTimeLabel.Enable = 'off';
            app.logger_gpsTimeLabel.Text = 'Atualizado em:';
            app.logger_gpsLatitudeLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_gpsLatitudeLabel.Enable = 'off';
            app.logger_gpsLatitudeLabel.Text = 'Latitude (graus):';
            app.logger_gpsLatitude = uieditfield(app.logger_Panel2_Grid, 'numeric');
            app.logger_gpsLatitude.ValueDisplayFormat = '%.6f';
            app.logger_gpsLatitude.Editable = 'off';
            app.logger_gpsLatitude.Enable = 'off';
            app.logger_gpsLatitude.Value = -1;
            app.logger_gpsLongitudeLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_gpsLongitudeLabel.Enable = 'off';
            app.logger_gpsLongitudeLabel.Text = 'Longitude (graus):';
            app.logger_gpsLongitude = uieditfield(app.logger_Panel2_Grid, 'numeric');
            app.logger_gpsLongitude.ValueDisplayFormat = '%.6f';
            app.logger_gpsLongitude.Editable = 'off';
            app.logger_gpsLongitude.Enable = 'off';
            app.logger_gpsLongitude.Value = -1;
            app.logger_gpsAltitudeLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_gpsAltitudeLabel.Enable = 'off';
            app.logger_gpsAltitudeLabel.Text = 'Altitude (metros):';
            app.logger_gpsAltitude = uieditfield(app.logger_Panel2_Grid, 'numeric');
            app.logger_gpsAltitude.Limits = [0 Inf];
            app.logger_gpsAltitude.ValueDisplayFormat = '%.6f';
            app.logger_gpsAltitude.Editable = 'off';
            app.logger_gpsAltitude.Enable = 'off';
            app.logger_Sublabel1 = uilabel(app.logger_Grid1A_Grid);
            app.logger_Sublabel1.Enable = 'off';
            app.logger_Sublabel1.Text = 'Arquivos relacionados à monitoração:';
            app.logger_Panel3 = uipanel(app.logger_Grid1A_Grid);
            app.logger_Panel3_Grid = uigridlayout(app.logger_Panel3);
            app.logger_ScriptNameLabel = uilabel(app.logger_Panel3_Grid);
            app.logger_ScriptNameLabel.Enable = 'off';
            app.logger_ScriptNameLabel.Text = 'Script:';
            app.logger_ScriptName = uieditfield(app.logger_Panel3_Grid, 'text');
            app.logger_ScriptName.Editable = 'off';
            app.logger_ScriptName.Enable = 'off';
            app.logger_MaskNameLabel = uilabel(app.logger_Panel3_Grid);
            app.logger_MaskNameLabel.Enable = 'off';
            app.logger_MaskNameLabel.Text = 'Máscara(s) espectral(is):';
            app.logger_MaskName = uidropdown(app.logger_Panel3_Grid);
            app.logger_MaskName.Items = {};
            app.logger_MaskName.Enable = 'off';
            app.logger_MaskName.Value = {};
            app.logger_Label1 = uilabel(app.logger_Grid1A);
            app.logger_Label1.Text = 'Instrumento';
            app.logger_Grid1B = uigridlayout(app.logger_Grid1);
            app.logger_Tree = uitree(app.logger_Grid1B);
            app.logger_Tree.SelectionChangedFcn = createCallbackFcn(app, @logger_TreeSelectionChanged, true);
            app.logger_Tree.Enable = 'off';
            app.logger_Label3 = uilabel(app.logger_Grid1B);
            app.logger_Label3.Text = 'Faixa(s) de frequência monitorada(s)';
            app.logger_Panel4 = uipanel(app.logger_Grid1B);
            app.logger_Panel4_Grid = uigridlayout(app.logger_Panel4);
            app.logger_Description = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_Description.Editable = 'off';
            app.logger_Description.Enable = 'off';
            app.logger_DescriptionLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_DescriptionLabel.Enable = 'off';
            app.logger_DescriptionLabel.Text = 'Descrição:';
            app.logger_Resolution = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_Resolution.ValueDisplayFormat = '%.3f';
            app.logger_Resolution.Editable = 'off';
            app.logger_Resolution.Enable = 'off';
            app.logger_Resolution.Value = -1;
            app.logger_ResolutionLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_ResolutionLabel.Enable = 'off';
            app.logger_ResolutionLabel.Text = {'Resolução '; '(kHz):'};
            app.logger_LevelUnit = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_LevelUnit.Editable = 'off';
            app.logger_LevelUnit.Enable = 'off';
            app.logger_LevelUnitLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_LevelUnitLabel.Enable = 'off';
            app.logger_LevelUnitLabel.Text = 'Unidade:';
            app.logger_ThreadIDLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_ThreadIDLabel.Enable = 'off';
            app.logger_ThreadIDLabel.Text = 'ThreadID';
            app.logger_ThreadID = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_ThreadID.Limits = [-1 Inf];
            app.logger_ThreadID.RoundFractionalValues = 'on';
            app.logger_ThreadID.ValueDisplayFormat = '%.0f';
            app.logger_ThreadID.Editable = 'off';
            app.logger_ThreadID.Enable = 'off';
            app.logger_ThreadID.Value = -1;
            app.logger_FreqStartLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_FreqStartLabel.Enable = 'off';
            app.logger_FreqStartLabel.Text = {'Frequência inicial'; '(MHz):'};
            app.logger_FreqStart = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_FreqStart.Limits = [-1 Inf];
            app.logger_FreqStart.ValueDisplayFormat = '%.6f';
            app.logger_FreqStart.Editable = 'off';
            app.logger_FreqStart.Enable = 'off';
            app.logger_FreqStart.Value = -1;
            app.logger_FreqStop = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_FreqStop.Limits = [-1 Inf];
            app.logger_FreqStop.ValueDisplayFormat = '%.6f';
            app.logger_FreqStop.Editable = 'off';
            app.logger_FreqStop.Enable = 'off';
            app.logger_FreqStop.Value = -1;
            app.logger_FreqStopLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_FreqStopLabel.Enable = 'off';
            app.logger_FreqStopLabel.Text = {'Frequência final'; '(MHz):'};
            app.logger_StepWidthLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_StepWidthLabel.Enable = 'off';
            app.logger_StepWidthLabel.Text = {'Passo da varredura'; '(kHz):'};
            app.logger_StepWidth = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_StepWidth.ValueDisplayFormat = '%.3f';
            app.logger_StepWidth.Editable = 'off';
            app.logger_StepWidth.Enable = 'off';
            app.logger_StepWidth.Value = -1;
            app.logger_OperationLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_OperationLabel.Enable = 'off';
            app.logger_OperationLabel.Text = 'Operação:';
            app.logger_Operation = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_Operation.Editable = 'off';
            app.logger_Operation.Enable = 'off';
            app.logger_DataPointsLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_DataPointsLabel.Enable = 'off';
            app.logger_DataPointsLabel.Text = 'Pontos por traço:';
            app.logger_DataPoints = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_DataPoints.Limits = [-1 Inf];
            app.logger_DataPoints.ValueDisplayFormat = '%.0f';
            app.logger_DataPoints.Editable = 'off';
            app.logger_DataPoints.Enable = 'off';
            app.logger_DataPoints.Value = -1;
            app.logger_TextInfoLabel = uilabel(app.logger_Grid1B);
            app.logger_TextInfoLabel.Text = 'Informações textuais';
            app.logger_TextInfo = uitextarea(app.logger_Grid1B);
            app.logger_TextInfo.Editable = 'off';
            app.logger_TextInfo.Enable = 'off';
            app.logger_ButtonPlay = uibutton(app.logger_Grid1B, 'push');
            app.logger_ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @logger_ButtonPlayValueChanged, true);
            app.logger_ButtonPlay.Icon = 'General_play.png';
            app.logger_ButtonPlay.Text = 'Executar tarefa';
            app.Tab_2 = uitab(app.TabGroup);
            app.play_Grid1 = uigridlayout(app.Tab_2);
            app.play_Grid1B = uigridlayout(app.play_Grid1);
            app.play_Panel = uipanel(app.play_Grid1B);
            app.play_Grid2 = uigridlayout(app.play_Panel);
            app.play_Grid2B = uigridlayout(app.play_Grid2);
            app.Panel_11 = uipanel(app.play_Grid2B);
            app.GridLayout15 = uigridlayout(app.Panel_11);
            app.Playback_ColorMap = uidropdown(app.GridLayout15);
            app.Playback_ColorMap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.Playback_ColorMap.Enable = 'off';
            app.Playback_ColorMap.Value = 'jet';
            app.EstiloLabel = uilabel(app.GridLayout15);
            app.EstiloLabel.Enable = 'off';
            app.EstiloLabel.Text = 'Estilo de cores:';
            app.Playback_DecimateFactor = uidropdown(app.GridLayout15);
            app.Playback_DecimateFactor.Items = {'Automático', '1', '2', '4', '8', '16', '32', '64', '128', '256'};
            app.Playback_DecimateFactor.Enable = 'off';
            app.Playback_DecimateFactor.Value = 'Automático';
            app.EstiloLabel_2 = uilabel(app.GridLayout15);
            app.EstiloLabel_2.Enable = 'off';
            app.EstiloLabel_2.Text = 'Fator de decimação:';
            app.Playback_wfLine = uidropdown(app.GridLayout15);
            app.Playback_wfLine.Items = {'Ativada', 'Desativada'};
            app.Playback_wfLine.Enable = 'off';
            app.Playback_wfLine.Value = 'Ativada';
            app.Playback_wfLineLabel = uilabel(app.GridLayout15);
            app.Playback_wfLineLabel.Enable = 'off';
            app.Playback_wfLineLabel.Text = 'Timestamp:';
            app.Playback_AxesHoles = uidropdown(app.GridLayout15);
            app.Playback_AxesHoles.Items = {'row', 'both'};
            app.Playback_AxesHoles.Enable = 'off';
            app.Playback_AxesHoles.Value = 'both';
            app.Playback_wfLineLabel_2 = uilabel(app.GridLayout15);
            app.Playback_wfLineLabel_2.Enable = 'off';
            app.Playback_wfLineLabel_2.Text = 'Direção:';
            app.Panel_12 = uipanel(app.play_Grid2B);
            app.GridLayout23_2 = uigridlayout(app.Panel_12);
            app.Playback_occTypeLabel = uilabel(app.GridLayout23_2);
            app.Playback_occTypeLabel.Enable = 'off';
            app.Playback_occTypeLabel.Text = 'Threshold:';
            app.Playback_occType = uidropdown(app.GridLayout23_2);
            app.Playback_occType.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_occType.Enable = 'off';
            app.Playback_occType.Value = 'Linear';
            app.Playback_occValueLabel = uilabel(app.GridLayout23_2);
            app.Playback_occValueLabel.Enable = 'off';
            app.Playback_occValueLabel.Text = 'Valor (dBm):';
            app.Playback_occValue = uispinner(app.GridLayout23_2);
            app.Playback_occValue.RoundFractionalValues = 'on';
            app.Playback_occValue.ValueDisplayFormat = '%d';
            app.Playback_occValue.Enable = 'off';
            app.Playback_occValue.Value = -80;
            app.Playback_occOffset = uispinner(app.GridLayout23_2);
            app.Playback_occOffset.Limits = [0 12];
            app.Playback_occOffset.Enable = 'off';
            app.Playback_occOffset.Value = 12;
            app.Playback_occOffsetLabel = uilabel(app.GridLayout23_2);
            app.Playback_occOffsetLabel.Enable = 'off';
            app.Playback_occOffsetLabel.Text = 'OffSet:';
            app.Playback_occTime = uispinner(app.GridLayout23_2);
            app.Playback_occTime.Limits = [2 Inf];
            app.Playback_occTime.ValueDisplayFormat = '%d';
            app.Playback_occTime.Tag = 'EXPORT';
            app.Playback_occTime.Enable = 'off';
            app.Playback_occTime.Tooltip = {''};
            app.Playback_occTime.Value = 10;
            app.Playback_occTimeLabel = uilabel(app.GridLayout23_2);
            app.Playback_occTimeLabel.Enable = 'off';
            app.Playback_occTimeLabel.Text = 'Fator integração:';
            app.Playback_occConfLevel = uidropdown(app.GridLayout23_2);
            app.Playback_occConfLevel.Items = {'68', '95', '99'};
            app.Playback_occConfLevel.Enable = 'off';
            app.Playback_occConfLevel.Value = '95';
            app.Playback_occConfLevelLabel = uilabel(app.GridLayout23_2);
            app.Playback_occConfLevelLabel.Enable = 'off';
            app.Playback_occConfLevelLabel.Text = 'Fator ceifamento:';
            app.Playback_occLabel = uilabel(app.GridLayout23_2);
            app.Playback_occLabel.Enable = 'off';
            app.Playback_occLabel.Text = 'Relacionados à estimativa do piso de ruído:';
            app.Playback_occPanel = uipanel(app.GridLayout23_2);
            app.Playback_occGrid2 = uigridlayout(app.Playback_occPanel);
            app.Playback_uSamples = uispinner(app.Playback_occGrid2);
            app.Playback_uSamples.Limits = [10 90];
            app.Playback_uSamples.RoundFractionalValues = 'on';
            app.Playback_uSamples.ValueDisplayFormat = '%.0f';
            app.Playback_uSamples.Enable = 'off';
            app.Playback_uSamples.Value = 20;
            app.Playback_uSamplesLabel = uilabel(app.Playback_occGrid2);
            app.Playback_uSamplesLabel.Enable = 'off';
            app.Playback_uSamplesLabel.Text = {'Amostras '; 'úteis (%):'};
            app.Playback_occFcn = uidropdown(app.Playback_occGrid2);
            app.Playback_occFcn.Items = {'Mediana', 'Média'};
            app.Playback_occFcn.Enable = 'off';
            app.Playback_occFcn.Value = 'Média';
            app.Playback_occFcnLabel = uilabel(app.Playback_occGrid2);
            app.Playback_occFcnLabel.Enable = 'off';
            app.Playback_occFcnLabel.Text = {'Função'; 'estatística:'};
            app.Playback_tSamples = uispinner(app.Playback_occGrid2);
            app.Playback_tSamples.Limits = [0 10];
            app.Playback_tSamples.RoundFractionalValues = 'on';
            app.Playback_tSamples.ValueDisplayFormat = '%.0f';
            app.Playback_tSamples.Enable = 'off';
            app.Playback_tSamplesLabel = uilabel(app.Playback_occGrid2);
            app.Playback_tSamplesLabel.Enable = 'off';
            app.Playback_tSamplesLabel.Text = {'Amostras '; 'descartadas (%):'};
            app.Playback_peakLabel0 = uilabel(app.play_Grid2B);
            app.Playback_peakLabel0.Text = 'Waterfall';
            app.Playback_peakLabel0_2 = uilabel(app.play_Grid2B);
            app.Playback_peakLabel0_2.Text = 'Ocupação';
            app.play_Grid2A = uigridlayout(app.play_Grid2);
            app.play_Label2 = uilabel(app.play_Grid2A);
            app.play_Label2.Text = 'Limites';
            app.Playback_peakRadioGroup = uibuttongroup(app.play_Grid2A);
            app.Playback_peakRadio2 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio2.Text = 'Automática';
            app.Playback_peakRadio1 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio1.Text = 'ROI';
            app.Playback_peakRadio1.Value = true;
            app.Playback_peakRadio3 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio3.Text = 'DataTips';
            app.Playback_Panel2 = uipanel(app.play_Grid2A);
            app.Playback_peakGrid = uigridlayout(app.Playback_Panel2);
            app.Playback_peakBWLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakBWLabel.Text = {'Largura mínima '; 'ocupada (kHz):'};
            app.Playback_peakBW = uispinner(app.Playback_peakGrid);
            app.Playback_peakBW.Step = 10;
            app.Playback_peakBW.Limits = [0 Inf];
            app.Playback_peakBW.RoundFractionalValues = 'on';
            app.Playback_peakBW.ValueDisplayFormat = '%.0f';
            app.Playback_peakBW.Value = 10;
            app.Playback_peakDistanceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakDistanceLabel.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.Playback_peakDistance = uispinner(app.Playback_peakGrid);
            app.Playback_peakDistance.Step = 25;
            app.Playback_peakDistance.Limits = [0 Inf];
            app.Playback_peakDistance.RoundFractionalValues = 'on';
            app.Playback_peakDistance.ValueDisplayFormat = '%.0f';
            app.Playback_peakDistance.Value = 25;
            app.Playback_peakProeminenceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakProeminenceLabel.Text = {'Proeminência '; 'mínima (dB):'};
            app.Playback_peakProeminence = uispinner(app.Playback_peakGrid);
            app.Playback_peakProeminence.Step = 10;
            app.Playback_peakProeminence.Limits = [1 Inf];
            app.Playback_peakProeminence.RoundFractionalValues = 'on';
            app.Playback_peakProeminence.ValueDisplayFormat = '%.0f';
            app.Playback_peakProeminence.Value = 30;
            app.Playback_peakTHRLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakTHRLabel.Text = {'Threshold'; '(dBμV/m):'};
            app.Playback_peakTHR = uispinner(app.Playback_peakGrid);
            app.Playback_peakTHR.Step = 10;
            app.Playback_peakTHR.RoundFractionalValues = 'on';
            app.Playback_peakTHR.ValueDisplayFormat = '%.0f';
            app.Playback_peakTHR.Value = -Inf;
            app.Playback_peakNumbers = uispinner(app.Playback_peakGrid);
            app.Playback_peakNumbers.Limits = [1 100];
            app.Playback_peakNumbers.RoundFractionalValues = 'on';
            app.Playback_peakNumbers.ValueDisplayFormat = '%.0f';
            app.Playback_peakNumbers.Value = 10;
            app.Playback_peakNumbersLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakNumbersLabel.Text = 'Número de picos:';
            app.Playback_peakTraceLabel = uilabel(app.Playback_peakGrid);
            app.Playback_peakTraceLabel.Text = 'Tipo de traço:';
            app.Playback_peakTrace = uidropdown(app.Playback_peakGrid);
            app.Playback_peakTrace.Items = {'Mediana', 'Média', 'MaxHold'};
            app.Playback_peakTrace.Value = 'Média';
            app.Playback_addMarker = uiimage(app.play_Grid2A);
            app.Playback_addMarker.Enable = 'off';
            app.Playback_addMarker.ImageSource = 'General_Add.png';
            app.Playback_peakTree = uitree(app.play_Grid2A);
            app.Playback_peakTree.Multiselect = 'on';
            app.play_Panel1 = uipanel(app.play_Grid2A);
            app.play_Grid3 = uigridlayout(app.play_Panel1);
            app.Playback_xLim1 = uispinner(app.play_Grid3);
            app.Playback_xLim1.ValueDisplayFormat = '%.3f';
            app.Playback_xLim1.Tag = 'FreqStart';
            app.Playback_xLim1.Enable = 'off';
            app.Playback_xLim1.Tooltip = {''};
            app.xSpinnerLabel = uilabel(app.play_Grid3);
            app.xSpinnerLabel.Enable = 'off';
            app.xSpinnerLabel.Text = 'x';
            app.Playback_xLim2 = uispinner(app.play_Grid3);
            app.Playback_xLim2.ValueDisplayFormat = '%.3f';
            app.Playback_xLim2.Tag = 'FreqStop';
            app.Playback_xLim2.Enable = 'off';
            app.Playback_xLim2.Tooltip = {''};
            app.ySpinnerLabel = uilabel(app.play_Grid3);
            app.ySpinnerLabel.Enable = 'off';
            app.ySpinnerLabel.Text = 'y';
            app.Playback_yLim2 = uispinner(app.play_Grid3);
            app.Playback_yLim2.Step = 5;
            app.Playback_yLim2.ValueDisplayFormat = '%.1f';
            app.Playback_yLim2.Tag = 'MaxLevel';
            app.Playback_yLim2.Enable = 'off';
            app.Playback_yLim2.Tooltip = {''};
            app.Playback_yLim1 = uispinner(app.play_Grid3);
            app.Playback_yLim1.Step = 5;
            app.Playback_yLim1.ValueDisplayFormat = '%.1f';
            app.Playback_yLim1.Tag = 'MinLevel';
            app.Playback_yLim1.Enable = 'off';
            app.Playback_yLim1.Tooltip = {''};
            app.play_Label3 = uilabel(app.play_Grid2A);
            app.play_Label3.Text = 'Detecção de emissões';
            app.PeakCF = uieditfield(app.play_Grid2A, 'numeric');
            app.PeakCF.ValueDisplayFormat = '%.3f';
            app.PeakCF.Enable = 'off';
            app.PeakCFLabel = uilabel(app.play_Grid2A);
            app.PeakCFLabel.Enable = 'off';
            app.PeakCFLabel.Text = 'Frequência central (MHz):';
            app.PeakBW = uieditfield(app.play_Grid2A, 'numeric');
            app.PeakBW.ValueDisplayFormat = '%.3f';
            app.PeakBW.Enable = 'off';
            app.PeakBWLabel = uilabel(app.play_Grid2A);
            app.PeakBWLabel.Enable = 'off';
            app.PeakBWLabel.Text = 'Largura ocupada (kHz):';
            app.play_Label = uilabel(app.play_Grid1B);
            app.play_Label.Text = 'PLOT';
            app.play_Grid1A = uigridlayout(app.play_Grid1);
            app.mainInfo_Text = uitextarea(app.play_Grid1A);
            app.mainInfo_Text.Editable = 'off';
            app.mainInfo_Toolbar = uigridlayout(app.play_Grid1A);
            app.mainInfo_Delete1 = uibutton(app.mainInfo_Toolbar, 'push');
            app.mainInfo_Delete1.Icon = 'toolbar_Trash.png';
            app.mainInfo_Delete1.Tooltip = {'Excluir specData, lista de exceções etc'};
            app.mainInfo_Delete1.Text = '';
            app.mainInfo_Delete2 = uibutton(app.mainInfo_Toolbar, 'push');
            app.mainInfo_Delete2.Icon = 'toolbar_redX.png';
            app.mainInfo_Delete2.Tooltip = {'Excluir janelas auxiliares'};
            app.mainInfo_Delete2.Text = '';
            app.mainInfo_Separator = uiimage(app.mainInfo_Toolbar);
            app.mainInfo_Separator.ImageSource = 'Line.png';
            app.mainInfo_Info = uibutton(app.mainInfo_Toolbar, 'push');
            app.mainInfo_Info.Icon = 'toolbar_info.png';
            app.mainInfo_Info.Tooltip = {'Informações gerais'};
            app.mainInfo_Info.Text = '';
            app.GridLayout39 = uigridlayout(app.play_Grid1A);
            app.mainInfo_Expand = uiimage(app.GridLayout39);
            app.mainInfo_Expand.ImageSource = 'General_Expand2.png';
            app.mainInfo_TextLabel = uilabel(app.GridLayout39);
            app.mainInfo_TextLabel.Text = 'Metadados:';
            app.mainInfo_Tree = uitree(app.play_Grid1A);
            app.mainInfo_Tree.Multiselect = 'on';
            app.GridLayout40 = uigridlayout(app.play_Grid1A);
            app.mainInfo_Visibility = uiimage(app.GridLayout40);
            app.mainInfo_Visibility.ImageClickedFcn = createCallbackFcn(app, @mainInfo_VisibilityClicked, true);
            app.mainInfo_Visibility.ImageSource = 'play_Unview.png';
            app.mainInfo_Label1 = uilabel(app.GridLayout40);
            app.mainInfo_Label1.Text = 'FLUXO(S) DE DADOS';
            app.tool_Panel = uipanel(app.Grid1);
            app.tool_Grid1 = uigridlayout(app.tool_Panel);
            app.tool_Grid2 = uigridlayout(app.tool_Grid1);
            app.tool_Button2 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button2.ButtonPushedFcn = createCallbackFcn(app, @tool_Button2Pushed, true);
            app.tool_Button2.Icon = 'toolbar_settings.png';
            app.tool_Button2.Text = '';
            app.tool_Button1 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button1.ButtonPushedFcn = createCallbackFcn(app, @tool_Button1Pushed, true);
            app.tool_Button1.Icon = 'toolbar_layout.png';
            app.tool_Button1.Text = '';
            app.tool_REC = uiimage(app.tool_Grid2);
            app.tool_REC.Visible = 'off';
            app.tool_REC.ImageSource = 'REC.gif';
            app.tool_Task = uilabel(app.tool_Grid2);
            app.tool_Task.Text = '';
            app.tool_Button3 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button3.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_Button3.Icon = 'toolbar_info.png';
            app.tool_Button3.Text = '';
            app.menu_Panel = uipanel(app.Grid1);
            app.menu_Grid1 = uigridlayout(app.menu_Panel);
            app.menu_Grid2 = uigridlayout(app.menu_Grid1);
            app.menu_Grid2B = uigridlayout(app.menu_Grid2);
            app.menu_searchPath = uiimage(app.menu_Grid2B);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @menu_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = 'toolbar_userPath.png';
            app.menu_userPath = uidropdown(app.menu_Grid2B);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @menu_userPathValueChanged, true);
            app.menu_userPath.Value = 'Path';
            app.menu_Grid2A = uigridlayout(app.menu_Grid2);
            app.Button_Task = uibutton(app.menu_Grid2A, 'state');
            app.Button_Task.ValueChangedFcn = createCallbackFcn(app, @Button_TaskValueChanged, true);
            app.Button_Task.Tag = 'TASK';
            app.Button_Task.Tooltip = {'Tarefa'; 'appColeta'};
            app.Button_Task.Icon = 'Menu_Task.png';
            app.Button_Task.Text = '';
            app.Button_Connect = uibutton(app.menu_Grid2A, 'state');
            app.Button_Connect.ValueChangedFcn = createCallbackFcn(app, @Button_ConnectValueChanged, true);
            app.Button_Connect.Tag = 'CONNECT';
            app.Button_Connect.Tooltip = {'Analisadores de'; 'espectro e receptores'};
            app.Button_Connect.Icon = 'Menu_Connect.png';
            app.Button_Connect.Text = '';
            app.menu_Separator1 = uiimage(app.menu_Grid2A);
            app.menu_Separator1.ImageSource = 'Line.png';
            app.Button_Home = uibutton(app.menu_Grid2A, 'state');
            app.Button_Home.ValueChangedFcn = createCallbackFcn(app, @Button_HomeValueChanged, true);
            app.Button_Home.Tag = 'HOME';
            app.Button_Home.Icon = 'home.png';
            app.Button_Home.Text = '';
            app.Button_Home.Value = true;
            app.menu_Separator2 = uiimage(app.menu_Grid2A);
            app.menu_Separator2.ImageSource = 'Line.png';
            app.Button_Logger = uibutton(app.menu_Grid2A, 'state');
            app.Button_Logger.ValueChangedFcn = createCallbackFcn(app, @Button_LoggerValueChanged, true);
            app.Button_Logger.Tag = 'LOGGER';
            app.Button_Logger.Tooltip = {'Tarefa'; 'Logger'};
            app.Button_Logger.Icon = 'Instr_CRFS.png';
            app.Button_Logger.Text = '';
            app.Button_Plot = uibutton(app.menu_Grid2A, 'state');
            app.Button_Plot.ValueChangedFcn = createCallbackFcn(app, @Button_PlotValueChanged, true);
            app.Button_Plot.Tag = 'PLOT';
            app.Button_Plot.Enable = 'off';
            app.Button_Plot.Tooltip = {'Plot'};
            app.Button_Plot.Icon = 'playback.png';
            app.Button_Plot.Text = '';
            app.Button_Logger_2 = uibutton(app.menu_Grid2A, 'state');
            app.Button_Logger_2.ValueChangedFcn = createCallbackFcn(app, @logger_EditRFeyeListButtonPushed, true);
            app.Button_Logger_2.Tag = 'LOGGER';
            app.Button_Logger_2.Tooltip = {'Receptores'; 'RFeye Node 20-6'};
            app.Button_Logger_2.Icon = 'Menu_ConnectBlue.png';
            app.Button_Logger_2.Text = '';
            app.Button_Logger_3 = uibutton(app.menu_Grid2A, 'state');
            app.Button_Logger_3.ValueChangedFcn = createCallbackFcn(app, @logger_winMonitorButtonPushed, true);
            app.Button_Logger_3.Tag = 'LOGGER';
            app.Button_Logger_3.Tooltip = {'Monitor'; 'Logger +'};
            app.Button_Logger_3.Icon = 'auto22.png';
            app.Button_Logger_3.Text = '';
            app.menu_Separator2_2 = uiimage(app.menu_Grid2A);
            app.menu_Separator2_2.ImageSource = 'Line.png';
            app.ContextMenu_logger = uicontextmenu(app.MainApp);
            app.logger_addRECList = uimenu(app.ContextMenu_logger);
            app.logger_addRECList.MenuSelectedFcn = createCallbackFcn(app, @logger_AddRECList, true);
            app.logger_addRECList.Text = 'Adicionar à lista de gravação do arquivo';
            app.logger_deleteRECList = uimenu(app.ContextMenu_logger);
            app.logger_deleteRECList.MenuSelectedFcn = createCallbackFcn(app, @logger_DelRECList, true);
            app.logger_deleteRECList.Text = 'Retirar da lista';
            app.logger_PlotMask = uimenu(app.ContextMenu_logger);
            app.logger_PlotMask.MenuSelectedFcn = createCallbackFcn(app, @logger_PlotMaskSelected, true);
            app.logger_PlotMask.Text = 'Máscara espectral';
            app.ContextMenu_Connection = uicontextmenu(app.MainApp);
            app.Connect_delete = uimenu(app.ContextMenu_Connection);
            app.Connect_delete.MenuSelectedFcn = createCallbackFcn(app, @connect_deleteInstrument, true);
            app.Connect_delete.Text = 'Excluir';
            app.ContextMenu_Task = uicontextmenu(app.MainApp);
            app.Task_delete = uimenu(app.ContextMenu_Task);
            app.Task_delete.MenuSelectedFcn = createCallbackFcn(app, @Task_deleteTaskImageClicked, true);
            app.Task_delete.Text = 'Excluir';
            app.ContextMenu_Band = uicontextmenu(app.MainApp);
            app.Task_deleteBand = uimenu(app.ContextMenu_Band);
            app.Task_deleteBand.MenuSelectedFcn = createCallbackFcn(app, @Task_deleteBandImageClicked, true);
            app.Task_deleteBand.Text = 'Excluir';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @Startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end