classdef winMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Grid1              matlab.ui.container.GridLayout
        GridLayout         matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        Button_info        matlab.ui.control.Button
        Separator          matlab.ui.control.Image
        Button_Integra     matlab.ui.control.StateButton
        Button_Log         matlab.ui.control.Button
        SettingsInfo       matlab.ui.control.Label
        Button_play        matlab.ui.control.StateButton
        Panel_2            matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        Table_Grid         matlab.ui.container.GridLayout
        Table_Label8       matlab.ui.control.Label
        Table_Label7       matlab.ui.control.Label
        Table_Label6       matlab.ui.control.Label
        Table_Label5       matlab.ui.control.Label
        Table_Label4       matlab.ui.control.Label
        Table_Label3       matlab.ui.control.Label
        Table_Label2       matlab.ui.control.Label
        Table_Label1       matlab.ui.control.Label
        Table              matlab.ui.control.Table
        Tree               matlab.ui.container.CheckBoxTree
        TreeLabel          matlab.ui.control.Label
        RadioGroup         matlab.ui.container.ButtonGroup
        Option1            matlab.ui.control.RadioButton
        Option2            matlab.ui.control.RadioButton
        RadioGroupLabel    matlab.ui.control.Label
        SubTitle           matlab.ui.control.Label
        instrPanel         matlab.ui.container.Panel
        instrGrid          matlab.ui.container.GridLayout
        Qtd_Unknown        matlab.ui.control.Label
        Qtd_UnknownLabel   matlab.ui.control.Label
        instrPanel_2       matlab.ui.container.Panel
        instrGrid_2        matlab.ui.container.GridLayout
        Qtd_Unmasked       matlab.ui.control.Label
        Qtd_UnmaskedLabel  matlab.ui.control.Label
        MainTitle          matlab.ui.control.Label
        Image              matlab.ui.control.Image
    end

    
    properties (Access = private)

        CallingApp
        RootFolder

        ftpObj
        
        fig
        axes1

        UnMaskedWebSheet
        PersistentWebSheet
        UnMaskedWebSheetPath = ''
        
        ScriptVersionSheet

        UnMaskedEmissions    = table('Size', [0 9],                                                                                 ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'single', 'uint32', 'cell', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'OCC', 'Description', 'TimeStamp', 'Tag'})

        PersistentEmissions  = table('Size', [0 9],                                                                                  ...
                                     'VariableTypes', {'cell', 'cell', 'cell', 'single', 'uint32', 'uint8', 'cell', 'cell', 'cell'}, ...
                                     'VariableNames', {'State', 'City', 'Node', 'Freq', 'BW', 'OCC', 'Description', 'TimeStamp', 'Tag'})

        RFeyeList                                                           % app.CallingApp.RFeyeList filtrada pelo campo ConnectFlag igual a 1.
        rfNodes                                                             % app.RFeyeList filtrada pela seleção da árvore.
        interestBands
        
        maskStruct                                                          % Aspectos gerais da máscara espectral.
        bandList

        % Thresholds:
        % - THR1: Valor absoluto que indica a quantidade média de 
        % rompimentos de mascára espectral em relação ao número de
        % traços capturados enquanto se esperava as x mensagens 
        % do tipo 4 (x definido por BrokenMsg).
        % - THR2: Valor percentual usado para identificar as emissões
        % persistentes (uma espécia de ocupação mínima).
        MonitorSettings                                                     % Estrutura com campos "MaxDistance", "BrokenMsg", "ThreadIDList", "THR1" e "THR2".

    end
    
    
    methods (Access = private)

        function tempList = Fcn1_OpenFile(app, FilePath)

            [tempList, msg] = OpenFile_rfeyeList(FilePath);

            if ~isempty(msg)
                Misc_uialertCreation(app, 'warning', msg)

            else
                tempList.Monitor = repmat({''}, height(tempList), 1);

                for ii = 1:height(tempList)
                    if ~isempty(tempList.Location{ii})
                        tempList.State{ii} = tempList.Location{ii}(end-1:end);
                    end
                end
            end

        end


        function TreeBuilding(app)
            
            delete(app.Tree.Children)
            
            States = unique(app.RFeyeList.State(logical(app.RFeyeList.Enable)));

            for ii = 1:height(States)
                ind = find(strcmp(app.RFeyeList.State, States{ii}));

                ParentNode = uitreenode(app.Tree, 'Text', States{ii}, ...
                                                  'NodeData', ind);

                for jj = ind'
                    ChildNode = uitreenode(ParentNode, 'Text', sprintf('%s - %s', app.RFeyeList.Name{jj}, app.RFeyeList.Location{jj}), ...
                                                       'NodeData', jj);
                end
                expand(ParentNode)
            end
            
        end


        function maskMonitor(app)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
            
            for ii = 1:height(app.rfNodes)
                set(d, 'Title', app.rfNodes.Name{ii}, 'Message', 'Em andamento tentativa de conexão FTP/SFTP ao sensor...')

                % Criação do objeto FTP/SFTP.
                Misc_ftpObject(app, ii)
                if isempty(app.ftpObj)
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [0.6350 0.0780 0.1840])
                    continue
                end
                
                % Download dos arquivos de script e máscara espectral e a 
                % sua leitura, possibilitando atualização das variáveis
                % app.ScriptVersionSheet (informação de uma das abas do arquivo
                % "monitorGeral.xlsx" constante no Integra) e app.maskStruct 
                % (antiga app.maskParam).
                d.Message = 'Em andamento tentativa de download dos arquivos de script e máscara espectral carregados no sensor...';
                errorFlag = maskMonitor_FileDownload(app, ii);

                if errorFlag
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [0.6350 0.0780 0.1840])
                    continue
                else
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [.5 .5 .5])
                end

                % Comparar app.maskStruct com as faixas de interesse (as
                % quais constam no arquivo "insterestBands.cfg", criando a 
                % lista de emissões desmascaradas.
                freqList = Misc_BandCheck(app);
                if ~isempty(freqList)
                    freqList(~freqList(:,2),:) = [];
                end

                if ~isempty(freqList)
                    Misc_LogWrite(app, app.rfNodes.Name{ii}, sprintf('Emissões desmascaradas (frequência central em MHz e largura em kHz): %s', jsonencode([freqList(:,1)/1000 freqList(:,2)])))
                            
                    % Processo de truncamento das frequências centrais das
                    % emissões desmascaradas, possibilitando comparação com
                    % base legal (Mosaico, Stel e SRD).
                    d.Message =  'Em andamento consulta à base legal da Agência (Mosaico, Stel e SRD)...';
                    
                    TruncatedFreqs = Misc_TruncatedFrequency(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
                    bwTruncated    = ceil(freqList(:,2));
    
                    nowTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    indNode = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.Name{ii}));
                    for jj = 1:numel(TruncatedFreqs)
                        [~, Information, ~] = Misc_closestStation(app, TruncatedFreqs(jj), [app.rfNodes.Latitude(ii), app.rfNodes.Longitude(ii)]);
                        
                        Tag = sprintf('%s_%.0f_%.0f', app.rfNodes.Name{ii}, TruncatedFreqs(jj), bwTruncated(jj));
                        if isempty(find(strcmp(app.UnMaskedEmissions.Tag, Tag), 1))
                            app.UnMaskedEmissions(end+1, :) = {app.rfNodes.State{ii},             ...
                                                               app.rfNodes.Location{ii},              ...
                                                               app.rfNodes.Name{ii},                ...
                                                               round(TruncatedFreqs(jj)/1000, 3), ...
                                                               bwTruncated(jj),                   ...
                                                               '-', Information, nowTime, Tag};

                            app.Qtd_Unmasked.Text = num2str(str2double(app.Qtd_Unmasked.Text) + 1);
                            if isempty(Information)
                                app.Qtd_Unknown.Text = num2str(str2double(app.Qtd_Unknown.Text) + 1);
                            end
                            drawnow

                            if bwTruncated(jj) > 0                        
                                if isempty(indNode)
                                    position = height(app.UnMaskedWebSheet) + 1;
                                    maskMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                    
                                else
                                    indFreq = find(strcmp(app.UnMaskedWebSheet.Node, app.rfNodes.Name{ii}) & ...
                                                  (abs(TruncatedFreqs(jj) - app.UnMaskedWebSheet.FreqCenter*1000) < 15), 1);
                                    
                                    if isempty(indFreq)
                                        position = height(app.UnMaskedWebSheet) + 1;
                                        maskMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), position, Information, nowTime);
                                    else
                                        maskMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), indFreq, Information, nowTime);
                                    end
                                end
                            end
                        end
                    end
                    
                    % if the emission is in UnMaskedWebSheet but not in the mask... update
                    % emission "InMask" field accordingly
                    for jj = indNode'
                        indEmiss = find(TruncatedFreqs == int32(app.UnMaskedWebSheet.FreqCenter(jj)*1000), 1);
    
                        if isempty(indEmiss)
                            app.UnMaskedWebSheet.Status(jj) = "Resolvido";
                            app.UnMaskedWebSheet.InMask(jj) = "Não";
                        end
                    end

                    app.Table.Data = app.UnMaskedEmissions(:,1:8);
                    pause(.5)
                    drawnow nocallbacks
                else
                    Misc_LogWrite(app, app.rfNodes.Name{ii}, 'Não identificada emissão desmascarada.')
                end

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];
            Misc_integraUpdate(app, d)

        end


        function errorFlag = maskMonitor_FileDownload(app, ind)
            
            errorFlag = 0;

            try
                cd(app.ftpObj,   '/etc/drops/');
                mget(app.ftpObj, 'rfeyed.cfg');

                scriptFilename = sprintf('%s_script.cfg', app.rfNodes.Name{ind});
                movefile('rfeyed.cfg', fullfile(app.RootFolder, 'Temp', scriptFilename));

                [maskName, methodField] = app.CallingApp.logger_ScriptRead(fullfile(app.RootFolder, 'Temp', scriptFilename));
                for ii = 1:numel(maskName)
                    cd(app.ftpObj,   '/etc/drops/logger_masks/');
                    mget(app.ftpObj, maskName{ii});

                    maskFileName = sprintf('%s_mask.csv', app.rfNodes.Name{ind});
                    movefile(maskName{ii}, fullfile(app.RootFolder, 'Temp', maskFileName));

% %                     % TESTE COM MÁSCARA PLANA EM -80DBM%
%                     maskFileName = 'RFeye002198_mask.csv';
% %                     % TESTE %

                    maskMonitor_FileRead(app, fullfile(app.RootFolder, 'Temp', maskFileName))

                    % Atualizando estrutura para planilha de controle de versões com dados atuais                
                    ind1 = find(strcmp(app.ScriptVersionSheet.Node, app.rfNodes.Name{ind}));
                    ind2 = find(strcmp(app.ScriptVersionSheet.MaskName, maskName{ii}));

                    ind3 = intersect(ind1, ind2);
                    if ~isempty(ind3)
                        app.ScriptVersionSheet.ScriptName(ind3)  = methodField;
                        app.ScriptVersionSheet.DateUpdated(ind3) = datetime("now");
                        app.ScriptVersionSheet.VisitCount(ind3)  = app.ScriptVersionSheet.VisitCount(ind3) + 1;

                    else
                        app.ScriptVersionSheet(end+1,:) = {app.rfNodes.Name{ind}, ...
                                                           methodField, ...
                                                           maskName{ii}, ...
                                                           datetime("now"), ...
                                                           datetime("now"), ...
                                                           1};
                    end
                end

            catch
                errorFlag = 1;
            end

        end


        function maskMonitor_FileRead(app, maskFileName)

            inData = readtable(maskFileName, 'VariableNamingRule', 'preserve');
            inData.Properties.VariableNames{1} = 'Start';
            inData.Properties.VariableNames{2} = 'Stop';

            app.maskStruct = struct('raw', inData, 'freq', [], 'FreqStart', [], 'FreqStop', []);
                     
            app.maskStruct.FreqStart = inData.Start(1);
            app.maskStruct.FreqStop  = inData.Stop(end);
             
            bw = inData.Start(2:end) - inData.Stop(1:end-1);
            app.maskStruct.freq = [(inData.Stop(1:end-1) + bw/2)  bw];

        end

        
        function maskMonitor_AddInfoToSheet(app, rfNode, frequency, bw, position, Information, nowTime)

            InitialHeight = height(app.UnMaskedWebSheet);
            
            if isempty(Information)
                RegulatoryInfo = "Não licenciado";
                Description    = "";

                if position <= InitialHeight
                    Uploaded    = app.UnMaskedWebSheet.Uploaded(position);
                    Status      = app.UnMaskedWebSheet.Status(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status      = "Pendente identificação";
                    dateCreated = nowTime;                                
                end
                
            else
                RegulatoryInfo = "Licenciado";
                Description    = Information;
                
                if position <= InitialHeight
                    Uploaded    = app.UnMaskedWebSheet.Uploaded(position);
                    Status      = app.UnMaskedWebSheet.Status(position);
                    dateCreated = app.UnMaskedWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status       = "Pendente confirmação";
                    dateCreated = nowTime;                    
                end
            end

            app.UnMaskedWebSheet(position,:) = {position,       ...
                                                rfNode,         ...
                                                round(frequency, 3), ...
                                                bw,             ...
                                                RegulatoryInfo, ...
                                                Description,    ...
                                                Status,         ...
                                                "Sim",          ...
                                                Uploaded,       ...
                                                dateCreated};

        end

        
        function Misc_SaveLog(app)

            indFind = find(~strcmp(app.RFeyeList.Monitor, ''));
            if ~isempty(indFind)
                fileID = fopen(fullfile(app.RootFolder, 'Temp', sprintf('%s_Log.txt', datestr(now, 'yyyymmdd_HHMMSS'))), "wt");

                logicalArray = contains(app.RFeyeList.Monitor(indFind), 'matlab.io');
                SuccessNodes = strjoin(app.RFeyeList.Name(indFind(find( logicalArray))), ', ');
                if isempty(SuccessNodes)
                    SuccessNodes = '-';
                end

                UnSuccessNodes = strjoin(app.RFeyeList.Name(indFind(find(~logicalArray))), ', ');
                if isempty(UnSuccessNodes)
                    UnSuccessNodes = '-';
                end
    
                fullLog = sprintf(['appColeta v. %s (Monitor)\n' ... 
                                   '%s\n'                        ...
                                   '- Sensores que apresentaram conectividade: %s\n'        ...
                                   '- Sensores que não apresentaram conectividade: %s\n\n', ...
                                   '%s'], app.CallingApp.Version.appColeta,    ...
                                          datestr(now, 'dd/mm/yyyy HH:MM:SS'), ...
                                          SuccessNodes,  ...
                                          UnSuccessNodes, ...
                                          replace(jsonencode(app.RFeyeList(indFind, [1,22]), 'PrettyPrint', true), '\\fontname{Calibri}\\fontsize{10}', ''));

                fwrite(fileID, fullLog, 'char');
                fclose(fileID);
            end

        end


        function Misc_integraUpdate(app, d)
         
            if app.Button_Integra.Value
                set(d, 'Cancelable', 'off', 'Message', 'Em andamento tentativa de atualizar planilha carregada no Integra...')

                switch app.RadioGroup.SelectedObject.Text
                    case 'Máscaras espectrais'
                        if ~issorted(app.ScriptVersionSheet.Node)
                            app.ScriptVersionSheet = sortrows(app.ScriptVersionSheet, 'Node');
                        end

                        writetable(app.UnMaskedWebSheet,   app.UnMaskedWebSheetPath, 'Sheet', 'desmascarados', 'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                        writetable(app.ScriptVersionSheet, app.UnMaskedWebSheetPath, 'Sheet', 'versions',      'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);

                    case 'Tráfego UDP'
                        writetable(app.PersistentWebSheet, app.UnMaskedWebSheetPath, 'Sheet', 'persistentes',  'WriteVariableNames', false, 'Range', 'A2', 'AutoFitWidth', false, 'PreserveFormat', true);
                end

                app.Button_Integra.Value = 0;
            end

        end


        function Misc_integraImport(app)
            
            opts1 = spreadsheetImportOptions("Sheet", "desmascarados",                                                                      ...
                                             "NumVariables", 10,                                                                            ...
                                             "VariableTypes", ["int32", "string", "double", "double", "string", "string", "string",         ...
                                                               "string", "string", "datetime"],                                             ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "RegulatoryInfo", "Description", "Status", ...
                                                               "InMask", "Uploaded", "dateCreated"],                                        ...
                                             "DataRange", "A2");
            
            app.UnMaskedWebSheet = readtable(app.UnMaskedWebSheetPath, opts1);
            
            opts2 = spreadsheetImportOptions("Sheet", "persistentes",                                                                    ...
                                             "NumVariables", 10,                                                                         ...
                                             "VariableTypes", ["int32", "string", "double", "double", "double", "string", "string",      ...
                                                               "string", "string", "datetime"],                                          ...
                                             "VariableNames", ["ID", "Node", "FreqCenter", "BW", "OCC", "RegulatoryInfo", "Description", ...
                                                               "Status", "Uploaded", "dateCreated"],                                     ...
                                             "DataRange", "A2");
            
            app.PersistentWebSheet = readtable(app.UnMaskedWebSheetPath, opts2);
            
            opts3 = spreadsheetImportOptions("Sheet", "versions",                                                                            ...
                                            "NumVariables", 6,                                                                               ...
                                            "VariableTypes", ["string", "string", "string", "datetime", "datetime", "int32"],                ...
                                            "VariableNames", ["Node", "ScriptName", "MaskName", "DateCreated", "DateUpdated", "VisitCount"], ...
                                            "DataRange", "A2", "MissingRule", "omitrow");
            
            app.ScriptVersionSheet = readtable(app.UnMaskedWebSheetPath, opts3);
            
        end


        function Misc_ftpObject(app, ind)

            IPs  = {app.rfNodes.IP1{ind}, app.rfNodes.IP2{ind}};
            Type = {'ftp', 'sftp'}; 

            app.ftpObj = [];
            for ii = 1:numel(IPs)
                for jj = 1:numel(Type)
                    try
                        switch Type{jj}
                            case  'ftp'; app.ftpObj =  ftp(sprintf('%s:%s', IPs{ii},app.rfNodes.ftpPort),  'root', app.rfNodes.rootPass{ind});
                            case 'sftp'; app.ftpObj = sftp(sprintf('%s:%s', IPs{ii},app.rfNodes.sftpPort), 'root', 'Password', app.rfNodes.rootPass{ind});
                        end

                        Misc_LogWrite(app, app.rfNodes.Name{ind}, sprintf('%s (%s:%.0f)', class(app.ftpObj), app.ftpObj.Host, app.ftpObj.Port))
                        return
                    catch ME
                        Misc_LogWrite(app, app.rfNodes.Name{ind}, ME.message)
                    end
                end
            end
            
        end


        function Misc_LogWrite(app, Node, msg)

            ind = find(strcmp(app.RFeyeList.Name, Node), 1);

            if isempty(app.RFeyeList.Monitor{ind})
                app.RFeyeList.Monitor{ind} = ['\fontname{Calibri}' '\fontsize{10}' msg];
            else
                app.RFeyeList.Monitor{ind} = sprintf('%s\n\\fontname{Calibri}\\fontsize{10}%s', app.RFeyeList.Monitor{ind}, msg);
            end

        end


        function Misc_TreeNodeColor(app, Node, color)

            % Atualiza o estado do sensor (que influencia no mapa)
            ind0 = find(strcmp(app.RFeyeList.Name, Node), 1);
            if color == [.5 .5 .5]                
                app.RFeyeList.ConnectFlag(ind0) = 1;
            else
                app.RFeyeList.ConnectFlag(ind0) = 0;
            end

            % Atualiza a cor do nome do sensor na árvore
            ind1 = [];
            ind2 = [];

            for ii = 1:numel(app.Tree.Children)
                for jj = 1:numel(app.Tree.Children(ii).Children)
                    if strcmp(app.Tree.Children(ii).Children(jj).Text, Node)
                        ind1 = ii;
                        ind2 = jj;

                        break
                    end
                end

                if ~isempty(ind1)
                    addStyle(app.Tree, uistyle('FontColor', color, 'FontWeight', 'bold'), 'node', app.Tree.Children(ind1).Children(ind2));
                    break
                end
            end            

        end

        
        function freqList = Misc_BandCheck(app)

            freqList = [];
            if ~isempty(app.maskStruct.freq)
                for ii = 1:height(app.maskStruct.freq)
                    if ~isempty(intersect(find(app.interestBands.FreqStart <= app.maskStruct.freq(ii,1)), find(app.interestBands.FreqStop >= app.maskStruct.freq(ii,1))))
                        freqList = [freqList; app.maskStruct.freq(ii,:)];
                    end           
                end
            end

        end   

        
        function TruncatedPeaks = Misc_TruncatedFrequency(app, FrequencyPeaks, F0, F1)

            global peaksReference
            if isempty(peaksReference)
                Misc_FindPeaksDefaultValues(app.RootFolder)
            end
            
            TruncatedPeaks = [];
            
            for ii = numel(FrequencyPeaks):-1:1
                ind = [];
                for jj = 1:numel(peaksReference)
                    if (peaksReference(jj).Band(1)/1000 <= FrequencyPeaks(ii)) && (peaksReference(jj).Band(2)/1000 >= FrequencyPeaks(ii))
                        ind = jj;
                        break
                    end
                end
                
                if ~isempty(ind)
                    FrequencyChannels = peaksReference(ind).Channels .* 1000;
                else
                    FrequencyChannels = single(F0:25:F1);
                end

                [~, auxIndex]        = min(abs(FrequencyChannels - FrequencyPeaks(ii)));
                TruncatedPeaks(ii,1) = FrequencyChannels(auxIndex);                
            end
            TruncatedPeaks = round(TruncatedPeaks);
            
        end
        
        
        function [Distance, Information, Station] = Misc_closestStation(app, freq, GPS)
            
            global AnatelDB
            if isempty(AnatelDB)
                Misc_DataBaseRead(app.RootFolder)
            end
            
            auxIndex = find(AnatelDB.Frequency == single(freq)/1000);
            
            Distance    = [];
            Information = '';
            Station     = [];
            
            if ~isempty(auxIndex)
                ll = 0;
                for kk = auxIndex'
                    ll = ll+1;
                    
                    auxDistance    = geo_lldistkm_v1(GPS, [AnatelDB.Latitude(kk), AnatelDB.Longitude(kk)]);
                    auxInformation = AnatelDB.Description{kk};
                    auxStation     = AnatelDB.Station(kk);
                    
                    if ll == 1
                        Distance    = auxDistance;
                        Information = auxInformation;
                        Station     = auxStation;
                    else
                        if auxDistance < Distance
                            Distance    = auxDistance;
                            Information = auxInformation;
                            Station     = auxStation;
                        end
                    end
                end

                if Distance > app.MonitorSettings.MaxDistance
                    Distance    = [];
                    Information = '';
                    Station     = [];
                else
                    Information = sprintf('%s (%.1f km)', Information, Distance);
                end
            end
            
        end
        

        function udpMonitor(app)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
            
            for ii = 1:height(app.rfNodes)
                set(d, 'Title', app.rfNodes.Name{ii}, 'Message', 'Em andamento tentativa de extrair dados do sensor...')

                % Criação do objeto FTP/SFTP.
                Misc_ftpObject(app, ii)
                if isempty(app.ftpObj)
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [0.6350 0.0780 0.1840])
                    continue
                end
                
                % Download dos arquivos de script e máscara espectral e a 
                % sua leitura, possibilitando atualização das variáveis
                % app.ScriptVersionSheet (informação de uma das abas do arquivo
                % "monitorGeral.xlsx" constante no Integra) e app.maskStruct 
                % (antiga app.maskParam).
                d.Message = 'Em andamento tentativa de download dos arquivos de script e máscara espectral carregados no sensor...';
                errorFlag = maskMonitor_FileDownload(app, ii);

                if errorFlag
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [0.6350 0.0780 0.1840])
                    continue
                else
                    Misc_TreeNodeColor(app, app.rfNodes.Name{ii}, [.5 .5 .5])
                end

                % Leitura do fluxo UDP e identificação das emissões
                % persistentes que rompem a máscara espectral e que estão 
                % em uma das faixas de interesse (as quais constam no arquivo 
                % "insterestBands.cfg")
                freqList = udpMonitor_udpLoggerRead(app, app.rfNodes.Name{ii}, d);

                if ~isempty(freqList)
                    Misc_LogWrite(app, app.rfNodes.Name{ii}, sprintf('Emissões persistentes (frequência central em MHz, largura em kHz e ocupação em %%): %s', jsonencode([round(freqList(:,1)/1000, 3) ceil(freqList(:,2)) ceil(freqList(:,3)*100)])))
                            
                    % Processo de truncamento das frequências centrais das
                    % emissões desmascaradas, possibilitando comparação com
                    % base legal (Mosaico, Stel e SRD).
                    d.Message =  'Em andamento consulta à base legal da Agência (Mosaico, Stel e SRD)...';
                    
                    TruncatedFreqs = Misc_TruncatedFrequency(app, freqList(:,1), app.maskStruct.FreqStart, app.maskStruct.FreqStop);
                    bwTruncated    = ceil(freqList(:,2));
    
                    nowTime = datestr(now, 'dd/mm/yyyy HH:MM:SS');
                    indNode = find(strcmp(app.PersistentWebSheet.Node, app.rfNodes.Name{ii}));
                    for jj = 1:numel(TruncatedFreqs)
                        [~, Information, ~] = Misc_closestStation(app, TruncatedFreqs(jj), [app.rfNodes.Latitude(ii), app.rfNodes.Longitude(ii)]);
                        
                        Tag = sprintf('%s_%.0f_%.0f', app.rfNodes.Name{ii}, TruncatedFreqs(jj), bwTruncated(jj));
                        if isempty(find(strcmp(app.PersistentEmissions.Tag, Tag), 1))
                            app.PersistentEmissions(end+1, :) = {app.rfNodes.State{ii},             ...
                                                                 app.rfNodes.Location{ii},          ...
                                                                 app.rfNodes.Name{ii},              ...
                                                                 round(TruncatedFreqs(jj)/1000, 3), ...
                                                                 bwTruncated(jj),                   ...
                                                                 round(freqList(jj,3) * 100),       ...
                                                                 Information, nowTime, Tag};

                            app.Qtd_Unmasked.Text = num2str(str2double(app.Qtd_Unmasked.Text) + 1);
                            if isempty(Information)
                                app.Qtd_Unknown.Text = num2str(str2double(app.Qtd_Unknown.Text) + 1);
                            end
                            drawnow
        
                            if bwTruncated(jj) > 0                        
                                if isempty(indNode)
                                    position = height(app.PersistentWebSheet) + 1;
                                    udpMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, position, Information, nowTime);
                                    
                                else
                                    indFreq = find(strcmp(app.PersistentWebSheet.Node, app.rfNodes.Name{ii}) & ...
                                                  (abs(TruncatedFreqs(jj) - app.PersistentWebSheet.FreqCenter*1000) < 15), 1);
                                    
                                    if isempty(indFreq)
                                        position = height(app.PersistentWebSheet) + 1;
                                        udpMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, position, Information, nowTime);
                                    else
                                        udpMonitor_AddInfoToSheet(app, app.rfNodes.Name{ii}, TruncatedFreqs(jj)/1000, bwTruncated(jj), freqList(jj,3) * 100, indFreq, Information, nowTime);
                                    end
                                end
                            end
                        end
                    end

                    app.Table.Data = app.PersistentEmissions(:,1:8);
                    pause(.5)
                    drawnow nocallbacks
                else
                    Misc_LogWrite(app, app.rfNodes.Name{ii}, 'Não identificada emissão persistente.')
                end

                if d.CancelRequested
                    break
                end
            end

            app.Tree.CheckedNodes = [];
            Misc_integraUpdate(app, d)

        end


        function freqList = udpMonitor_udpLoggerRead(app, Node, d)

            freqList     = [];
            app.bandList = [];

            udpPortArray = app.CallingApp.udpPortArray;
            IP           = app.ftpObj.Host;
            Port         = 8910;
            
            udpConfig    = struct('ConnectionTime', 60*app.MonitorSettings.BrokenMsg, 'HandshakePause', 1, 'Timeout', 5);            

            try
                [app.CallingApp.udpPortArray, ind] = Connection_udpLogger(udpPortArray, Port, udpConfig.Timeout);
                udpPort = app.CallingApp.udpPortArray{ind};
                flush(udpPort)
                
                udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)                
            catch ME
                uialert(app.UIFigure, ME.message, Node, 'Icon', 'error')
                return
            end
            
            app.bandList       = [];
            countBroken        = [];
            AvailableDatagrams = 0;
            nZeroDatagrams     = 0;

            set(d, 'Indeterminate', 'off', 'Value', 0)
            
            tic
            t1 = toc;
            while (width(countBroken) < app.MonitorSettings.BrokenMsg) && (nZeroDatagrams < 3)
                if t1 > udpConfig.ConnectionTime
                    udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)
        
                    if ~udpPort.NumDatagramsAvailable
                        nZeroDatagrams = nZeroDatagrams + 1;
                    end
                    
                elseif udpPort.NumDatagramsAvailable
                    AvailableDatagrams = AvailableDatagrams + udpPort.NumDatagramsAvailable;
                    d.Message = sprintf('%.0f datagramas lidos...', AvailableDatagrams);
                    drawnow

                    SpecInfo = read(udpPort, udpPort.NumDatagramsAvailable, 'uint8');

                    for ii = 1:numel(SpecInfo)
                        if ~strcmp(SpecInfo(ii).SenderAddress, IP) || numel(SpecInfo(ii).Data) < 96
                            continue
                        end
        
                        MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                        ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                        DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                        EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                        
                        if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                            switch DataType  
                                case 42
                                    countBroken = udpMonitor_udpDataType42(app, SpecInfo(ii).Data, countBroken);
                                    d.Value = width(countBroken)/app.MonitorSettings.BrokenMsg;
                                    
                                case {67, 68}
                                    if ismember(ThreadID, app.MonitorSettings.ThreadIDList) 
                                        [Bin, newArray]  = udpLogger_Read_DataType(DataType, SpecInfo(ii).Data, ThreadID);
                                        Bin.WritedTraces = 0;
                                        Bin.Traces       = [];

                                        udpMonitor_BandListUpdate(app, Bin, newArray)
                                    else 
                                        continue
                                    end
                                    
                                otherwise
                                    continue
                            end    
                        end    
                    end    
                end
                t1 = toc;
                pause(0.1)
            end

            band = app.bandList;
            save(fullfile(app.RootFolder, 'Temp', sprintf('bandList_%s.mat', Node)), 'countBroken', 'band')

%             load(sprintf('bandList_%s.mat', Node), 'countBroken', 'band')
%             app.bandList = band;
            
            if isempty(app.bandList) || numel(app.bandList) ~= numel(app.MonitorSettings.ThreadIDList)
                uialert(app.UIFigure, sprintf('Não foram recebidos datagramas válidos para ao menos um dos TheadID de interesse. Favor verificar arquivo %s.', sprintf('bandList_%s.mat', Node)), Node, 'Icon', 'warning')
                return
            else
                if ~issorted([app.bandList.ThreadID])
                    [~, indSort] = sort([app.bandList.ThreadID]);
                    app.bandList = app.bandList(indSort);
                end
            end

            countBroken_Mean = ceil((countBroken(:,end)-countBroken(:,1))/width(countBroken));
            indThreadID      = find(countBroken_Mean > app.MonitorSettings.THR1);

            Misc_LogWrite(app, Node, sprintf('Contador médio de rompimento de máscara: %s', jsonencode(countBroken_Mean)))

            for ii = indThreadID'
                F0 = app.bandList(ii).FreqStart/1000;
                F1 = app.bandList(ii).FreqStop/1000;
                
                maskArray = ones([app.bandList(ii).DataPoints, 1])*1000;
                maskFreq  = linspace(F0, F1, app.bandList(ii).DataPoints)';
                        
                for jj = 1:height(app.maskStruct.freq)
                    maskArray(maskFreq >= (app.maskStruct.raw.Start(jj) - app.bandList(ii).StepWidth/2000) & ...
                              maskFreq <= (app.maskStruct.raw.Stop(jj)  + app.bandList(ii).StepWidth/2000)) = app.maskStruct.raw.Threshold(jj);
                end

                occMat = zeros([app.bandList(ii).DataPoints app.bandList(ii).WritedTraces], 'single');
                occMat(bsxfun(@gt, app.bandList(ii).Traces(:, 1:app.bandList(ii).WritedTraces), maskArray)) = 1;

                occMatEdited = sum(occMat,2)/app.bandList(ii).WritedTraces;
                indFreq      = find(occMatEdited);

                InitialHeight = height(freqList);
                for kk = indFreq'
                    refFreq = [kk 1];
                
                    if isempty(freqList) || refFreq(1) ~= sum(freqList(end,:))
                        freqList(end+1,:) = refFreq;
                    else
                        freqList(end,2)   = freqList(end,2)+1;
                    end
                end
                
                if ~isempty(indFreq)
                    for ll = InitialHeight+1:height(freqList)
                        [Max, indMax] = max(occMatEdited(freqList(ll,1):sum(freqList(ll,1:2))-1));
    
                        freqList(ll, 1) = maskFreq(freqList(ll, 1)+indMax-1);
                        freqList(ll, 2) = (freqList(ll, 2) + 1) * app.bandList(ii).StepWidth/1000;
                        freqList(ll, 3) = Max;
                    end
                    freqList(freqList(:,3) < app.MonitorSettings.THR2, :) = [];
                end
            end
            
        end


        function udpMonitor_udpConnect(app, udpPort, udpConfig, IP, Port)
            
            msg = sprintf('open %.0f', udpConfig.ConnectionTime);
        
            write(udpPort, msg, 'uint8', IP, Port);
            pause(udpConfig.HandshakePause)
        
        end


        function countBroken = udpMonitor_udpDataType42(app, Datagram, countBroken)

            FreeTextLength = double(typecast(uint8(Datagram(73:76)), 'uint32'));
            if FreeTextLength
                FreeText = deblank(char(Datagram(77:76+FreeTextLength)));

                try
                    jsonStruct = jsondecode(FreeText);
                    if jsonStruct.type == 4
                        countBroken(:,end+1) = jsonStruct.values;
                    end
                catch
                end
            end

        end


        function udpMonitor_BandListUpdate(app, Bin, newArray)

            if isempty(app.bandList)
                app.bandList = Bin;
                app.bandList.Traces = zeros(Bin.DataPoints, 60*app.MonitorSettings.BrokenMsg, 'single');

                indCurr   = 1;
            else
                indFind = find([app.bandList.ThreadID] == Bin.ThreadID);
                if isempty(indFind)
                    app.bandList(end+1) = Bin;
                    app.bandList(end).Traces = zeros(Bin.DataPoints, 60*app.MonitorSettings.BrokenMsg, 'single');

                    indCurr = numel(app.bandList);
                else
                    indCurr = indFind;
                end
            end
            
            app.bandList(indCurr).WritedTraces = app.bandList(indCurr).WritedTraces + 1;
            app.bandList(indCurr).Traces(:, app.bandList(indCurr).WritedTraces) = newArray;

       end


       function udpMonitor_AddInfoToSheet(app, rfNode, frequency, bw, occ, position, Information, nowTime)

            InitialHeight = height(app.PersistentWebSheet);
            
            if isempty(Information)
                RegulatoryInfo = "Não licenciado";
                Description    = "";

                if position <= InitialHeight
                    Uploaded    = "Sim";
                    Status      = app.PersistentWebSheet.Status(position);
                    dateCreated = app.PersistentWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    Status      = "Pendente identificação";
                    dateCreated = nowTime;                                
                end
                
            else
                RegulatoryInfo = "Licenciado";
                Description    = Information;
                Status         = "Pendente confirmação";

                if position <= InitialHeight
                    Uploaded    = app.PersistentWebSheet.Uploaded(position);
                    dateCreated = app.PersistentWebSheet.dateCreated(position);
                else
                    Uploaded    = "Não";
                    dateCreated = nowTime;                    
                end
            end

            app.PersistentWebSheet(position,:) = {position,       ...
                                                  rfNode,         ...
                                                  round(frequency, 3), ...
                                                  bw,             ...
                                                  occ,            ...
                                                  RegulatoryInfo, ...
                                                  Description,    ...
                                                  Status,         ...
                                                  Uploaded,       ...
                                                  dateCreated};

       end

       
       function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
 
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            app.RFeyeList  = Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'rfeyeList.cfg'));            

            app.MonitorSettings = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'Monitor.json')));
            app.interestBands   = readtable(fullfile(app.RootFolder, 'Settings', 'interestBands.cfg'), 'FileType', 'text');

            if isfile(app.MonitorSettings.WebSheetPath)
                app.UnMaskedWebSheetPath = app.MonitorSettings.WebSheetPath;
            else
                app.UnMaskedWebSheetPath = fullfile(app.RootFolder, 'Settings', 'monitorGeral.xlsx');
            end
            Misc_integraImport(app)

            TreeBuilding(app)

            app.Table.Data = app.UnMaskedEmissions(:,1:8);
            RadioGroupSelectionChanged(app)

        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            
%             Misc_SaveLog(app)

            app.CallingApp.auxWin5 = [];
            delete(app.fig)
            delete(app)
            
        end

        % Value changed function: Button_play
        function Button_playValueChanged(app, event)
            
            ind = [];
            for ii = 1:numel(app.Tree.CheckedNodes)
                ind = [ind; app.Tree.CheckedNodes(ii).NodeData];
            end
            ind = unique(ind);

            if isempty(ind)
                if app.Button_Integra.Value
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Cancelable', 'on', 'CancelText', 'Cancelar');
                    Misc_integraUpdate(app, d)
                else
                    uialert(app.UIFigure, 'É preciso selecionar ao menos um sensor.', 'appColeta', 'Icon', 'warning')
                end

                return
            end
            app.rfNodes = app.RFeyeList(ind,:);

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    maskMonitor(app)

                case 'Tráfego UDP'
                    udpMonitor(app)
            end
            TreeSelectionChanged(app)
                     
        end

        % Button pushed function: Button_Log
        function Button_LogPushed(app, event)

            ind = find(strcmp(app.RFeyeList.Name, app.Tree.SelectedNodes.Text), 1);

            if ~isempty(app.RFeyeList.Monitor{ind})
                uialert(app.UIFigure, app.RFeyeList.Monitor{ind}, app.RFeyeList.Name{ind}, 'Icon', 'info', 'Interpreter','tex')
            end

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            ind = find(strcmp(app.RFeyeList.Name, app.Tree.SelectedNodes.Text), 1);

            if isempty(ind) || isempty(app.RFeyeList.Monitor{ind})
                app.Button_Log.Enable = 0;
            else
                app.Button_Log.Enable = 1;
            end            

        end

        % Selection changed function: RadioGroup
        function RadioGroupSelectionChanged(app, event)

            switch app.RadioGroup.SelectedObject.Text
                case 'Máscaras espectrais'
                    app.SubTitle.Text = 'MODO EMISSÕES DESMASCARADAS';
                    app.Table.Data = app.UnMaskedEmissions(:,1:8);

                    app.Qtd_UnmaskedLabel.Text = {'EMISSÕES'; 'DESMASCARADAS'};

                    msg = sprintf('%.3f-%.3f, ', ([app.interestBands.FreqStart app.interestBands.FreqStop]/1000)');
                    app.SettingsInfo.Text = sprintf('Faixa(s) de interesse (MHz): %s; Distância máxima: %.0f km', ...
                                             msg(1:end-2), app.MonitorSettings.MaxDistance);

                case 'Tráfego UDP'
                    app.SubTitle.Text = 'MODO EMISSÕES PERSISTENTES ROMPENDO MÁSCARAS ESPECTRAIS';
                    app.Table.Data = app.PersistentEmissions(:,1:8);

                    app.Qtd_UnmaskedLabel.Text = {'EMISSÕES'; 'PERSISTENTES'};

                    app.SettingsInfo.Text = sprintf('Fluxo(s) de interesse: %s\nQtd. ciclos: %.0f; Threshold 1: %.0f; Threshold 2: %.0f%%; Distância máxima: %.0f km', ...
                                             jsonencode(app.MonitorSettings.ThreadIDList), app.MonitorSettings.BrokenMsg, app.MonitorSettings.THR1, app.MonitorSettings.THR2*100, app.MonitorSettings.MaxDistance);
            end

            app.Qtd_Unmasked.Text = num2str(height(app.Table.Data));
            app.Qtd_Unknown.Text  = num2str(numel(find(strcmp(app.Table.Data.Description, ''))));

        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winMonitor>', '</winMonitor>')));

            Misc_uialertCreation(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.GridLayout = uigridlayout(app.Grid1);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'auto22.png';
            app.MainTitle = uilabel(app.GridLayout);
            app.MainTitle.Interpreter = 'html';
            app.MainTitle.Text = {'<b style="color: #00355f; font-size: 24;">Monitor Logger +</b>'; '<font>Análise de emissões desmascaradas e persistentes</font>'; ' '};
            app.instrPanel_2 = uipanel(app.GridLayout);
            app.instrGrid_2 = uigridlayout(app.instrPanel_2);
            app.Qtd_UnmaskedLabel = uilabel(app.instrGrid_2);
            app.Qtd_UnmaskedLabel.Text = {'EMISSÕES'; 'DESMASCARADAS'};
            app.Qtd_Unmasked = uilabel(app.instrGrid_2);
            app.Qtd_Unmasked.Text = '0';
            app.instrPanel = uipanel(app.GridLayout);
            app.instrGrid = uigridlayout(app.instrPanel);
            app.Qtd_UnknownLabel = uilabel(app.instrGrid);
            app.Qtd_UnknownLabel.Text = 'NÃO IDENTIFICADA';
            app.Qtd_Unknown = uilabel(app.instrGrid);
            app.Qtd_Unknown.Text = '0';
            app.SubTitle = uilabel(app.GridLayout);
            app.SubTitle.Text = 'MODO EMISSÕES DESMASCARADAS';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.RadioGroupLabel = uilabel(app.GridLayout3);
            app.RadioGroupLabel.Text = 'Tipo de monitoração:';
            app.RadioGroup = uibuttongroup(app.GridLayout3);
            app.RadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupSelectionChanged, true);
            app.Option2 = uiradiobutton(app.RadioGroup);
            app.Option2.Text = 'Tráfego UDP';
            app.Option1 = uiradiobutton(app.RadioGroup);
            app.Option1.Text = 'Máscaras espectrais';
            app.Option1.Value = true;
            app.TreeLabel = uilabel(app.GridLayout3);
            app.TreeLabel.Text = 'Sensores:';
            app.Tree = uitree(app.GridLayout3, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Table_Grid = uigridlayout(app.GridLayout3);
            app.Table = uitable(app.Table_Grid);
            app.Table.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.Table.RowName = {};
            app.Table.SelectionType = 'row';
            app.Table_Label1 = uilabel(app.Table_Grid);
            app.Table_Label1.Text = '  UF';
            app.Table_Label2 = uilabel(app.Table_Grid);
            app.Table_Label2.Text = '  Município';
            app.Table_Label3 = uilabel(app.Table_Grid);
            app.Table_Label3.Text = '  Sensor';
            app.Table_Label4 = uilabel(app.Table_Grid);
            app.Table_Label4.Text = {'  Frequência'; '  (MHz)'};
            app.Table_Label5 = uilabel(app.Table_Grid);
            app.Table_Label5.Text = {'  Largura '; '  (kHz)'};
            app.Table_Label6 = uilabel(app.Table_Grid);
            app.Table_Label6.Text = {'  OCC '; '  (%)'};
            app.Table_Label7 = uilabel(app.Table_Grid);
            app.Table_Label7.Text = '  Provável emissor';
            app.Table_Label8 = uilabel(app.Table_Grid);
            app.Table_Label8.Text = '  Data/hora de registro';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Button_play = uibutton(app.GridLayout4, 'state');
            app.Button_play.ValueChangedFcn = createCallbackFcn(app, @Button_playValueChanged, true);
            app.Button_play.Icon = 'General_play.png';
            app.Button_play.Text = 'Iniciar';
            app.SettingsInfo = uilabel(app.GridLayout4);
            app.SettingsInfo.Text = '';
            app.Button_Log = uibutton(app.GridLayout4, 'push');
            app.Button_Log.ButtonPushedFcn = createCallbackFcn(app, @Button_LogPushed, true);
            app.Button_Log.Icon = 'Log2.png';
            app.Button_Log.Enable = 'off';
            app.Button_Log.Tooltip = {'Log'};
            app.Button_Log.Text = '';
            app.Button_Integra = uibutton(app.GridLayout4, 'state');
            app.Button_Integra.Tooltip = {'Atualizar planilha Integra'};
            app.Button_Integra.Icon = 'Flow.png';
            app.Button_Integra.Text = '';
            app.Separator = uiimage(app.GridLayout4);
            app.Separator.ImageSource = 'Line.png';
            app.Button_info = uibutton(app.GridLayout4, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'toolbar_info.png';
            app.Button_info.Tooltip = {''};
            app.Button_info.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end