classdef winRFeyeCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Button                  matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        Grid2                   matlab.ui.container.GridLayout
        Password                matlab.ui.control.HTML
        Login                   matlab.ui.control.EditField
        Port                    matlab.ui.control.NumericEditField
        PortaEditField_2Label   matlab.ui.control.Label
        IP                      matlab.ui.control.EditField
        IPEditFieldLabel        matlab.ui.control.Label
        UsurioEditFieldLabel    matlab.ui.control.Label
        Protocol                matlab.ui.control.DropDown
        ProtocoloDropDownLabel  matlab.ui.control.Label
        PasswordLabel           matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)
        
        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appAnálise', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'LT_info.png'))
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, NodeIP, NodePass)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;

            app.Password.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n\t<script type="text/javascript">\n\t\tfunction setup(htmlComponent) {\n\t\t\tvar dom = document.getElementById("pwrd");\n\t\t\twindow.updateData = function (newData) {\n\t\t\t\thtmlComponent.Data = dom.value;\n\t\t\t};\n\t\t\thtmlComponent.addEventListener("DataChanged", function() {\n\t\t\t\tdom.value = htmlComponent.Data;\n\t\t\t});\n\t\t}\n\t</script>\n</head>\n<body>\n\t<input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 210px; height: 16px"></input>\n</body>\n</html>');
            
            x0 = fix(Position(1)+(Position(3)-app.UIFigure.Position(3))/2);
            y0 = fix(Position(2)+(Position(4)-app.UIFigure.Position(4))/2);
            app.UIFigure.Position(1:2) = [x0, y0];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.IP.Value      = NodeIP;
            app.Password.Data = NodePass;
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if ~isempty(app.Login.Value) && ~isempty(app.Password.Data)
                if ~isempty(app.CallingApp.ftpObj)
                    close(app.CallingApp.ftpObj)
                    app.CallingApp.ftpObj = [];
                    
                    drawnow nocallbacks
                end

                try
                    d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                    d.Message = '<font style="font-size:12;">Tentando se conectar ao sensor...</font>';

                    host = sprintf('%s:%d', app.IP.Value, app.Port.Value);
                    switch app.Protocol.Value
                        case 'File Transfer Protocol (FTP)'
                            app.CallingApp.ftpObj =  ftp(host, app.Login.Value, app.Password.Data);
                            app.CallingApp.logger_ftpInfo('sucess', sprintf('%s.%s', 'ftp', host))

                        case 'Secure File Transfer Protocol (SFTP)'
                            app.CallingApp.ftpObj = sftp(host, app.Login.Value, 'Password', app.Password.Data);
                            app.CallingApp.logger_ftpInfo('sucess', sprintf('%s.%s', 'sftp', host))
                    end
                                        
                    delete(app)
                catch ME
                    app.CallingApp.logger_ftpInfo('error', '')
                    MessageBox(app, 'error', getReport(ME))
                end
                    
            else
                msg = 'Favor inserir as credenciais.';
                MessageBox(app, 'warning', msg)
            end
            
        end

        % Value changed function: Protocol
        function ProtocolValueChanged(app, event)

            switch app.Protocol.Value
                case 'File Transfer Protocol (FTP)'
                    app.Port.Value = 21;

                case 'Secure File Transfer Protocol (SFTP)'
                    app.Port.Value = 2828;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Instr_CRFS.png';
            app.Title = uilabel(app.GridLayout);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #00355f; font-size: 24;">RFeye Node 20-6</b>'; '<font><i>Download</i> do <i>script</i> e dos arquivos de máscara espectral carregados no Logger</font>'; ' '};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Credenciais:';
            app.Panel = uipanel(app.GridLayout);
            app.Grid2 = uigridlayout(app.Panel);
            app.PasswordLabel = uilabel(app.Grid2);
            app.PasswordLabel.Text = 'Senha:';
            app.ProtocoloDropDownLabel = uilabel(app.Grid2);
            app.ProtocoloDropDownLabel.Text = 'Protocolo:';
            app.Protocol = uidropdown(app.Grid2);
            app.Protocol.Items = {'File Transfer Protocol (FTP)', 'Secure File Transfer Protocol (SFTP)'};
            app.Protocol.ValueChangedFcn = createCallbackFcn(app, @ProtocolValueChanged, true);
            app.Protocol.Value = 'File Transfer Protocol (FTP)';
            app.UsurioEditFieldLabel = uilabel(app.Grid2);
            app.UsurioEditFieldLabel.Text = 'Usuário:';
            app.IPEditFieldLabel = uilabel(app.Grid2);
            app.IPEditFieldLabel.Text = 'IP:';
            app.IP = uieditfield(app.Grid2, 'text');
            app.IP.Editable = 'off';
            app.PortaEditField_2Label = uilabel(app.Grid2);
            app.PortaEditField_2Label.Text = 'Porta:';
            app.Port = uieditfield(app.Grid2, 'numeric');
            app.Port.Limits = [0 65535];
            app.Port.RoundFractionalValues = 'on';
            app.Port.ValueDisplayFormat = '%.0f';
            app.Port.Value = 21;
            app.Login = uieditfield(app.Grid2, 'text');
            app.Login.Value = 'root';
            app.Password = uihtml(app.Grid2);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'DT_play.png';
            app.Button.Text = 'Confirmar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFeyeCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end