classdef winRFeyeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        GridLayout        matlab.ui.container.GridLayout
        LocalidadeLabel   matlab.ui.control.Label
        LongitudeLabel_2  matlab.ui.control.Label
        LatitudeLabel_2   matlab.ui.control.Label
        IP2Label_2        matlab.ui.control.Label
        IP1Label_2        matlab.ui.control.Label
        NomeLabel         matlab.ui.control.Label
        IDLabel           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        Image             matlab.ui.control.Image
        Title             matlab.ui.control.Label
        instrPanel        matlab.ui.container.Panel
        instrGrid         matlab.ui.container.GridLayout
        instrTag          matlab.ui.control.Label
        instrImage        matlab.ui.control.Image
        mainLabel         matlab.ui.control.Label
        toolGrid          matlab.ui.container.GridLayout
        Button_play       matlab.ui.control.Button
        Button_connect    matlab.ui.control.Button
        Button_edit       matlab.ui.control.StateButton
        Separator2        matlab.ui.control.Image
        Button_export     matlab.ui.control.Button
        Button_open       matlab.ui.control.Button
        Separator1        matlab.ui.control.Image
        Button_info       matlab.ui.control.Button
        mainPanel         matlab.ui.container.Panel
        mainGrid          matlab.ui.container.GridLayout
        mainGrid3         matlab.ui.container.GridLayout
        IPType            matlab.ui.control.DropDown
        IPTypeLabel       matlab.ui.control.Label
        LOGText           matlab.ui.control.TextArea
        LOGTextLabel      matlab.ui.control.Label
        mainGrid2         matlab.ui.container.GridLayout
        Subpanel2B        matlab.ui.container.Panel
        Subgrid2B         matlab.ui.container.GridLayout
        rootPass          matlab.ui.control.HTML
        rootPassLabel     matlab.ui.control.Label
        rootLogin         matlab.ui.control.EditField
        rootLoginLabel    matlab.ui.control.Label
        sftpPort          matlab.ui.control.Spinner
        sftpPortLabel     matlab.ui.control.Label
        ftpPort           matlab.ui.control.Spinner
        ftpPortLabel      matlab.ui.control.Label
        Sublabel2B        matlab.ui.control.Label
        Subpanel2A        matlab.ui.container.Panel
        Subgrid2A         matlab.ui.container.GridLayout
        webPass           matlab.ui.control.HTML
        webPassLabel      matlab.ui.control.Label
        webLogin          matlab.ui.control.EditField
        webLoginLabel     matlab.ui.control.Label
        Sublabel2A        matlab.ui.control.Label
        mainGrid1         matlab.ui.container.GridLayout
        LocationGrid      matlab.ui.container.GridLayout
        LocationSearch    matlab.ui.control.Image
        LocationWarn      matlab.ui.control.Image
        SublabelA2        matlab.ui.control.Label
        Status            matlab.ui.control.DropDown
        StatusLabel       matlab.ui.control.Label
        SubpanelA2        matlab.ui.container.Panel
        SubgridA2         matlab.ui.container.GridLayout
        Longitude         matlab.ui.control.NumericEditField
        LongitudeLabel    matlab.ui.control.Label
        Latitude          matlab.ui.control.NumericEditField
        LatitudeLabel     matlab.ui.control.Label
        SubpanelA1        matlab.ui.container.Panel
        SubgridA1         matlab.ui.container.GridLayout
        IP2               matlab.ui.control.EditField
        IP2Label          matlab.ui.control.Label
        IP1               matlab.ui.control.EditField
        IP1Label          matlab.ui.control.Label
        SublabelA1        matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        RFeyeList
        editedList

        editedFlag = false
        
    end
    
    methods (Access = private)

        function tempList = Fcn1_OpenFile(app, FilePath)

            [tempList, msg] = OpenFile_rfeyeList(FilePath);

            if ~isempty(msg)
                MessageBox(app, 'warning', msg)
            end

        end


        function Fcn2_TableCreation(app, SelectedRow)
            
            set(app.UITable, 'Data', app.editedList(:, [1:4, 9:11]), 'Selection', SelectedRow)            

            if ~isempty(app.UITable.StyleConfigurations)
                removeStyle(app.UITable)
            end

            DisableRows = find(~app.editedList.Enable);

            if ~isempty(DisableRows)
                s = uistyle('FontColor', [.75,.75,.75]);
                addStyle(app.UITable, s, 'row', DisableRows)
            end

            TableSelectionChanged(app)

        end

        
        function Flag = Fcn3_IPv4Validation(app, event)
            
            switch event.Source
                case app.IP1; ipAddress = app.IP1.Value;
                case app.IP2; ipAddress = app.IP2.Value;
            end

            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');

            Flag = 0;
            if isempty(ipString)
                Flag = 1;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    Flag = 1;
                end                    
            end

            if Flag
                MessageBox(app, 'error', sprintf('Valor "%s" inválido.', event.Value))

                switch event.Source
                    case app.IP1; app.IP1.Value = event.PreviousValue;
                    case app.IP2; app.IP2.Value = event.PreviousValue;
                end
            else
                if ~strcmp(ipAddress, char(ipString))
                    switch event.Source
                        case app.IP1; app.IP1.Value = char(ipString);
                        case app.IP2; app.IP2.Value = char(ipString);
                    end
                end
            end

        end


        function Fcn4_saveNewFile(app, Folder)
            
            rfeyeList = app.RFeyeList;
            try
                save(fullfile(Folder, 'rfeyeList.cfg'), 'rfeyeList')
                MessageBox(app, 'info', sprintf('Salvo o arquivo <b>rfeyeList.cfg</b> na pasta "%s"', Folder))
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.webPass.HTMLSource  = '<!DOCTYPE html><html><head><script type="text/javascript">function setup(htmlComponent) {var dom = document.getElementById("pwrd");window.updateData = function (newData) {htmlComponent.Data = dom.value;};htmlComponent.addEventListener("DataChanged", function() {dom.value = htmlComponent.Data;});}</script></head><body><input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 126px; height: 16px" disabled></input></body></html>';
            app.rootPass.HTMLSource = app.webPass.HTMLSource;

            app.RFeyeList  = Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'rfeyeList.cfg'));
            app.editedList = app.RFeyeList;

            Fcn2_TableCreation(app, 1)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                Fcn4_saveNewFile(app, fullfile(app.RootFolder, 'Settings'))

                app.CallingApp.rfeyeList = [];
                if app.CallingApp.menu_Button3.Value
                    app.CallingApp.Misc_winAux('winRFeyeEdit');
                end
            end

            app.CallingApp.auxWin4 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function TableSelectionChanged(app, event)
            
            if isempty(app.UITable.Selection)
                app.UITable.Selection = 1;
            end

            idx = app.UITable.Selection;

            app.IP1.Value = app.editedList.IP1{idx};
            app.IP2.Value = app.editedList.IP2{idx};

            app.LocationWarn.Visible  = 0;
            app.LocationSearch.Enable = 0;
            app.Latitude.Value  = app.editedList.Latitude(idx);
            app.Longitude.Value = app.editedList.Longitude(idx);

            app.webLogin.Value  = 'admin';
            app.rootLogin.Value = 'root';

            app.webPass.Data    = app.editedList.webPass{idx};
            app.rootPass.Data   = app.editedList.rootPass{idx};

            app.LOGText.Value   = app.editedList.LOG{idx};

            if app.Button_edit.Value
                if app.editedList.Enable(idx); app.Status.Value = 'ON';
                else;                          app.Status.Value = 'OFF';
                end

                app.ftpPort.Value  = app.editedList.ftpPort(idx);
                app.sftpPort.Value = app.editedList.sftpPort(idx);
                
            else
                if app.editedList.Enable(idx); app.Status.Items = {'ON'};
                else;                          app.Status.Items = {'OFF'};
                end

                app.ftpPort.Limits  = [app.editedList.ftpPort(idx),  app.editedList.ftpPort(idx)+.1 ];
                app.sftpPort.Limits = [app.editedList.sftpPort(idx), app.editedList.sftpPort(idx)+.1];
            end
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winRFeyeEdit>', '</winRFeyeEdit>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_open
        function Button_openPushed(app, event)
            
            [File, Folder] = uigetfile({'*.cfg', '*.cfg'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
                tempList = Fcn1_OpenFile(app, fullfile(Folder, File));

                if ~isempty(tempList)
                    app.RFeyeList     = tempList;
                    app.RFeyeList.idx = (1:height(app.RFeyeList))';

                    app.editedList = app.RFeyeList;
                    app.editedFlag = true;

                    Fcn2_TableCreation(app, 1)
                end
            end

        end

        % Button pushed function: Button_export
        function Button_exportPushed(app, event)
            
            Folder = uigetdir(app.CallingApp.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de instrumentos');
            figure(app.UIFigure)

            if Folder
                Fcn4_saveNewFile(app, Folder)
            end

        end

        % Value changed function: Button_edit
        function Button_editValueChanged(app, event)
            
            if app.Button_edit.Value
                app.mainLabel.Text = 'MODO DE EDIÇÃO';

                set(app.Status,    'Items', {'ON', 'OFF'})
                set(app.IP1,       'Editable', 1)
                set(app.IP2,       'Editable', 1)
                set(app.Latitude,  'Editable', 1)
                set(app.Longitude, 'Editable', 1)                
                set(app.ftpPort,   'Editable', 1, 'Limits', [0 65535])
                set(app.sftpPort,  'Editable', 1, 'Limits', [0 65535])

                app.webPass.HTMLSource  = replace(app.webPass.HTMLSource,  'disabled', 'enabled');
                app.rootPass.HTMLSource = replace(app.rootPass.HTMLSource, 'disabled', 'enabled');

                set(app.toolGrid.Children, 'Enable', 1)
                app.Button_open.Enable   = 0;
                app.Button_export.Enable = 0;

            else
                app.mainLabel.Text = 'MODO DE VISUALIZAÇÃO';

                set(app.Status,    'Items', {app.Status.Value})
                set(app.IP1,       'Editable', 0)
                set(app.IP2,       'Editable', 0)
                set(app.Latitude,  'Editable', 0)
                set(app.Longitude, 'Editable', 0)
                set(app.ftpPort,   'Editable', 0, 'Limits', [app.ftpPort.Value,  app.ftpPort.Value+.1])
                set(app.sftpPort,  'Editable', 0, 'Limits', [app.sftpPort.Value, app.sftpPort.Value+.1])

                app.webPass.HTMLSource  = replace(app.webPass.HTMLSource,  'enabled', 'disabled');
                app.rootPass.HTMLSource = replace(app.rootPass.HTMLSource, 'enabled', 'disabled');
                
                set(app.toolGrid.Children, 'Enable', 1)
                app.Button_connect.Enable = 0;
                app.Button_play.Enable    = 0;

                if ~isequal(app.RFeyeList, app.editedList)
                    app.editedList = app.RFeyeList;
                    Fcn2_TableCreation(app, 1)
                end
            end
            
        end

        % Button pushed function: Button_connect
        function Button_connectPushed(app, event)
            
            if ~isempty(app.IP1.Value) | ~isempty(app.IP2.Value)
                d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Indeterminate", "on", "Interpreter", "html");

                idx = app.UITable.Selection;
                switch app.IPType.Value
                    case 'Público / Intranet'
                        ipType = 'Public/Intranet';
                        if isempty(app.IP1.Value); ipType = 'OpenVPN';
                        end

                    case 'OpenVPN'
                        ipType = 'OpenVPN';
                        if isempty(app.IP2.Value); ipType = 'Public/Intranet';
                        end
                end

                tempList = Misc_RFeyeListCreation(app.editedList(idx,:), app.RootFolder, ipType);
                delete(d)

                LOG = tempList.LOG{1};
                LOG = sprintf('%s\n\n<b>cgi-versions</b>\n%s\n\n<b>cgi-ifconfig</b>\n%s<b>cgi-apps</b>\n%s\n\n<b>cgi-status</b>\n%s', ...
                                  jsonencode(rmfield(LOG, 'cgi'), "PrettyPrint", true), ...
                                  LOG.cgi.versions, ...
                                  LOG.cgi.ifconfig, ...
                                  LOG.cgi.apps, ...
                                  LOG.cgi.status);

                msg = sprintf('<font style="font-size:12;">Deseja incluir as informações apresentadas a seguir no cadastro do sensor <b>%s</b>?\n\n%s</font>', app.editedList.Name{idx}, LOG);
                selection = uiconfirm(app.UIFigure, msg, 'appColeta', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                
                if selection == "Cancelar"
                    return
                end

                app.editedList(idx,:)   = tempList;
                app.editedList.LOG{idx} = LOG;

                Fcn2_TableCreation(app, idx)

            else
                MessageBox(app, 'error', 'O teste de conectividade demanda informação válida do endereço IP.')
            end
            
        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            if ~isequal(app.RFeyeList, app.editedList)
                try
                    idx = find(app.editedList.Enable)';
                    
                    msg = {};               
                    for ii = idx
                        if isempty(app.editedList.IP1{ii}) & isempty(app.editedList.IP2{ii})
                            msg{end+1} = sprintf('• ID%d - Necessário incluir ao menos um IP válido.', ii);
                        end

                        if isempty(app.editedList.Location{ii})
                            msg{end+1} = sprintf('• ID%d - Necessário indicar localidade onde o sensor está instalado.', ii);
                        end
                    end

                    if ~isempty(msg)
                        error(strjoin(msg, newline))
                    end
                    
                    app.RFeyeList  = app.editedList;
                    app.editedFlag = true;

                catch ME
                    MessageBox(app, 'error', ME.message)
                    return
                end
            end
            
            app.Button_edit.Value = 0;
            Button_editValueChanged(app)

        end

        % Callback function: IP1, IP2, Latitude, LocationSearch, Longitude, 
        % Status, ftpPort, rootPass, sftpPort, webPass
        function ParameterEdited(app, event)
            
            idx = app.UITable.Selection;

            switch event.Source
                case app.Status
                    if app.Status.Value == "ON"
                        app.editedList.Enable(idx) = 1;
                    else
                        app.editedList.Enable(idx) = 0;
                    end
                    Fcn2_TableCreation(app, idx)

                case {app.IP1, app.IP2}
                    if ~isempty(event.Source.Value)
                        Flag = Fcn3_IPv4Validation(app, event);

                        if Flag
                            return
                        end
                    end

                    switch event.Source
                        case app.IP1; app.editedList.IP1{idx} = app.IP1.Value; app.UITable.Data.IP1{idx} = app.IP1.Value;
                        case app.IP2; app.editedList.IP2{idx} = app.IP2.Value; app.UITable.Data.IP2{idx} = app.IP2.Value;
                    end

                case {app.Latitude, app.Longitude}
                    app.LocationWarn.Visible  = 1;
                    app.LocationSearch.Enable = 1;

                case app.LocationSearch
                    City = '';
                    
                    if (app.Latitude.Value ~= -1) | (app.Longitude.Value ~= -1)
                        d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Indeterminate", "on", "Interpreter", "html");
                        [City, Distance] = geo_FindCity(struct('Latitude',  app.Latitude.Value, 'Longitude', app.Longitude.Value));
                        delete(d)
    
                        msg = sprintf('<font style="font-size:12;">Confirma que o local de instalação do sensor <b>%s</b> está a %.1f quilômetros da sede de %s?</font>', app.editedList.Name{idx}, Distance, City);
                        selection = uiconfirm(app.UIFigure, msg, 'appColeta', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'question', 'Interpreter', 'html');
                        
                        if selection == "Cancelar"
                            app.Latitude.Value  = app.UITable.Data.Latitude(idx);
                            app.Longitude.Value = app.UITable.Data.Longitude(idx);

                            app.LocationWarn.Visible  = 0;
                            app.LocationSearch.Enable = 0;

                            return
                        end
                    end

                    app.editedList.Latitude(idx)  = app.Latitude.Value;  
                    app.editedList.Longitude(idx) = app.Longitude.Value; 
                    app.editedList.Location{idx}  = City;
                    
                    app.UITable.Data.Latitude(idx)  = app.Latitude.Value;
                    app.UITable.Data.Longitude(idx) = app.Longitude.Value;
                    app.UITable.Data.Location{idx}  = City;

                    app.LocationWarn.Visible  = 0;
                    app.LocationSearch.Enable = 0;

                case app.webPass
                    app.webPass.Data = strtrim(app.webPass.Data);
                    if isempty(app.webPass.Data)
                        app.webPass.Data = event.PreviousData;
                        
                        MessageBox(app, 'error', 'Campo "Senha" do ambiente <i>web</i> não pode ficar vazio.')
                        return
                    end
                    app.editedList.webPass{idx} = app.webPass.Data;

                case app.rootPass
                    app.rootPass.Data = strtrim(app.rootPass.Data);
                    if isempty(app.rootPass.Data)
                        app.rootPass.Data = event.PreviousData;
                        
                        MessageBox(app, 'error', 'Campo "Senha" do ambiente <i>root</i> não pode ficar vazio.')
                        return
                    end
                    app.editedList.rootPass{idx} = app.rootPass.Data;

                case app.ftpPort
                    app.editedList.ftpPort(idx)  = app.ftpPort.Value;

                case app.sftpPort
                    app.editedList.sftpPort(idx) = app.sftpPort.Value;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.mainPanel = uipanel(app.Grid1);
            app.mainGrid = uigridlayout(app.mainPanel);
            app.mainGrid1 = uigridlayout(app.mainGrid);
            app.SublabelA1 = uilabel(app.mainGrid1);
            app.SublabelA1.Text = 'Endereço(s) IP';
            app.SubpanelA1 = uipanel(app.mainGrid1);
            app.SubgridA1 = uigridlayout(app.SubpanelA1);
            app.IP1Label = uilabel(app.SubgridA1);
            app.IP1Label.Text = 'Público / Intranet:';
            app.IP1 = uieditfield(app.SubgridA1, 'text');
            app.IP1.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.IP1.Editable = 'off';
            app.IP2Label = uilabel(app.SubgridA1);
            app.IP2Label.Text = 'OpenVPN:';
            app.IP2 = uieditfield(app.SubgridA1, 'text');
            app.IP2.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.IP2.Editable = 'off';
            app.SubpanelA2 = uipanel(app.mainGrid1);
            app.SubgridA2 = uigridlayout(app.SubpanelA2);
            app.LatitudeLabel = uilabel(app.SubgridA2);
            app.LatitudeLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.SubgridA2, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -1;
            app.LongitudeLabel = uilabel(app.SubgridA2);
            app.LongitudeLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.SubgridA2, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -1;
            app.StatusLabel = uilabel(app.mainGrid1);
            app.StatusLabel.Text = 'Estado:';
            app.Status = uidropdown(app.mainGrid1);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.Status.Value = 'ON';
            app.LocationGrid = uigridlayout(app.mainGrid1);
            app.SublabelA2 = uilabel(app.LocationGrid);
            app.SublabelA2.Text = 'Coordenadas geográficas';
            app.LocationWarn = uiimage(app.LocationGrid);
            app.LocationWarn.Visible = 'off';
            app.LocationWarn.ImageSource = 'LT_redWarn.png';
            app.LocationSearch = uiimage(app.LocationGrid);
            app.LocationSearch.ImageClickedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.LocationSearch.Enable = 'off';
            app.LocationSearch.ImageSource = 'LT_refresh.png';
            app.mainGrid2 = uigridlayout(app.mainGrid);
            app.Sublabel2A = uilabel(app.mainGrid2);
            app.Sublabel2A.Text = 'Web';
            app.Subpanel2A = uipanel(app.mainGrid2);
            app.Subgrid2A = uigridlayout(app.Subpanel2A);
            app.webLoginLabel = uilabel(app.Subgrid2A);
            app.webLoginLabel.Text = 'Usuário:';
            app.webLogin = uieditfield(app.Subgrid2A, 'text');
            app.webLogin.Editable = 'off';
            app.webLogin.Value = 'admin';
            app.webPassLabel = uilabel(app.Subgrid2A);
            app.webPassLabel.Text = 'Senha:';
            app.webPass = uihtml(app.Subgrid2A);
            app.webPass.DataChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.Sublabel2B = uilabel(app.mainGrid2);
            app.Sublabel2B.Text = 'FTP/SFTP';
            app.Subpanel2B = uipanel(app.mainGrid2);
            app.Subgrid2B = uigridlayout(app.Subpanel2B);
            app.ftpPortLabel = uilabel(app.Subgrid2B);
            app.ftpPortLabel.Text = 'Porta FTP:';
            app.ftpPort = uispinner(app.Subgrid2B);
            app.ftpPort.Limits = [0 65535];
            app.ftpPort.RoundFractionalValues = 'on';
            app.ftpPort.ValueDisplayFormat = '%.0f';
            app.ftpPort.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.ftpPort.Editable = 'off';
            app.ftpPort.Value = 21;
            app.sftpPortLabel = uilabel(app.Subgrid2B);
            app.sftpPortLabel.Text = 'Porta SFTP:';
            app.sftpPort = uispinner(app.Subgrid2B);
            app.sftpPort.Limits = [0 65535];
            app.sftpPort.RoundFractionalValues = 'on';
            app.sftpPort.ValueDisplayFormat = '%.0f';
            app.sftpPort.ValueChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.sftpPort.Editable = 'off';
            app.sftpPort.Value = 2828;
            app.rootLoginLabel = uilabel(app.Subgrid2B);
            app.rootLoginLabel.Text = 'Usuário:';
            app.rootLogin = uieditfield(app.Subgrid2B, 'text');
            app.rootLogin.Editable = 'off';
            app.rootLogin.Value = 'root';
            app.rootPassLabel = uilabel(app.Subgrid2B);
            app.rootPassLabel.Text = 'Senha:';
            app.rootPass = uihtml(app.Subgrid2B);
            app.rootPass.DataChangedFcn = createCallbackFcn(app, @ParameterEdited, true);
            app.mainGrid3 = uigridlayout(app.mainGrid);
            app.LOGTextLabel = uilabel(app.mainGrid3);
            app.LOGTextLabel.Text = 'LOG';
            app.LOGText = uitextarea(app.mainGrid3);
            app.LOGText.Editable = 'off';
            app.IPTypeLabel = uilabel(app.mainGrid3);
            app.IPTypeLabel.Text = 'Endereço IP prioritário:';
            app.IPType = uidropdown(app.mainGrid3);
            app.IPType.Items = {'Público / Intranet', 'OpenVPN'};
            app.IPType.Value = 'Público / Intranet';
            app.toolGrid = uigridlayout(app.Grid1);
            app.Button_info = uibutton(app.toolGrid, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Text = '';
            app.Separator1 = uiimage(app.toolGrid);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = 'LT_LineV.png';
            app.Button_open = uibutton(app.toolGrid, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Button_open.Icon = 'LT_OpenFile.png';
            app.Button_open.Tooltip = {'Abrir'};
            app.Button_open.Text = '';
            app.Button_export = uibutton(app.toolGrid, 'push');
            app.Button_export.ButtonPushedFcn = createCallbackFcn(app, @Button_exportPushed, true);
            app.Button_export.Icon = 'LT_save.png';
            app.Button_export.Tooltip = {'Exportar'};
            app.Button_export.Text = '';
            app.Separator2 = uiimage(app.toolGrid);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = 'LT_LineV.png';
            app.Button_edit = uibutton(app.toolGrid, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editValueChanged, true);
            app.Button_edit.Tooltip = {'Modo de edição'};
            app.Button_edit.Icon = 'LT_edit.png';
            app.Button_edit.Text = '';
            app.Button_connect = uibutton(app.toolGrid, 'push');
            app.Button_connect.ButtonPushedFcn = createCallbackFcn(app, @Button_connectPushed, true);
            app.Button_connect.Icon = 'LT_connectivity.png';
            app.Button_connect.Enable = 'off';
            app.Button_connect.Tooltip = {'Teste de conectividade'};
            app.Button_connect.Text = '';
            app.Button_play = uibutton(app.toolGrid, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'DT_edit.png';
            app.Button_play.Enable = 'off';
            app.Button_play.Text = 'Confirma edição';
            app.mainLabel = uilabel(app.Grid1);
            app.mainLabel.Text = 'MODO DE VISUALIZAÇÃO';
            app.instrPanel = uipanel(app.Grid1);
            app.instrGrid = uigridlayout(app.instrPanel);
            app.instrGrid.Tag = 'COLORLOCKED';
            app.instrImage = uiimage(app.instrGrid);
            app.instrImage.ImageSource = 'Instr_RFeye.png';
            app.instrTag = uilabel(app.instrGrid);
            app.instrTag.Text = 'RFEYE NODE 20-6';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #00355f; font-size: 24;">RFeye Node 20-6</b><font> INSTRUMENTOS</font>'; '<font>Edição de informações e teste de conectividade</font>'; ' '};
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_connectBlue.png';
            app.GridLayout = uigridlayout(app.Grid1);
            app.GridLayout.Tag = 'COLORLOCKED';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true false false true];
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @TableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.IDLabel = uilabel(app.GridLayout);
            app.IDLabel.Text = '  ID';
            app.NomeLabel = uilabel(app.GridLayout);
            app.NomeLabel.Text = '  Nome';
            app.IP1Label_2 = uilabel(app.GridLayout);
            app.IP1Label_2.Text = '  IP1';
            app.IP2Label_2 = uilabel(app.GridLayout);
            app.IP2Label_2.Text = '  IP2';
            app.LatitudeLabel_2 = uilabel(app.GridLayout);
            app.LatitudeLabel_2.Text = '  Latitude';
            app.LongitudeLabel_2 = uilabel(app.GridLayout);
            app.LongitudeLabel_2.Text = '  Longitude';
            app.LocalidadeLabel = uilabel(app.GridLayout);
            app.LocalidadeLabel.Text = '  Localidade';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winRFeyeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end