classdef winSCPIEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Grid1                     matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        Button_2                  matlab.ui.control.Button
        connect_Button1           matlab.ui.control.Button
        connect_Button4           matlab.ui.control.Button
        Image2                    matlab.ui.control.Image
        connect_Button3           matlab.ui.control.StateButton
        connect_Button2           matlab.ui.control.StateButton
        Button                    matlab.ui.control.Button
        Panel_4                   matlab.ui.container.Panel
        GridLayout7               matlab.ui.container.GridLayout
        Grid4                     matlab.ui.container.GridLayout
        Sublabel1                 matlab.ui.control.Label
        GridLayout10              matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
        connect_DescriptionLabel  matlab.ui.control.Label
        connect_Description       matlab.ui.control.TextArea
        LOG                       matlab.ui.control.TextArea
        LOGLabel                  matlab.ui.control.Label
        GridLayout9               matlab.ui.container.GridLayout
        Panel2                    matlab.ui.container.Panel
        Grid3                     matlab.ui.container.GridLayout
        connect_TimeoutLabel      matlab.ui.control.Label
        connect_Timeout           matlab.ui.control.NumericEditField
        connect_Panel             matlab.ui.container.Panel
        connect_Grid2             matlab.ui.container.GridLayout
        connect_publicIP          matlab.ui.control.EditField
        connect_publicIPLabel     matlab.ui.control.Label
        connect_localIPLabel      matlab.ui.control.Label
        connect_localIP           matlab.ui.control.EditField
        GridLayout8               matlab.ui.container.GridLayout
        connect_CheckBoxLabel     matlab.ui.control.Label
        connect_CheckBox          matlab.ui.control.CheckBox
        connect_PortLabel         matlab.ui.control.Label
        connect_Port              matlab.ui.control.EditField
        connect_IPLabel           matlab.ui.control.Label
        connect_IP                matlab.ui.control.EditField
        Sublabel1_2               matlab.ui.control.Label
        connect_NameLabel         matlab.ui.control.Label
        connect_Name              matlab.ui.control.DropDown
        connect_Type              matlab.ui.control.DropDown
        connect_TypeLabel         matlab.ui.control.Label
        connect_FamilyLabel       matlab.ui.control.Label
        connect_Family            matlab.ui.control.DropDown
        Label1                    matlab.ui.control.Label
        Grid2                     matlab.ui.container.GridLayout
        Column7                   matlab.ui.control.Label
        Column9_2                 matlab.ui.control.Label
        Column2_2                 matlab.ui.control.Label
        Column2                   matlab.ui.control.Label
        Column_1                  matlab.ui.control.Label
        Column3                   matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)
        
        function Fcn_SelectedRowValues(app)

            row = app.UITable.Selection;

            app.connect_Family.Items       = app.CallingApp.scpiList.Family{row};
            app.connect_Type.Items         = app.CallingApp.scpiList.Type{row};            
            app.connect_Name.Items         = app.CallingApp.scpiList.webPass{row};
            app.connect_Description.Value  = app.CallingApp.scpiList.Description{row};
            
            app.connect_IP.Value           = app.CallingApp.scpiList.IP{row};
            app.connect_Port.Value         = app.CallingApp.scpiList.Port{row};
            app.connect_Timeout.Value      = app.CallingApp.scpiList.Timeout(row);

            app.connect_CheckBox.Value     = app.CallingApp.scpiList.ConnectFlaf(row);

            if app.connect_CheckBox.Value
                app.connect_localIP.Value  = app.CallingApp.scpiList.Localhost_localIP{row};
                app.connect_publicIP.Value = app.CallingApp.scpiList.Localhost_publicIP{row};
            else
                app.connect_localIP.Value  = '';
                app.connect_publicIP.Value = '';
            end

            app.LOG.Value                  = '';           
            
        end

        
        function result = Fcn_IP(app)

            IP1Status = Fcn_IPValidation(app, app.IP1.Value);
            IP2Status = Fcn_IPValidation(app, app.IP2.Value);

            msg = '';
            if (~IP1Status && ~IP2Status) || (IP1Status + IP2Status == -2)
                msg = 'Endereços IP inválidos.';
            elseif ~IP1Status
                msg = 'Endereço IP do tipo "Público / Intranet" inválido.';
            elseif ~IP2Status
                msg = 'Endereço IP do tipo "Open VPN" inválido.';
            end

            if ~isempty(msg)
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', 'error')
            end

            if ~IP1Status || ~IP2Status || (IP1Status + IP2Status == -2)
                result = 0;
            else
                result = 1;
            end

        end
        

        function result = Fcn_IPValidation(app, IP)

            if isempty(IP)
                result = -1;

            else
                IP = strsplit(IP, '.');
                IP = cellfun(@(x) str2double(x), IP);
        
                if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                    result = 0;
                else
                    result = 1;
                end
            end

        end


        function connect_infoPanel(app)

            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                SelectedNode = app.connect_Tree.SelectedNodes;

                if ~isempty(SelectedNode)
                    if SelectedNode.Parent == app.connect_Tree
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Text), 1);
                    else
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Parent.Text), 1);
                    end
                else
                    indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
                end
            else
                indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            end

            app.connect_instrName.Text         = {app.instrInfo.Tag{indFind}; app.instrInfo.Band{indFind}};
            app.connect_instrImage.ImageSource = app.instrInfo.Image{indFind};
            app.connect_instrDescription.Text  = app.instrInfo.Description{indFind};

        end


        function connect_Layout(app)
            
            % Visibilidade relacionada ao modo de apresentação do painel -
            % "Adicionar instrumento", "Editar instrumento" e "Visualizar".
            if app.connect_Button2.Value || app.connect_Button3.Value

                app.connect_Grid0.RowHeight = {22, 254, 1, 24, 1, '1x', 1, 24};
                set(app.connect_Tree, 'Enable', 'off')

                if app.connect_Button2.Value
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Incluir', 'Icon', 'AddWhite3.png')

                    set(app.connect_Family, 'BackgroundColor', 'white')
                    set(app.connect_Name,   'BackgroundColor', 'white')
                else
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Editar', 'Icon', 'White_Edit.png')

                    set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                    set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                end

                set(app.connect_Type,        'BackgroundColor', 'white')
                set(app.connect_IP,          'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Port,        'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Timeout,     'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Buffer,      'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Description, 'BackgroundColor', 'white', 'Editable', 'on')

                set(app.connect_CheckBox, 'Enable', 1)
                set(app.connect_localIP,  'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_publicIP, 'BackgroundColor', 'white', 'Editable', 'on')
                
                app.connect_Button4.Enable = 0;

            else
                app.connect_Grid0.RowHeight = {22, 254, 0, 0, 1, '1x', 1, 24};
                set(app.connect_Tree,    'Enable', 1);
                set(app.connect_Button1, 'Enable', 0)

                set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                set(app.connect_Type,   'BackgroundColor', [.94,.94,.94])

                set(app.connect_IP,          'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Port,        'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Timeout,     'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Buffer,      'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Description, 'BackgroundColor', [.80,.80,.80], 'Editable', 'off')

                set(app.connect_CheckBox, 'Enable', 0, 'Value', 0)
                set(app.connect_localIP,  'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                set(app.connect_publicIP, 'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                
                ind = app.connect_Tree.SelectedNodes.NodeData;
                if numel(ind) == 1
                    app.connect_Button3.Enable = 1;
                    app.connect_Button4.Enable = 1;
                    
                    connect_PanelValues(app, ind)
                end
            end
            
            % Visibilidade relacionada ao tipo da conexão (IP, Porta).
            set(app.connect_Grid1.Children, 'Visible', 1)
            switch app.connect_Type.Value
                case 'Serial'
                    app.connect_IPLabel.Visible = 0;
                    set(app.connect_IP, 'Visible', 0, 'Value', '')
                
                    app.connect_PortLabel.Layout.Column = 1;
                    app.connect_Port.Layout.Column      = 1;
                    
                    app.connect_CheckBox.Enable = 0;
                    
                case 'Visa TCPIP'
                    app.connect_PortLabel.Visible = 0;
                    app.connect_Port.Visible      = 0;
                    
                otherwise
                    app.connect_PortLabel.Layout.Column = 2;
                    app.connect_Port.Layout.Column      = 2;
            end

        end
        
        
        function connect_PanelValues(app, ind)
            
            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                app.connect_Family.Items  = app.scpiList.Family(ind);
                app.connect_Name.Items    = app.scpiList.Name(ind);
            end
            
            app.connect_Type.Items        = app.scpiList.Type(ind);
            app.connect_IP.Value          = app.scpiList.IP{ind};
            app.connect_Port.Value        = app.scpiList.Port{ind};
            
            set(app.connect_Timeout, 'Value', app.scpiList.Timeout(ind),  'FontColor', 'black')
            set(app.connect_Buffer,  'Value', app.scpiList.inBuffer(ind), 'FontColor', 'black')
            
            app.connect_Description.Value = app.scpiList.Description{ind};
            
            app.connect_CheckBox.Value    = app.scpiList.Localhost_Flag(ind);
            app.connect_localIP.Value     = app.scpiList.Localhost_localIP{ind};
            app.connect_publicIP.Value    = app.scpiList.Localhost_publicIP{ind};

            if app.connect_CheckBox.Value; set(app.connect_Grid2.Children, 'Enable', 'on')
            else;                          set(app.connect_Grid2.Children, 'Enable', 'off')
            end
                    
        end


        function connect_infoValidation(app, Type, IP)

            switch Type
                case 'Only Instrument IP'
                    IP = strsplit(IP, '.');
                    IP = cellfun(@(x) str2double(x), IP);

                    if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                        error('Endereço IP inválido.')
                    end

                otherwise
                    if contains(app.connect_Type.Value, 'IP')
                        if ~app.connect_CheckBox.Value
                            IPValues = {app.connect_IP.Value};
                        else
                            IPValues = {app.connect_IP.Value;      ...
                                app.connect_localIP.Value; ...
                                app.connect_publicIP.Value};
                        end

                        for ii = 1:numel(IPValues)
                            IP = strsplit(IPValues{ii}, '.');

                            if (ii == 3) && isempty(IPValues{ii})
                                continue
                            end

                            IP = cellfun(@(x) str2double(x), IP);

                            if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                                error('Endereço IP inválido.')
                            end
                        end

                        if ~isempty(app.connect_Port.Value)
                            Port = str2double(app.connect_Port.Value);

                            if isnan(Port) || Port < 0 || Port >= 65536
                                error('Número da porta inválido.')
                            end
                        end
                    end
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            set(app.UITable, 'Data', app.CallingApp.scpiList(:, [2, 4:6, 8, 12]), ...
                             'Selection', 1)

            Fcn_SelectedRowValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            Fcn_SelectedRowValues(app)
            
        end

        % Callback function
        function Button1Pushed(app, event)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.RootFolder)
            end
            
            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ExceptionGlobalList.cfg".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b> - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button2Pushed(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            app.CallingApp.exceptionList(idx,:) = [];
            app.row = 1;

            Fcn1_TableStyle(app)
            app.UITable.Selection = [1, 1];
            Fcn_SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Callback function
        function Button3_Clicked(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            if ~isempty(idx)
                idx = idx(1);
            end

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value) || ...
               app.CallingApp.peaksTable.Station(app.row) ~= app.Station_edited.Value               || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                     || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)         || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel.Value)           || ...
               ~isempty(deblank(strjoin(app.Info.Value)))

                stationInfo = '';
                try
                    stationInfo = jsondecode(app.Info.Value{1});
                catch
                end
    
                if isstruct(stationInfo)
                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      app.Type.Value,                               ...
                                      app.RegInfo_edited.Value,                     ...
                                      str2double(stationInfo.Service),              ...
                                      str2double(stationInfo.Station),              ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      app.Compliance.Value,                         ...
                                      app.RiskLevel.Value};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.CallingApp.peaksTable.Service(app.row),      ...
                                                  app.CallingApp.peaksTable.Station(app.row),      ...
                                                  app.CallingApp.peaksTable.Description{app.row},  ...
                                                  app.CallingApp.peaksTable.Distance{app.row},     ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            else
                                if app.Station_edited.Value == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.Service_edited.Value,                        ...
                                                  app.Station_edited.Value,                        ...
                                                  app.Info.Value,                                  ...
                                                  Distance,                                        ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            end

                        otherwise
                            if isempty(deblank(strjoin(app.Info.Value)))
                                Misc_uialertCreation(app, 'warning', 'Campo "Informações adicionais" não pode ficar vazio.')
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                              app.CallingApp.peaksTable.Frequency(app.row),    ...
                                              app.Type.Value,                                  ...
                                              app.RegInfo_edited.Value,                        ...
                                              -1, -1, ['[EXC] ' strjoin(app.Info.Value)], '-', ...
                                              app.Compliance.Value,                            ...
                                              app.RiskLevel.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                Fcn1_TableStyle(app)            
                Fcn_SelectedRowValues(app)

            else
                Button2Pushed(app)
            end
            
            app.CallingApp.auxWin_SignalAnalysis
            
        end

        % Callback function
        function Station_editedValueChanged(app, event)
            
            latNode  = app.CallingApp.peaksTable.Latitude(app.row);
            longNode = app.CallingApp.peaksTable.Longitude(app.row);
            
            try
                StationInfo = anateldb_Query(app.Station_edited.Value, latNode, longNode, app.RootFolder);
                
                msg = sprintf(['<font style="font-size:12;">Indicação automática:\n• Frequência: %.3f MHz\n• Descrição: %s\n• Distância: %s\n\n'                      ...
                               'Indicação manual:    \n• Frequência(s): %s MHz\n• Descrição: %s\n• Distância: %.1f\n\n Confirma edição?</font>'], ...
                               app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row},              ...
                               app.CallingApp.peaksTable.Distance{app.row}, StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
    
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('Service', num2str(StationInfo.Service), ...
                                                             'Station', num2str(StationInfo.Station), ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0, 'BackgroundColor', [.80,.80,.80])

                else
                    app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Callback function
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.Station_edited, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.Info,    'Editable', 0, 'BackgroundColor', [.80,.80,.80], 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Station_edited, 'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', -1)
                    set(app.Info,    'Editable', 1, 'BackgroundColor', [1,1,1])
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Callback function
        function xLimFactor_DownClicked(app, event)
            
            if app.xLimFactor > .5
                app.xLimFactor = app.xLimFactor - .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function xLimFactor_UpClicked(app, event)
            
            if app.xLimFactor < 10
                app.xLimFactor = app.xLimFactor + .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winRFeyeEdit>\n'), sprintf('\n</winRFeyeEdit>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button_2Pushed(app, event)
            
            result = Fcn_IP(app);
            if result
                d = uiprogressdlg(app.UIFigure, "Message", "Em andamento...", "Indeterminate", "on");
    
                app.Status = Misc_RFeyeDatabase(app.RootFolder,                         ...
                                                app.IP1.Value, app.IP2.Value,           ...
                                                app.webLogin.Value, app.webPass.Data,   ...
                                                app.rootLogin.Value, app.rootPass.Data, ...
                                                app.ftpPort.Value, app.sftpPort.Value);
    
                if app.Status.ConnectFlag
                    app.Lamp.Color = [0,1,0];
                    app.Button_2.Enable = 1;

                    if isempty(app.Status.ID)
                        msg  = 'Não identificado o sensor sob análise.';
                        icon = 'warning';
                    else
                        if strcmp(app.Status.ID, app.Node.Value)
                            msg  = sprintf('Confirmado se tratar do sensor %s.', app.Status.ID); 
                            icon = 'success';
                        else
                            msg  = sprintf('Confirmado se tratar do sensor %s, diferente do selecionado (%s).', app.Status.ID, app.Node.Value);
                            icon = 'error'; 
                            app.Button_2.Enable = 0;
                        end
                    end
                    msg = sprintf('%s\n\n%s', msg, jsonencode(rmfield(app.Status, {'IP1', 'IP2', 'webPass', 'rootPass', 'ftpPort', 'sftpPort', 'ID', 'ConnectFlag', 'LogDate'})));
                else
                    msg = 'Sensor não respondeu às tentativas de conexão.';
                    icon = 'error'; 
                    
                    app.Lamp.Color = [1,0,0];
                    app.Button_2.Enable = 0;
                end
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', icon)

            end
            
        end

        % Callback function
        function Button_3Pushed(app, event)
            
            ind = find(strcmp(app.CallingApp.RFeyeList.ID, app.Node.Value), 1);

            try
                app.CallingApp.RFeyeList(ind, 2:21) = {app.Status.IP1, ...
                                                       app.Status.IP2, ...
                                                       app.Status.webPass, ...
                                                       app.Status.rootPass, ...
                                                       app.Status.ftpPort, ...
                                                       app.Status.sftpPort, ...
                                                       app.Status.sftpHostname, ...
                                                       app.Status.LoggerNodeInfo, ...
                                                       app.Status.Latitude, ...
                                                       app.Status.Longitude, ...
                                                       app.Status.City, ...
                                                       app.Status.State, ...
                                                       app.Status.webNodeStatus, ...
                                                       app.Status.webCoordinatesStatus, ...
                                                       app.Status.ftpStatus, ...
                                                       app.Status.sftpStatus, ...
                                                       app.Status.ftpStatus_OpenVPN, ...
                                                       app.Status.sftpStatus_OpenVPN, ...
                                                       app.Status.LogDate, ...
                                                       app.Status.ConnectFlag};
    
                app.CallingApp.logger_NodeCreation
                closeFcn(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end

        end

        % Value changed function: connect_Family
        function connect_FamilyValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Family, app.connect_Family.Value));

            app.connect_Name.Items = unique(app.instrInfo(ind,:).Name);
            connect_NameValueChanged(app)

        end

        % Value changed function: connect_Name
        function connect_NameValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            
            app.connect_Type.Items = app.instrInfo.connectType{ind};
            connect_TypeValueChanged(app)

        end

        % Value changed function: connect_Type
        function connect_TypeValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);

            app.connect_Port.Value = app.instrInfo.connectPort{ind};
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Value changed function: connect_CheckBox
        function connect_CheckBoxValueChanged(app, event)
            
            if app.connect_CheckBox.Value
                try
                    connect_infoValidation(app, 'Only Instrument IP', app.connect_IP.Value)
                    set(app.connect_Grid2.Children, 'Enable', 'on')

                catch ME
                    app.connect_CheckBox.Value = 0;
                    Misc_uialertCreation(app, 'error', getReport(ME))

                    return
                end

            else
                set(app.connect_Grid2.Children, 'Enable', 'off')
                app.connect_localIP.Value  = '';
                app.connect_publicIP.Value = '';
            end

        end

        % Button pushed function: connect_Button1
        function connect_Button1Pushed(app, event)
            
            try
                if strcmp(app.connect_Name.Value, 'CRFS RFeye')
                    error(['Implementada apenas a leitura do fluxo UDP gerado pelo Logger, o que é acessível no modo "RFeye", ' ...
                        'restando pendente a comunicação com o RFeye por meio do protocolo NCP.'])
                end

                connect_infoValidation(app, 'All', '')

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end

            if app.connect_Button3.Value; ind = app.connect_Tree.SelectedNodes.NodeData;
            else;                         ind = height(app.scpiList)+1;
            end

            app.scpiList(ind,:) = {app.connect_Family.Value, ...
                                    app.connect_Name.Value,        ...
                                    app.connect_instrName.Text{1}, ...
                                    app.connect_Type.Value,        ...
                                    app.connect_IP.Value,          ...
                                    app.connect_Port.Value,        ...
                                    app.connect_Timeout.Value,     ...
                                    app.connect_Buffer.Value,      ...
                                    app.connect_Description.Value, ...
                                    app.connect_CheckBox.Value,    ...
                                    app.connect_localIP.Value,     ...
                                    app.connect_publicIP.Value, 0};

            app.connect_Button2.Value  = 0;
            app.connect_Button3.Value  = 0;

            connect_TreeBuilding(app)

        end

        % Value changed function: connect_Button2
        function connect_Button2ValueChanged(app, event)
            
            if app.connect_Button2.Value
                app.connect_Button3.Enable = 0;

                app.connect_Family.Items      = unique(app.instrInfo.Family);
                app.connect_Family.Value      = 'Receptor';
                app.connect_Description.Value = '';

                connect_FamilyValueChanged(app)
            else
                if isempty(app.connect_Tree.Children)
                    app.connect_Button2.Value = 1;
                    return
                end
                
                connect_Layout(app)
                connect_infoPanel(app)
            end

        end

        % Value changed function: connect_Button3
        function connect_Button3ValueChanged(app, event)
            
            if app.connect_Button3.Value
                app.connect_Button2.Enable = 0;
                
                ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);                
                app.connect_Type.Items = app.instrInfo.connectType{ind};
            else
                app.connect_Button2.Enable = 1;
            end
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Button pushed function: connect_Button4
        function connect_Button4Pushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Tentando se conectar ao instrumento...');

            ind = app.connect_Tree.SelectedNodes.NodeData;
            if strcmp(app.scpiList.Family{ind}, 'Receptor')
                try
                    [app.Instrument, Node] = Connection_Instrument(app.scpiList(ind,:), app.scpiList.Timeout(ind), app.instrInfo, app.udpPortArray, 'JustConnectivity', [], []);

                    if ~isempty(Node)
                        msg = sprintf('%s\n%s', app.scpiList.Name{ind}, Node);

                        if contains(Node, app.connect_instrName.Text{1})
                            app.scpiList.Flag(ind) = 1;
                        end
                        Misc_uialertCreation(app, 'info', msg)

                    else
                        error('Apesar de criado objeto, não foi recebida resposta ao comando "*IDN?".')
                    end

                catch ME
                    app.scpiList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end

            else
                try
                    [~, gps] = Connection_gps(app.scpiList.Type{ind},    ...
                                              app.scpiList.IP{ind},      ...
                                              app.scpiList.Port{ind},    ...
                                              app.scpiList.Timeout(ind), ...
                                              app.scpiList.inBuffer(ind));

                    if ~isempty(gps)
                        app.scpiList.Flag(ind) = 1;

                        msg = sprintf('Status: %.0f\nLatitude: %.6f\nLongitude: %.6f\nHorário: %s (UTC)', gps.Status, gps.Latitude, gps.Longitude, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                        Misc_uialertCreation(app, 'info', msg)
                        
                        if gps.Status
                            app.Task_manualLatitude.Value  = round(gps.Latitude,  6);
                            app.Task_manualLongitude.Value = round(gps.Longitude, 6);
                        end
                    else
                        error('Apesar de criado objeto, não foi recebida informação NMEA/RMC.')
                    end

                catch ME
                    app.scpiList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end
            connect_TreeBuilding(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Receptores RFeye Node 20-6';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid2 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.Grid2);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Column3 = uilabel(app.Grid2);
            app.Column3.Text = {'  Port'; ' '; ''};
            app.Column_1 = uilabel(app.Grid2);
            app.Column_1.Text = {'  Nome'; '  '};
            app.Column2 = uilabel(app.Grid2);
            app.Column2.Text = {'  Tipo'; '  '};
            app.Column2_2 = uilabel(app.Grid2);
            app.Column2_2.Text = {'  IP'; ' '};
            app.Column9_2 = uilabel(app.Grid2);
            app.Column9_2.Text = {'  Conectividade'; ' '};
            app.Column7 = uilabel(app.Grid2);
            app.Column7.Text = {'  Descrição'; '  '};
            app.Panel_4 = uipanel(app.Grid1);
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.Grid4 = uigridlayout(app.GridLayout7);
            app.Label1 = uilabel(app.Grid4);
            app.Label1.Text = 'ASPECTOS GERAIS';
            app.GridLayout9 = uigridlayout(app.Grid4);
            app.connect_Family = uidropdown(app.GridLayout9);
            app.connect_Family.Items = {};
            app.connect_Family.ValueChangedFcn = createCallbackFcn(app, @connect_FamilyValueChanged, true);
            app.connect_Family.Value = {};
            app.connect_FamilyLabel = uilabel(app.GridLayout9);
            app.connect_FamilyLabel.Text = 'Família:';
            app.connect_TypeLabel = uilabel(app.GridLayout9);
            app.connect_TypeLabel.Text = 'Tipo de conexão:';
            app.connect_Type = uidropdown(app.GridLayout9);
            app.connect_Type.Items = {};
            app.connect_Type.ValueChangedFcn = createCallbackFcn(app, @connect_TypeValueChanged, true);
            app.connect_Type.Value = {};
            app.connect_Name = uidropdown(app.GridLayout9);
            app.connect_Name.Items = {};
            app.connect_Name.ValueChangedFcn = createCallbackFcn(app, @connect_NameValueChanged, true);
            app.connect_Name.Value = {};
            app.connect_NameLabel = uilabel(app.GridLayout9);
            app.connect_NameLabel.Text = 'Fabricante e modelo';
            app.Sublabel1_2 = uilabel(app.GridLayout9);
            app.Sublabel1_2.Text = 'Aspectos de rede:';
            app.Panel2 = uipanel(app.GridLayout9);
            app.Grid3 = uigridlayout(app.Panel2);
            app.connect_IP = uieditfield(app.Grid3, 'text');
            app.connect_IP.Editable = 'off';
            app.connect_IPLabel = uilabel(app.Grid3);
            app.connect_IPLabel.Text = 'Endereço IP:';
            app.connect_Port = uieditfield(app.Grid3, 'text');
            app.connect_Port.Editable = 'off';
            app.connect_PortLabel = uilabel(app.Grid3);
            app.connect_PortLabel.Text = 'Porta:';
            app.GridLayout8 = uigridlayout(app.Grid3);
            app.connect_CheckBox = uicheckbox(app.GridLayout8);
            app.connect_CheckBox.ValueChangedFcn = createCallbackFcn(app, @connect_CheckBoxValueChanged, true);
            app.connect_CheckBox.Enable = 'off';
            app.connect_CheckBox.Text = '';
            app.connect_CheckBoxLabel = uilabel(app.GridLayout8);
            app.connect_CheckBoxLabel.Enable = 'off';
            app.connect_CheckBoxLabel.Text = 'Localhost';
            app.connect_Panel = uipanel(app.Grid3);
            app.connect_Grid2 = uigridlayout(app.connect_Panel);
            app.connect_localIP = uieditfield(app.connect_Grid2, 'text');
            app.connect_localIP.Editable = 'off';
            app.connect_localIP.Enable = 'off';
            app.connect_localIPLabel = uilabel(app.connect_Grid2);
            app.connect_localIPLabel.Enable = 'off';
            app.connect_localIPLabel.Text = 'IP local:';
            app.connect_publicIPLabel = uilabel(app.connect_Grid2);
            app.connect_publicIPLabel.Enable = 'off';
            app.connect_publicIPLabel.Text = 'IP público:';
            app.connect_publicIP = uieditfield(app.connect_Grid2, 'text');
            app.connect_publicIP.Editable = 'off';
            app.connect_publicIP.Enable = 'off';
            app.connect_Timeout = uieditfield(app.Grid3, 'numeric');
            app.connect_Timeout.Limits = [1 10];
            app.connect_Timeout.Editable = 'off';
            app.connect_Timeout.Value = 5;
            app.connect_TimeoutLabel = uilabel(app.Grid3);
            app.connect_TimeoutLabel.Text = 'Timeout (seg):';
            app.GridLayout10 = uigridlayout(app.Grid4);
            app.LOGLabel = uilabel(app.GridLayout10);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uitextarea(app.GridLayout10);
            app.LOG.Editable = 'off';
            app.connect_Description = uitextarea(app.GridLayout10);
            app.connect_Description.Editable = 'off';
            app.connect_DescriptionLabel = uilabel(app.GridLayout10);
            app.connect_DescriptionLabel.Text = 'Descrição (opcional):';
            app.Image = uiimage(app.GridLayout10);
            app.Image.ImageSource = 'Instr_R&S_EB500.png';
            app.Sublabel1 = uilabel(app.Grid4);
            app.Sublabel1.Text = 'O teste de conectividade consiste na tentativa de criação de objeto e, no caso de analisadores de espectro e receptores, na identificação do nome do sensor.';
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            app.connect_Button2 = uibutton(app.GridLayout5, 'state');
            app.connect_Button2.ValueChangedFcn = createCallbackFcn(app, @connect_Button2ValueChanged, true);
            app.connect_Button2.Tooltip = {'Adicionar registro'};
            app.connect_Button2.Icon = 'Edit2.png';
            app.connect_Button2.Text = '';
            app.connect_Button3 = uibutton(app.GridLayout5, 'state');
            app.connect_Button3.ValueChangedFcn = createCallbackFcn(app, @connect_Button3ValueChanged, true);
            app.connect_Button3.Enable = 'off';
            app.connect_Button3.Tooltip = {'Editar registro'};
            app.connect_Button3.Icon = 'edit.png';
            app.connect_Button3.Text = '';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'Line.png';
            app.connect_Button4 = uibutton(app.GridLayout5, 'push');
            app.connect_Button4.ButtonPushedFcn = createCallbackFcn(app, @connect_Button4Pushed, true);
            app.connect_Button4.Icon = 'ConnectWhite.png';
            app.connect_Button4.Enable = 'off';
            app.connect_Button4.Text = 'Conectividade';
            app.connect_Button1 = uibutton(app.GridLayout5, 'push');
            app.connect_Button1.ButtonPushedFcn = createCallbackFcn(app, @connect_Button1Pushed, true);
            app.connect_Button1.Icon = 'General_editWhite.png';
            app.connect_Button1.Enable = 'off';
            app.connect_Button1.Text = 'Editar';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.Icon = 'toolbar_redX.png';
            app.Button_2.Tooltip = {'Excluir registro'};
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCPIEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end