classdef winScpiEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Grid1             matlab.ui.container.GridLayout
        GridLayout10      matlab.ui.container.GridLayout
        Image2            matlab.ui.control.Image
        GridLayout9       matlab.ui.container.GridLayout
        DescrioLabel      matlab.ui.control.Label
        ParmetrosLabel    matlab.ui.control.Label
        TipoLabel         matlab.ui.control.Label
        NomeLabel         matlab.ui.control.Label
        IDLabel           matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        toolGrid          matlab.ui.container.GridLayout
        Button_play       matlab.ui.control.Button
        Button_delete     matlab.ui.control.Button
        Button_add        matlab.ui.control.Button
        Button_connect    matlab.ui.control.Button
        Button_edit       matlab.ui.control.StateButton
        Separator2        matlab.ui.control.Image
        Button_export     matlab.ui.control.Button
        Button_open       matlab.ui.control.Button
        Separator1        matlab.ui.control.Image
        Button_info       matlab.ui.control.Button
        mainPanel         matlab.ui.container.Panel
        mainGrid          matlab.ui.container.GridLayout
        mainGrid_C        matlab.ui.container.GridLayout
        LOG               matlab.ui.control.TextArea
        LOGLabel          matlab.ui.control.Label
        mainGrid_B        matlab.ui.container.GridLayout
        Panel2            matlab.ui.container.Panel
        Grid3             matlab.ui.container.GridLayout
        connect_Panel     matlab.ui.container.Panel
        connect_Grid2     matlab.ui.container.GridLayout
        publicIP          matlab.ui.control.EditField
        publicIPLabel     matlab.ui.control.Label
        localIP           matlab.ui.control.EditField
        localIPLabel      matlab.ui.control.Label
        GridLayout8       matlab.ui.container.GridLayout
        CheckBox          matlab.ui.control.CheckBox
        CheckBoxLabel     matlab.ui.control.Label
        Timeout           matlab.ui.control.NumericEditField
        TimeoutLabel      matlab.ui.control.Label
        Port              matlab.ui.control.EditField
        PortLabel         matlab.ui.control.Label
        IP                matlab.ui.control.EditField
        IPLabel           matlab.ui.control.Label
        Type              matlab.ui.control.DropDown
        TypeLabel         matlab.ui.control.Label
        mainGrid_A        matlab.ui.container.GridLayout
        Description       matlab.ui.control.TextArea
        DescriptionLabel  matlab.ui.control.Label
        Name              matlab.ui.control.DropDown
        NameLabel         matlab.ui.control.Label
        Family            matlab.ui.control.DropDown
        FamilyLabel       matlab.ui.control.Label
        Status            matlab.ui.control.DropDown
        StatusLabel       matlab.ui.control.Label
        mainLabel         matlab.ui.control.Label
        instrPanel        matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        instrTag          matlab.ui.control.Label
        instrImage        matlab.ui.control.Image
        Title             matlab.ui.control.Label
        Image             matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder

        instrInfo
        scpiList
        editedList

        editedFlag = false
        
    end
    
    methods (Access = private)

        function tempList = Fcn1_OpenFile(app, FilePath)

            [tempList, msg] = OpenFile_scpiList(FilePath, app.RootFolder);

            if ~isempty(msg)
                MessageBox(app, 'warning', msg)
            end

        end


        function Fcn1_saveNewFile(app, Folder)

            fileList = rmfield(table2struct(app.scpiList), 'idx');
            for ii = 1:numel(fileList)
                fileList(ii).Parameters = jsondecode(fileList(ii).Parameters);
            end
            
            try
                fileID = fopen(fullfile(Folder, 'scpiList.json'), 'wt');
                fwrite(fileID, jsonencode(fileList, 'PrettyPrint', true));
                fclose(fileID);

                MessageBox(app, 'info', sprintf('Salvo o arquivo <b>scpiList.json</b> na pasta "%s"', Folder))
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

        end


        function Fcn1_TableCreation(app, SelectedRow)

            set(app.UITable, 'Data', app.editedList(:, [1, 3:6]), 'Selection', SelectedRow)            

            if ~isempty(app.UITable.StyleConfigurations)
                removeStyle(app.UITable)
            end

            DisableRows = find(~app.editedList.Enable);

            if ~isempty(DisableRows)
                s = uistyle('FontColor', [.75,.75,.75]);
                addStyle(app.UITable, s, 'row', DisableRows)
            end

            TableSelectionChanged(app)

        end


        function Fcn2_FamilyChanged(app)

            idx = find(strcmp(app.instrInfo.Family, app.Family.Value));
            app.Name.Items = unique(app.instrInfo.Name(idx));

            Fcn3_NameChanged(app)

        end


        function Fcn3_NameChanged(app)

            idx = find(strcmp(app.instrInfo.Name, app.Name.Value), 1);            
            app.Type.Items = app.instrInfo.connectType{idx};

            Fcn4_TypeValueChanged(app)

        end


        function Fcn3_NameChanged_img(app)
            
            idx1 = app.UITable.Selection;
            idx2 = find(strcmp(app.instrInfo.Name, app.editedList.Name{idx1}), 1);

            app.instrTag.Text          = app.instrInfo.Tag{idx2};
            app.instrImage.ImageSource = app.instrInfo.Image{idx2};

        end


        function Fcn4_TypeValueChanged(app)

            switch app.Type.Value
                case 'Serial'
                    portValidation = contains(app.Port.Value, 'COM');
                    if ~portValidation
                        portValidation = [];
                    end

                otherwise
                    portValidation = [];

                    if (app.Family.Value == "GPS") && ~isnan(str2double(app.Port.Value))
                        portValidation = true;
                    end
            end
            
            if isempty(portValidation)
                idx = find(strcmp(app.instrInfo.Name, app.Name.Value), 1);
                app.Port.Value = app.instrInfo.connectPort{idx};
            end

            switch app.Type.Value
                case 'Serial'
                    set(app.IP,   'Enable', 0, 'Value', '')
                    set(app.Port, 'Enable', 1)

                case 'TCPIP Socket'
                    set(app.IP,   'Enable', 1)
                    set(app.Port, 'Enable', 1)
                
                case 'TCP/UDP IP Socket'
                    set(app.IP,   'Enable', 1)
                    set(app.Port, 'Enable', 1)

                case 'TCPIP Visa'
                    set(app.IP,   'Enable', 1)
                    set(app.Port, 'Enable', 0, 'Value', '')
            end

            Fcn5_LocalhostChanged1(app)

        end


        function Fcn5_LocalhostChanged1(app)

            if app.Type.Value == "TCP/UDP IP Socket"
                app.CheckBox.Enable = 1;
            else
                app.CheckBox.Enable = 0;
            end

        end


        function Fcn5_LocalhostChanged2(app)

            if app.CheckBox.Value
                set(app.connect_Grid2.Children, 'Enable', 1)
            else
                set(app.localIP,  'Enable', 0, 'Value', '')
                set(app.publicIP, 'Enable', 0, 'Value', '')
            end

        end


        function Fcn6_Parameters(app)

            idx = app.UITable.Selection;

            switch app.Type.Value
                case 'Serial'
                    app.editedList.Parameters{idx} = jsonencode(struct('Port',    app.Port.Value, ...
                                                                       'Timeout', app.Timeout.Value));

                case 'TCPIP Socket'
                    app.editedList.Parameters{idx} = jsonencode(struct('IP',      app.IP.Value,   ...
                                                                       'Port',    app.Port.Value, ...
                                                                       'Timeout', app.Timeout.Value));
                
                case 'TCP/UDP IP Socket'
                    if app.CheckBox.Value; Localhost_Enable = 1;
                    else;                  Localhost_Enable = 0;
                    end

                    app.editedList.Parameters{idx} = jsonencode(struct('IP',      app.IP.Value,   ...
                                                                       'Port',    app.Port.Value, ...
                                                                       'Timeout', app.Timeout.Value, ...
                                                                       'Localhost_Enable',   Localhost_Enable,   ...
                                                                       'Localhost_localIP',  app.localIP.Value,  ...
                                                                       'Localhost_publicIP', app.publicIP.Value));

                case 'TCPIP Visa'
                    app.editedList.Parameters{idx} = jsonencode(struct('IP',      app.IP.Value,   ...
                                                                       'Timeout', app.Timeout.Value));
            end

        end


        function Flag = Fcn7_IPv4Validation(app, event)
            
            switch event.Source
                case app.IP;       ipAddress = app.IP.Value;
                case app.localIP;  ipAddress = app.localIP.Value;
                case app.publicIP; ipAddress = app.publicIP.Value;
            end

            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');

            Flag = 0;
            if isempty(ipString)
                Flag = 1;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    Flag = 1;
                end                    
            end

            if Flag
                idx1 = app.UITable.Selection;

                msg = sprintf('É necessário corrigir o seguinte campo:\n• ID%d - Endereço IP inválido', idx1);
                MessageBox(app, 'error', msg)

                switch event.Source
                    case app.IP;       app.IP.Value       = event.PreviousValue;
                    case app.localIP;  app.localIP.Value  = event.PreviousValue;
                    case app.publicIP; app.publicIP.Value = event.PreviousValue;
                end
            else
                if ~strcmp(ipAddress, char(ipString))
                    switch event.Source
                        case app.IP;       app.IP.Value       = char(ipString);
                        case app.localIP;  app.localIP.Value  = char(ipString);
                        case app.publicIP; app.publicIP.Value = char(ipString);
                    end
                end
            end

        end


        function Flag = Fcn8_PortValidation(app, event)

            Flag = 0;

            switch app.Type.Value
                case 'Serial'
                    portValidation = regexpi(app.Port.Value, 'COM\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    end

                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    portValidation = regexp(app.Port.Value, '\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    else
                        if str2double(portValidation) > 65535
                            Flag = 1;
                        end
                    end
            end

            if Flag
                idx1 = app.UITable.Selection;

                msg = sprintf('É necessário corrigir o seguinte campo:\n• ID%d - Porta inválida', idx1);
                MessageBox(app, 'error', msg)

                app.Port.Value = event.PreviousValue;
            else
                if ~strcmp(app.Port.Value, upper(char(portValidation)))
                    app.Port.Value = upper(char(portValidation));
                end
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            app.instrInfo  = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'instrInfo.json'))));

            app.scpiList   = Fcn1_OpenFile(app, fullfile(app.RootFolder, 'Settings', 'scpiList.json'));
            app.editedList = app.scpiList;

            Fcn1_TableCreation(app, 1)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.editedFlag
                Fcn1_saveNewFile(app, fullfile(app.RootFolder, 'Settings'))

                app.CallingApp.taskList = [];
                if app.CallingApp.menu_Button2.Value
                    app.CallingApp.Misc_winAux('winScpiEdit');
                end
            end

            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end

        % Cell selection callback: UITable
        function TableSelectionChanged(app, event)
            
            if isempty(app.UITable.Selection)
                app.UITable.Selection = 1;
            end

            idx1 = app.UITable.Selection;

            if app.Button_edit.Value
                if app.editedList.Enable(idx1); app.Status.Value = 'ON';
                else;                           app.Status.Value = 'OFF';
                end

                app.Family.Value = app.editedList.Family{idx1};
                Fcn2_FamilyChanged(app)
                
                app.Name.Value = app.editedList.Name{idx1};
                Fcn3_NameChanged(app)

                app.Type.Value = app.editedList.Type{idx1};

            else
                if app.editedList.Enable(idx1); app.Status.Items = {'ON'};
                else;                           app.Status.Items = {'OFF'};
                end

                app.Family.Items = app.editedList.Family(idx1);
                app.Name.Items   = app.editedList.Name(idx1);
                app.Type.Items   = app.editedList.Type(idx1);
            end            
            
            app.Description.Value  = app.editedList.Description{idx1};
            app.LOG.Value          = app.editedList.LOG{idx1};
            app.CheckBox.Value     = 0;

            Parameters = jsondecode(app.editedList.Parameters{idx1});
            switch app.Type.Value
                case 'Serial'
                    set(app.IP,   'Enable', 0, 'Value', '')
                    set(app.Port, 'Enable', 1, 'Value', Parameters.Port)

                case 'TCPIP Socket'
                    set(app.IP,   'Enable', 1, 'Value', Parameters.IP)
                    set(app.Port, 'Enable', 1, 'Value', Parameters.Port)
                
                case 'TCP/UDP IP Socket'
                    set(app.IP,   'Enable', 1, 'Value', Parameters.IP)
                    set(app.Port, 'Enable', 1, 'Value', Parameters.Port)

                    app.CheckBox.Value = Parameters.Localhost_Enable;
                    app.localIP.Value  = Parameters.Localhost_localIP;
                    app.publicIP.Value = Parameters.Localhost_publicIP;

                case 'TCPIP Visa'
                    set(app.IP,   'Enable', 1, 'Value', Parameters.IP)
                    set(app.Port, 'Enable', 0, 'Value', '')
            end

            app.Timeout.Value = Parameters.Timeout;

            if app.Button_edit.Value
                Fcn5_LocalhostChanged1(app)
            end
            Fcn5_LocalhostChanged2(app)

            Fcn3_NameChanged_img(app)
            
        end

        % Button pushed function: Button_info
        function Button_infoPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winScpiEdit>', '</winScpiEdit>')));

            MessageBox(app, 'info', msg)

        end

        % Button pushed function: Button_open
        function Button_openPushed(app, event)
            
            [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
                tempList = Fcn1_OpenFile(app, fullfile(Folder, File));

                if ~isempty(tempList)
                    app.scpiList     = [app.scpiList; tempList];
                    app.scpiList.idx = (1:height(app.scpiList))';

                    app.editedList = app.scpiList;
                    app.editedFlag = true;

                    Fcn1_TableCreation(app, 1)
                end
            end

        end

        % Button pushed function: Button_export
        function Button_exportPushed(app, event)
            
            Folder = uigetdir(app.CallingApp.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de instrumentos');
            figure(app.UIFigure)

            if Folder
                Fcn1_saveNewFile(app, Folder)
            end

        end

        % Value changed function: Button_edit
        function Button_editValueChanged(app, event)
            
            if app.Button_edit.Value
                app.mainLabel.Text = 'MODO DE EDIÇÃO';

                set(app.Status,      'Items', {'ON', 'OFF'})
                set(app.Family,      'Items', {'GPS', 'Receptor'})
                set(app.Description, 'Editable', 1)
                set(app.IP,          'Editable', 1)
                set(app.Port,        'Editable', 1)
                set(app.Timeout,     'Editable', 1)
                set(app.localIP,     'Editable', 1)
                set(app.publicIP,    'Editable', 1)

                set(app.toolGrid.Children, 'Enable', 0)
                app.Button_info.Enable    = 1;
                app.Button_edit.Enable    = 1;
                app.Button_connect.Enable = 1;
                app.Button_add.Enable     = 1;
                app.Button_delete.Enable  = 1;
                app.Button_play.Enable    = 1;

                Fcn2_FamilyChanged(app)
                Fcn5_LocalhostChanged1(app)

            else
                app.mainLabel.Text = 'MODO DE VISUALIZAÇÃO';

                set(app.Status,      'Items', {app.Status.Value})
                set(app.Family,      'Items', {app.Family.Value})
                set(app.Type,        'Items', {app.Type.Value})
                set(app.Name,        'Items', {app.Name.Value})
                set(app.Description, 'Editable', 0)
                set(app.IP,          'Editable', 0)
                set(app.Port,        'Editable', 0)
                set(app.Timeout,     'Editable', 0)
                set(app.localIP,     'Editable', 0)
                set(app.publicIP,    'Editable', 0)
                app.CheckBox.Enable = 0;

                set(app.toolGrid.Children, 'Enable', 0)
                app.Button_info.Enable    = 1;
                app.Button_open.Enable    = 1;
                app.Button_export.Enable  = 1;
                app.Button_edit.Enable    = 1;

                if ~isequal(app.scpiList, app.editedList)
                    app.editedList = app.scpiList;

                    Fcn1_TableCreation(app, 1)
                end
            end

            Fcn5_LocalhostChanged2(app)

        end

        % Button pushed function: Button_connect
        function Button_connectPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            idx = app.UITable.Selection;

            try
                switch app.Family.Value
                    case 'Receptor'
                        instrSelected = struct('Type', app.Type.Value, 'IP', app.IP.Value, 'Port', str2double(app.Port.Value));
                        scpiNode      = connect_scpi(instrSelected, []);
        
                        if ~isempty(scpiNode.UserData.IDN)
                            if contains(scpiNode.UserData.IDN, app.instrTag.Text)
                                msg = sprintf('Conectado ao <b>%s</b>.', scpiNode.UserData.IDN);
                                MessageBox(app, 'info', msg)
                            else
                                msg = sprintf('Esperava-se instrumento da família <b>%s</b>, mas identificado <b>%s</b>.', app.Name.Value, scpiNode.UserData.IDN);
                                MessageBox(app, 'error', msg)
                            end
                        else
                            msg = 'Não recebida resposta ao comando "*IDN?".';
                            MessageBox(app, 'error', msg)
                        end
                        app.LOG.Value = jsonencode(struct('time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'info', msg), 'PrettyPrint', true);
        
                        delete(scpiNode)
                        clear scpiNode

                    case 'GPS'
                        switch app.Type.Value
                            case 'Serial'
                                instrSelected = struct('Type', app.Type.Value, 'Port', app.Port.Value, 'BaudRate', 9600);
                            case 'TCPIP Socket'
                                instrSelected = struct('Type', app.Type.Value, 'Port', app.Port.Value, 'IP', app.IP.Value);
                        end
                        
                        gpsNode = connect_gps(instrSelected);
                        gpsInfo = gpsNode.UserData;
    
                        delete(gpsNode)
                        clear gpsNode
            
                        if gpsInfo.Status
                            [City, Distance] = geo_FindCity(gpsInfo);
        
                            msg = sprintf('Coordenadas geográficas distam <b>%.1f km</b> da sede do município <b>%s</b>.', Distance, City);
                            
                            if ~isempty(gpsInfo.TimeStamp)
                                msg = sprintf('%s\n\nHorário: %s (UTC)', msg, datestr(gpsInfo.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                            end
                            MessageBox(app, 'info', msg)        
                        else
                            error('<b>Não recebida informação válida do instrumento acerca das coordenadas geográficas do local de monitoração.</b>\n%s', jsonencode(gpsInfo))
                        end
                        app.LOG.Value = jsonencode(struct('time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'info', jsonencode(gpsInfo)), "PrettyPrint", true);
                end

            catch ME
                MessageBox(app, 'error', getReport(ME))
                app.LOG.Value = jsonencode(struct('time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'info', ME.message), "PrettyPrint", true);
            end

            app.editedList.LOG{idx} = app.LOG.Value;

            delete(d)

        end

        % Button pushed function: Button_add
        function Button_addPushed(app, event)
            
            idx_old = app.UITable.Selection;
            idx_new = height(app.editedList)+1;

            app.editedList(end+1,:) = app.editedList(idx_old,:);
            app.editedList.idx(end) = idx_new;

            Fcn1_TableCreation(app, idx_new)

        end

        % Button pushed function: Button_delete
        function Button_deletePushed(app, event)
            
            idx = app.UITable.Selection;
            
            if height(app.editedList) > 1
                app.editedList(idx,:) = [];
                app.editedList.idx    = (1:height(app.editedList))';

                Fcn1_TableCreation(app, 1)
            else
                MessageBox(app, 'info', 'Não é possível excluir o único registro.')
            end

        end

        % Button pushed function: Button_play
        function Button_playPushed(app, event)
            
            if ~isequal(app.scpiList, app.editedList)
                msg1 = {};
                for ii = 1:height(app.editedList)
                    Parameters = jsondecode(app.editedList.Parameters{ii});

                    if isfield(Parameters, 'IP') && isempty(Parameters.IP)
                        msg1{end+1} = sprintf('• ID%d - Endereço IP inválido', ii);
                    end

                    if isfield(Parameters, 'Port') && isempty(Parameters.Port)
                        msg1{end+1} = sprintf('• ID%d - Porta inválida', ii);
                    end

                    if isfield(Parameters, 'Localhost_Enable') && Parameters.Localhost_Enable
                        if isempty(Parameters.Localhost_localIP) && isempty(Parameters.Localhost_publicIP)
                            msg1{end+1} = sprintf('• ID%d - Endereço IP inválido (<i>localhost</i>)', ii);
                        end
                    end
                end

                msg2 = '';
                idx = intersect(find(app.editedList.Family == "Receptor"), find(app.editedList.Enable));
                if isempty(idx)
                    msg2 = 'É necessário constar ao menos um receptor com estado "ON".';
                end

                if ~isempty(msg1) || ~isempty(msg2)
                    if ~isempty(msg1) && ~isempty(msg2)
                        msg1 = sprintf('É necessário corrigir ao menos o(s) seguinte(s) campo(s):\n%s', strjoin(msg1, '\n'));
                        msg  = sprintf('%s\n\nAlém disso, é necessário constar ao menos um receptor com estado "ON".', msg1);
                    elseif ~isempty(msg1)
                        msg  = sprintf('É necessário corrigir ao menos o(s) seguinte(s) campo(s):\n\n%s', strjoin(msg1, '\n'));
                    else
                        msg  = msg2;
                    end

                    MessageBox(app, 'error', msg)
                    return
                end
                
                app.scpiList   = app.editedList;
                app.editedFlag = true;
            end
            
            app.Button_edit.Value = 0;
            Button_editValueChanged(app)

        end

        % Value changed function: CheckBox, Description, Family, IP, Name, 
        % Port, Status, Timeout, Type, localIP, publicIP
        function ScpiParameterChanged(app, event)

            idx = app.UITable.Selection;

            switch event.Source
                case app.Status
                    if app.Status.Value == "ON"
                        app.editedList.Enable(idx) = 1;
                    else
                        app.editedList.Enable(idx) = 0;
                    end

                case app.Family
                    Fcn2_FamilyChanged(app)
                    
                    app.editedList.Family{idx} = app.Family.Value;
                    app.editedList.Name{idx}   = app.Name.Value;
                    app.editedList.Type{idx}   = app.Type.Value;
                    
                    Fcn3_NameChanged_img(app)
                    Fcn6_Parameters(app)
                    
                case app.Name
                    Fcn3_NameChanged(app)

                    app.editedList.Name{idx} = app.Name.Value;
                    app.editedList.Type{idx} = app.Type.Value;

                    Fcn3_NameChanged_img(app)
                    Fcn6_Parameters(app)

                case app.Type
                    Fcn4_TypeValueChanged(app)

                    app.editedList.Type{idx} = app.Type.Value;
                    Fcn6_Parameters(app)

                case {app.Port, app.IP, app.localIP, app.publicIP}
                    Flag = 0;

                    if ~isempty(event.Source.Value)
                        if event.Source == app.Port
                            Flag = Fcn8_PortValidation(app, event);
                        else
                            Flag = Fcn7_IPv4Validation(app, event);
                        end
                    end

                    if ~Flag
                        Fcn6_Parameters(app)
                    end

                case app.Timeout
                    Fcn6_Parameters(app)

                case app.CheckBox
                    Fcn5_LocalhostChanged2(app)
                    Fcn6_Parameters(app)

                case app.Description
                    app.editedList.Description{idx} = strjoin(app.Description.Value);
            end

            Fcn1_TableCreation(app, idx)
            
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
            Selection = uiconfirm(app.UIFigure, sprintf('<font style="font-size:12;">Deseja excluir os objetos de instrumentos?! Isso terá impacto em eventual tarefa em execução...</font>'), 'appColeta', 'Options', {'Sim', 'Não'}, 'Interpreter', 'html');
            
            if Selection == "Sim"
                instrreset
            end            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'LT_connect.png';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #000000; font-size: 24;">Analisador de espectro, receptor e GPS</b><font> INSTRUMENTOS</font>'; '<font>Edição de informações e teste de conectividade</font>'; ' '};
            app.instrPanel = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.instrPanel);
            app.GridLayout2.Tag = 'COLORLOCKED';
            app.instrImage = uiimage(app.GridLayout2);
            app.instrImage.ImageSource = 'Instr_R&S_FSL.png';
            app.instrTag = uilabel(app.GridLayout2);
            app.instrTag.Text = 'FSL';
            app.mainLabel = uilabel(app.Grid1);
            app.mainLabel.Text = 'MODO DE VISUALIZAÇÃO';
            app.mainPanel = uipanel(app.Grid1);
            app.mainGrid = uigridlayout(app.mainPanel);
            app.mainGrid_A = uigridlayout(app.mainGrid);
            app.StatusLabel = uilabel(app.mainGrid_A);
            app.StatusLabel.Text = 'Estado:';
            app.Status = uidropdown(app.mainGrid_A);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Status.Value = 'ON';
            app.FamilyLabel = uilabel(app.mainGrid_A);
            app.FamilyLabel.Text = 'Família:';
            app.Family = uidropdown(app.mainGrid_A);
            app.Family.Items = {};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Family.Value = {};
            app.NameLabel = uilabel(app.mainGrid_A);
            app.NameLabel.Text = 'Fabricante e modelo';
            app.Name = uidropdown(app.mainGrid_A);
            app.Name.Items = {};
            app.Name.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Name.Value = {};
            app.DescriptionLabel = uilabel(app.mainGrid_A);
            app.DescriptionLabel.Text = 'Descrição (opcional):';
            app.Description = uitextarea(app.mainGrid_A);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Description.Editable = 'off';
            app.mainGrid_B = uigridlayout(app.mainGrid);
            app.TypeLabel = uilabel(app.mainGrid_B);
            app.TypeLabel.Text = 'Tipo de conexão:';
            app.Type = uidropdown(app.mainGrid_B);
            app.Type.Items = {};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Type.Value = {};
            app.Panel2 = uipanel(app.mainGrid_B);
            app.Grid3 = uigridlayout(app.Panel2);
            app.IPLabel = uilabel(app.Grid3);
            app.IPLabel.Text = 'Endereço IP:';
            app.IP = uieditfield(app.Grid3, 'text');
            app.IP.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.IP.Editable = 'off';
            app.PortLabel = uilabel(app.Grid3);
            app.PortLabel.Text = 'Porta:';
            app.Port = uieditfield(app.Grid3, 'text');
            app.Port.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Port.Editable = 'off';
            app.TimeoutLabel = uilabel(app.Grid3);
            app.TimeoutLabel.Text = 'Timeout (s):';
            app.Timeout = uieditfield(app.Grid3, 'numeric');
            app.Timeout.Limits = [1 20];
            app.Timeout.RoundFractionalValues = 'on';
            app.Timeout.ValueDisplayFormat = '%.0f';
            app.Timeout.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.Timeout.Editable = 'off';
            app.Timeout.Value = 5;
            app.GridLayout8 = uigridlayout(app.Grid3);
            app.CheckBoxLabel = uilabel(app.GridLayout8);
            app.CheckBoxLabel.Text = 'Localhost';
            app.CheckBox = uicheckbox(app.GridLayout8);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.connect_Panel = uipanel(app.Grid3);
            app.connect_Grid2 = uigridlayout(app.connect_Panel);
            app.localIPLabel = uilabel(app.connect_Grid2);
            app.localIPLabel.Enable = 'off';
            app.localIPLabel.Text = 'IP local:';
            app.localIP = uieditfield(app.connect_Grid2, 'text');
            app.localIP.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.localIP.Editable = 'off';
            app.localIP.Enable = 'off';
            app.publicIPLabel = uilabel(app.connect_Grid2);
            app.publicIPLabel.Enable = 'off';
            app.publicIPLabel.Text = 'IP público:';
            app.publicIP = uieditfield(app.connect_Grid2, 'text');
            app.publicIP.ValueChangedFcn = createCallbackFcn(app, @ScpiParameterChanged, true);
            app.publicIP.Editable = 'off';
            app.publicIP.Enable = 'off';
            app.mainGrid_C = uigridlayout(app.mainGrid);
            app.LOGLabel = uilabel(app.mainGrid_C);
            app.LOGLabel.Text = 'LOG';
            app.LOG = uitextarea(app.mainGrid_C);
            app.LOG.Editable = 'off';
            app.toolGrid = uigridlayout(app.Grid1);
            app.Button_info = uibutton(app.toolGrid, 'push');
            app.Button_info.ButtonPushedFcn = createCallbackFcn(app, @Button_infoPushed, true);
            app.Button_info.Icon = 'LT_info.png';
            app.Button_info.Text = '';
            app.Separator1 = uiimage(app.toolGrid);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = 'LT_LineV.png';
            app.Button_open = uibutton(app.toolGrid, 'push');
            app.Button_open.ButtonPushedFcn = createCallbackFcn(app, @Button_openPushed, true);
            app.Button_open.Icon = 'LT_OpenFile.png';
            app.Button_open.Tooltip = {'Abrir'};
            app.Button_open.Text = '';
            app.Button_export = uibutton(app.toolGrid, 'push');
            app.Button_export.ButtonPushedFcn = createCallbackFcn(app, @Button_exportPushed, true);
            app.Button_export.Icon = 'LT_save.png';
            app.Button_export.Tooltip = {'Exportar'};
            app.Button_export.Text = '';
            app.Separator2 = uiimage(app.toolGrid);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = 'LT_LineV.png';
            app.Button_edit = uibutton(app.toolGrid, 'state');
            app.Button_edit.ValueChangedFcn = createCallbackFcn(app, @Button_editValueChanged, true);
            app.Button_edit.Tooltip = {'Modo de edição'};
            app.Button_edit.Icon = 'LT_edit.png';
            app.Button_edit.Text = '';
            app.Button_connect = uibutton(app.toolGrid, 'push');
            app.Button_connect.ButtonPushedFcn = createCallbackFcn(app, @Button_connectPushed, true);
            app.Button_connect.Icon = 'LT_connectivity.png';
            app.Button_connect.Enable = 'off';
            app.Button_connect.Tooltip = {'Teste de conectividade'};
            app.Button_connect.Text = '';
            app.Button_add = uibutton(app.toolGrid, 'push');
            app.Button_add.ButtonPushedFcn = createCallbackFcn(app, @Button_addPushed, true);
            app.Button_add.Icon = 'LT_newEdit.png';
            app.Button_add.Enable = 'off';
            app.Button_add.Tooltip = {'Adicionar registro'};
            app.Button_add.Text = '';
            app.Button_delete = uibutton(app.toolGrid, 'push');
            app.Button_delete.ButtonPushedFcn = createCallbackFcn(app, @Button_deletePushed, true);
            app.Button_delete.Icon = 'LT_redX.png';
            app.Button_delete.Enable = 'off';
            app.Button_delete.Tooltip = {'Excluir registro'};
            app.Button_delete.Text = '';
            app.Button_play = uibutton(app.toolGrid, 'push');
            app.Button_play.ButtonPushedFcn = createCallbackFcn(app, @Button_playPushed, true);
            app.Button_play.Icon = 'DT_edit.png';
            app.Button_play.Enable = 'off';
            app.Button_play.Text = 'Confirma edição';
            app.GridLayout9 = uigridlayout(app.Grid1);
            app.UITable = uitable(app.GridLayout9);
            app.UITable.ColumnName = {''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true false true];
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @TableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.IDLabel = uilabel(app.GridLayout9);
            app.IDLabel.Text = '  ID';
            app.NomeLabel = uilabel(app.GridLayout9);
            app.NomeLabel.Text = '  Nome';
            app.TipoLabel = uilabel(app.GridLayout9);
            app.TipoLabel.Text = '  Tipo';
            app.ParmetrosLabel = uilabel(app.GridLayout9);
            app.ParmetrosLabel.Text = '  Parâmetros';
            app.DescrioLabel = uilabel(app.GridLayout9);
            app.DescrioLabel.Text = '  Descrição';
            app.GridLayout10 = uigridlayout(app.Grid1);
            app.Image2 = uiimage(app.GridLayout10);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Tooltip = {'Excluir objetos'};
            app.Image2.ImageSource = 'LT_redX.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winScpiEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end