classdef winSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Grid1                    matlab.ui.container.GridLayout
        ButtonGroup              matlab.ui.container.ButtonGroup
        Separator4               matlab.ui.control.Image
        Separator3               matlab.ui.control.Image
        Separator2               matlab.ui.control.Image
        Separator1               matlab.ui.control.Image
        Button4_Label            matlab.ui.control.Label
        Button3_Label            matlab.ui.control.Label
        Button2_Label            matlab.ui.control.Label
        Button1_Label            matlab.ui.control.Label
        Button5_Info             matlab.ui.control.Button
        Button4_Graphic          matlab.ui.control.ToggleButton
        Button3_Data             matlab.ui.control.ToggleButton
        Button2_User             matlab.ui.control.ToggleButton
        Button1_Version          matlab.ui.control.ToggleButton
        TabGroup                 matlab.ui.container.TabGroup
        Tab1_Version             matlab.ui.container.Tab
        Tab1_Grid                matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        version_info             matlab.ui.control.HTML
        Tab1_Tool1               matlab.ui.container.GridLayout
        version_anateldb         matlab.ui.control.Button
        version_redmine          matlab.ui.control.Button
        version_python           matlab.ui.control.Button
        Tab1_Grid1               matlab.ui.container.GridLayout
        Tab1_Grid1A              matlab.ui.container.GridLayout
        Tab1_refresh1            matlab.ui.control.Image
        Tab1_Title1              matlab.ui.control.Label
        Tab2_User                matlab.ui.container.Tab
        Tab2_Grid                matlab.ui.container.GridLayout
        Tab2_Tool4               matlab.ui.container.GridLayout
        logger_Button            matlab.ui.control.Button
        Tab2_Panel4              matlab.ui.container.Panel
        Tab2_Grid3B_2            matlab.ui.container.GridLayout
        logger_Timeout           matlab.ui.control.NumericEditField
        logger_TimeoutLabel      matlab.ui.control.Label
        logger_HandShake         matlab.ui.control.NumericEditField
        logger_HandShakeLabel    matlab.ui.control.Label
        logger_ConnectTime       matlab.ui.control.NumericEditField
        logger_ConnectTimeLabel  matlab.ui.control.Label
        Tab2_Grid4               matlab.ui.container.GridLayout
        Tab2_Grid4A              matlab.ui.container.GridLayout
        logger_imgRefresh        matlab.ui.control.Image
        Tab2_Title4              matlab.ui.control.Label
        Tab2_Tool3               matlab.ui.container.GridLayout
        img_Button               matlab.ui.control.Button
        Tab2_Panel3              matlab.ui.container.Panel
        Tab2_Grid3B              matlab.ui.container.GridLayout
        img_VisibilityLabel      matlab.ui.control.Label
        img_Visibility           matlab.ui.control.DropDown
        img_Resolution           matlab.ui.control.DropDown
        img_ResolutionLabel      matlab.ui.control.Label
        img_Format               matlab.ui.control.DropDown
        img_FormatLabel          matlab.ui.control.Label
        Tab2_Grid3               matlab.ui.container.GridLayout
        Tab2_Grid3A              matlab.ui.container.GridLayout
        img_imgRefresh           matlab.ui.control.Image
        Tab2_Title3              matlab.ui.control.Label
        Tab2_Tool2               matlab.ui.container.GridLayout
        Color_Button             matlab.ui.control.Button
        Tab2_Panel2              matlab.ui.container.Panel
        Tab2_Grid2B              matlab.ui.container.GridLayout
        FatordeintegraoLabel     matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        IntegrationFactor2Label  matlab.ui.control.Label
        IntegrationFactor2       matlab.ui.control.Spinner
        IntegrationFactor1Label  matlab.ui.control.Label
        IntegrationFactor1       matlab.ui.control.Spinner
        HistoryDepth             matlab.ui.control.DropDown
        HistoryDepthLabel        matlab.ui.control.Label
        Color_WaterfallLabel     matlab.ui.control.Label
        Color_Waterfall          matlab.ui.control.DropDown
        GridLayout5              matlab.ui.container.GridLayout
        Color_ClearWrite         matlab.ui.control.Button
        Color_MaxHold            matlab.ui.control.Button
        Color_Median             matlab.ui.control.Button
        Color_MinHold            matlab.ui.control.Button
        CoresLabel               matlab.ui.control.Label
        Tab2_Grid2               matlab.ui.container.GridLayout
        Tab2_Grid2A              matlab.ui.container.GridLayout
        Color_imgRefresh         matlab.ui.control.Image
        Tab2_Title2              matlab.ui.control.Label
        Tab2_Tool1               matlab.ui.container.GridLayout
        File_Button              matlab.ui.control.Button
        Tab2_Panel1              matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        FileSize                 matlab.ui.control.Spinner
        FileSizeLabel            matlab.ui.control.Label
        Tab2_Grid1               matlab.ui.container.GridLayout
        Tab2_Grid1A              matlab.ui.container.GridLayout
        File_imgRefresh          matlab.ui.control.Image
        Tab2_Title1              matlab.ui.control.Label
        Tab3_Data                matlab.ui.container.Tab
        GridLayout5_2            matlab.ui.container.GridLayout
        Merge_Panel_2            matlab.ui.container.Panel
        Variables_Grid           matlab.ui.container.GridLayout
        Variables_Info2_3        matlab.ui.control.Label
        Variables_Info2_2        matlab.ui.control.Label
        Variables_Info2          matlab.ui.control.Label
        Variables_CheckBox3      matlab.ui.control.CheckBox
        Variables_CheckBox2      matlab.ui.control.CheckBox
        Variables_CheckBox1      matlab.ui.control.CheckBox
        GridLayout13_2           matlab.ui.container.GridLayout
        Variables_Button         matlab.ui.control.Button
        GridLayout6_2            matlab.ui.container.GridLayout
        VARIVEISLabel            matlab.ui.control.Label
        GridLayout8_2            matlab.ui.container.GridLayout
        Tab4_Graphic             matlab.ui.container.Tab
        Graphic_Grid1            matlab.ui.container.GridLayout
        Graphic_mode             matlab.ui.control.DropDown
        Graphic_modeLabel        matlab.ui.control.Label
        Panel_2                  matlab.ui.container.Panel
        GridLayout9              matlab.ui.container.GridLayout
        Graphic_info             matlab.ui.control.HTML
        Graphic_tool             matlab.ui.container.GridLayout
        Graphic_Button           matlab.ui.control.Button
        Graphic_Title            matlab.ui.container.GridLayout
        Graphic_Grid2            matlab.ui.container.GridLayout
        Graphic_Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
        newVersion
        
    end
    
    methods (Access = private)
        
        function Fcn1_UpdateVersion(app)
            app.CallingApp.General.ver = startup_Versions("Full", app.RootFolder);
        end


        function Fcn1_VersionInfo(app)
            cssStyle = '<style>p{font-family:helvetica;font-size:10px;color:#262626;margin: 10px 10px 10px 10px;}a{font-family:helvetica;font-size:10px;color:#808080;}</style>';
            app.version_info.HTMLSource  = sprintf(['%s<p><b>Sistema operacional:</b> <a>%s</a></p>' ...
                                                      '<p><b>Matlab:</b> <a>%s</a></p>'     ...
                                                      '<p><b>Python:</b> <a>%s</a></p>'     ...
                                                      '<p><b>appColeta:</b> <a>%s</a></p>' ...
                                                      '<p><b>fiscaliza:</b> <a>%s</a></p>'  ...
                                                      '<p><b>anateldb:</b> <a>%s</a></p>'   ...
                                                      '<p>&nbsp;</p>'],                                  ...
                                                      cssStyle,                                          ...
                                                      app.CallingApp.General.ver.OS,                     ...
                                                      jsonencode(app.CallingApp.General.ver.Matlab),     ...
                                                      jsonencode(app.CallingApp.General.ver.Python),     ...
                                                      jsonencode(app.CallingApp.General.ver.appColeta),  ...
                                                      app.CallingApp.General.ver.fiscaliza,              ...
                                                      jsonencode(app.CallingApp.General.ver.anateldb));       
        end


        function Fcn2_File(app)
            app.FileSize.Value = app.CallingApp.General.FileSize/1e+6;            
            app.File_Button.Enable  = 0;
        end


        function Fcn2_Color(app)
            app.Color_MinHold.BackgroundColor    = app.CallingApp.General.Colors(1,:);
            app.Color_Median.BackgroundColor     = app.CallingApp.General.Colors(2,:);
            app.Color_MaxHold.BackgroundColor    = app.CallingApp.General.Colors(3,:);
            app.Color_ClearWrite.BackgroundColor = app.CallingApp.General.Colors(4,:);
            app.Color_Waterfall.Value            = app.CallingApp.General.Waterfall;

            app.HistoryDepth.Value               = num2str(app.CallingApp.General.HistoryDepth);
            app.IntegrationFactor1.Value         = app.CallingApp.General.AverageFactor;
            app.IntegrationFactor2.Value         = app.CallingApp.General.SampleTimeFactor;

            app.Color_Button.Enable = 0;
        end


        function Fcn2_Image(app)
            app.img_Visibility.Value = app.CallingApp.General.Image.Visibility;
            app.img_Format.Value     = app.CallingApp.General.Image.Format;
            app.img_Resolution.Value = num2str(app.CallingApp.General.Image.Resolution);

            app.img_Button.Enable = 0;
        end
        

        function Fcn2_logger(app)
            app.logger_ConnectTime.Value = app.CallingApp.General.logger.ConnectionTime;
            app.logger_HandShake.Value   = app.CallingApp.General.logger.HandshakePause;
            app.logger_Timeout.Value     = app.CallingApp.General.logger.Timeout;

            app.logger_Button.Enable = 0;
        end


        function Fcn3_Graphics(app)
            graphRender = opengl('data');

            cssStyle = '<style>p{font-family:helvetica;font-size:10px;color:#262626;;margin: 10px 10px 10px 10px;}a{font-family:helvetica;font-size:10px;color:#808080;}</style>';
            app.Graphic_info.HTMLSource  = sprintf('%s<p><b>OpenGL:</b> <a>%s</a></p><p>&nbsp;</p>', ...
                                                   cssStyle, jsonencode(app.CallingApp.General.ver.OpenGL));
            
            switch graphRender.HardwareSupportLevel
                case 'basic'; app.Graphic_mode.Value = 'Hardware Basic';
                case 'full';  app.Graphic_mode.Value = 'Hardware Full';
                case 'none';  app.Graphic_mode.Value = 'Software';
            end
            app.Graphic_Button.Enable = 0;            
        end


        function Fcn4_SaveFile(app)
            appVersion = rmfield(app.CallingApp.General, 'ver');
            appVersion.userpath = '';

            writematrix(jsonencode(appVersion, "PrettyPrint", true), fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json'), "FileType", "text", "QuoteStrings", "none", "WriteMode", "overwrite")
        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'LT_info.png')
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            if app.CallingApp.General.layout.theme ~= "cyan"
                app.CallingApp.startup_theme
            end

            if isempty(app.CallingApp.General.ver.fiscaliza)
                app.CallingApp.General.ver = startup_Versions("Full", app.RootFolder);
            end
            
            if isdeployed
                set(app.Variables_Grid.Children, 'Enable', 0)
            end

            Fcn1_VersionInfo(app)
            Fcn2_File(app)
            Fcn2_Color(app)
            Fcn2_Image(app)
            Fcn2_logger(app)
            Fcn3_Graphics(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function mainMenu_ButtonSelectionChanged(app, event)
            
            switch event.Source.SelectedObject
                case app.Button1_Version; idx = 1;
                case app.Button2_User;    idx = 2;
                case app.Button3_Data;    idx = 3;
                case app.Button4_Graphic; idx = 4;
            end

            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);

        end

        % Button pushed function: Button5_Info
        function mainMenu_Button_Info(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = strtrim(char(extractBetween(info, '<winSettings>', '</winSettings>')));

            MessageBox(app, 'info', msg)

        end

        % Image clicked function: Tab1_refresh1
        function version_RefreshClicked(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Verificando as versões dos módulos do appColeta...');
        
            try
                presentVersion = struct('appColeta', app.CallingApp.General.ver.appColeta.Version, ...
                                        'fiscaliza', app.CallingApp.General.ver.fiscaliza,         ...
                                        'anateldb',  app.CallingApp.General.ver.anateldb); 
                
                publicLink     = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'PublicLinks.json')));
                publicLink     = publicLink.VersionFile;
                FileVersion    = webread(publicLink, weboptions("ContentType", "json"));
                app.newVersion = struct('appColeta', FileVersion.appColeta.Version, ...
                                        'fiscaliza', FileVersion.fiscaliza.Version, ...
                                        'anateldb',  FileVersion.anateldb);
                
                if isequal(presentVersion, app.newVersion)
                    MessageBox(app, 'info', sprintf('Módulos atualizados:\n• appColeta, fiscaliza, anateldb'))
                    
                else
                    app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>CONSULTA VERSÕES</b></p>'  ...
                                                           '<p>• appColeta: <a>%s</a></p>'               ...
                                                           '<p>• fiscaliza: <a>%s</a></p>'               ...
                                                           '<p>• anateldb: <a>%s</a></p><p>&nbsp;</p>'], ...
                                                           app.version_info.HTMLSource,                  ...
                                                           app.newVersion.appColeta,                    ...
                                                           app.newVersion.fiscaliza,                     ...
                                                           jsonencode(app.newVersion.anateldb));
                                                                                               
                    str1 = {};
                    str2 = {};
                    if strcmp(presentVersion.appColeta, app.newVersion.appColeta);  str1(end+1) = {'appColeta'};
                    else;                                                           str2(end+1) = {'appColeta'};
                    end                    
                    if strcmp(presentVersion.fiscaliza,  app.newVersion.fiscaliza); str1(end+1) = {'fiscaliza'};
                    else;                                                           str2(end+1) = {'fiscaliza'}; app.version_redmine.Enable = 1;
                    end                    
                    if isequal(presentVersion.anateldb,  app.newVersion.anateldb);  str1(end+1) = {'anateldb'};
                    else;                                                           str2(end+1) = {'anateldb'};  app.version_anateldb.Enable = 1;
                    end
                    
                    if ~isempty(str1) && ~isempty(str2); msg = sprintf('Módulo(s) atualizado(s):\n• %s\n\nMódulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str1, ', '), strjoin(str2, ', '));
                    else;                                msg = sprintf('Módulo(s) cuja(s) versão(ões) difere(m) da(s) indicada(s) no arquivo de versões:\n• %s', strjoin(str2, ', '));
                    end

                    MessageBox(app, 'warning', msg)
                    
                end
                
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end

            delete(d)
            
        end

        % Button pushed function: version_python
        function version_pythonPushed(app, event)
            
            Python = pyenv;
            
            [file, pathfile] = uigetfile({'python*.exe', 'python*.exe'}, 'Selecione o arquivo executável do Python', Python.Executable);
            figure(app.UIFigure)
            
            if file
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');
                try                    
                    pyenv('Version', fullfile(pathfile, file));

                    Fcn1_UpdateVersion(app)
                    app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>EDIÇÃO AMBIENTE PYTHON</b></p>' ...
                                                           '<p>• Python: <a>%s</a></p>'                   ...
                                                           '<p>• fiscaliza: <a>%s</a></p><p>&nbsp;</p>'], ...
                                                           app.version_info.HTMLSource,                   ...
                                                           jsonencode(app.CallingApp.General.ver.Python), ...
                                                           app.CallingApp.General.ver.fiscaliza);
                catch ME
                    MessageBox(app, 'error', getReport(ME))
                end
                delete(d)
            end
            
        end

        % Button pushed function: version_redmine
        function version_redminePushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');            
            try
                Python   = pyenv;
                [~, msg] = system(sprintf('"%s" %s', fullfile(Python.Home, 'Scripts', 'pip'), sprintf('install fiscaliza==%s', app.newVersion.fiscaliza)));

                Fcn1_UpdateVersion(app)
                app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>ATUALIZAÇÃO FISCALIZA</b></p>' ...
                                                       '<p><a>%s</a></p><p>&nbsp;</p>'], app.version_info.HTMLSource, msg);

                app.version_redmine.Enable  = 0;
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end
            delete(d)
            
        end

        % Button pushed function: version_anateldb
        function version_anateldbPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');
            try
                publicLink     = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'PublicLinks.json')));
                publicLink     = publicLink.anateldb;
                websave(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'), publicLink);

                ParquetInfoFile = parquetinfo(fullfile(app.RootFolder, 'Temp', 'AnatelDB.parquet.gzip'));
                if ~isequal(ParquetInfoFile.VariableTypes(1:9), ["string", "double", "single", "single", "string", "int16", "int32", "categorical", "single"])
                    error('Ao menos uma das colunas da nova versão do anateldb não estão no formato esperado.')
                end

                if isfile(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                    delete(fullfile(app.RootFolder, 'DataBase', 'Anatel_old.mat'))
                end

                while true
                    status = system(sprintf('rename "%s" "%s"', fullfile(app.RootFolder, 'DataBase', 'Anatel.mat'), 'Anatel_old.mat'));
                    if ~status
                        break
                    end
                    pause(.1)
                end

                clear global AnatelDB
                clear global AnatelDB_info
                drawnow nocallbacks
                
                anateldb_Read(app.RootFolder)

                global AnatelDB_info
                msg = sprintf('<b style="color:#262626;">anateldb:</b> %s', jsonencode(AnatelDB_info));

                Fcn1_UpdateVersion(app)
                app.version_info.HTMLSource = sprintf(['%s<p>Operação: <b>ATUALIZAÇÃO ANATELDB</b></p>' ...
                                                       '<p>• anateldb: <a>%s</a></p><p>&nbsp;</p>'],    ...
                                                       app.version_info.HTMLSource,                     ...
                                                       jsonencode(app.CallingApp.General.ver.anateldb));

                app.version_anateldb.Enable = 0;
            catch ME
                MessageBox(app, 'error', getReport(ME))
            end
            delete(d)
            
        end

        % Value changed function: FileSize
        function File_SizeValueChanged(app, event)

            app.File_Button.Enable = 1;

        end

        % Image clicked function: File_imgRefresh
        function File_RefreshClicked(app, event)
            
            app.FileSize.Value = 100;
            File_ButtonPushed(app)

        end

        % Button pushed function: File_Button
        function File_ButtonPushed(app, event)
            
            app.CallingApp.General.FileSize = app.FileSize.Value * 1e+6;
            
            Fcn2_File(app)
            Fcn4_SaveFile(app)
            
        end

        % Button pushed function: Color_ClearWrite, Color_MaxHold, 
        % ...and 2 other components
        function Color_LineColorButtonPushed(app, event)
            
            initialColor  = event.Source.BackgroundColor;
            selectedColor = uisetcolor(initialColor);
            figure(app.UIFigure)

            if ~isequal(initialColor, selectedColor)
                switch event.Source
                    case app.Color_MinHold;    app.Color_MinHold.BackgroundColor    = selectedColor;
                    case app.Color_Median;     app.Color_Median.BackgroundColor     = selectedColor;
                    case app.Color_MaxHold;    app.Color_MaxHold.BackgroundColor    = selectedColor;
                    case app.Color_ClearWrite; app.Color_ClearWrite.BackgroundColor = selectedColor;
                end

                app.Color_Button.Enable = 1;
            end

        end

        % Value changed function: Color_Waterfall, HistoryDepth, 
        % ...and 2 other components
        function Color_WaterfallValueChanged(app, event)
        
            app.Color_Button.Enable = 1;

        end

        % Image clicked function: Color_imgRefresh
        function Color_RefreshClicked(app, event)
            
            app.CallingApp.General.Colors    = [0.38,0.60,0.73; ...
                                                0.39,0.83,0.07; ...
                                                1.00,0.07,0.65; ...
                                                0.93,0.69,0.13];
            app.CallingApp.General.Waterfall = 'winter';

            app.CallingApp.General.HistoryDepth     = 128;
            app.CallingApp.General.AverageFactor    = 10;
            app.CallingApp.General.SampleTimeFactor = 10;

            Fcn2_Color(app)
            Fcn4_SaveFile(app)

        end

        % Button pushed function: Color_Button
        function Color_ButtonPushed(app, event)
            
            app.CallingApp.General.Colors    = [app.Color_MinHold.BackgroundColor; ...
                                                app.Color_Median.BackgroundColor;  ...
                                                app.Color_MaxHold.BackgroundColor; ...
                                                app.Color_ClearWrite.BackgroundColor];
            app.CallingApp.General.Waterfall = app.Color_Waterfall.Value;

            app.CallingApp.General.HistoryDepth     = str2double(app.HistoryDepth.Value);
            app.CallingApp.General.AverageFactor    = app.IntegrationFactor1.Value;
            app.CallingApp.General.SampleTimeFactor = app.IntegrationFactor2.Value;

            Fcn2_Color(app)
            Fcn4_SaveFile(app)

        end

        % Value changed function: img_Format, img_Resolution, 
        % ...and 1 other component
        function img_ValueChanged(app, event)

            app.img_Button.Enable = 1;
            
        end

        % Image clicked function: img_imgRefresh
        function img_RefreshClicked(app, event)
            
            app.CallingApp.General.Image = struct('Visibility', 'on',   ...
                                                  'Format',     'jpeg', ...
                                                  'Resolution', 120);
            Fcn2_Image(app)
            Fcn4_SaveFile(app)

        end

        % Button pushed function: img_Button
        function img_ButtonPushed(app, event)
            
            app.CallingApp.General.Image = struct('Visibility', app.img_Visibility.Value, ...
                                                  'Format',     app.img_Format.Value,     ...
                                                  'Resolution', str2double(app.img_Resolution.Value));

            Fcn2_Image(app)
            Fcn4_SaveFile(app)

        end

        % Value changed function: logger_ConnectTime, logger_HandShake, 
        % ...and 1 other component
        function logger_ValueChanged(app, event)
            
            app.logger_Button.Enable = 1;
            
        end

        % Image clicked function: logger_imgRefresh
        function logger_RefreshClicked(app, event)
            
            app.CallingApp.General.logger = struct('ConnectionTime', 30, 'HandshakePause', 1, 'Timeout', 5);

            Fcn2_logger(app)
            Fcn4_SaveFile(app)

        end

        % Button pushed function: logger_Button
        function logger_ButtonPushed(app, event)
            
            app.CallingApp.General.logger = struct('ConnectionTime', app.logger_ConnectTime.Value, ...
                                                   'HandshakePause', app.logger_HandShake.Value,   ...
                                                   'Timeout',        app.logger_Timeout.Value);

            Fcn2_logger(app)
            Fcn4_SaveFile(app)

        end

        % Value changed function: Variables_CheckBox1, 
        % ...and 2 other components
        function Variables_CheckBoxValueChanged(app, event)
            
            if app.Variables_CheckBox1.Value || app.Variables_CheckBox2.Value || app.Variables_CheckBox3.Value
                app.Variables_Button.Enable = 1;
            else
                app.Variables_Button.Enable = 0;
            end
            
        end

        % Button pushed function: Variables_Button
        function Variables_ButtonPushed(app, event)
            
            if app.Variables_CheckBox1.Value
                assignin('base', 'specInfo',   app.CallingApp.specInfo)
                openvar('specInfo')
            end
            
            if app.Variables_CheckBox2.Value
                assignin('base', 'bandList',   app.CallingApp.bandList)
                openvar('bandList')
            end
            
            if app.Variables_CheckBox3.Value
                assignin('base', 'specMatrix', app.CallingApp.specMatrix)
                openvar('specMatrix')
            end
            
        end

        % Value changed function: Graphic_mode
        function Graphic_TypeValueChanged(app, event)
            
            app.Graphic_Button.Enable = 1;
            
        end

        % Button pushed function: Graphic_Button
        function Graphic_ButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento...');

            switch app.Graphic_mode.Value
                case 'Software';       opengl software
                case 'Hardware Full';  opengl hardware
                case 'Hardware Basic'; opengl hardwarebasic
            end

            Fcn1_UpdateVersion(app)            
            Fcn3_Graphics(app)

            app.CallingApp.General.openGL = app.Graphic_mode.Value;
            Fcn4_SaveFile(app)

            delete(d)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_Version = uitab(app.TabGroup);
            app.Tab1_Version.Title = '1';
            app.Tab1_Grid = uigridlayout(app.Tab1_Version);
            app.Tab1_Grid1 = uigridlayout(app.Tab1_Grid);
            app.Tab1_Grid1.Tag = 'COLORLOCKED';
            app.Tab1_Title1 = uilabel(app.Tab1_Grid1);
            app.Tab1_Title1.Text = 'ASPECTOS GERAIS';
            app.Tab1_Grid1A = uigridlayout(app.Tab1_Grid1);
            app.Tab1_refresh1 = uiimage(app.Tab1_Grid1A);
            app.Tab1_refresh1.ImageClickedFcn = createCallbackFcn(app, @version_RefreshClicked, true);
            app.Tab1_refresh1.Tooltip = {''};
            app.Tab1_refresh1.ImageSource = 'LT_refresh.png';
            app.Tab1_Tool1 = uigridlayout(app.Tab1_Grid);
            app.version_python = uibutton(app.Tab1_Tool1, 'push');
            app.version_python.ButtonPushedFcn = createCallbackFcn(app, @version_pythonPushed, true);
            app.version_python.Icon = 'LT_Python.png';
            app.version_python.Tooltip = {''};
            app.version_python.Text = '';
            app.version_redmine = uibutton(app.Tab1_Tool1, 'push');
            app.version_redmine.ButtonPushedFcn = createCallbackFcn(app, @version_redminePushed, true);
            app.version_redmine.Icon = 'LT_redmine.png';
            app.version_redmine.Enable = 'off';
            app.version_redmine.Tooltip = {''};
            app.version_redmine.Text = '';
            app.version_anateldb = uibutton(app.Tab1_Tool1, 'push');
            app.version_anateldb.ButtonPushedFcn = createCallbackFcn(app, @version_anateldbPushed, true);
            app.version_anateldb.Icon = 'LT_mosaic.png';
            app.version_anateldb.Enable = 'off';
            app.version_anateldb.Tooltip = {''};
            app.version_anateldb.Text = '';
            app.Panel = uipanel(app.Tab1_Grid);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.version_info = uihtml(app.GridLayout8);
            app.version_info.HTMLSource = ' ';
            app.Tab2_User = uitab(app.TabGroup);
            app.Tab2_User.Title = '2';
            app.Tab2_Grid = uigridlayout(app.Tab2_User);
            app.Tab2_Grid1 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid1.Tag = 'COLORLOCKED';
            app.Tab2_Title1 = uilabel(app.Tab2_Grid1);
            app.Tab2_Title1.Text = 'ARQUIVO';
            app.Tab2_Grid1A = uigridlayout(app.Tab2_Grid1);
            app.File_imgRefresh = uiimage(app.Tab2_Grid1A);
            app.File_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @File_RefreshClicked, true);
            app.File_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel1 = uipanel(app.Tab2_Grid);
            app.GridLayout6 = uigridlayout(app.Tab2_Panel1);
            app.FileSizeLabel = uilabel(app.GridLayout6);
            app.FileSizeLabel.Text = 'Tamanho máximo (MB):';
            app.FileSize = uispinner(app.GridLayout6);
            app.FileSize.Step = 10;
            app.FileSize.Limits = [1 500];
            app.FileSize.RoundFractionalValues = 'on';
            app.FileSize.ValueDisplayFormat = '%.0f';
            app.FileSize.ValueChangedFcn = createCallbackFcn(app, @File_SizeValueChanged, true);
            app.FileSize.Value = 100;
            app.Tab2_Tool1 = uigridlayout(app.Tab2_Grid);
            app.File_Button = uibutton(app.Tab2_Tool1, 'push');
            app.File_Button.ButtonPushedFcn = createCallbackFcn(app, @File_ButtonPushed, true);
            app.File_Button.Icon = 'LT_edit.png';
            app.File_Button.Enable = 'off';
            app.File_Button.Text = '';
            app.Tab2_Grid2 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid2.Tag = 'COLORLOCKED';
            app.Tab2_Title2 = uilabel(app.Tab2_Grid2);
            app.Tab2_Title2.Text = 'PLOT';
            app.Tab2_Grid2A = uigridlayout(app.Tab2_Grid2);
            app.Color_imgRefresh = uiimage(app.Tab2_Grid2A);
            app.Color_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @Color_RefreshClicked, true);
            app.Color_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel2 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid2B = uigridlayout(app.Tab2_Panel2);
            app.CoresLabel = uilabel(app.Tab2_Grid2B);
            app.CoresLabel.Text = 'Cores:';
            app.GridLayout5 = uigridlayout(app.Tab2_Grid2B);
            app.Color_MinHold = uibutton(app.GridLayout5, 'push');
            app.Color_MinHold.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_MinHold.Text = 'MinHold';
            app.Color_Median = uibutton(app.GridLayout5, 'push');
            app.Color_Median.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_Median.Text = 'Tendência central';
            app.Color_MaxHold = uibutton(app.GridLayout5, 'push');
            app.Color_MaxHold.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_MaxHold.Text = 'MaxHold';
            app.Color_ClearWrite = uibutton(app.GridLayout5, 'push');
            app.Color_ClearWrite.ButtonPushedFcn = createCallbackFcn(app, @Color_LineColorButtonPushed, true);
            app.Color_ClearWrite.Text = 'ClearWrite';
            app.Color_Waterfall = uidropdown(app.Tab2_Grid2B);
            app.Color_Waterfall.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.Color_Waterfall.ValueChangedFcn = createCallbackFcn(app, @Color_WaterfallValueChanged, true);
            app.Color_Waterfall.Value = 'jet';
            app.Color_WaterfallLabel = uilabel(app.Tab2_Grid2B);
            app.Color_WaterfallLabel.Interpreter = 'html';
            app.Color_WaterfallLabel.Text = 'Estilo de cores:';
            app.HistoryDepthLabel = uilabel(app.Tab2_Grid2B);
            app.HistoryDepthLabel.Interpreter = 'html';
            app.HistoryDepthLabel.Text = 'Profundidade <i>waterfall</i>:';
            app.HistoryDepth = uidropdown(app.Tab2_Grid2B);
            app.HistoryDepth.Items = {'64', '128', '256', '512'};
            app.HistoryDepth.ValueChangedFcn = createCallbackFcn(app, @Color_WaterfallValueChanged, true);
            app.HistoryDepth.Value = '128';
            app.Panel_3 = uipanel(app.Tab2_Grid2B);
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.IntegrationFactor1 = uispinner(app.GridLayout7);
            app.IntegrationFactor1.Limits = [2 100];
            app.IntegrationFactor1.RoundFractionalValues = 'on';
            app.IntegrationFactor1.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor1.ValueChangedFcn = createCallbackFcn(app, @Color_WaterfallValueChanged, true);
            app.IntegrationFactor1.Value = 10;
            app.IntegrationFactor1Label = uilabel(app.GridLayout7);
            app.IntegrationFactor1Label.Text = 'Traço médio:';
            app.IntegrationFactor2 = uispinner(app.GridLayout7);
            app.IntegrationFactor2.Limits = [2 100];
            app.IntegrationFactor2.RoundFractionalValues = 'on';
            app.IntegrationFactor2.ValueDisplayFormat = '%.0f';
            app.IntegrationFactor2.ValueChangedFcn = createCallbackFcn(app, @Color_WaterfallValueChanged, true);
            app.IntegrationFactor2.Value = 20;
            app.IntegrationFactor2Label = uilabel(app.GridLayout7);
            app.IntegrationFactor2Label.Text = 'Tempo de escrita médio:';
            app.FatordeintegraoLabel = uilabel(app.Tab2_Grid2B);
            app.FatordeintegraoLabel.Text = 'Fator de integração:';
            app.Tab2_Tool2 = uigridlayout(app.Tab2_Grid);
            app.Color_Button = uibutton(app.Tab2_Tool2, 'push');
            app.Color_Button.ButtonPushedFcn = createCallbackFcn(app, @Color_ButtonPushed, true);
            app.Color_Button.Icon = 'LT_edit.png';
            app.Color_Button.Enable = 'off';
            app.Color_Button.Text = '';
            app.Tab2_Grid3 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid3.Tag = 'COLORLOCKED';
            app.Tab2_Title3 = uilabel(app.Tab2_Grid3);
            app.Tab2_Title3.Text = 'IMAGEM';
            app.Tab2_Grid3A = uigridlayout(app.Tab2_Grid3);
            app.img_imgRefresh = uiimage(app.Tab2_Grid3A);
            app.img_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @img_RefreshClicked, true);
            app.img_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel3 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid3B = uigridlayout(app.Tab2_Panel3);
            app.img_FormatLabel = uilabel(app.Tab2_Grid3B);
            app.img_FormatLabel.Text = 'Formato:';
            app.img_Format = uidropdown(app.Tab2_Grid3B);
            app.img_Format.Items = {'jpeg', 'png'};
            app.img_Format.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Format.Value = 'jpeg';
            app.img_ResolutionLabel = uilabel(app.Tab2_Grid3B);
            app.img_ResolutionLabel.Text = 'Resolução (dpi):';
            app.img_Resolution = uidropdown(app.Tab2_Grid3B);
            app.img_Resolution.Items = {'100', '120', '150', '200'};
            app.img_Resolution.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Resolution.Value = '120';
            app.img_Visibility = uidropdown(app.Tab2_Grid3B);
            app.img_Visibility.Items = {'on', 'off'};
            app.img_Visibility.ValueChangedFcn = createCallbackFcn(app, @img_ValueChanged, true);
            app.img_Visibility.Value = 'on';
            app.img_VisibilityLabel = uilabel(app.Tab2_Grid3B);
            app.img_VisibilityLabel.Text = 'Visibilidade:';
            app.Tab2_Tool3 = uigridlayout(app.Tab2_Grid);
            app.img_Button = uibutton(app.Tab2_Tool3, 'push');
            app.img_Button.ButtonPushedFcn = createCallbackFcn(app, @img_ButtonPushed, true);
            app.img_Button.Icon = 'LT_edit.png';
            app.img_Button.Enable = 'off';
            app.img_Button.Text = '';
            app.Tab2_Grid4 = uigridlayout(app.Tab2_Grid);
            app.Tab2_Grid4.Tag = 'COLORLOCKED';
            app.Tab2_Title4 = uilabel(app.Tab2_Grid4);
            app.Tab2_Title4.Text = 'LOGGER';
            app.Tab2_Grid4A = uigridlayout(app.Tab2_Grid4);
            app.logger_imgRefresh = uiimage(app.Tab2_Grid4A);
            app.logger_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @logger_RefreshClicked, true);
            app.logger_imgRefresh.ImageSource = 'LT_refresh.png';
            app.Tab2_Panel4 = uipanel(app.Tab2_Grid);
            app.Tab2_Grid3B_2 = uigridlayout(app.Tab2_Panel4);
            app.logger_ConnectTimeLabel = uilabel(app.Tab2_Grid3B_2);
            app.logger_ConnectTimeLabel.Text = 'Tempo de conexão (seg):';
            app.logger_ConnectTime = uieditfield(app.Tab2_Grid3B_2, 'numeric');
            app.logger_ConnectTime.Limits = [10 120];
            app.logger_ConnectTime.RoundFractionalValues = 'on';
            app.logger_ConnectTime.ValueDisplayFormat = '%.0f';
            app.logger_ConnectTime.ValueChangedFcn = createCallbackFcn(app, @logger_ValueChanged, true);
            app.logger_ConnectTime.Value = 30;
            app.logger_HandShakeLabel = uilabel(app.Tab2_Grid3B_2);
            app.logger_HandShakeLabel.Interpreter = 'html';
            app.logger_HandShakeLabel.Text = 'Pausa <i>handshake</i> (seg):';
            app.logger_HandShake = uieditfield(app.Tab2_Grid3B_2, 'numeric');
            app.logger_HandShake.Limits = [1 10];
            app.logger_HandShake.RoundFractionalValues = 'on';
            app.logger_HandShake.ValueDisplayFormat = '%.0f';
            app.logger_HandShake.ValueChangedFcn = createCallbackFcn(app, @logger_ValueChanged, true);
            app.logger_HandShake.Value = 1;
            app.logger_TimeoutLabel = uilabel(app.Tab2_Grid3B_2);
            app.logger_TimeoutLabel.Text = 'Timeout (seg):';
            app.logger_Timeout = uieditfield(app.Tab2_Grid3B_2, 'numeric');
            app.logger_Timeout.Limits = [5 20];
            app.logger_Timeout.RoundFractionalValues = 'on';
            app.logger_Timeout.ValueDisplayFormat = '%.0f';
            app.logger_Timeout.ValueChangedFcn = createCallbackFcn(app, @logger_ValueChanged, true);
            app.logger_Timeout.Value = 5;
            app.Tab2_Tool4 = uigridlayout(app.Tab2_Grid);
            app.logger_Button = uibutton(app.Tab2_Tool4, 'push');
            app.logger_Button.ButtonPushedFcn = createCallbackFcn(app, @logger_ButtonPushed, true);
            app.logger_Button.Icon = 'LT_edit.png';
            app.logger_Button.Enable = 'off';
            app.logger_Button.Text = '';
            app.Tab3_Data = uitab(app.TabGroup);
            app.Tab3_Data.Title = '3';
            app.GridLayout5_2 = uigridlayout(app.Tab3_Data);
            app.GridLayout6_2 = uigridlayout(app.GridLayout5_2);
            app.GridLayout6_2.Tag = 'COLORLOCKED';
            app.GridLayout8_2 = uigridlayout(app.GridLayout6_2);
            app.VARIVEISLabel = uilabel(app.GridLayout6_2);
            app.VARIVEISLabel.Text = 'VARIÁVEIS';
            app.GridLayout13_2 = uigridlayout(app.GridLayout5_2);
            app.Variables_Button = uibutton(app.GridLayout13_2, 'push');
            app.Variables_Button.ButtonPushedFcn = createCallbackFcn(app, @Variables_ButtonPushed, true);
            app.Variables_Button.Icon = 'LT_play.png';
            app.Variables_Button.Enable = 'off';
            app.Variables_Button.Text = '';
            app.Merge_Panel_2 = uipanel(app.GridLayout5_2);
            app.Variables_Grid = uigridlayout(app.Merge_Panel_2);
            app.Variables_CheckBox1 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox1.Text = 'app.specInfo';
            app.Variables_CheckBox2 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox2.Text = 'app.bandList';
            app.Variables_CheckBox3 = uicheckbox(app.Variables_Grid);
            app.Variables_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Variables_CheckBoxValueChanged, true);
            app.Variables_CheckBox3.Text = 'app.specMatrix';
            app.Variables_Info2 = uilabel(app.Variables_Grid);
            app.Variables_Info2.Interpreter = 'html';
            app.Variables_Info2.Text = 'Trata-se de variável criada no modo TAREFA LOGGER, armazenando informações das faixas de frequência sob monitoração.';
            app.Variables_Info2_2 = uilabel(app.Variables_Grid);
            app.Variables_Info2_2.Interpreter = 'html';
            app.Variables_Info2_2.Text = 'Trata-se de variável criada no modo TAREFA SCPI, armazenando informações das faixas de frequência sob monitoração.';
            app.Variables_Info2_3 = uilabel(app.Variables_Grid);
            app.Variables_Info2_3.Interpreter = 'html';
            app.Variables_Info2_3.Text = 'Trata-se de variável criada tanto no modo TAREFA SCPI quanto no modo TAREFA LOGGER, possuindo uma estrutura muito parecida com a variável app.specData do appAnálise e armazenando informações da faixa de frequência selecionada (metadados da monitoração, vetor de <i>timestamp</i> e matriz de níveis).';
            app.Tab4_Graphic = uitab(app.TabGroup);
            app.Tab4_Graphic.Title = '4';
            app.Graphic_Grid1 = uigridlayout(app.Tab4_Graphic);
            app.Graphic_Title = uigridlayout(app.Graphic_Grid1);
            app.Graphic_Title.Tag = 'COLORLOCKED';
            app.Graphic_Label = uilabel(app.Graphic_Title);
            app.Graphic_Label.Text = 'UNIDADE GRÁFICA';
            app.Graphic_Grid2 = uigridlayout(app.Graphic_Title);
            app.Graphic_tool = uigridlayout(app.Graphic_Grid1);
            app.Graphic_Button = uibutton(app.Graphic_tool, 'push');
            app.Graphic_Button.ButtonPushedFcn = createCallbackFcn(app, @Graphic_ButtonPushed, true);
            app.Graphic_Button.Icon = 'LT_edit.png';
            app.Graphic_Button.Enable = 'off';
            app.Graphic_Button.Text = '';
            app.Panel_2 = uipanel(app.Graphic_Grid1);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.Graphic_info = uihtml(app.GridLayout9);
            app.Graphic_info.HTMLSource = ' ';
            app.Graphic_modeLabel = uilabel(app.Graphic_Grid1);
            app.Graphic_modeLabel.Text = 'Tipo:';
            app.Graphic_mode = uidropdown(app.Graphic_Grid1);
            app.Graphic_mode.Items = {'Hardware Basic', 'Hardware Full', 'Software'};
            app.Graphic_mode.ValueChangedFcn = createCallbackFcn(app, @Graphic_TypeValueChanged, true);
            app.Graphic_mode.Value = 'Hardware Basic';
            app.ButtonGroup = uibuttongroup(app.Grid1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @mainMenu_ButtonSelectionChanged, true);
            app.ButtonGroup.Tag = 'COLORLOCKED';
            app.Button1_Version = uitogglebutton(app.ButtonGroup);
            app.Button1_Version.Icon = 'DT_home.png';
            app.Button1_Version.Text = '';
            app.Button1_Version.Value = true;
            app.Button2_User = uitogglebutton(app.ButtonGroup);
            app.Button2_User.Icon = 'DT_save.png';
            app.Button2_User.Text = '';
            app.Button3_Data = uitogglebutton(app.ButtonGroup);
            app.Button3_Data.Icon = 'DT_connect.png';
            app.Button3_Data.Text = '';
            app.Button4_Graphic = uitogglebutton(app.ButtonGroup);
            app.Button4_Graphic.Icon = 'DT_photo.png';
            app.Button4_Graphic.Text = '';
            app.Button5_Info = uibutton(app.ButtonGroup, 'push');
            app.Button5_Info.ButtonPushedFcn = createCallbackFcn(app, @mainMenu_Button_Info, true);
            app.Button5_Info.Icon = 'DT_info.png';
            app.Button5_Info.Text = '';
            app.Button1_Label = uilabel(app.ButtonGroup);
            app.Button1_Label.Text = 'Aspectos gerais';
            app.Button2_Label = uilabel(app.ButtonGroup);
            app.Button2_Label.Text = 'Personalização';
            app.Button3_Label = uilabel(app.ButtonGroup);
            app.Button3_Label.Text = 'Variáveis';
            app.Button4_Label = uilabel(app.ButtonGroup);
            app.Button4_Label.Text = 'Unidade gráfica';
            app.Separator1 = uiimage(app.ButtonGroup);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = 'DT_LineH.png';
            app.Separator2 = uiimage(app.ButtonGroup);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = 'DT_LineH.png';
            app.Separator3 = uiimage(app.ButtonGroup);
            app.Separator3.Enable = 'off';
            app.Separator3.ImageSource = 'DT_LineH.png';
            app.Separator4 = uiimage(app.ButtonGroup);
            app.Separator4.Enable = 'off';
            app.Separator4.ImageSource = 'DT_LineH.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end