classdef winTaskEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Grid1                        matlab.ui.container.GridLayout
        Label                        matlab.ui.control.Label
        task_Panel1                  matlab.ui.container.Panel
        task_GridA                   matlab.ui.container.GridLayout
        task_GridA3                  matlab.ui.container.GridLayout
        task_BitsPerPoint            matlab.ui.control.DropDown
        task_BitsPerPointLabel       matlab.ui.control.Label
        task_DurationUnit            matlab.ui.control.DropDown
        task_DurationLabel           matlab.ui.control.Label
        task_Duration                matlab.ui.control.NumericEditField
        task_Tree                    matlab.ui.container.Tree
        task_TreeLabel               matlab.ui.control.Label
        task_GridA2                  matlab.ui.container.GridLayout
        task_TypeEdit                matlab.ui.control.Button
        task_TypeLabel               matlab.ui.control.Label
        task_Type                    matlab.ui.control.DropDown
        task_GridA1                  matlab.ui.container.GridLayout
        task_NameEdit                matlab.ui.control.Button
        task_NameLabel               matlab.ui.control.Label
        task_Name                    matlab.ui.control.DropDown
        task_BandLabel               matlab.ui.control.Label
        task_PanelA1                 matlab.ui.container.Panel
        task_GridA4                  matlab.ui.container.GridLayout
        task_IntegrationFactorLabel  matlab.ui.control.Label
        task_IntegrationFactor       matlab.ui.control.NumericEditField
        task_RFMode                  matlab.ui.control.DropDown
        task_RFModeLabel             matlab.ui.control.Label
        task_LevelUnitLabel          matlab.ui.control.Label
        task_LevelUnit               matlab.ui.control.DropDown
        task_DetectorLabel           matlab.ui.control.Label
        task_Detector                matlab.ui.control.DropDown
        task_TraceModeLabel          matlab.ui.control.Label
        task_TraceMode               matlab.ui.control.DropDown
        task_RevisitTime             matlab.ui.control.NumericEditField
        task_RevisitTimeLabel        matlab.ui.control.Label
        task_Resolution              matlab.ui.control.NumericEditField
        task_ResolutionLabel         matlab.ui.control.Label
        task_StepWidth               matlab.ui.control.NumericEditField
        task_StepWidthLabel          matlab.ui.control.Label
        task_FreqStopLabel           matlab.ui.control.Label
        task_FreqStop                matlab.ui.control.NumericEditField
        task_FreqStartLabel          matlab.ui.control.Label
        task_FreqStart               matlab.ui.control.NumericEditField
        task_DescriptionLabel        matlab.ui.control.Label
        task_Description             matlab.ui.control.EditField
        task_IDLabel                 matlab.ui.control.Label
        task_ID                      matlab.ui.control.NumericEditField
        GridLayout5                  matlab.ui.container.GridLayout
        Button_2                     matlab.ui.control.Button
        connect_Button1              matlab.ui.control.Button
        connect_Button4              matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        connect_Button3              matlab.ui.control.StateButton
        connect_Button2              matlab.ui.control.StateButton
        Button                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        RootFolder
        
    end
    
    methods (Access = private)

%% SAVE TASK
%             Folder = uigetdir(app.menu_userPath.Value, 'Escolha o diretório em que será salva a lista de tarefas');
%             figure(app.MainApp)
% 
%             if Folder
%                 Misc_CloseRequest([], app.taskList, Folder, 'JustTask')
%             end

%% OPEN TASK
%             [File, Path] = uigetfile({'*.cfg', '*.cfg'}, 'Selecione um arquivo', 'MultiSelect', 'off');
%             figure(app.MainApp)
% 
%             if File
%                 [~, Tasks] = Misc_Startup('TasksOnly', app.RootFolder, fullfile(Path, File));
% 
%                 if ~isempty(Tasks)
%                     app.taskList = Tasks;
% 
%                     delete(app.task_Tree.Children)
%                     ModeOperation(app)
%                 end
%             end
        
%% NEW TASK
%             if app.Task_NewTask.Value
%                 Task_EditInfo(app)
% 
%                 app.Task_Name.Value              = 'Nova tarefa';
%                 app.task_BitsPerPoint.Value      = '8 bits';
% 
%                 app.task_Duration.Value          = 10;
%                 app.task_DurationUnit.Value      = 'min';
% 
%                 app.task_ID.Value          = 1;
%                 app.task_Description.Value       = 'Faixa 1 de 1';
%                 app.task_FreqStart.Value                = 88;
%                 app.task_FreqStop.Value                = 108;
%                 app.task_RFMode.Value            = 'Normal';
%                 app.task_StepWidth.Value         = 25;
%                 app.task_Resolution.Value       = 100;
%                 app.task_LevelUnit.Value         = 'dBm';
%                 app.task_TraceMode.Value         = 'ClearWrite';
%                 app.task_Detector.Value          = 'Average/RMS';
%                 app.task_RevisitTime.Value       = 10;
%                 app.task_IntegrationFactor.Value = 1;
% 
%                 delete(app.task_Tree.Children)
% 
%                 set(app.GridLayout19.Children,  'Enable', 0)
%                 app.Task_NewTask.Enable = 1;
% 
%             else
%                 ModeOperation(app)
%                 Task_EditInfo(app)
%             end

%% TASK EDIT
%         function Task_EditInfo(app)
% 
%             if app.Task_NewTask.Value || app.Task_newBand.Value || app.Task_editBand.Value
%                 set(app.Task_Name,              'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_BitsPerPoint,                     'BackgroundColor', [1,1,1], 'Items', {'8 bits', '16 bits', '32 bits'})
%                 set(app.task_Description,       'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_FreqStart,                'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_FreqStop,                'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_StepWidth,         'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_Resolution,       'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_IntegrationFactor, 'Editable', 1, 'BackgroundColor', [1,1,1])
%                 set(app.task_RevisitTime,       'Editable', 1, 'BackgroundColor', [1,1,1])
% 
%                 set(app.task_RFMode,    'BackgroundColor', [1,1,1], 'Items', {'High Sensitivity', 'Normal', 'Low Distortion'})
%                 set(app.task_TraceMode, 'BackgroundColor', [1,1,1], 'Items', {'ClearWrite', 'Average', 'MaxHold', 'MinHold'})
%                 set(app.task_Detector,  'BackgroundColor', [1,1,1], 'Items', {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'})
%                 set(app.task_LevelUnit, 'BackgroundColor', [1,1,1], 'Items', {'dBm', 'dBμV'})
% 
%                 if     app.Task_NewTask.Value; set(app.Task_addBand, 'Text', 'Incluir tarefa', 'Icon', 'AddWhite3.png')
%                 elseif app.Task_newBand.Value; set(app.Task_addBand, 'Text', 'Incluir faixa',  'Icon', 'AddWhite3.png')
%                 else;                          set(app.Task_addBand, 'Text', 'Editar faixa',   'Icon', 'White_Edit.png')
%                 end
% 
%                 app.Task_Tree1.Enable      = 0;
%                 app.task_Tree.Enable      = 0;
%                 app.task_scpiList.Enable = 0;
% 
%                 set(app.GridLayout5.Children, 'Enable', 0)
%                 
%                 app.tb_playback.Visible  = 0;
%                 app.Task_executeTask.Enable = 0;
% 
%             else
%                 ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
% 
%                 set(app.Task_Name,              'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', app.taskList{ind1}.Name)
%                 set(app.task_BitsPerPoint,                     'BackgroundColor', [.94,.94,.94], 'Items', {sprintf('%d bits', app.taskList{ind1}.BitsPerSample)})
% 
%                 set(app.task_Description,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_FreqStart,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_FreqStop,                'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_StepWidth,         'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_Resolution,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_IntegrationFactor, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                 set(app.task_RevisitTime,       'Editable', 0, 'BackgroundColor', [.94,.94,.94])
% 
%                 set(app.task_RFMode,    'BackgroundColor', [.94,.94,.94], 'Items', {app.task_RFMode.Value})
%                 set(app.task_TraceMode, 'BackgroundColor', [.94,.94,.94], 'Items', {app.task_TraceMode.Value})
%                 set(app.task_Detector,  'BackgroundColor', [.94,.94,.94], 'Items', {app.task_Detector.Value})
%                 set(app.task_LevelUnit, 'BackgroundColor', [.94,.94,.94], 'Items', {app.task_LevelUnit.Value})
% 
%                 app.Task_Tree1.Enable      = 1;
%                 app.task_Tree.Enable      = 1;
%                 app.task_scpiList.Enable = 1;
% 
%                 set(app.GridLayout5.Children, 'Enable', 1)
% 
%                 if ~isempty(app.task_scpiList.Items)
%                     ind2 = find(strcmp(app.instrInfo.Name, extractAfter(app.task_scpiList.Value, ': ')), 1);
%                     if isempty(app.instrInfo.StepWidth_Values{ind2}) && ~fix(diff(app.instrInfo.DataPoints_Limits{ind2}))
%                         set(app.Task_StepWidth1, 'Editable', 0, 'BackgroundColor', [.94,.94,.94])
%                     else
%                         set(app.Task_StepWidth1, 'Editable', 1, 'BackgroundColor', [1,1,1])
%                     end
%                     
%                     app.tb_playback.Visible    = 1;
%                     app.Task_executeTask.Enable   = 1;
%                 else
%                     app.tb_playback.Visible    = 0;
%                     app.Task_executeTask.Enable   = 0;
%                 end
%                 
%                 set(app.GridLayout19.Children, 'Enable', 1)
%                 
%                 if ~isempty(app.task_Tree.SelectedNodes)
%                     ind3 = app.task_Tree.SelectedNodes.NodeData;
%                     if isempty(app.taskList{ind1}.Band(ind3).Flag) || ~app.taskList{ind1}.Band(ind3).Flag
%                         app.task_imgRefresh.Visible = 0;
%                     end
% 
%                     task_TreeSelectionChanged(app)
%                 end
%             end
%             Task_RightPanel_Grid(app)
% 
%         end

        function Fcn_SelectedRowValues(app)

            row = app.UITable.Selection;

            app.connect_Family.Items       = app.CallingApp.scpiList.Family{row};
            app.connect_Type.Items         = app.CallingApp.scpiList.Type{row};            
            app.connect_Name.Items         = app.CallingApp.scpiList.webPass{row};
            app.connect_Description.Value  = app.CallingApp.scpiList.Description{row};
            
            app.connect_IP.Value           = app.CallingApp.scpiList.IP{row};
            app.connect_Port.Value         = app.CallingApp.scpiList.Port{row};
            app.connect_Timeout.Value      = app.CallingApp.scpiList.Timeout(row);

            app.connect_CheckBox.Value     = app.CallingApp.scpiList.ConnectFlaf(row);

            if app.connect_CheckBox.Value
                app.connect_localIP.Value  = app.CallingApp.scpiList.Localhost_localIP{row};
                app.connect_publicIP.Value = app.CallingApp.scpiList.Localhost_publicIP{row};
            else
                app.connect_localIP.Value  = '';
                app.connect_publicIP.Value = '';
            end

            app.LOG.Value                  = '';           
            
        end

        
        function result = Fcn_IP(app)

            IP1Status = Fcn_IPValidation(app, app.IP1.Value);
            IP2Status = Fcn_IPValidation(app, app.IP2.Value);

            msg = '';
            if (~IP1Status && ~IP2Status) || (IP1Status + IP2Status == -2)
                msg = 'Endereços IP inválidos.';
            elseif ~IP1Status
                msg = 'Endereço IP do tipo "Público / Intranet" inválido.';
            elseif ~IP2Status
                msg = 'Endereço IP do tipo "Open VPN" inválido.';
            end

            if ~isempty(msg)
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', 'error')
            end

            if ~IP1Status || ~IP2Status || (IP1Status + IP2Status == -2)
                result = 0;
            else
                result = 1;
            end

        end
        

        function result = Fcn_IPValidation(app, IP)

            if isempty(IP)
                result = -1;

            else
                IP = strsplit(IP, '.');
                IP = cellfun(@(x) str2double(x), IP);
        
                if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                    result = 0;
                else
                    result = 1;
                end
            end

        end


        function connect_infoPanel(app)

            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                SelectedNode = app.connect_Tree.SelectedNodes;

                if ~isempty(SelectedNode)
                    if SelectedNode.Parent == app.connect_Tree
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Text), 1);
                    else
                        indFind = find(strcmp(app.instrInfo.Name, SelectedNode.Parent.Text), 1);
                    end
                else
                    indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
                end
            else
                indFind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            end

            app.connect_instrName.Text         = {app.instrInfo.Tag{indFind}; app.instrInfo.Band{indFind}};
            app.connect_instrImage.ImageSource = app.instrInfo.Image{indFind};
            app.connect_instrDescription.Text  = app.instrInfo.Description{indFind};

        end


        function connect_Layout(app)
            
            % Visibilidade relacionada ao modo de apresentação do painel -
            % "Adicionar instrumento", "Editar instrumento" e "Visualizar".
            if app.connect_Button2.Value || app.connect_Button3.Value

                app.connect_Grid0.RowHeight = {22, 254, 1, 24, 1, '1x', 1, 24};
                set(app.connect_Tree, 'Enable', 'off')

                if app.connect_Button2.Value
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Incluir', 'Icon', 'AddWhite3.png')

                    set(app.connect_Family, 'BackgroundColor', 'white')
                    set(app.connect_Name,   'BackgroundColor', 'white')
                else
                    set(app.connect_Button1, 'Enable', 1, 'Text', 'Editar', 'Icon', 'White_Edit.png')

                    set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                    set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                end

                set(app.connect_Type,        'BackgroundColor', 'white')
                set(app.connect_IP,          'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Port,        'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Timeout,     'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Buffer,      'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_Description, 'BackgroundColor', 'white', 'Editable', 'on')

                set(app.connect_CheckBox, 'Enable', 1)
                set(app.connect_localIP,  'BackgroundColor', 'white', 'Editable', 'on')
                set(app.connect_publicIP, 'BackgroundColor', 'white', 'Editable', 'on')
                
                app.connect_Button4.Enable = 0;

            else
                app.connect_Grid0.RowHeight = {22, 254, 0, 0, 1, '1x', 1, 24};
                set(app.connect_Tree,    'Enable', 1);
                set(app.connect_Button1, 'Enable', 0)

                set(app.connect_Family, 'BackgroundColor', [.94,.94,.94])
                set(app.connect_Name,   'BackgroundColor', [.94,.94,.94])
                set(app.connect_Type,   'BackgroundColor', [.94,.94,.94])

                set(app.connect_IP,          'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Port,        'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Timeout,     'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Buffer,      'BackgroundColor', [.94,.94,.94], 'Editable', 'off')
                set(app.connect_Description, 'BackgroundColor', [.80,.80,.80], 'Editable', 'off')

                set(app.connect_CheckBox, 'Enable', 0, 'Value', 0)
                set(app.connect_localIP,  'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                set(app.connect_publicIP, 'BackgroundColor', [.94,.94,.94], 'Editable', 'off', 'Value', '')
                
                ind = app.connect_Tree.SelectedNodes.NodeData;
                if numel(ind) == 1
                    app.connect_Button3.Enable = 1;
                    app.connect_Button4.Enable = 1;
                    
                    connect_PanelValues(app, ind)
                end
            end
            
            % Visibilidade relacionada ao tipo da conexão (IP, Porta).
            set(app.connect_Grid1.Children, 'Visible', 1)
            switch app.connect_Type.Value
                case 'Serial'
                    app.connect_IPLabel.Visible = 0;
                    set(app.connect_IP, 'Visible', 0, 'Value', '')
                
                    app.connect_PortLabel.Layout.Column = 1;
                    app.connect_Port.Layout.Column      = 1;
                    
                    app.connect_CheckBox.Enable = 0;
                    
                case 'Visa TCPIP'
                    app.connect_PortLabel.Visible = 0;
                    app.connect_Port.Visible      = 0;
                    
                otherwise
                    app.connect_PortLabel.Layout.Column = 2;
                    app.connect_Port.Layout.Column      = 2;
            end

        end
        
        
        function connect_PanelValues(app, ind)
            
            if ~app.connect_Button2.Value && ~app.connect_Button3.Value
                app.connect_Family.Items  = app.scpiList.Family(ind);
                app.connect_Name.Items    = app.scpiList.Name(ind);
            end
            
            app.connect_Type.Items        = app.scpiList.Type(ind);
            app.connect_IP.Value          = app.scpiList.IP{ind};
            app.connect_Port.Value        = app.scpiList.Port{ind};
            
            set(app.connect_Timeout, 'Value', app.scpiList.Timeout(ind),  'FontColor', 'black')
            set(app.connect_Buffer,  'Value', app.scpiList.inBuffer(ind), 'FontColor', 'black')
            
            app.connect_Description.Value = app.scpiList.Description{ind};
            
            app.connect_CheckBox.Value    = app.scpiList.Localhost_Flag(ind);
            app.connect_localIP.Value     = app.scpiList.Localhost_localIP{ind};
            app.connect_publicIP.Value    = app.scpiList.Localhost_publicIP{ind};

            if app.connect_CheckBox.Value; set(app.connect_Grid2.Children, 'Enable', 'on')
            else;                          set(app.connect_Grid2.Children, 'Enable', 'off')
            end
                    
        end


        function connect_infoValidation(app, Type, IP)

            switch Type
                case 'Only Instrument IP'
                    IP = strsplit(IP, '.');
                    IP = cellfun(@(x) str2double(x), IP);

                    if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                        error('Endereço IP inválido.')
                    end

                otherwise
                    if contains(app.connect_Type.Value, 'IP')
                        if ~app.connect_CheckBox.Value
                            IPValues = {app.connect_IP.Value};
                        else
                            IPValues = {app.connect_IP.Value;      ...
                                app.connect_localIP.Value; ...
                                app.connect_publicIP.Value};
                        end

                        for ii = 1:numel(IPValues)
                            IP = strsplit(IPValues{ii}, '.');

                            if (ii == 3) && isempty(IPValues{ii})
                                continue
                            end

                            IP = cellfun(@(x) str2double(x), IP);

                            if numel(IP) ~= 4 || any(isnan(IP(1:4))) || IP(1) <= 0 || IP(1) > 255 || any(IP(2:4) < 0) || any(IP(2:4) > 255)
                                error('Endereço IP inválido.')
                            end
                        end

                        if ~isempty(app.connect_Port.Value)
                            Port = str2double(app.connect_Port.Value);

                            if isnan(Port) || Port < 0 || Port >= 65536
                                error('Número da porta inválido.')
                            end
                        end
                    end
            end

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'toolbar_info.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.RootFolder = app.CallingApp.RootFolder;
            
            mainMonitor = get(0, 'MonitorPositions');
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4))/2)];

            set(app.UITable, 'Data', app.CallingApp.scpiList(:, [2, 4:6, 8, 12]), ...
                             'Selection', 1)

            Fcn_SelectedRowValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin1 = [];
            delete(app)
            
        end

        % Callback function
        function UITable_CellSelection(app, event)
            
            Fcn_SelectedRowValues(app)
            
        end

        % Callback function
        function Button1Pushed(app, event)
            
            global ExceptionGlobalList
            
            if isempty(ExceptionGlobalList)
                Misc_ExceptionGlobalList(app.RootFolder)
            end
            
            if isempty(ExceptionGlobalList)
                msg = 'Não identificada emissão na lista global de exceções contida no arquivo "ExceptionGlobalList.cfg".';

            else
                msg = [];
                for ii = 1:height(ExceptionGlobalList)
                    msg = sprintf('%s\n%s', msg, sprintf('<b>%.3f MHz</b> - "%s"', ExceptionGlobalList.Frequency(ii), ExceptionGlobalList.Description{ii}));
                end
            end

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button2Pushed(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            app.CallingApp.exceptionList(idx,:) = [];
            app.row = 1;

            Fcn1_TableStyle(app)
            app.UITable.Selection = [1, 1];
            Fcn_SelectedRowValues(app)

            scroll(app.UITable, 'top')

        end

        % Callback function
        function Button3_Clicked(app, event)
            
            idx = intersect(find(strcmp(app.CallingApp.exceptionList.Tag,  app.CallingApp.peaksTable.Tag(app.row))), ...
                            find(app.CallingApp.exceptionList.Frequency == app.CallingApp.peaksTable.Frequency(app.row)));

            if ~isempty(idx)
                idx = idx(1);
            end

            if ~strcmp(app.CallingApp.peaksTable.Regulatory{app.row}, app.RegInfo_edited.Value) || ...
               app.CallingApp.peaksTable.Station(app.row) ~= app.Station_edited.Value               || ...
               ~strcmp(app.CallingApp.peaksTable.Type{app.row}, app.Type.Value)                     || ...
               ~strcmp(app.CallingApp.peaksTable.Irregular{app.row}, app.Compliance.Value)         || ...
               ~strcmp(app.CallingApp.peaksTable.RiskLevel{app.row}, app.RiskLevel.Value)           || ...
               ~isempty(deblank(strjoin(app.Info.Value)))

                stationInfo = '';
                try
                    stationInfo = jsondecode(app.Info.Value{1});
                catch
                end
    
                if isstruct(stationInfo)
                    exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},       ...
                                      app.CallingApp.peaksTable.Frequency(app.row), ...
                                      app.Type.Value,                               ...
                                      app.RegInfo_edited.Value,                     ...
                                      str2double(stationInfo.Service),              ...
                                      str2double(stationInfo.Station),              ...
                                      stationInfo.Description,                      ...
                                      stationInfo.Distance,                         ...
                                      app.Compliance.Value,                         ...
                                      app.RiskLevel.Value};
    
                else
                    switch app.RegInfo_edited.Value
                        case 'Licenciada'
                            if isempty(idx)
                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.CallingApp.peaksTable.Service(app.row),      ...
                                                  app.CallingApp.peaksTable.Station(app.row),      ...
                                                  app.CallingApp.peaksTable.Description{app.row},  ...
                                                  app.CallingApp.peaksTable.Distance{app.row},     ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            else
                                if app.Station_edited.Value == app.CallingApp.peaksTable.Station(idx)
                                    Distance = app.CallingApp.peaksTable.Distance{app.row};
                                else
                                    Distance = app.CallingApp.exceptionList.Distance{idx};
                                end

                                exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                                  app.CallingApp.peaksTable.Frequency(app.row),    ...
                                                  app.Type.Value,                                  ...
                                                  app.RegInfo_edited.Value,                        ...
                                                  app.Service_edited.Value,                        ...
                                                  app.Station_edited.Value,                        ...
                                                  app.Info.Value,                                  ...
                                                  Distance,                                        ...
                                                  app.Compliance.Value,                            ...
                                                  app.RiskLevel.Value};
                            end

                        otherwise
                            if isempty(deblank(strjoin(app.Info.Value)))
                                Misc_uialertCreation(app, 'warning', 'Campo "Informações adicionais" não pode ficar vazio.')
                                return
                            end

                            exceptionValue = {app.CallingApp.peaksTable.Tag{app.row},          ...
                                              app.CallingApp.peaksTable.Frequency(app.row),    ...
                                              app.Type.Value,                                  ...
                                              app.RegInfo_edited.Value,                        ...
                                              -1, -1, ['[EXC] ' strjoin(app.Info.Value)], '-', ...
                                              app.Compliance.Value,                            ...
                                              app.RiskLevel.Value};
                    end
                end

                if isempty(idx); app.CallingApp.exceptionList(end+1,:) = exceptionValue;
                else;            app.CallingApp.exceptionList(idx,:)   = exceptionValue;
                end

                Fcn1_TableStyle(app)            
                Fcn_SelectedRowValues(app)

            else
                Button2Pushed(app)
            end
            
            app.CallingApp.auxWin_SignalAnalysis
            
        end

        % Callback function
        function Station_editedValueChanged(app, event)
            
            latNode  = app.CallingApp.peaksTable.Latitude(app.row);
            longNode = app.CallingApp.peaksTable.Longitude(app.row);
            
            try
                StationInfo = anateldb_Query(app.Station_edited.Value, latNode, longNode, app.RootFolder);
                
                msg = sprintf(['<font style="font-size:12;">Indicação automática:\n• Frequência: %.3f MHz\n• Descrição: %s\n• Distância: %s\n\n'                      ...
                               'Indicação manual:    \n• Frequência(s): %s MHz\n• Descrição: %s\n• Distância: %.1f\n\n Confirma edição?</font>'], ...
                               app.CallingApp.peaksTable.Frequency(app.row), app.CallingApp.peaksTable.Description{app.row},              ...
                               app.CallingApp.peaksTable.Distance{app.row}, StationInfo.Frequency, StationInfo.Description, StationInfo.Distance);
    
                selection = uiconfirm(app.UIFigure, msg, 'appAnálise', 'Options', {'OK', 'Cancelar'}, 'DefaultOption', 1, 'CancelOption', 2, 'Interpreter', 'html');
                
                if strcmp(selection, 'OK')
                    set(app.Info, 'Value', jsonencode(struct('Service', num2str(StationInfo.Service), ...
                                                             'Station', num2str(StationInfo.Station), ...
                                                             'Description', StationInfo.Description,  ...
                                                             'Distance', sprintf('%.1f', StationInfo.Distance))), ...
                                  'Editable', 0, 'BackgroundColor', [.80,.80,.80])

                else
                    app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                end

            catch ME
                app.Station_edited.Value = double(app.CallingApp.peaksTable.Station(app.row));
                
                Misc_uialertCreation(app, 'error', getReport(ME))
            end
            
        end

        % Callback function
        function RegInfo_editedValueChanged(app, event)
            
            switch app.RegInfo_edited.Value
                case 'Licenciada'
                    set(app.Station_edited, 'Editable', 1, 'BackgroundColor', [1,1,1])
                    set(app.Info,    'Editable', 0, 'BackgroundColor', [.80,.80,.80], 'Value', '')
                    
                    set(app.Type, 'Value', 'Fundamental', 'Enable', 0)
                    app.TypeLabel.Enable = 0;
    
                    set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')

                case {'Não licenciada', 'Não passível de licenciamento'}
                    set(app.Station_edited, 'Editable', 0, 'BackgroundColor', [.94,.94,.94], 'Value', -1)
                    set(app.Info,    'Editable', 1, 'BackgroundColor', [1,1,1])
    
                    app.Type.Enable      = 1;
                    app.TypeLabel.Enable = 1;
    
                    switch app.RegInfo_edited.Value
                        case 'Não licenciada'
                            set(app.Compliance, 'Items', {'Sim'})
                        case 'Não passível de licenciamento'
                            set(app.Compliance, 'Items', {'Não', 'Sim'}, 'Value', 'Não')
                    end
            end
            ComplianceValueChanged(app)
            
        end

        % Callback function
        function xLimFactor_DownClicked(app, event)
            
            if app.xLimFactor > .5
                app.xLimFactor = app.xLimFactor - .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function xLimFactor_UpClicked(app, event)
            
            if app.xLimFactor < 10
                app.xLimFactor = app.xLimFactor + .5;
                app.xLimFactorValue.Text = num2str(2*app.xLimFactor);
    
                [F0_axes, F1_axes] = Fcn4_xLim(app);
                set(app.UIAxes, 'XLim', [F0_axes, F1_axes])
            end

        end

        % Callback function
        function ComplianceValueChanged(app, event)
            
            if strcmp(app.Compliance.Value, 'Não')
                set(app.RiskLevel,      'Enable', 0, 'Items', {'-'})
                set(app.RiskLevelLabel, 'Enable', 0)
            else
                set(app.RiskLevel,      'Enable', 1, 'Items', {'Baixo', 'Médio', 'Alto'})
                set(app.RiskLevelLabel, 'Enable', 1)
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));
            msg  = char(extractBetween(info, sprintf('<winRFeyeEdit>\n'), sprintf('\n</winRFeyeEdit>')));

            Misc_uialertCreation(app, 'info', msg)

        end

        % Callback function
        function Button_2Pushed(app, event)
            
            result = Fcn_IP(app);
            if result
                d = uiprogressdlg(app.UIFigure, "Message", "Em andamento...", "Indeterminate", "on");
    
                app.Status = Misc_RFeyeDatabase(app.RootFolder,                         ...
                                                app.IP1.Value, app.IP2.Value,           ...
                                                app.webLogin.Value, app.webPass.Data,   ...
                                                app.rootLogin.Value, app.rootPass.Data, ...
                                                app.ftpPort.Value, app.sftpPort.Value);
    
                if app.Status.ConnectFlag
                    app.Lamp.Color = [0,1,0];
                    app.Button_2.Enable = 1;

                    if isempty(app.Status.ID)
                        msg  = 'Não identificado o sensor sob análise.';
                        icon = 'warning';
                    else
                        if strcmp(app.Status.ID, app.Node.Value)
                            msg  = sprintf('Confirmado se tratar do sensor %s.', app.Status.ID); 
                            icon = 'success';
                        else
                            msg  = sprintf('Confirmado se tratar do sensor %s, diferente do selecionado (%s).', app.Status.ID, app.Node.Value);
                            icon = 'error'; 
                            app.Button_2.Enable = 0;
                        end
                    end
                    msg = sprintf('%s\n\n%s', msg, jsonencode(rmfield(app.Status, {'IP1', 'IP2', 'webPass', 'rootPass', 'ftpPort', 'sftpPort', 'ID', 'ConnectFlag', 'LogDate'})));
                else
                    msg = 'Sensor não respondeu às tentativas de conexão.';
                    icon = 'error'; 
                    
                    app.Lamp.Color = [1,0,0];
                    app.Button_2.Enable = 0;
                end
                uialert(app.UIFigure, msg, 'appColeta', 'Icon', icon)

            end
            
        end

        % Callback function
        function Button_3Pushed(app, event)
            
            ind = find(strcmp(app.CallingApp.RFeyeList.ID, app.Node.Value), 1);

            try
                app.CallingApp.RFeyeList(ind, 2:21) = {app.Status.IP1, ...
                                                       app.Status.IP2, ...
                                                       app.Status.webPass, ...
                                                       app.Status.rootPass, ...
                                                       app.Status.ftpPort, ...
                                                       app.Status.sftpPort, ...
                                                       app.Status.sftpHostname, ...
                                                       app.Status.LoggerNodeInfo, ...
                                                       app.Status.Latitude, ...
                                                       app.Status.Longitude, ...
                                                       app.Status.City, ...
                                                       app.Status.State, ...
                                                       app.Status.webNodeStatus, ...
                                                       app.Status.webCoordinatesStatus, ...
                                                       app.Status.ftpStatus, ...
                                                       app.Status.sftpStatus, ...
                                                       app.Status.ftpStatus_OpenVPN, ...
                                                       app.Status.sftpStatus_OpenVPN, ...
                                                       app.Status.LogDate, ...
                                                       app.Status.ConnectFlag};
    
                app.CallingApp.logger_NodeCreation
                closeFcn(app)
                
            catch ME
                uialert(app.UIFigure, ME.message, 'appColeta', 'Icon', 'error')
            end

        end

        % Callback function
        function connect_FamilyValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Family, app.connect_Family.Value));

            app.connect_Name.Items = unique(app.instrInfo(ind,:).Name);
            connect_NameValueChanged(app)

        end

        % Callback function
        function connect_NameValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);
            
            app.connect_Type.Items = app.instrInfo.connectType{ind};
            connect_TypeValueChanged(app)

        end

        % Callback function
        function connect_TypeValueChanged(app, event)
            
            ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);

            app.connect_Port.Value = app.instrInfo.connectPort{ind};
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Callback function
        function connect_CheckBoxValueChanged(app, event)
            
            if app.connect_CheckBox.Value
                try
                    connect_infoValidation(app, 'Only Instrument IP', app.connect_IP.Value)
                    set(app.connect_Grid2.Children, 'Enable', 'on')

                catch ME
                    app.connect_CheckBox.Value = 0;
                    Misc_uialertCreation(app, 'error', getReport(ME))

                    return
                end

            else
                set(app.connect_Grid2.Children, 'Enable', 'off')
                app.connect_localIP.Value  = '';
                app.connect_publicIP.Value = '';
            end

        end

        % Button pushed function: connect_Button1
        function connect_Button1Pushed(app, event)
            
            try
                if strcmp(app.connect_Name.Value, 'CRFS RFeye')
                    error(['Implementada apenas a leitura do fluxo UDP gerado pelo Logger, o que é acessível no modo "RFeye", ' ...
                        'restando pendente a comunicação com o RFeye por meio do protocolo NCP.'])
                end

                connect_infoValidation(app, 'All', '')

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end

            if app.connect_Button3.Value; ind = app.connect_Tree.SelectedNodes.NodeData;
            else;                         ind = height(app.scpiList)+1;
            end

            app.scpiList(ind,:) = {app.connect_Family.Value, ...
                                    app.connect_Name.Value,        ...
                                    app.connect_instrName.Text{1}, ...
                                    app.connect_Type.Value,        ...
                                    app.connect_IP.Value,          ...
                                    app.connect_Port.Value,        ...
                                    app.connect_Timeout.Value,     ...
                                    app.connect_Buffer.Value,      ...
                                    app.connect_Description.Value, ...
                                    app.connect_CheckBox.Value,    ...
                                    app.connect_localIP.Value,     ...
                                    app.connect_publicIP.Value, 0};

            app.connect_Button2.Value  = 0;
            app.connect_Button3.Value  = 0;

            connect_TreeBuilding(app)

        end

        % Value changed function: connect_Button2
        function connect_Button2ValueChanged(app, event)
            
            if app.connect_Button2.Value
                app.connect_Button3.Enable = 0;

                app.connect_Family.Items      = unique(app.instrInfo.Family);
                app.connect_Family.Value      = 'Receptor';
                app.connect_Description.Value = '';

                connect_FamilyValueChanged(app)
            else
                if isempty(app.connect_Tree.Children)
                    app.connect_Button2.Value = 1;
                    return
                end
                
                connect_Layout(app)
                connect_infoPanel(app)
            end

        end

        % Value changed function: connect_Button3
        function connect_Button3ValueChanged(app, event)
            
            if app.connect_Button3.Value
                app.connect_Button2.Enable = 0;
                
                ind = find(strcmp(app.instrInfo.Name, app.connect_Name.Value), 1);                
                app.connect_Type.Items = app.instrInfo.connectType{ind};
            else
                app.connect_Button2.Enable = 1;
            end
            connect_Layout(app)
            connect_infoPanel(app)

        end

        % Button pushed function: connect_Button4
        function connect_Button4Pushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Tentando se conectar ao instrumento...');

            ind = app.connect_Tree.SelectedNodes.NodeData;
            if strcmp(app.scpiList.Family{ind}, 'Receptor')
                try
                    [app.Instrument, Node] = Connection_Instrument(app.scpiList(ind,:), app.scpiList.Timeout(ind), app.instrInfo, app.udpPortArray, 'JustConnectivity', [], []);

                    if ~isempty(Node)
                        msg = sprintf('%s\n%s', app.scpiList.Name{ind}, Node);

                        if contains(Node, app.connect_instrName.Text{1})
                            app.scpiList.Flag(ind) = 1;
                        end
                        Misc_uialertCreation(app, 'info', msg)

                    else
                        error('Apesar de criado objeto, não foi recebida resposta ao comando "*IDN?".')
                    end

                catch ME
                    app.scpiList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end

            else
                try
                    [~, gps] = Connection_gps(app.scpiList.Type{ind},    ...
                                              app.scpiList.IP{ind},      ...
                                              app.scpiList.Port{ind},    ...
                                              app.scpiList.Timeout(ind), ...
                                              app.scpiList.inBuffer(ind));

                    if ~isempty(gps)
                        app.scpiList.Flag(ind) = 1;

                        msg = sprintf('Status: %.0f\nLatitude: %.6f\nLongitude: %.6f\nHorário: %s (UTC)', gps.Status, gps.Latitude, gps.Longitude, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                        Misc_uialertCreation(app, 'info', msg)
                        
                        if gps.Status
                            app.Task_manualLatitude.Value  = round(gps.Latitude,  6);
                            app.Task_manualLongitude.Value = round(gps.Longitude, 6);
                        end
                    else
                        error('Apesar de criado objeto, não foi recebida informação NMEA/RMC.')
                    end

                catch ME
                    app.scpiList.Flag(ind) = -1;
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end
            connect_TreeBuilding(app)

        end

        % Value changed function: task_FreqStart, task_FreqStop
        function task_FreqStartValueChanged(app, event)
            
            span = (app.task_FreqStop.Value - app.task_FreqStart.Value)*1e+6;

            if span <= 0
                app.Task_Warning1.Visible = 1;
                app.task_StepWidth.Enable = 0;

            else
                app.Task_Warning1.Visible = 0;
                app.task_StepWidth.Enable = 1;
            end

        end

        % Value changed function: task_TraceMode
        function task_TraceModeValueChanged(app, event)
            
            switch app.task_TraceMode.Value
                case 'ClearWrite'
                    app.task_IntegrationFactor.Value = 1;

                otherwise
                    if app.task_IntegrationFactor.Value == 1
                        app.task_IntegrationFactor.Value = 3;
                    end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Receptores RFeye Node 20-6';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Grid1);
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'toolbar_info.png';
            app.Button.Text = '';
            app.connect_Button2 = uibutton(app.GridLayout5, 'state');
            app.connect_Button2.ValueChangedFcn = createCallbackFcn(app, @connect_Button2ValueChanged, true);
            app.connect_Button2.Tooltip = {'Adicionar registro'};
            app.connect_Button2.Icon = 'Edit2.png';
            app.connect_Button2.Text = '';
            app.connect_Button3 = uibutton(app.GridLayout5, 'state');
            app.connect_Button3.ValueChangedFcn = createCallbackFcn(app, @connect_Button3ValueChanged, true);
            app.connect_Button3.Enable = 'off';
            app.connect_Button3.Tooltip = {'Editar registro'};
            app.connect_Button3.Icon = 'edit.png';
            app.connect_Button3.Text = '';
            app.Image2 = uiimage(app.GridLayout5);
            app.Image2.ImageSource = 'Line.png';
            app.connect_Button4 = uibutton(app.GridLayout5, 'push');
            app.connect_Button4.ButtonPushedFcn = createCallbackFcn(app, @connect_Button4Pushed, true);
            app.connect_Button4.Icon = 'ConnectWhite.png';
            app.connect_Button4.Enable = 'off';
            app.connect_Button4.Text = 'Conectividade';
            app.connect_Button1 = uibutton(app.GridLayout5, 'push');
            app.connect_Button1.ButtonPushedFcn = createCallbackFcn(app, @connect_Button1Pushed, true);
            app.connect_Button1.Icon = 'General_editWhite.png';
            app.connect_Button1.Enable = 'off';
            app.connect_Button1.Text = 'Editar';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.Icon = 'toolbar_redX.png';
            app.Button_2.Tooltip = {'Excluir registro'};
            app.Button_2.Text = '';
            app.task_Panel1 = uipanel(app.Grid1);
            app.task_GridA = uigridlayout(app.task_Panel1);
            app.task_PanelA1 = uipanel(app.task_GridA);
            app.task_GridA4 = uigridlayout(app.task_PanelA1);
            app.task_ID = uieditfield(app.task_GridA4, 'numeric');
            app.task_ID.Limits = [1 255];
            app.task_ID.RoundFractionalValues = 'on';
            app.task_ID.ValueDisplayFormat = '%.0f';
            app.task_ID.Editable = 'off';
            app.task_ID.Value = 1;
            app.task_IDLabel = uilabel(app.task_GridA4);
            app.task_IDLabel.Text = 'ThreadID';
            app.task_Description = uieditfield(app.task_GridA4, 'text');
            app.task_Description.Editable = 'off';
            app.task_DescriptionLabel = uilabel(app.task_GridA4);
            app.task_DescriptionLabel.Text = 'Descrição:';
            app.task_FreqStart = uieditfield(app.task_GridA4, 'numeric');
            app.task_FreqStart.Limits = [0 Inf];
            app.task_FreqStart.ValueDisplayFormat = '%.6f';
            app.task_FreqStart.ValueChangedFcn = createCallbackFcn(app, @task_FreqStartValueChanged, true);
            app.task_FreqStart.Editable = 'off';
            app.task_FreqStart.Value = 108;
            app.task_FreqStartLabel = uilabel(app.task_GridA4);
            app.task_FreqStartLabel.Text = 'Frequência inicial (MHz):';
            app.task_FreqStop = uieditfield(app.task_GridA4, 'numeric');
            app.task_FreqStop.Limits = [0 Inf];
            app.task_FreqStop.ValueDisplayFormat = '%.6f';
            app.task_FreqStop.ValueChangedFcn = createCallbackFcn(app, @task_FreqStartValueChanged, true);
            app.task_FreqStop.Editable = 'off';
            app.task_FreqStop.Value = 108;
            app.task_FreqStopLabel = uilabel(app.task_GridA4);
            app.task_FreqStopLabel.Text = 'Frequência final (MHz):';
            app.task_StepWidthLabel = uilabel(app.task_GridA4);
            app.task_StepWidthLabel.Text = 'Passo varredura (kHz):';
            app.task_StepWidth = uieditfield(app.task_GridA4, 'numeric');
            app.task_StepWidth.Limits = [0 Inf];
            app.task_StepWidth.ValueDisplayFormat = '%.3f';
            app.task_StepWidth.Editable = 'off';
            app.task_ResolutionLabel = uilabel(app.task_GridA4);
            app.task_ResolutionLabel.Text = 'Resolução (kHz):';
            app.task_Resolution = uieditfield(app.task_GridA4, 'numeric');
            app.task_Resolution.Limits = [0 Inf];
            app.task_Resolution.ValueDisplayFormat = '%.3f';
            app.task_Resolution.Editable = 'off';
            app.task_RevisitTimeLabel = uilabel(app.task_GridA4);
            app.task_RevisitTimeLabel.Text = 'Tempo revisita (seg):';
            app.task_RevisitTime = uieditfield(app.task_GridA4, 'numeric');
            app.task_RevisitTime.Limits = [0.001 Inf];
            app.task_RevisitTime.ValueDisplayFormat = '%.3f';
            app.task_RevisitTime.Editable = 'off';
            app.task_RevisitTime.Value = 1;
            app.task_TraceMode = uidropdown(app.task_GridA4);
            app.task_TraceMode.Items = {'ClearWrite', 'Average', 'MaxHold', 'MinHold'};
            app.task_TraceMode.ValueChangedFcn = createCallbackFcn(app, @task_TraceModeValueChanged, true);
            app.task_TraceMode.Value = 'Average';
            app.task_TraceModeLabel = uilabel(app.task_GridA4);
            app.task_TraceModeLabel.Text = 'Modo do traço:';
            app.task_Detector = uidropdown(app.task_GridA4);
            app.task_Detector.Items = {'Sample', 'Average/RMS', 'Positive Peak', 'Negative Peak'};
            app.task_Detector.Value = 'Sample';
            app.task_DetectorLabel = uilabel(app.task_GridA4);
            app.task_DetectorLabel.Text = 'Detector:';
            app.task_LevelUnit = uidropdown(app.task_GridA4);
            app.task_LevelUnit.Items = {'dBm', 'dBuV', 'dBuV/m'};
            app.task_LevelUnit.Value = 'dBm';
            app.task_LevelUnitLabel = uilabel(app.task_GridA4);
            app.task_LevelUnitLabel.Text = 'Unidade:';
            app.task_RFModeLabel = uilabel(app.task_GridA4);
            app.task_RFModeLabel.Text = 'Modo de RF:';
            app.task_RFMode = uidropdown(app.task_GridA4);
            app.task_RFMode.Items = {'High Sensitivity', 'Low Distortion', 'Normal'};
            app.task_RFMode.Value = 'High Sensitivity';
            app.task_IntegrationFactor = uieditfield(app.task_GridA4, 'numeric');
            app.task_IntegrationFactor.Limits = [1 Inf];
            app.task_IntegrationFactor.RoundFractionalValues = 'on';
            app.task_IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.task_IntegrationFactor.Editable = 'off';
            app.task_IntegrationFactor.Value = 1;
            app.task_IntegrationFactorLabel = uilabel(app.task_GridA4);
            app.task_IntegrationFactorLabel.Text = 'Fator de integração:';
            app.task_BandLabel = uilabel(app.task_GridA);
            app.task_BandLabel.Text = 'Faixa de frequência selecionada';
            app.task_GridA1 = uigridlayout(app.task_GridA);
            app.task_Name = uidropdown(app.task_GridA1);
            app.task_Name.Items = {};
            app.task_Name.Value = {};
            app.task_NameLabel = uilabel(app.task_GridA1);
            app.task_NameLabel.Text = 'Nome:';
            app.task_NameEdit = uibutton(app.task_GridA1, 'push');
            app.task_NameEdit.Icon = 'General_edit.png';
            app.task_NameEdit.Tooltip = {'Conectividade'};
            app.task_NameEdit.Text = '';
            app.task_GridA2 = uigridlayout(app.task_GridA);
            app.task_Type = uidropdown(app.task_GridA2);
            app.task_Type.Items = {'Monitoração espectral', 'Monitoração espectral - Drive-test', 'Monitoração espectral - Rompimento de máscara espectral'};
            app.task_Type.Value = 'Monitoração espectral';
            app.task_TypeLabel = uilabel(app.task_GridA2);
            app.task_TypeLabel.Text = 'Tipo:';
            app.task_TypeEdit = uibutton(app.task_GridA2, 'push');
            app.task_TypeEdit.Icon = 'tb_occ.gif';
            app.task_TypeEdit.Tooltip = {'Conectividade'};
            app.task_TypeEdit.Text = '';
            app.task_TreeLabel = uilabel(app.task_GridA);
            app.task_TreeLabel.Text = 'Faixa(s) de frequências a monitorar:';
            app.task_Tree = uitree(app.task_GridA);
            app.task_GridA3 = uigridlayout(app.task_GridA);
            app.task_Duration = uieditfield(app.task_GridA3, 'numeric');
            app.task_Duration.Limits = [0 Inf];
            app.task_Duration.ValueDisplayFormat = '%.3f';
            app.task_Duration.Value = 10;
            app.task_DurationLabel = uilabel(app.task_GridA3);
            app.task_DurationLabel.Text = 'Duração:';
            app.task_DurationUnit = uidropdown(app.task_GridA3);
            app.task_DurationUnit.Items = {'min', 'hr'};
            app.task_DurationUnit.Value = 'min';
            app.task_BitsPerPointLabel = uilabel(app.task_GridA3);
            app.task_BitsPerPointLabel.Text = 'Codificação:';
            app.task_BitsPerPoint = uidropdown(app.task_GridA3);
            app.task_BitsPerPoint.Items = {'8 bits'};
            app.task_BitsPerPoint.Value = '8 bits';
            app.Label = uilabel(app.Grid1);
            app.Label.Text = 'Ao lado esquerdo uma árvore com as tarefas. Ao lado direito os aspectos gerais (duração, codificação) e depois os parâmetros de uma faixa. Embaixo dele a árvore com as bandas. Possibilidade de criar ThreadID virtual (ou automático, que é uma contagem). Possibilidade de excluir, alterar ordem, etc etc etc.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTaskEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end